classdef retrospective10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GitHubHyperlink                 matlab.ui.control.Hyperlink
        MessageBox                      matlab.ui.control.TextArea
        AutoRecoLabel                   matlab.ui.control.Label
        AutoRecoCheckBox                matlab.ui.control.CheckBox
        RetrospectiveTitleImage         matlab.ui.control.Image
        JoveHyperlink                   matlab.ui.control.Hyperlink
        RetrospectiveCreditLabel        matlab.ui.control.Label
        DashboardTabGroup               matlab.ui.container.TabGroup
        DashboardTab                    matlab.ui.container.Tab
        ReconstructionTypeButtonGroup   matlab.ui.container.ButtonGroup
        DeepLearningButton              matlab.ui.control.RadioButton
        CompressedSensingButton         matlab.ui.control.RadioButton
        ProgressGauge                   matlab.ui.control.LinearGauge
        ExitButton                      matlab.ui.control.Button
        ExportMRDButton                 matlab.ui.control.Button
        ExportGIFButton                 matlab.ui.control.Button
        ExportDCMButton                 matlab.ui.control.Button
        ReconstructButton               matlab.ui.control.Button
        SortKspaceButton                matlab.ui.control.Button
        FilterButton                    matlab.ui.control.Button
        LoadMRDButton                   matlab.ui.control.Button
        ExportPanel                     matlab.ui.container.Panel
        ReconstructionPanel             matlab.ui.container.Panel
        CinePanel                       matlab.ui.container.Panel
        DynamicsEditFieldLabel          matlab.ui.control.Label
        FramesEditFieldLabel            matlab.ui.control.Label
        RecoTypeDropDownLabel           matlab.ui.control.Label
        DynamicsEditField               matlab.ui.control.NumericEditField
        FramesEditField                 matlab.ui.control.NumericEditField
        RecoTypeDropDown                matlab.ui.control.DropDown
        DetectedRatesBpmPanel           matlab.ui.container.Panel
        RespWindowEditFieldLabel        matlab.ui.control.Label
        RespEditFieldLabel              matlab.ui.control.Label
        HeartEditFieldLabel             matlab.ui.control.Label
        WidthLabel                      matlab.ui.control.Label
        CenterLabel                     matlab.ui.control.Label
        ResetFilterValuesButton         matlab.ui.control.Button
        RespWindowEditField             matlab.ui.control.NumericEditField
        RespWidthEditField              matlab.ui.control.NumericEditField
        RespEditField                   matlab.ui.control.NumericEditField
        HeartWidthEditField             matlab.ui.control.NumericEditField
        HeartEditField                  matlab.ui.control.NumericEditField
        Lamp7                           matlab.ui.control.Lamp
        Lamp6                           matlab.ui.control.Lamp
        Lamp5                           matlab.ui.control.Lamp
        Lamp4                           matlab.ui.control.Lamp
        Lamp3                           matlab.ui.control.Lamp
        Lamp2                           matlab.ui.control.Lamp
        Lamp1                           matlab.ui.control.Lamp
        NavTabGroup                     matlab.ui.container.TabGroup
        NavTab                          matlab.ui.container.Tab
        HeartCoilSpinner1Label          matlab.ui.control.Label
        HeartCoilSpinner1               matlab.ui.control.Spinner
        RespCoilSpinner1Label           matlab.ui.control.Label
        RespCoilSpinner1                matlab.ui.control.Spinner
        StopMotionButton                matlab.ui.control.Button
        StartMotionButton               matlab.ui.control.Button
        NavNRPoints2EditField           matlab.ui.control.NumericEditField
        NavNRPoints2EditFieldLabel      matlab.ui.control.Label
        NavEnd2EditField                matlab.ui.control.NumericEditField
        NavEnd2EditFieldLabel           matlab.ui.control.Label
        Nav2ButtonGroup                 matlab.ui.container.ButtonGroup
        ExtECHO2Button                  matlab.ui.control.RadioButton
        ExtFID2Button                   matlab.ui.control.RadioButton
        SliceFID2Button                 matlab.ui.control.RadioButton
        Nav1ButtonGroup                 matlab.ui.container.ButtonGroup
        ExtECHO1Button                  matlab.ui.control.RadioButton
        ExtFID1Button                   matlab.ui.control.RadioButton
        SliceFID1Button                 matlab.ui.control.RadioButton
        NavNRPoints1EditField           matlab.ui.control.NumericEditField
        NavNRPoints1EditFieldLabel      matlab.ui.control.Label
        NavEnd1EditField                matlab.ui.control.NumericEditField
        NavEnd1EditFieldLabel           matlab.ui.control.Label
        Nav1Fig                         matlab.ui.control.UIAxes
        Nav2Fig                         matlab.ui.control.UIAxes
        HeartTab                        matlab.ui.container.Tab
        HeartMaxEditField               matlab.ui.control.NumericEditField
        HeartMaxEditFieldLabel          matlab.ui.control.Label
        HeartMinEditField               matlab.ui.control.NumericEditField
        HeartMinEditFieldLabel          matlab.ui.control.Label
        HeartNavFlipSwitch              matlab.ui.control.ToggleSwitch
        HeartNavFlipSwitchLabel         matlab.ui.control.Label
        HeartLogLinButton               matlab.ui.control.Button
        HeartCoilSpinner2Label          matlab.ui.control.Label
        HeartCoilSpinner2               matlab.ui.control.Spinner
        HeartSpectrumFig                matlab.ui.control.UIAxes
        HeartRawNavFig                  matlab.ui.control.UIAxes
        RespTab                         matlab.ui.container.Tab
        RespMaxEditField                matlab.ui.control.NumericEditField
        RespMaxEditFieldLabel           matlab.ui.control.Label
        RespMinEditField                matlab.ui.control.NumericEditField
        RespMinEditFieldLabel           matlab.ui.control.Label
        RespNavFlipSwitch               matlab.ui.control.ToggleSwitch
        RespNavFlipSwitchLabel          matlab.ui.control.Label
        RespLogLinButton                matlab.ui.control.Button
        RespCoilSpinner2Label           matlab.ui.control.Label
        RespCoilSpinner2                matlab.ui.control.Spinner
        RespSpectrumFig                 matlab.ui.control.UIAxes
        RespRawNavFig                   matlab.ui.control.UIAxes
        NavSettingsTab                  matlab.ui.container.Tab
        DiscardCheckBox                 matlab.ui.control.CheckBox
        FilterPerSliceLabel             matlab.ui.control.Label
        RespShiftEditFieldLabel         matlab.ui.control.Label
        MaxRespRateEditFieldLabel       matlab.ui.control.Label
        MinRespRateEditFieldLabel       matlab.ui.control.Label
        MaxHeartRateEditFieldLabel      matlab.ui.control.Label
        MinHeartRateEditFieldLabel      matlab.ui.control.Label
        FilterOrderEditFieldLabel       matlab.ui.control.Label
        MessageLevelSpinnerLabel        matlab.ui.control.Label
        AutoSoundsCheckBoxLabel         matlab.ui.control.Label
        IgnoreRespToggleCheckBoxLabel   matlab.ui.control.Label
        RespToggleCheckBoxLabel         matlab.ui.control.Label
        DiscardEditFieldLabel           matlab.ui.control.Label
        NavLabel2                       matlab.ui.control.Label
        RespLabel                       matlab.ui.control.Label
        SoundMessagesLabel              matlab.ui.control.Label
        ColorsLabel                     matlab.ui.control.Label
        PhysioFilteringLabel            matlab.ui.control.Label
        FilterPerSliceCheckBox          matlab.ui.control.CheckBox
        RespShiftEditField              matlab.ui.control.NumericEditField
        MaxRespRateEditField            matlab.ui.control.NumericEditField
        MinRespRateEditField            matlab.ui.control.NumericEditField
        MaxHeartRateEditField           matlab.ui.control.NumericEditField
        MinHeartRateEditField           matlab.ui.control.NumericEditField
        FilterOrderEditField            matlab.ui.control.NumericEditField
        SpeciesButton                   matlab.ui.container.ButtonGroup
        ZebraFishButton                 matlab.ui.control.RadioButton
        RatButton                       matlab.ui.control.RadioButton
        MouseButton                     matlab.ui.control.RadioButton
        PresetsLabel                    matlab.ui.control.Label
        ColorsButtonGroup               matlab.ui.container.ButtonGroup
        BlackWhiteButton                matlab.ui.control.RadioButton
        ClassicButton                   matlab.ui.control.RadioButton
        MessageLevelSpinner             matlab.ui.control.Spinner
        AutoSoundsCheckBox              matlab.ui.control.CheckBox
        IgnoreRespToggleCheckBox        matlab.ui.control.CheckBox
        RespToggleCheckBox              matlab.ui.control.CheckBox
        DiscardEditField                matlab.ui.control.NumericEditField
        NavAnalysisTabGroup             matlab.ui.container.TabGroup
        NavAnalysisTab                  matlab.ui.container.Tab
        ExcludeEndSlider                matlab.ui.control.Slider
        ExcludeStartSlider              matlab.ui.control.Slider
        ExcludeCheckBoxLabel            matlab.ui.control.Label
        ExcludeCheckBox                 matlab.ui.control.CheckBox
        IncludeEndSlider                matlab.ui.control.Slider
        IncludeStartSlider              matlab.ui.control.Slider
        IncludeCheckBoxLabel            matlab.ui.control.Label
        IncludeCheckBox                 matlab.ui.control.CheckBox
        NavFastSlowSwitch               matlab.ui.control.RockerSwitch
        NavStopButton                   matlab.ui.control.Button
        NavStartButton                  matlab.ui.control.Button
        RangeSlider                     matlab.ui.control.Slider
        RangeEditField                  matlab.ui.control.NumericEditField
        RangeEditFieldLabel             matlab.ui.control.Label
        StartSlider                     matlab.ui.control.Slider
        StartEditField                  matlab.ui.control.NumericEditField
        startsLabel                     matlab.ui.control.Label
        DisplayRangeLabel               matlab.ui.control.Label
        HeartNavFig                     matlab.ui.control.UIAxes
        RespNavFig                      matlab.ui.control.UIAxes
        HeartRateTimeFig                matlab.ui.control.UIAxes
        RespRateTimeFig                 matlab.ui.control.UIAxes
        ParametersTabGroup              matlab.ui.container.TabGroup
        SequenceTab                     matlab.ui.container.Tab
        FlipAnglesViewField             matlab.ui.control.EditField
        FlipAnglesViewFieldLabel        matlab.ui.control.Label
        TEViewField                     matlab.ui.control.NumericEditField
        TEViewFieldLabel                matlab.ui.control.Label
        FieldOfViewViewField            matlab.ui.control.NumericEditField
        FieldOfViewViewFieldLabel       matlab.ui.control.Label
        NrFlipAnglesViewField           matlab.ui.control.NumericEditField
        NrFlipAnglesViewFieldLabel      matlab.ui.control.Label
        TimeDynViewField                matlab.ui.control.EditField
        TimeDynViewFieldLabel           matlab.ui.control.Label
        ScanTimeViewField               matlab.ui.control.EditField
        ScanTimeViewFieldLabel          matlab.ui.control.Label
        NcViewField                     matlab.ui.control.NumericEditField
        NcViewFieldLabel                matlab.ui.control.Label
        NzViewField                     matlab.ui.control.NumericEditField
        NzViewFieldLabel                matlab.ui.control.Label
        TrajectoryViewField             matlab.ui.control.EditField
        TrajectoryViewFieldLabel        matlab.ui.control.Label
        TRViewField                     matlab.ui.control.NumericEditField
        TRViewFieldLabel                matlab.ui.control.Label
        FillingViewField                matlab.ui.control.NumericEditField
        FillingViewFieldLabel           matlab.ui.control.Label
        AveragesViewField               matlab.ui.control.NumericEditField
        AveragesViewFieldLabel          matlab.ui.control.Label
        NrKlinesViewField               matlab.ui.control.NumericEditField
        NrKlinesViewFieldLabel          matlab.ui.control.Label
        RepetitionsViewField            matlab.ui.control.NumericEditField
        RepetitionsViewFieldLabel       matlab.ui.control.Label
        TrajectorySizeViewField         matlab.ui.control.NumericEditField
        TrajectorySizeViewFieldLabel    matlab.ui.control.Label
        NxViewField                     matlab.ui.control.NumericEditField
        NxViewFieldLabel                matlab.ui.control.Label
        NyViewField                     matlab.ui.control.NumericEditField
        NyViewFieldLabel                matlab.ui.control.Label
        DataFileLabel                   matlab.ui.control.Label
        DataFile                        matlab.ui.control.TextArea
        TrajectoryFig                   matlab.ui.control.UIAxes
        GeometryTab                     matlab.ui.container.Tab
        SliceGapViewField               matlab.ui.control.NumericEditField
        SliceGapViewFieldLabel          matlab.ui.control.Label
        OffsetXViewField                matlab.ui.control.NumericEditField
        OffsetXViewFieldLabel           matlab.ui.control.Label
        OffsetYViewField                matlab.ui.control.NumericEditField
        OffsetYViewFieldLabel           matlab.ui.control.Label
        OffsetZViewField                matlab.ui.control.NumericEditField
        OffsetZViewFieldLabel           matlab.ui.control.Label
        AngleXViewField                 matlab.ui.control.NumericEditField
        AngleXViewFieldLabel            matlab.ui.control.Label
        AngleYViewField                 matlab.ui.control.NumericEditField
        AngleYViewFieldLabel            matlab.ui.control.Label
        AngleZViewField                 matlab.ui.control.NumericEditField
        AngleZViewFieldLabel            matlab.ui.control.Label
        FieldOfViewViewField2           matlab.ui.control.NumericEditField
        FieldOfViewViewField2Label      matlab.ui.control.Label
        AspectRatioViewField            matlab.ui.control.NumericEditField
        AspectRatioViewFieldLabel       matlab.ui.control.Label
        SliceThicknessViewField         matlab.ui.control.NumericEditField
        SliceThicknessViewFieldLabel    matlab.ui.control.Label
        PhaseOrientationViewField       matlab.ui.control.NumericEditField
        PhaseOrientationViewFieldLabel  matlab.ui.control.Label
        RecoParametersTab               matlab.ui.container.Tab
        FilteringLabel                  matlab.ui.control.Label
        VariousLabel                    matlab.ui.control.Label
        ReconstructionPresetsLabel      matlab.ui.control.Label
        FramesharingLabel               matlab.ui.control.Label
        CSrecoLabel                     matlab.ui.control.Label
        MatlabRecoCheckBoxLabel         matlab.ui.control.Label
        AutoGuessRecoCheckBoxLabel      matlab.ui.control.Label
        SharingEditFieldLabel           matlab.ui.control.Label
        TukeyFilterCheckBoxLabel        matlab.ui.control.Label
        ZeroFillCheckBoxLabel           matlab.ui.control.Label
        RingRmCheckBoxLabel             matlab.ui.control.Label
        NLMFCheckBoxLabel               matlab.ui.control.Label
        ESPIRiTCheckBoxLabel            matlab.ui.control.Label
        TVdynEditFieldLabel             matlab.ui.control.Label
        TVcineEditFieldLabel            matlab.ui.control.Label
        LLRxyzEditFieldLabel            matlab.ui.control.Label
        TVxyzEditFieldLabel             matlab.ui.control.Label
        WVxyzEditFieldLabel             matlab.ui.control.Label
        RecoPresetsButtonGroup          matlab.ui.container.ButtonGroup
        MultiEchoButton                 matlab.ui.control.RadioButton
        VfaFunctionButton               matlab.ui.control.RadioButton
        RealTimeFunctionButton          matlab.ui.control.RadioButton
        RespFunctionButton              matlab.ui.control.RadioButton
        DiastolicFunctionButton         matlab.ui.control.RadioButton
        SystolicFunctionButton          matlab.ui.control.RadioButton
        ScoutButton                     matlab.ui.control.RadioButton
        MatlabRecoCheckBox              matlab.ui.control.CheckBox
        AutoGuessRecoCheckBox           matlab.ui.control.CheckBox
        SharingEditField                matlab.ui.control.NumericEditField
        TukeyFilterCheckBox             matlab.ui.control.CheckBox
        ZeroFillCheckBox                matlab.ui.control.CheckBox
        RingRmCheckBox                  matlab.ui.control.CheckBox
        NLMFfilterStrengthEditField     matlab.ui.control.NumericEditField
        NLMFCheckBox                    matlab.ui.control.CheckBox
        ESPIRiTCheckBox                 matlab.ui.control.CheckBox
        TVdynEditField                  matlab.ui.control.NumericEditField
        TVcineEditField                 matlab.ui.control.NumericEditField
        LLRxyzEditField                 matlab.ui.control.NumericEditField
        TVxyzEditField                  matlab.ui.control.NumericEditField
        WVxyzEditField                  matlab.ui.control.NumericEditField
        RadialTab                       matlab.ui.container.Tab
        ZTELabel                        matlab.ui.control.Label
        ZTEShiftEditFieldLabel          matlab.ui.control.Label
        ZTEShiftEditField               matlab.ui.control.NumericEditField
        ZTEDeadTimeEditFieldLabel       matlab.ui.control.Label
        ZTEDeadTimeEditField            matlab.ui.control.NumericEditField
        ZTEFullEchoCheckBoxLabel        matlab.ui.control.Label
        ZTEFullEchoCheckBox             matlab.ui.control.CheckBox
        DensityCorrectCheckBoxLabel     matlab.ui.control.Label
        DensityCorrectCheckBox          matlab.ui.control.CheckBox
        CenterEchoCheckBoxLabel         matlab.ui.control.Label
        CenterEchoCheckBox              matlab.ui.control.CheckBox
        PhaseCorrectCheckBoxLabel       matlab.ui.control.Label
        PhaseCorrectCheckBox            matlab.ui.control.CheckBox
        RadialTrajectoryLabel           matlab.ui.control.Label
        RingMethodCheckBoxLabel         matlab.ui.control.Label
        RingMethodCheckBox              matlab.ui.control.CheckBox
        DataOffsetRadialEditField       matlab.ui.control.NumericEditField
        DataOffsetRadialEditFieldLabel  matlab.ui.control.Label
        GzDelayEditField                matlab.ui.control.NumericEditField
        GzDelayEditFieldLabel           matlab.ui.control.Label
        GyDelayEditField                matlab.ui.control.NumericEditField
        GyDelayEditFieldLabel           matlab.ui.control.Label
        GxDelayEditField                matlab.ui.control.NumericEditField
        GxDelayEditFieldLabel           matlab.ui.control.Label
        GradientdelaysLabel             matlab.ui.control.Label
        GradDelayCalibrationCheckBoxLabel  matlab.ui.control.Label
        GradDelayCalibrationCheckBox    matlab.ui.control.CheckBox
        ReconstructionTabGroup          matlab.ui.container.TabGroup
        RecoTab                         matlab.ui.container.Tab
        SegmentLVButton                 matlab.ui.control.Button
        ShowNavButton                   matlab.ui.control.Button
        OrientationLabel4               matlab.ui.control.Label
        OrientationLabel3               matlab.ui.control.Label
        OrientationLabel1               matlab.ui.control.Label
        OrientationLabel2               matlab.ui.control.Label
        AutoWLButton                    matlab.ui.control.StateButton
        MovieDynEditFieldLabel          matlab.ui.control.Label
        MovieDynEditField               matlab.ui.control.NumericEditField
        LevelScaleSlider                matlab.ui.control.Slider
        LevelScaleSliderLabel           matlab.ui.control.Label
        WindowScaleSlider               matlab.ui.control.Slider
        WindowScaleSliderLabel          matlab.ui.control.Label
        MovieSliceEditField             matlab.ui.control.NumericEditField
        MovieSliceEditFieldLabel        matlab.ui.control.Label
        FinalRespViewField              matlab.ui.control.NumericEditField
        FinalRespViewFieldLabel         matlab.ui.control.Label
        FinalHeartViewField             matlab.ui.control.NumericEditField
        FinalHeartViewFieldLabel        matlab.ui.control.Label
        MovieFrameEditField             matlab.ui.control.NumericEditField
        MovieFrameEditFieldLabel        matlab.ui.control.Label
        MovieCheckBox1                  matlab.ui.control.CheckBox
        MovieCheckBox2                  matlab.ui.control.CheckBox
        MovieCheckBox3                  matlab.ui.control.CheckBox
        MovieDynDecrButton              matlab.ui.control.Button
        MovieDynLabel                   matlab.ui.control.Label
        MovieDynIncrButton              matlab.ui.control.Button
        SenseMapSpinner2                matlab.ui.control.Spinner
        SenseMapSpinner1                matlab.ui.control.Spinner
        MovieCoilSpinner                matlab.ui.control.Spinner
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SenseMapButton                  matlab.ui.control.RadioButton
        kSpaceButton                    matlab.ui.control.RadioButton
        AveragesButton                  matlab.ui.control.RadioButton
        MovieButton                     matlab.ui.control.RadioButton
        MovieSliceDecrButton            matlab.ui.control.Button
        MovieSliceLabel                 matlab.ui.control.Label
        MovieSliceIncrButton            matlab.ui.control.Button
        MovieFrameDecrButton            matlab.ui.control.Button
        MovieFrameLabel                 matlab.ui.control.Label
        MovieFrameIncrButton            matlab.ui.control.Button
        MovieRotateLabel                matlab.ui.control.Label
        MovieccwButton                  matlab.ui.control.Button
        MoviecwButton                   matlab.ui.control.Button
        ScaleLabel                      matlab.ui.control.Label
        MovieStartButton                matlab.ui.control.Button
        MovieStopButton                 matlab.ui.control.Button
        MovieFig                        matlab.ui.control.UIAxes
        RadialRecoTab                   matlab.ui.container.Tab
        TrajMovie3DStopButton           matlab.ui.control.Button
        TrajMovie3DStartButton          matlab.ui.control.Button
        Traj3DLabel                     matlab.ui.control.Label
        SpokeEditFieldLabel             matlab.ui.control.Label
        SpokeEditField                  matlab.ui.control.NumericEditField
        TrajMovieStopButton             matlab.ui.control.Button
        TrajMovieStartButton            matlab.ui.control.Button
        TrajMovieDynEditField           matlab.ui.control.NumericEditField
        TrajMovieSliceEditField         matlab.ui.control.NumericEditField
        TrajMovieFrameEditField         matlab.ui.control.NumericEditField
        TrajMovieCheckBox3              matlab.ui.control.CheckBox
        TrajMovieCheckBox2              matlab.ui.control.CheckBox
        TrajMovieCheckBox1              matlab.ui.control.CheckBox
        TrajMovieFrameEditFieldLabel    matlab.ui.control.Label
        TrajMovieSliceEditFieldLabel    matlab.ui.control.Label
        TrajMovieDynEditFieldLabel      matlab.ui.control.Label
        TrajectoryLargeFig              matlab.ui.control.UIAxes
        StatusLabel                     matlab.ui.control.Label
        StatusImage                     matlab.ui.control.Image
        StatusBackgroundBorder          matlab.ui.control.EditField
        StatusBackgroundImage           matlab.ui.control.Image
        MRSImage                        matlab.ui.control.Image
        TulipImage                      matlab.ui.control.Image
        Credits                         matlab.ui.control.Label
    end


    % Retrospective -- retrospectively gated MRI reconstruction
    %
    % Author : Gustav Strijkers
    % Date   : 2026-09-03
    %
    % Amsterdam UMC, Academic Medical Center (AMC), Amsterdam, the Netherlands
    % g.j.strijkers@amsterdamumc.nl
    %
    % Purpose:
    %   The interface. It owns the widgets and the state that belongs to what is
    %   on screen, and nothing else: the data, the reconstruction and the exports
    %   are @retroData and the +retro package.
    %
    % How it works:
    %   The pipeline is the dashboard, left to right, and each step is gated on
    %   the one before it by a validity flag: load, filter the navigator, sort
    %   into k-space, reconstruct, export. A step that invalidates what follows
    %   clears those flags and resets the lamps, so the interface only ever
    %   offers what the current state supports.
    %
    %   The boundary with the data is a plain struct. DataParametersFcn and
    %   gatherRecoParams read the controls into it, and @retroData is handed that
    %   struct -- or the app itself, which retro.asParams turns into one. Nothing
    %   below this file reads a widget, which is what lets the same
    %   reconstruction run from a script and in a test.
    %
    %   Reporting goes the other way through AppReporter: the class writes
    %   messages, status and progress through an interface, and this file
    %   supplies the implementation that draws them.
    %
    %   Movies are loops that check a stop flag each frame rather than being
    %   interrupted, which is what leaves the axes in a valid state. The first
    %   frame is drawn in full and the rest update in place; that split is what
    %   makes playback smooth.
    %
    %   The private helpers come first, then the callbacks. The component layout
    %   at the end is generated by App Designer.
    %
    % Inputs:
    %   none - the application is started by running it
    %
    % Output:
    %   none - the application window
    %
    %
    %
    %
    % Version: 2 Sept 2026
    % Matlab 2025a
    %
    %
    % Version 1.0
    % - initial commit
    %
    % Version 2.0 changes:
    % - app designer GUI 
    %
    % Version 2.1 changes:
    % - Cleaned-up the code
    % - GIF export file name now includes the number of frames
    % - Automatically start sorting k-space when the number of frames is changed
    % - Added buttons to rotate movie
    % - Added buttons to shift movie in the frame/time dimension
    % - Animated gif export image intensity is scaled by the intensity scaling slider
    % - Trajectory figure added
    % - Full x-scale in plot windows
    % - Added sliders to adjust navigator view range
    % - Parameter no_effective_views is now obsolete
    %
    % Version 3.0 changes:
    % - Added a matlab Conjugent Gradient method for CS-reconstruction without Bart toolbox
    %
    % Version 3.1 changes:
    % - Added the possibility to reconstruction a respiratory CINE
    % - Corrected a bug in the calculation of the total acquisition time
    % - Added the possibility to reconstruct only part of the data
    % - Added detected respiration and heart rate markers and values in navigator power spectrum
    % - Bug fixed number_of_cardiac frames
    % - Improved check on correct loading of MRD file
    % - Cleaned up the code
    %
    % Version 3.2 changes:
    % - Added edit fields for cardiac and respiratory filter values
    % - Fixed an issue with the image size of the movie in the app
    % - Added a reconstruction progress bar
    %
    % Version 3.3 changes:
    % - Bug fixes
    %
    % Version 3.4 changes:
    % - Major clean up of the code to accomodate future versions
    % - Added app message box
    % - Bug fixes
    % - Simplification of how to switch the buttons and lights on and off
    %
    % Version 3.5 changes:
    % - Bug fix for movie frame circular rotation DICOM export
    % - Fix a crash when respiratory or heart filter width > center value
    %
    % Version 3.6 changes:
    % - Added Total Variation in space regularization - Only works with Bart toolbox
    % - Now reading DICOM information from scanner reconstruction, including correct orientations
    % - VNSA weighting added
    % - Added additional search for DICOM information, compatible with PET/MRI systems
    %
    % Version 4.0 changes:
    % - Bug fixes
    % - Reconstructions of 3D+time data implemented
    % - Cleaned-up Matlab conjugate-gradient reconstruction code
    % - Added busy icons
    %
    % Version 5.0 changes:
    % - Added averages and k-space view
    % - Reconstruction of multi-coil data, tested with 2 receiver coils
    % - PCA analysis of navigator data and multi-coil data
    % - ESPIRiT coil sensitivity maps for multi-coil data
    % - Cosmetic changes
    % - Window/Level intensity scaling
    % - Respiration filtering with main peak + 1 harmonic
    % - Image orientation corrected for phase-encoding-orientation direction
    %
    % Version 5.1 changes:
    % - Added MRD export button, not functional yet
    % - Added cardiac absolute binning sorting option
    %
    % Version 5.2 changes:
    % - Added average navigator window (not very useful it seems)
    %
    % Version 5.3 changes:
    % - Radial import, first attempts
    % - Cleaned up code to include radial option
    % - Data window includes now an 'include' and 'exclude' data option
    % - Cleaned up code to make it easier to include more navigator types in the future
    %
    % Version 6.0 changes:
    % - Now supports reconstructions of dynamics
    % - VNSA removed (does not seem very useful)
    % - Average navigator removed (idem)
    % - MRD export and reconstruction by scanner reconstructor
    % - Improved check for loading unique dataset in loadMRD
    %
    % Version 6.1 changes:
    % - Implemented 1 frame reconstruction for 2D and 3D recos
    % - Corrected sequential and centric k-space filling for views2
    % - SUR file export to /1 directory on MRI/PET systems
    % - Multi-slice 2D acquisitions supported
    % - If on pc, check for system MRD and data directories
    %
    % Version 6.2 changes:
    % - Correction on 3D navigator code / bug fixes
    % - Discard points parameter added to prevent zipper artifact
    %
    % Version 6.3 changes:
    % - 2D and 3D Tukey filter of k-space, prevents zipper artifact
    % - Added autointensity button
    % - Navigator movie function added
    % - 3D P2ROUD trajectory import implemented, with pe1_order = 4
    %
    % Version 7.0 changes:
    % - Saves textfile with reconstruction setting parameters
    % - L-curve analysis of single slice 2D cine data
    % - Working on radial reconstruction
    % - Zoom of movie possible
    %
    % Version 7.1 changes:
    % - Advanced navigator panel with presets for mouse and rat
    % - Preset panel with presets for different reconstructions
    %
    % Version 7.2 changes:
    % - Bug fix in initial values navigator sliders
    % - Improved respiration detection for multi-slice acquistions
    %
    % Version 7.3 changes:
    % - Reconstruction of realtime = 1 dynamic per heart beat added
    % - Improved export of dynamic series
    % - Added Locally Low-Rank regularization parameter
    %
    % Version 7.3.1 changes:
    % - Import for B-type scanners
    %
    % Version 7.4
    % - Removed CLEAR option - does not seem to do much
    % - Weighted k-line sharing option
    % - Bug fix in L-curve analysis
    %
    % Version 7.5
    % - Support for non-square FOV
    %
    % Version 8.0
    % - VFA T1 mapping import added
    % - 3D gif data is exported as collage
    %
    % Version 8.1
    % - Cleaned up the code
    % - Software version added
    % - Introduced classes for better maintainablity
    %
    % Version 8.2 & 8.3
    % - Several bug fixes
    % - TR = 0 bug
    %
    % Version 8.4
    % - RescaleSlope & RescaleIntercept output in dicom file
    %
    % Version 8.5
    % - Added button to toggle reconstruction to inspiration or expiration
    % - Fixed a bug with MRD export
    % - Fixed a bug in the image orientation with DICOM export
    %
    % Version 8.6
    % - 3D UTE
    % - median filter for navigator spectrum
    % - GIF export also for averages and k-space
    % - Increased MRD import speed dramatically
    % - Improved L-curve analysis
    %
    % Version 9
    % - Gradient delay calibration for 3D UTE
    % - Worked on 2D (gradient-echo) radial scanning
    % - Aspectratio bug fixed
    % - Viewing of trajectory and trajectory movie
    % - Total generalized variation regularization if available
    % - 2D (multi-slice) gradient-echo radial scanning available
    % - 3D UTE scanning available
    % - Some fancy trajectory visualizations for UTE and radial scans
    % - beta: image orientation indications
    % - gif export bug fix
    % - Auto detect nr discard samples
    % - PCA image denoising
    % - spoke increment added for radial imaging
    % - Bug fixes in the 3D P2ROUD reco
    % - Pressing 's' in the app saves an image of the app
    % - Cleaned up gif, dicom, mrd, sur export functions
    % - Fixed error on exit bug
    %
    % Version 9.4
    % - Massive cleanup, reduced number of methods classes to 1 (r) to improve readability
    % - Updated tool tips
    % - Better error check for writing MRD, Dicom, and GIF files
    %
    % Version 9.5
    % - Non-square matrix implementation in app
    % - Square pixel size for DICOM export and MEDIS compatibility
    % - Dicom attributes imageType and ScanningSequence added for MEDIS import
    %
    % Version 9.6
    % - Black/white and classic color interface
    % - Analyzed code to remove dependencies on too many toolboxes
    % - Remaining toolboxes:
    %           MATLAB
    %           Image processing toolbox
    %           Parallel computing toolbox
    %           Signal processing toolbox
    % - Improved B-type scanner import
    % - Some bug-fixes to the interface
    % - Improved DICOM export
    % - Shift respiratory window option
    % - Export K-space option
    %
    % Version 9.7
    % - FLASH sequence version check for off-center corrections
    % - Adjustments to DICOM export to accomodate ITKsnap import
    % - 'a' hotkey to import matlab file back into app
    %
    % Version 9.8
    % - Some preps to include AI reconstructor
    % - Some bug fixes to on and off switching of buttons
    % - Pixel interpolation of movie
    % - AI reconstructor implemented
    % - Fixed orientation labels
    % - Fixed filtering overflow error with large data (hopefully fixed)
    % - Some bug fixes to 3D B-type data
    % - Nifti export
    % - Added debug/message levels 1,2,3
    %
    % Version 10.0
    % - New functionality for external navigator
    % - Cleaned up retro.m class functions
    % - Much improved algorithm for detecting positive respiratory peaks
    % - Fixed an error in the PCA denoising algorithm
    % - Introduced log file in temp directory
    % - Navigator plots x-axes in seconds
    % - Centering of k-space
    % - Check for GPU support with BART
    %
    % Version 10.1
    % - Fixed broken radial import
    % - Improved external navigator analysis
    % - Some bug fixes
    % - Successfully tested bart gpu implementation
    % - Non-local means filter replaces PCA
    % - External navigator visualization
    %
    % Version 10.2
    % - Bug fixes in MRD export
    % - JSON settings file
    % - Several bug fixes
    % - Bug in colors of app fixed
    % - Automatic DCM export with MRD export
    % - Zero-filling and Tukey filter optional
    %
    % Version 10.3
    % - Improved AI reconstruction (uses Python)
    % - Fixed a bug in multi-stack import and export
    % - Separate heart and resp rates for different stacks
    % - Improved auto reco
    % - Fixed version check
    % - Various bug fixes
    %
    % Version 10.4
    % - Improved AI reconstruction
    % - Automatic LV segmentation on short-axis slices
    % - Improved frame sharing and removed bug
    % - Removed L-curve option
    % - Massive file structure overhaul
    % - Many bugs resolved
    % -
    % -

    % Constants
    properties (Constant)

        appVersion = "10.4.4"    % software version

    end

    % Variables within the app
    properties (Access = public)

        %#ok<*ST2NM>
        %#ok<*AGROW>

        % Software version
        bartVersion                                                     % Bart version
        pcType                                                          % AppleIntel, AppleSilicon, Windows, Unix

        % Settings
        jsonPar                                                         % JSON parameters

        % Classes
        r                                   retroData                   % methods class

        % Directories & filenames
        appPath                                                         % application path
        oldPath                                                         % starting path
        currentPath                                                     % search path of a deployed app
        mrdFileName                                                     % mrd file name
        mrdImportPath                                                   % mrd import path
        mrdAutoImportPath                                               % auto loading mrd import path
        dicomExportPath                                                 % dicom export path
        gifExportPath                                                   % gif export path
        mrdExportPath                                                   % mrd export path
        tag                                                             % filename tag for dicom export
        mrdFile                                                         % mrd data file
        mrdAutoFile                                                     % auto loading mrd file
        logFile                                                         % log file ID
        oldWarningState             % warning state MATLAB had when the app started
        busyFlag = false            % a step is running: status BUSY until it ends
        closeRequested = false      % the window was closed while a step was running
        closeTimer                  % timer that closes the app once that step has finished

        % Movie parameters
        nrFrames                                                        % number of movie frames
        nrDynamics                                                      % number of dynamics or flip-angles
        nrSlices                                                        % number of slices
        nrCardFrames                                                    % number of cardiac frames
        nrRespFrames                                                    % number of respiratory frames

        % Flags
        bartDetectedFlag                                                % bart toolbox available true or false
        aiDetectedFlag                                                  % AI engine detected
        gpuPresentFlag                                                  % Bart compiled with GPU or not (will be checked automatically)
        aiModuleFlag                                                    % compile with (true) or without (false) AI module
        validFilterFlag                                                 % navigator data filtered true or false
        validSortFlag                                                   % valid sorting of the data true or false
        validRecoFlag                                                   % valid reconstruction true or false
        validSenseMapFlag                                               % sense maps exists, true or false
        validSegmentationFlag                                           % valid segmentation exists, true or false
        recoDeclinedFlag = false                                        % reconstruction declined with a reason, rather than attempted and failed
        stopMovieFlag                                                   % flag to stop the movie (true / false)
        stopNavTimeMovieFlag                                            % flag to stop the navigator movie (true / false)
        stopRawNavMovieFlag                                             % flag to stop the raw navigator FID or ECHO movie (true / false)
        stopRadialMovieFlag                                             % flag to stop the radial trajectorie movie (true / false)
        stop3DMovieFlag                                                 % flag to stop the 3D trajectorie movie (true / false)
        orientationsFlag                                                % plot image orientations (true / false)
        fidNavFlag = false                                              % slice fid navigator (true / false)
        extFidNavFlag = false                                           % external fid navigator (true / false)
        extEchoNavFlag = false                                          % external echo navigator (true / false)
        showExtNavFlag = false                                          % show external navigator slice (true / false)
        blockMessagesFlag = false                                       % block messages in message window (true / false)
        testMode = false                                                % app running in automatic test mode (true / false)

        % Plot and movie elements
        progress                                                        % reconstruction progress bar
        cbar                                                            % color bar
        level                                                           % movie intensity level
        window                                                          % movie intensity window
        navMovieSpeed                                                   % speed of the navigator movie
        sliceNr                                                         % slice shown in the movie
        dynamicNr                                                       % dynamic shown in the movie
        dWin1                                                           % inclusion datawindow in heartrate plot
        dWin2                                                           % inclusion datawindow in respirationrate plot
        dWin3                                                           % exclusion datawindow in heartrate plot
        dWin4                                                           % exclusion datawindow in respirationrate plot
        dPlot1                                                          % dynamics indication in the heartrate plot
        dPlot2                                                          % dynamics indication in the respirationrate plot
        dPlot1Txt                                                       % dynamics indication text
        dPlot2Txt                                                       % dynamics indication text
        msPlot1                                                         % multislice indication in the heartrate plot
        msPlot2                                                         % multislice indication in the respirationrate plot
        msPlot1Txt                                                      % multislice indication text
        msPlot2Txt                                                      % multislice indication text
        moviePlot                                                       % movie plot handle
        movieZoomView                                                   % view of the last movie redraw; a zoom is kept while it stays the same
        cardRateWindow                                                  % start-end range indicator in time-heartrate figure
        respRateWindow                                                  % start-end range indicator in time-respirationrate figure
        trajPlotData                                                    % trajectory plot array
        trajPlot                                                        % trajectory plot
        traj3DPlot                                                      % trajectory 3D plot handle
        traj3Dcnt                                                       % trajectory 3D plot counter
        imageRot                                                        % image rotation (0,1,2,3)
        maxNrSpokesPlot                                                 % maximum number of spokes visualized in 3D plot
        navTrace3                                                       % navigator trace raw heart navigator
        navTrace4                                                       % navigator trace raw resp navigator
        traceLength                                                     % number of points in navigator trace
        navLines                                                        % navigator lines handle
        navArea                                                         % shaded nav area
        epiContour                                                      % Epicardial contour line in the movie figure
        endoContour                                                     % Endocardial contour line in the movie figure

        % Colors and sounds
        green                                                           % other shade of green
        brightgreen                                                     % green
        traceGreen                                                      % green for navigator trace
        red                                                             % other shade of red
        brightred                                                       % red
        blue                                                            % blue
        lightblue                                                       % lightblue
        lightgrey                                                       % lightgray
        yellow                                                          % yellow
        orange                                                          % orange
        traceOrange                                                     % orange for nav trace
        black                                                           % black
        guiBlack                                                        % app black
        guiGrey                                                         % app grey
        guiMiddleGrey                                                   % app middle grey
        guiDarkGrey                                                     % app dark grey
        guiWhite                                                        % app white
        guiBlackBlue                                                    % app black/blueish
        autoRecoColor                                                   % automatic reconstruction background color
        manualRecoColor                                                 % manual reconstruction background color
        warningSound                                                    % warning sound
        errorSound                                                      % error sound

        % Structure for automatic reconstruction
        autoRecoState = struct( ...
            'watchService', [], ...
            'timer', [], ...
            'keyToDir', [], ...
            'registeredDirs', [], ...
            'pendingFiles', [], ...
            'processedFiles', [], ...
            'rootDir', "" );

    end % properties


    methods (Access = private)

        % -------------------------------------------------
        % Buttons and lights controls
        % -------------------------------------------------


        % Traffic lights
        function SetLightsFcn(app,bitmap)

            % Purpose:
            %   Set the seven traffic lights from one number, so a caller states the whole
            %   state of the pipeline in a single call.
            %
            % How it works:
            %   The bitmap is expanded to seven binary digits and flipped, so that bit 1 is
            %   the first lamp and the number reads left to right in the order the lamps sit
            %   on screen. Each digit picks bright green for a step that is done and bright
            %   red for one that is not.
            %
            %   One number rather than seven arguments is what lets a caller set the lamps
            %   to a known state instead of adjusting the ones it happens to know about,
            %   which is how a lamp gets left showing the previous scan.
            %
            % Inputs:
            %   bitmap - integer 0..127, one bit per lamp, bit 1 the leftmost
            %
            % Output:
            %   none - the lamps are set on the interface

            code = flip(dec2bin(bitmap,7));

            if code(1) == '0' app.Lamp1.Color = app.brightred; else app.Lamp1.Color = app.brightgreen; end %#ok<*SEPEX>
            if code(2) == '0' app.Lamp2.Color = app.brightred; else app.Lamp2.Color = app.brightgreen; end
            if code(3) == '0' app.Lamp3.Color = app.brightred; else app.Lamp3.Color = app.brightgreen; end
            if code(4) == '0' app.Lamp4.Color = app.brightred; else app.Lamp4.Color = app.brightgreen; end
            if code(5) == '0' app.Lamp5.Color = app.brightred; else app.Lamp5.Color = app.brightgreen; end
            if code(6) == '0' app.Lamp6.Color = app.brightred; else app.Lamp6.Color = app.brightgreen; end
            if code(7) == '0' app.Lamp7.Color = app.brightred; else app.Lamp7.Color = app.brightgreen; end

            drawnow;

        end % SetLightsFcn


        % Enable or disable a list of components in one call
        function setEnable(app, names, tf)

            % Purpose:
            %   Set the Enable state of several components at once, so the button logic reads
            %   as a list of what is affected rather than as one line per component.
            %
            % How it works:
            %   The names are property names on the app, so the components are reached
            %   dynamically and the caller writes a list instead of a block of assignments.
            %
            %   That is what makes the button-group functions below readable: each states
            %   which controls belong to it, and a control added to a group is one entry in a
            %   list rather than a line that can be forgotten in one branch of two.
            %
            %   The logical is converted to the char state MATLAB wants once, here, rather
            %   than at every call site.
            %
            % Inputs:
            %   names - cell array of component property names
            %   tf    - true to enable, false to disable
            %
            % Output:
            %   none - the controls are set on the interface

            if tf
                state = 'on';
            else
                state = 'off';
            end

            for k = 1:numel(names)
                app.(names{k}).Enable = state;
            end

        end % setEnable


        % Dashboard buttons
        function SetDashboardButtonsFcn(app, bitmap)

            % Purpose:
            %   Enable or disable the eight dashboard buttons from one number, in the order
            %   the pipeline runs.
            %
            % How it works:
            %   As SetLightsFcn: the bitmap is expanded to eight binary digits and flipped,
            %   so bit 1 is Load and bit 8 is Exit, and each digit enables or disables its
            %   button. Setting them together is what keeps the dashboard consistent -- a
            %   button left enabled from a previous state is an invitation to run a step out
            %   of order.
            %
            %   The automatic reconstruction checkbox follows the Load button. Ticking it
            %   starts the folder watcher and unticking it stops it, and neither may happen
            %   in the middle of an operation; whenever a new file could be loaded the app is
            %   idle enough for that to be safe, so the two share a condition. It is decided
            %   here rather than at the call sites because every path in and out of the busy
            %   state already passes through this function.
            %
            % Inputs:
            %   bitmap - integer 0..255, one bit per button, bit 1 Load and bit 8 Exit
            %
            % Output:
            %   none - the buttons are set on the interface

            code = flip(dec2bin(bitmap,8));

            if code(1) == '0' app.LoadMRDButton.Enable = 'off';     else app.LoadMRDButton.Enable = 'on'; end
            if code(2) == '0' app.FilterButton.Enable = 'off';      else app.FilterButton.Enable = 'on'; end
            if code(3) == '0' app.SortKspaceButton.Enable = 'off';  else app.SortKspaceButton.Enable = 'on'; end
            if code(4) == '0' app.ReconstructButton.Enable = 'off'; else app.ReconstructButton.Enable = 'on'; end
            if code(5) == '0' app.ExportDCMButton.Enable = 'off';   else app.ExportDCMButton.Enable = 'on'; end
            if code(6) == '0' app.ExportGIFButton.Enable = 'off';   else app.ExportGIFButton.Enable = 'on'; end
            if code(7) == '0' app.ExportMRDButton.Enable = 'off';   else app.ExportMRDButton.Enable = 'on'; end
            if code(8) == '0' app.ExitButton.Enable = 'off';        else app.ExitButton.Enable = 'on'; end

            % The automatic reconstruction checkbox follows the Load button. Ticking it
            % starts the folder watcher and unticking it stops it, and neither may happen
            % in the middle of an operation. Whenever a new file could be loaded the app
            % is idle enough for that to be safe, so the two share a condition. Put here
            % rather than at the call sites because every path in and out of the busy
            % state already goes through this function.
            app.setEnable({'AutoRecoCheckBox', 'AutoRecoLabel'}, code(1) == '1');
            drawnow;

        end % SetDashboardButtonsFcn


        % The lamps, dashboard and control groups for the steps that hold
        function SetValidStepsFcn(app)

            % Purpose:
            %   Set the lamps, the dashboard and the control groups to what the pipeline
            %   validly holds, after a setting that changes the next reconstruction but
            %   none of the steps before it.
            %
            % How it works:
            %   A reconstruction parameter or the reconstruction type changes what the
            %   next reconstruction does. What the interface offers then follows from how
            %   far the pipeline has validly come: a sorted k-space can be reconstructed, a
            %   filtered navigator sorted, a loaded scan filtered, and before any scan only
            %   Load and Exit are offered. The movie stays off: it shows a reconstruction
            %   made with the previous settings.
            %
            % Inputs:
            %   none
            %
            % Output:
            %   none - the lamps and controls are set on the interface

            app.SwitchOffAllButtonsFcn;

            if app.validSortFlag
                app.SetLightsFcn(7);
                app.SetDashboardButtonsFcn(143);
            elseif app.validFilterFlag
                app.SetLightsFcn(3);
                app.SetDashboardButtonsFcn(135);
            elseif app.r.validDataFlag
                app.SetLightsFcn(1);
                app.SetDashboardButtonsFcn(131);
            else
                app.SetLightsFcn(0);
                app.SetDashboardButtonsFcn(129);
                return
            end

            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(app.validFilterFlag);
            app.CineRecoButtonsFcn(app.validFilterFlag);
            app.MovieButtonsFcn(false);

        end % SetValidStepsFcn


        % Set application colors
        function SetAppColorsFcn(app, guiColors)

            % Purpose:
            %   Apply a colour scheme to every component of the interface.
            %
            % How it works:
            %   The scheme arrives as a list of eleven colours, unpacked here into named
            %   roles -- background, font, box, gauge, figure, grid and the two
            %   reconstruction colours -- and then applied component by component.
            %
            %   It is written out in full rather than looped, because the components do not
            %   take the same role: a button takes the button font colour, an axes takes the
            %   figure colour, a gauge takes two of its own. Grouping them by role is what
            %   makes a scheme change one edit to the list instead of a hunt through the
            %   components.
            %
            %   The two reconstruction colours are kept on the app rather than applied here,
            %   since they mark a state -- automatic or manual -- that changes while the app
            %   runs.
            %
            % Inputs:
            %   guiColors - 11-element cell of colours, in the order unpacked at the top
            %
            % Output:
            %   none - the colours are applied to the interface

            % The colors
            backgroundColor = guiColors{1};
            fontColor = guiColors{2};
            boxColor = guiColors{3};
            gaugeColor = guiColors{4};
            gaugeBackgroundColor = guiColors{5};
            figureColor = guiColors{6};
            movieFigureColor = guiColors{7};
            gridColor = guiColors{8};
            app.autoRecoColor = guiColors{9};
            app.manualRecoColor = guiColors{10};
            buttonFontColor = guiColors{11};

            % Buttons
            app.LoadMRDButton.FontColor = buttonFontColor;
            app.FilterButton.FontColor = buttonFontColor;
            app.SortKspaceButton.FontColor = buttonFontColor;
            app.ReconstructButton.FontColor = buttonFontColor;
            app.ExportDCMButton.FontColor = buttonFontColor;
            app.ExportGIFButton.FontColor = buttonFontColor;
            app.ExportMRDButton.FontColor = buttonFontColor;
            app.ExitButton.FontColor = buttonFontColor;
            app.MovieStartButton.FontColor = buttonFontColor;
            app.MovieStopButton.FontColor = buttonFontColor;
            app.TrajMovie3DStartButton.FontColor = buttonFontColor;
            app.TrajMovie3DStopButton.FontColor = buttonFontColor;
            app.TrajMovieStartButton.FontColor = buttonFontColor;
            app.TrajMovieStopButton.FontColor = buttonFontColor;
            app.ShowNavButton.FontColor = buttonFontColor;
            app.HeartLogLinButton.FontColor = buttonFontColor;
            app.RespLogLinButton.FontColor = buttonFontColor;
            app.AutoWLButton.FontColor = buttonFontColor;

            % Main tabs
            app.UIFigure.Color = backgroundColor;
            app.DashboardTab.BackgroundColor = backgroundColor;
            app.NavTab.BackgroundColor = backgroundColor;
            app.HeartTab.BackgroundColor = backgroundColor;
            app.RespTab.BackgroundColor = backgroundColor;
            app.NavAnalysisTab.BackgroundColor = backgroundColor;
            app.NavSettingsTab.BackgroundColor = backgroundColor;
            app.SequenceTab.BackgroundColor = backgroundColor;
            app.GeometryTab.BackgroundColor = backgroundColor;
            app.RecoParametersTab.BackgroundColor = backgroundColor;
            app.RadialTab.BackgroundColor = backgroundColor;
            app.RecoTab.BackgroundColor = backgroundColor;
            app.RadialRecoTab.BackgroundColor = backgroundColor;
            app.MessageBox.FontColor = boxColor;
            app.MessageBox.BackgroundColor = figureColor;
            app.StatusBackgroundImage.BackgroundColor = figureColor;

            % Panels
            app.DetectedRatesBpmPanel.BackgroundColor = backgroundColor;
            app.CinePanel.BackgroundColor = backgroundColor;
            app.ReconstructionPanel.BackgroundColor = backgroundColor;
            app.ExportPanel.BackgroundColor = backgroundColor;
            app.SpeciesButton.BackgroundColor = backgroundColor;
            app.ColorsButtonGroup.BackgroundColor = backgroundColor;
            app.RecoPresetsButtonGroup.BackgroundColor = backgroundColor;
            app.ButtonGroup.BackgroundColor = backgroundColor;
            app.ReconstructionTypeButtonGroup.BackgroundColor = backgroundColor;
            app.Nav1ButtonGroup.BackgroundColor = backgroundColor;
            app.Nav2ButtonGroup.BackgroundColor = backgroundColor;

            % Label colors
            app.AutoRecoLabel.FontColor = app.blue;
            app.CenterLabel.FontColor = fontColor;
            app.WidthLabel.FontColor = fontColor;
            app.HeartEditFieldLabel.FontColor = fontColor;
            app.RespEditFieldLabel.FontColor = fontColor;
            app.RespWindowEditFieldLabel.FontColor = fontColor;
            app.RecoTypeDropDownLabel.FontColor = fontColor;
            app.FramesEditFieldLabel.FontColor = fontColor;
            app.DynamicsEditFieldLabel.FontColor = fontColor;
            app.WVxyzEditFieldLabel.FontColor = fontColor;
            app.TVxyzEditFieldLabel.FontColor = fontColor;
            app.LLRxyzEditFieldLabel.FontColor = fontColor;
            app.TVcineEditFieldLabel.FontColor = fontColor;
            app.TVdynEditFieldLabel.FontColor = fontColor;
            app.ESPIRiTCheckBoxLabel.FontColor = fontColor;
            app.DeepLearningButton.FontColor = fontColor;
            app.CompressedSensingButton.FontColor = fontColor;
            app.ZeroFillCheckBoxLabel.FontColor = fontColor;
            app.TukeyFilterCheckBoxLabel.FontColor = fontColor;

            app.HeartCoilSpinner1Label.FontColor = fontColor;
            app.HeartCoilSpinner2Label.FontColor = fontColor;
            app.HeartNavFlipSwitch.FontColor = fontColor;
            app.HeartNavFlipSwitchLabel.FontColor = fontColor;
            app.HeartLogLinButton.FontColor = fontColor;
            app.HeartLogLinButton.BackgroundColor = backgroundColor;
            app.HeartMinEditFieldLabel.FontColor = fontColor;
            app.HeartMaxEditFieldLabel.FontColor = fontColor;
            app.RespCoilSpinner1Label.FontColor = fontColor;
            app.RespCoilSpinner2Label.FontColor = fontColor;
            app.RespNavFlipSwitch.FontColor = fontColor;
            app.RespNavFlipSwitchLabel.FontColor = fontColor;
            app.RespLogLinButton.FontColor = fontColor;
            app.RespLogLinButton.BackgroundColor = backgroundColor;
            app.RespMinEditFieldLabel.FontColor = fontColor;
            app.RespMaxEditFieldLabel.FontColor = fontColor;
            app.NavEnd1EditFieldLabel.FontColor = fontColor;
            app.NavNRPoints1EditFieldLabel.FontColor = fontColor;
            app.NavEnd2EditFieldLabel.FontColor = fontColor;
            app.NavNRPoints2EditFieldLabel.FontColor = fontColor;
            app.DiscardEditFieldLabel.FontColor = fontColor;
            app.RespToggleCheckBoxLabel.FontColor = fontColor;
            app.IgnoreRespToggleCheckBoxLabel.FontColor = fontColor;
            app.AutoSoundsCheckBoxLabel.FontColor = fontColor;
            app.MessageLevelSpinnerLabel.FontColor = fontColor;
            app.ClassicButton.FontColor = fontColor;
            app.BlackWhiteButton.FontColor = fontColor;
            app.MouseButton.FontColor = fontColor;
            app.ZebraFishButton.FontColor = fontColor;
            app.RatButton.FontColor = fontColor;
            app.FilterOrderEditFieldLabel.FontColor = fontColor;
            app.MinHeartRateEditFieldLabel.FontColor = fontColor;
            app.MaxHeartRateEditFieldLabel.FontColor = fontColor;
            app.MinRespRateEditFieldLabel.FontColor = fontColor;
            app.MaxRespRateEditFieldLabel.FontColor = fontColor;
            app.RespShiftEditFieldLabel.FontColor = fontColor;
            app.FilterPerSliceLabel.FontColor = fontColor;
            app.startsLabel.FontColor = fontColor;
            app.RangeEditFieldLabel.FontColor = fontColor;
            app.NavFastSlowSwitch.FontColor = fontColor;
            app.IncludeCheckBoxLabel.FontColor = fontColor;
            app.ExcludeCheckBoxLabel.FontColor = fontColor;
            app.DataFileLabel.FontColor = fontColor;
            app.NxViewFieldLabel.FontColor = fontColor;
            app.NyViewFieldLabel.FontColor = fontColor;
            app.NzViewFieldLabel.FontColor = fontColor;
            app.NcViewFieldLabel.FontColor = fontColor;
            app.RepetitionsViewFieldLabel.FontColor = fontColor;
            app.NrFlipAnglesViewFieldLabel.FontColor = fontColor;
            app.FlipAnglesViewFieldLabel.FontColor = fontColor;
            app.FieldOfViewViewFieldLabel.FontColor = fontColor;
            app.TRViewFieldLabel.FontColor = fontColor;
            app.TEViewFieldLabel.FontColor = fontColor;
            app.AveragesViewFieldLabel.FontColor = fontColor;
            app.FillingViewFieldLabel.FontColor = fontColor;
            app.NrKlinesViewFieldLabel.FontColor = fontColor;
            app.TrajectoryViewFieldLabel.FontColor = fontColor;
            app.TrajectorySizeViewFieldLabel.FontColor = fontColor;
            app.ScanTimeViewFieldLabel.FontColor = fontColor;
            app.TimeDynViewFieldLabel.FontColor = fontColor;
            app.FieldOfViewViewField2Label.FontColor = fontColor;
            app.SliceGapViewFieldLabel.FontColor = fontColor;
            app.OffsetXViewFieldLabel.FontColor = fontColor;
            app.OffsetYViewFieldLabel.FontColor = fontColor;
            app.OffsetZViewFieldLabel.FontColor = fontColor;
            app.AngleXViewFieldLabel.FontColor = fontColor;
            app.AngleYViewFieldLabel.FontColor = fontColor;
            app.AngleZViewFieldLabel.FontColor = fontColor;
            app.AspectRatioViewFieldLabel.FontColor = fontColor;
            app.SliceThicknessViewFieldLabel.FontColor = fontColor;
            app.PhaseOrientationViewFieldLabel.FontColor = fontColor;
            app.NLMFCheckBoxLabel.FontColor = fontColor;
            app.RingRmCheckBoxLabel.FontColor = fontColor;
            app.AutoGuessRecoCheckBoxLabel.FontColor = fontColor;
            app.MatlabRecoCheckBoxLabel.FontColor = fontColor;
            app.SharingEditFieldLabel.FontColor = fontColor;
            app.ScoutButton.FontColor = fontColor;
            app.SystolicFunctionButton.FontColor = fontColor;
            app.DiastolicFunctionButton.FontColor = fontColor;
            app.RespFunctionButton.FontColor = fontColor;
            app.RealTimeFunctionButton.FontColor = fontColor;
            app.VfaFunctionButton.FontColor = fontColor;
            app.MultiEchoButton.FontColor = fontColor;
            app.GradDelayCalibrationCheckBoxLabel.FontColor = fontColor;
            app.RingMethodCheckBoxLabel.FontColor = fontColor;
            app.GxDelayEditFieldLabel.FontColor = fontColor;
            app.GyDelayEditFieldLabel.FontColor = fontColor;
            app.GzDelayEditFieldLabel.FontColor = fontColor;
            app.DataOffsetRadialEditFieldLabel.FontColor = fontColor;
            app.CenterEchoCheckBoxLabel.FontColor = fontColor;
            app.PhaseCorrectCheckBoxLabel.FontColor = fontColor;
            app.DensityCorrectCheckBoxLabel.FontColor = fontColor;
            app.ZTEDeadTimeEditFieldLabel.FontColor = fontColor;
            app.ZTEShiftEditFieldLabel.FontColor = fontColor;
            app.ZTEFullEchoCheckBoxLabel.FontColor = fontColor;
            app.MovieFrameEditFieldLabel.FontColor = fontColor;
            app.MovieSliceEditFieldLabel.FontColor = fontColor;
            app.MovieDynEditFieldLabel.FontColor = fontColor;
            app.FinalHeartViewFieldLabel.FontColor = fontColor;
            app.FinalRespViewFieldLabel.FontColor = fontColor;
            app.LevelScaleSliderLabel.FontColor = fontColor;
            app.WindowScaleSliderLabel.FontColor = fontColor;
            app.MovieFrameLabel.FontColor = fontColor;
            app.MovieSliceLabel.FontColor = fontColor;
            app.MovieDynLabel.FontColor = fontColor;
            app.MovieRotateLabel.FontColor = fontColor;
            app.MovieButton.FontColor = fontColor;
            app.AveragesButton.FontColor = fontColor;
            app.kSpaceButton.FontColor = fontColor;
            app.SenseMapButton.FontColor = fontColor;
            app.TrajMovieFrameEditFieldLabel.FontColor = fontColor;
            app.TrajMovieSliceEditFieldLabel.FontColor = fontColor;
            app.TrajMovieDynEditFieldLabel.FontColor = fontColor;
            app.SpokeEditFieldLabel.FontColor = fontColor;
            app.SliceFID1Button.FontColor = fontColor;
            app.ExtFID1Button.FontColor = fontColor;
            app.ExtECHO1Button.FontColor = fontColor;
            app.SliceFID2Button.FontColor = fontColor;
            app.ExtFID2Button.FontColor = fontColor;
            app.ExtECHO2Button.FontColor = fontColor;

            % Gauges
            app.ProgressGauge.FontColor = gaugeColor;
            app.ProgressGauge.BackgroundColor = gaugeBackgroundColor;

            % Figures
            app.Nav1Fig.Title.Color = fontColor;
            app.Nav1Fig.XColor = fontColor;
            app.Nav1Fig.YColor = fontColor;
            app.Nav1Fig.Color = figureColor;
            app.Nav2Fig.Title.Color = fontColor;
            app.Nav2Fig.XColor = fontColor;
            app.Nav2Fig.YColor = fontColor;
            app.Nav2Fig.Color = figureColor;
            app.HeartRawNavFig.Title.Color = fontColor;
            app.HeartRawNavFig.XColor = fontColor;
            app.HeartRawNavFig.YColor = fontColor;
            app.HeartRawNavFig.Color = figureColor;
            app.HeartSpectrumFig.Title.Color = fontColor;
            app.HeartSpectrumFig.XColor = fontColor;
            app.HeartSpectrumFig.YColor = fontColor;
            app.HeartSpectrumFig.Color = figureColor;
            app.RespRawNavFig.Title.Color = fontColor;
            app.RespRawNavFig.XColor = fontColor;
            app.RespRawNavFig.YColor = fontColor;
            app.RespRawNavFig.Color = figureColor;
            app.RespSpectrumFig.Title.Color = fontColor;
            app.RespSpectrumFig.XColor = fontColor;
            app.RespSpectrumFig.YColor = fontColor;
            app.RespSpectrumFig.Color = figureColor;
            app.HeartNavFig.Title.Color = fontColor;
            app.HeartNavFig.XColor = fontColor;
            app.HeartNavFig.YColor = fontColor;
            app.HeartNavFig.Color = figureColor;
            app.RespNavFig.Title.Color = fontColor;
            app.RespNavFig.XColor = fontColor;
            app.RespNavFig.YColor = fontColor;
            app.RespNavFig.Color = figureColor;
            app.HeartRateTimeFig.Title.Color = fontColor;
            app.HeartRateTimeFig.XColor = fontColor;
            app.HeartRateTimeFig.YColor = fontColor;
            app.HeartRateTimeFig.Color = figureColor;
            app.RespRateTimeFig.Title.Color = fontColor;
            app.RespRateTimeFig.XColor = fontColor;
            app.RespRateTimeFig.YColor = fontColor;
            app.RespRateTimeFig.Color = figureColor;
            app.MovieFig.Color = movieFigureColor;
            app.MovieFig.XColor = movieFigureColor;
            app.MovieFig.YColor = movieFigureColor;
            app.TrajectoryLargeFig.Color = figureColor;
            app.TrajectoryLargeFig.XColor = movieFigureColor;
            app.TrajectoryLargeFig.YColor = movieFigureColor;
            app.TrajectoryFig.Color = figureColor;
            app.TrajectoryFig.XColor = movieFigureColor;
            app.TrajectoryFig.YColor = movieFigureColor;

        end % SetAppColorsFcn


        % App version check
        function CheckVersionFnc(app)

            % Purpose:
            %   Tell the user at start up whether a newer release exists.
            %
            % How it works:
            %   The published version is fetched by retro.io.latestReleaseVersion and
            %   compared with this one through retro.util.isVersionNewer, which compares
            %   version numbers as numbers rather than as text -- 10.4 is newer than 10.10
            %   when compared as strings.
            %
            %   Two things keep this from delaying the start of the app on a machine that
            %   cannot reach the network. It can be switched off entirely in the settings,
            %   which is what an isolated console should do. And a host that cannot be
            %   reached at all stops the search rather than letting every candidate address
            %   cost its own timeout, which is decided by the identifier the failure carries.
            %
            %   That case is reported at level 2 rather than level 1: no route out is not a
            %   fault worth announcing at every start, it is simply a check that could not
            %   run. A real failure still reports normally.
            %
            %   The whole thing is wrapped, because a version check must never be the reason
            %   the application does not start.
            %
            % Inputs:
            %   none
            %
            % Output:
            %   none - the outcome is written to the message pane

            try

                % A machine on an isolated network cannot reach the version file,
                % and trying costs the timeout on every one of the candidate URLs
                % at every start, so the settings file can switch it off.
                if ~app.jsonPar.checkForUpdates
                    app.TextMessage("Version check is switched off in the settings ...",2);
                    return
                end

                currentVersion = string(app.appVersion);
                newVersion = retro.io.latestReleaseVersion(app.jsonPar.versionCheckTimeout, app.r.reporter);

                app.TextMessage("Current version = " + currentVersion, 1);
                app.TextMessage("Newest version = " + newVersion, 1);

                if retro.util.isVersionNewer(newVersion, currentVersion)
                    app.TextMessage("A new version of Retrospective is available ...", 1);
                    app.TextMessage("Visit https://github.com/Moby1971/ to update ...", 1);
                else
                    app.TextMessage("Retrospective is up to date ...", 1);
                end

            catch ME

                app.TextMessage(ME.message, 2);

                % A machine with no route out is not a fault to report at level 1
                % at every start; it is simply a check that could not run.
                if ~strcmp(ME.identifier, 'Retrospective:offline')
                    app.TextMessage("Could not check retrospective version ...", 1);
                end

            end

        end % CheckVersionFnc


        % Navigator selection buttons
        function NavSelButtonsFcn(app, on)

            % Purpose:
            %   Enable or disable the navigator selection and filtering controls as a group.
            %
            % How it works:
            %   The controls that always apply -- the navigator extents, the point counts,
            %   the flip switches, the discard field and the motion buttons -- are set
            %   together through setEnable.
            %
            %   The navigator source buttons are then set individually, each against the flag
            %   that says whether that kind of navigator exists in this scan: a slice FID, an
            %   external FID, an external echo. Offering a source the data does not carry is
            %   how a reconstruction gets started against a navigator that was never
            %   measured.
            %
            %   The per-coil spinners follow multiCoilFlag, there being nothing to choose
            %   with a single coil.
            %
            %   Disabling is unconditional: every control in the group goes off, whatever the
            %   flags say, so the idle state does not depend on what was loaded.
            %
            % Inputs:
            %   on - true to enable the group, false to disable it
            %
            % Output:
            %   none - the controls are set on the interface

            if on

                app.setEnable({'NavEnd1EditField', 'NavEnd1EditFieldLabel', 'NavNRPoints1EditField', ...
                    'NavNRPoints1EditFieldLabel', 'HeartNavFlipSwitch', 'HeartNavFlipSwitchLabel', ...
                    'NavEnd2EditField', 'NavEnd2EditFieldLabel', 'NavNRPoints2EditField', ...
                    'NavNRPoints2EditFieldLabel', 'RespNavFlipSwitch', 'RespNavFlipSwitchLabel', ...
                    'DiscardEditField', 'DiscardEditFieldLabel', 'DiscardCheckBox', ...
                    'StartMotionButton', 'StopMotionButton'}, true);

                if app.fidNavFlag buttonStatus = 'on'; else buttonStatus = 'off'; end
                app.SliceFID1Button.Enable = buttonStatus;
                app.SliceFID2Button.Enable = buttonStatus;

                if app.extFidNavFlag buttonStatus = 'on'; else buttonStatus = 'off'; end
                app.ExtFID1Button.Enable = buttonStatus;
                app.ExtFID2Button.Enable = buttonStatus;

                if app.extEchoNavFlag buttonStatus = 'on'; else buttonStatus = 'off'; end
                app.ExtECHO1Button.Enable = buttonStatus;
                app.ExtECHO2Button.Enable = buttonStatus;

                if app.r.multiCoilFlag buttonStatus = 'on'; else buttonStatus = 'off'; end
                app.HeartCoilSpinner1.Enable = buttonStatus;
                app.HeartCoilSpinner1Label.Enable = buttonStatus;
                app.RespCoilSpinner1.Enable = buttonStatus;
                app.RespCoilSpinner1Label.Enable = buttonStatus;
                app.HeartCoilSpinner2.Enable = buttonStatus;
                app.HeartCoilSpinner2Label.Enable = buttonStatus;
                app.RespCoilSpinner2.Enable = buttonStatus;
                app.RespCoilSpinner2Label.Enable = buttonStatus;

                app.setEnable({'HeartMinEditField', 'HeartMinEditFieldLabel', 'HeartMaxEditField', ...
                    'HeartMaxEditFieldLabel', 'RespMinEditField', 'RespMinEditFieldLabel', ...
                    'RespMaxEditField', 'RespMaxEditFieldLabel', 'HeartLogLinButton', ...
                    'RespLogLinButton', 'CenterLabel', 'WidthLabel', ...
                    'HeartEditField', 'HeartEditFieldLabel', 'RespEditField', ...
                    'RespEditFieldLabel', 'HeartWidthEditField', 'RespWidthEditField', ...
                    'RespWindowEditField', 'RespWindowEditFieldLabel', 'RespEditFieldLabel', ...
                    'ResetFilterValuesButton', 'MouseButton', 'RatButton', ...
                    'ZebraFishButton', 'FilterOrderEditField', 'FilterOrderEditFieldLabel', ...
                    'MaxHeartRateEditField', 'MaxHeartRateEditFieldLabel', 'MinHeartRateEditField', ...
                    'MinHeartRateEditFieldLabel', 'MinRespRateEditField', 'MinRespRateEditFieldLabel', ...
                    'MaxRespRateEditField', 'MaxRespRateEditFieldLabel', 'RespShiftEditField', ...
                    'RespShiftEditFieldLabel', 'FilterPerSliceLabel', 'FilterPerSliceCheckBox', ...
                    'RespToggleCheckBox', 'RespToggleCheckBoxLabel', 'IgnoreRespToggleCheckBox', ...
                    'IgnoreRespToggleCheckBoxLabel'}, true);

            else

                app.setEnable({'NavEnd1EditField', 'NavEnd1EditFieldLabel', 'NavNRPoints1EditField', ...
                    'NavNRPoints1EditFieldLabel', 'NavEnd2EditField', 'NavEnd2EditFieldLabel', ...
                    'NavNRPoints2EditField', 'NavNRPoints2EditFieldLabel', 'HeartNavFlipSwitch', ...
                    'HeartNavFlipSwitchLabel', 'RespNavFlipSwitch', 'RespNavFlipSwitchLabel', ...
                    'DiscardEditField', 'DiscardEditFieldLabel', 'DiscardCheckBox', ...
                    'HeartCoilSpinner1', 'HeartCoilSpinner1Label', 'RespCoilSpinner1', ...
                    'RespCoilSpinner1Label', 'HeartCoilSpinner2', ...
                    'HeartCoilSpinner2Label', 'RespCoilSpinner2', 'RespCoilSpinner2Label', ...
                    'HeartMinEditField', 'HeartMinEditFieldLabel', 'HeartMaxEditField', ...
                    'HeartMaxEditFieldLabel', 'RespMinEditField', 'RespMinEditFieldLabel', ...
                    'RespMaxEditField', 'RespMaxEditFieldLabel', 'SliceFID1Button', ...
                    'SliceFID2Button', 'ExtFID1Button', 'ExtFID2Button', ...
                    'ExtECHO1Button', 'ExtECHO2Button', 'StartMotionButton', ...
                    'StopMotionButton', 'HeartLogLinButton', 'RespLogLinButton', ...
                    'CenterLabel', 'WidthLabel', 'HeartEditField', ...
                    'HeartEditFieldLabel', 'RespEditField', 'RespEditFieldLabel', ...
                    'HeartWidthEditField', 'RespWidthEditField', 'RespWindowEditField', ...
                    'RespWindowEditFieldLabel', 'ResetFilterValuesButton', 'MouseButton', ...
                    'RatButton', 'ZebraFishButton', 'FilterOrderEditField', ...
                    'FilterOrderEditFieldLabel', 'MaxHeartRateEditField', 'MaxHeartRateEditFieldLabel', ...
                    'MinHeartRateEditField', 'MinHeartRateEditFieldLabel', 'MinRespRateEditField', ...
                    'MinRespRateEditFieldLabel', 'MaxRespRateEditField', 'MaxRespRateEditFieldLabel', ...
                    'RespShiftEditField', 'RespShiftEditFieldLabel', 'FilterPerSliceLabel', ...
                    'FilterPerSliceCheckBox', 'RespToggleCheckBox', 'RespToggleCheckBoxLabel', ...
                    'IgnoreRespToggleCheckBox', 'IgnoreRespToggleCheckBoxLabel'}, false);

            end

            drawnow;

        end % NavSelButtonsFcn


        % Navigator viewing buttons
        function NavViewingButtonsFcn(app,on)

            % Purpose:
            %   Enable or disable the navigator viewing controls -- the time window, the
            %   sliders and the navigator movie buttons.
            %
            % How it works:
            %   The whole group is set through setEnable, then the window controls are
            %   reconsidered: selecting a data window only means something while there is a
            %   single dynamic, since with more than one the window is what separates them.
            %
            %   Realtime is exempt from that rule. Its dynamics are consecutive heartbeats,
            %   so an include or exclude window selects a contiguous run of them, which is
            %   what fillKspace2Drealtime does with it. Without the exemption the controls
            %   switch themselves off after the first sort, because realtime sets the dynamic
            %   count to the number of heartbeats and the test for one dynamic then fails.
            %
            % Inputs:
            %   on - true to enable the group, false to disable it
            %
            % Output:
            %   none - the controls are set on the interface

            if on

                app.setEnable({'StartEditField', 'startsLabel', 'RangeEditField', ...
                    'RangeEditFieldLabel', 'StartSlider', 'RangeSlider', ...
                    'NavStartButton', 'NavStopButton', 'NavFastSlowSwitch'}, true);

                % Realtime is exempt from the single-dynamic rule. Its dynamics are consecutive
                % heartbeats, so an include or exclude window selects a contiguous run of them,
                % which is what fillKspace2Drealtime does. Without the exemption the controls
                % switch themselves off after the first sort, because realtime sets the dynamic
                % count to the number of heartbeats and the == 1 test then fails.
                isRealtime = strcmp(app.RecoTypeDropDown.Value, 'realtime');
                if (app.DynamicsEditField.Value == 1 || isRealtime) && ~app.r.multi2DFlag && ~app.r.vfaDataFlag && ~app.r.multiEchoFlag
                    app.setEnable({'IncludeCheckBox', 'IncludeCheckBoxLabel', 'ExcludeCheckBox', ...
                        'ExcludeCheckBoxLabel'}, true);
                else
                    app.setEnable({'IncludeCheckBox', 'IncludeCheckBoxLabel', 'ExcludeCheckBox', ...
                        'ExcludeCheckBoxLabel', 'IncludeStartSlider', 'IncludeEndSlider', ...
                        'ExcludeStartSlider', 'ExcludeEndSlider'}, false);
                end

                if app.IncludeCheckBox.Value buttonStatus = 'on'; else buttonStatus = 'off'; end
                app.IncludeStartSlider.Enable = buttonStatus;
                app.IncludeEndSlider.Enable = buttonStatus;

                if app.ExcludeCheckBox.Value buttonStatus = 'on'; else buttonStatus = 'off'; end
                app.ExcludeStartSlider.Enable = buttonStatus;
                app.ExcludeEndSlider.Enable = buttonStatus;

            else

                app.setEnable({'StartEditField', 'startsLabel', 'RangeEditField', ...
                    'RangeEditFieldLabel', 'StartSlider', 'RangeSlider', ...
                    'NavStartButton', 'NavStopButton', 'NavFastSlowSwitch', ...
                    'IncludeCheckBox', 'IncludeCheckBoxLabel', 'IncludeStartSlider', ...
                    'IncludeEndSlider', 'ExcludeCheckBox', 'ExcludeCheckBoxLabel', ...
                    'ExcludeStartSlider', 'ExcludeEndSlider'}, false);

            end

            drawnow;

        end % NavViewingButtonsFcn

        % Cine reco buttons
        function CineRecoButtonsFcn(app,on)

            % Purpose:
            %   Enable or disable the cine reconstruction controls, which is the largest
            %   group and the one that depends most on what the data supports.
            %
            % How it works:
            %   The controls common to every reconstruction are set together, and the rest
            %   are decided per data type: what has cardiac frames, what has respiratory
            %   frames, what can be undersampled, which regularisation applies, and which
            %   export formats make sense for the result.
            %
            %   Driving all of that from the data type in one place is what keeps the
            %   interface from offering a setting the reconstruction would then ignore -- the
            %   kind of mismatch that is not an error and produces a result that quietly is
            %   not what was asked for.
            %
            %   The state flags gate the group as a whole: there is nothing to reconstruct
            %   until the data has been loaded and sorted.
            %
            % Inputs:
            %   on - true to enable the group, false to disable it
            %
            % Output:
            %   none - the controls are set on the interface

            if on

                app.setEnable({'ScoutButton', 'SystolicFunctionButton', 'DiastolicFunctionButton', ...
                    'RespFunctionButton', 'FramesEditField', 'FramesEditFieldLabel', ...
                    'DynamicsEditField', 'DynamicsEditFieldLabel', 'RealTimeFunctionButton', ...
                    'TukeyFilterCheckBox', 'TukeyFilterCheckBoxLabel'}, true);
                if app.r.multi2DFlag
                    app.setEnable({'DynamicsEditField', 'DynamicsEditFieldLabel', 'RealTimeFunctionButton'}, false);
                    app.DynamicsEditField.Value = 1;
                end
                switch app.r.dataType
                    case {'3D','3Dp2roud','2Dms','3Dute','ZTE'}
                        app.RealTimeFunctionButton.Enable = 'off';
                end
                switch app.r.dataType
                    case {'2Dradial','2Dradialms','3Dute','ZTE'}
                        app.setEnable({'SharingEditField', 'SharingEditFieldLabel', 'ZeroFillCheckBox'}, false);
                        app.ZeroFillCheckBox.Value = 0;
                        app.ZeroFillCheckBoxLabel.Enable = 'off';
                    otherwise
                        app.setEnable({'SharingEditField', 'SharingEditFieldLabel', 'ZeroFillCheckBox', ...
                            'ZeroFillCheckBoxLabel'}, true);
                end
                app.VfaFunctionButton.Enable = 'off';
                if app.r.vfaDataFlag
                    app.setEnable({'DynamicsEditField', 'DynamicsEditFieldLabel'}, false);
                    app.VfaFunctionButton.Enable = 'on';
                    app.RealTimeFunctionButton.Enable = 'off';
                end
                % MultiEchoButton is deliberately never enabled. There is no multi-echo
                % sequence yet, so the preset is not offered, and the missing counterpart
                % to the VfaFunctionButton line above is intentional rather than an
                % oversight. Two things will need adding when one exists: an
                % Enable = 'on' here, and a look at retroData.guessRecoType, which already
                % returns "multi-echo" on multiEchoFlag and makes DataParametersFcn select
                % a button the user cannot see selected.
                if app.r.multiEchoFlag
                    app.setEnable({'DynamicsEditField', 'DynamicsEditFieldLabel', 'VfaFunctionButton', ...
                        'RealTimeFunctionButton'}, false);
                end
                app.setEnable({'RecoTypeDropDown', 'RecoTypeDropDownLabel', 'AutoGuessRecoCheckBox', ...
                    'AutoGuessRecoCheckBoxLabel'}, true);
                app.setEnable({'MatlabRecoCheckBox', 'MatlabRecoCheckBoxLabel'}, false);
                if app.aiDetectedFlag && (app.nrFrames > 3) && (strcmp(app.r.dataType,'2D') || strcmp(app.r.dataType,'2Dms'))
                    app.setEnable({'DeepLearningButton', 'CompressedSensingButton'}, true);
                else
                    app.DeepLearningButton.Enable = 'off';
                    app.CompressedSensingButton.Enable = 'on';
                    app.CompressedSensingButton.Value = 1;
                end

                % K-space data sorted and Bart is available
                if app.validSortFlag && app.bartDetectedFlag && ~app.MatlabRecoCheckBox.Value

                    app.setEnable({'WVxyzEditField', 'WVxyzEditFieldLabel', 'TVxyzEditField', ...
                        'TVxyzEditFieldLabel', 'LLRxyzEditField', 'LLRxyzEditFieldLabel', ...
                        'TVcineEditField', 'TVcineEditFieldLabel', 'TVdynEditField', ...
                        'TVdynEditFieldLabel', 'NLMFCheckBox', 'NLMFCheckBoxLabel', ...
                        'NLMFfilterStrengthEditField', 'RingRmCheckBox', 'RingRmCheckBoxLabel', ...
                        'TukeyFilterCheckBox', 'TukeyFilterCheckBoxLabel'}, true);
                    if app.r.multiCoilFlag
                        app.setEnable({'ESPIRiTCheckBox', 'ESPIRiTCheckBoxLabel'}, true);
                    else
                        app.setEnable({'ESPIRiTCheckBox', 'ESPIRiTCheckBoxLabel'}, false);
                        app.ESPIRiTCheckBox.Value = 0;
                    end
                    app.setEnable({'MatlabRecoCheckBox', 'MatlabRecoCheckBoxLabel'}, true);

                    switch app.r.dataType

                        case '3Dute'

                            app.setEnable({'GradDelayCalibrationCheckBox', 'GradDelayCalibrationCheckBoxLabel'}, true);
                            app.setEnable({'RingMethodCheckBox', 'RingMethodCheckBoxLabel'}, false);
                            app.setEnable({'GxDelayEditField', 'GxDelayEditFieldLabel', 'GyDelayEditField', ...
                                'GyDelayEditFieldLabel', 'GzDelayEditField', 'GzDelayEditFieldLabel', ...
                                'DataOffsetRadialEditField', 'DataOffsetRadialEditFieldLabel'}, true);
                            app.setEnable({'CenterEchoCheckBox', 'CenterEchoCheckBoxLabel'}, false);
                            app.setEnable({'PhaseCorrectCheckBox', 'PhaseCorrectCheckBoxLabel', 'DensityCorrectCheckBox', ...
                                'DensityCorrectCheckBoxLabel'}, true);
                            app.setEnable({'DeepLearningButton', 'ZTEShiftEditField', 'ZTEShiftEditFieldLabel', ...
                                'ZTEDeadTimeEditField', 'ZTEDeadTimeEditFieldLabel', 'ZTEFullEchoCheckBox', ...
                                'ZTEFullEchoCheckBoxLabel'}, false);
                            app.CompressedSensingButton.Value = 1;
                            app.TrajMovieButtonsFcn(true);
                            app.Traj3DMovieButtonsFcn(true);

                        case 'ZTE'

                            app.setEnable({'GradDelayCalibrationCheckBox', 'GradDelayCalibrationCheckBoxLabel', 'RingMethodCheckBox', ...
                                'RingMethodCheckBoxLabel', 'GxDelayEditField', 'GxDelayEditFieldLabel', ...
                                'GyDelayEditField', 'GyDelayEditFieldLabel', 'GzDelayEditField', ...
                                'GzDelayEditFieldLabel', 'DataOffsetRadialEditField', 'DataOffsetRadialEditFieldLabel', ...
                                'CenterEchoCheckBox', 'CenterEchoCheckBoxLabel', ...
                                'PhaseCorrectCheckBox', 'PhaseCorrectCheckBoxLabel', 'DensityCorrectCheckBox', ...
                                'DensityCorrectCheckBoxLabel', 'DeepLearningButton'}, false);
                            app.setEnable({'ZTEShiftEditField', 'ZTEShiftEditFieldLabel', 'ZTEDeadTimeEditField', ...
                                'ZTEDeadTimeEditFieldLabel', 'ZTEFullEchoCheckBox', 'ZTEFullEchoCheckBoxLabel'}, true);
                            app.CompressedSensingButton.Value = 1;
                            app.TrajMovieButtonsFcn(true);
                            app.Traj3DMovieButtonsFcn(true);

                        case {'2Dradial','2Dradialms'}

                            app.setEnable({'GradDelayCalibrationCheckBox', 'GradDelayCalibrationCheckBoxLabel', 'RingMethodCheckBox', ...
                                'RingMethodCheckBoxLabel', 'GxDelayEditField', 'GxDelayEditFieldLabel', ...
                                'GyDelayEditField', 'GyDelayEditFieldLabel', 'GzDelayEditField', ...
                                'GzDelayEditFieldLabel'}, true);
                            app.setEnable({'DataOffsetRadialEditField', 'DataOffsetRadialEditFieldLabel'}, false);
                            app.setEnable({'CenterEchoCheckBox', 'CenterEchoCheckBoxLabel', 'PhaseCorrectCheckBox', ...
                                'PhaseCorrectCheckBoxLabel', 'DensityCorrectCheckBox', 'DensityCorrectCheckBoxLabel'}, true);
                            app.DeepLearningButton.Enable = 'off';
                            app.CompressedSensingButton.Value = 1;
                            app.setEnable({'ZTEShiftEditField', 'ZTEShiftEditFieldLabel', 'ZTEDeadTimeEditField', ...
                                'ZTEDeadTimeEditFieldLabel', 'ZTEFullEchoCheckBox', 'ZTEFullEchoCheckBoxLabel'}, false);
                            app.TrajMovieButtonsFcn(true);
                            app.Traj3DMovieButtonsFcn(false);

                        otherwise

                            app.setEnable({'GradDelayCalibrationCheckBox', 'GradDelayCalibrationCheckBoxLabel', 'RingMethodCheckBox', ...
                                'RingMethodCheckBoxLabel', 'GxDelayEditField', 'GxDelayEditFieldLabel', ...
                                'GyDelayEditField', 'GyDelayEditFieldLabel', 'GzDelayEditField', ...
                                'GzDelayEditFieldLabel', 'DataOffsetRadialEditField', 'DataOffsetRadialEditFieldLabel', ...
                                'CenterEchoCheckBox', 'CenterEchoCheckBoxLabel', ...
                                'PhaseCorrectCheckBox', 'PhaseCorrectCheckBoxLabel', 'DensityCorrectCheckBox', ...
                                'DensityCorrectCheckBoxLabel', 'ZTEShiftEditField', 'ZTEShiftEditFieldLabel', ...
                                'ZTEDeadTimeEditField', 'ZTEDeadTimeEditFieldLabel', 'ZTEFullEchoCheckBox', ...
                                'ZTEFullEchoCheckBoxLabel'}, false);
                            app.TrajMovieButtonsFcn(false);
                            app.Traj3DMovieButtonsFcn(false);

                    end

                end

                % K-space data sorted and Bart is not available
                if app.validSortFlag && (~app.bartDetectedFlag || app.MatlabRecoCheckBox.Value)

                    app.setEnable({'WVxyzEditField', 'WVxyzEditFieldLabel', 'TVxyzEditField', ...
                        'TVxyzEditFieldLabel', 'LLRxyzEditField', 'LLRxyzEditFieldLabel'}, false);
                    app.setEnable({'NLMFCheckBox', 'NLMFCheckBoxLabel', 'NLMFfilterStrengthEditField', ...
                        'RingRmCheckBox', 'RingRmCheckBoxLabel', 'TukeyFilterCheckBox', ...
                        'TukeyFilterCheckBoxLabel'}, true);
                    switch app.r.dataType
                        case {'2Dradial','2Dradialms','3Dute','ZTE'}
                            app.setEnable({'TVcineEditField', 'TVcineEditFieldLabel'}, false);
                        otherwise
                            app.setEnable({'TVcineEditField', 'TVcineEditFieldLabel'}, true);
                    end
                    app.setEnable({'TVdynEditField', 'TVdynEditFieldLabel'}, false);
                    app.setEnable({'MatlabRecoCheckBox', 'MatlabRecoCheckBoxLabel'}, true);
                    if ~app.bartDetectedFlag
                        app.MatlabRecoCheckBox.Value = 1;
                        app.setEnable({'MatlabRecoCheckBox', 'MatlabRecoCheckBoxLabel'}, false);
                    end

                    switch app.r.dataType

                        case '3Dute'

                            app.setEnable({'GradDelayCalibrationCheckBox', 'GradDelayCalibrationCheckBoxLabel', 'RingMethodCheckBox', ...
                                'RingMethodCheckBoxLabel'}, false);
                            app.setEnable({'GxDelayEditField', 'GxDelayEditFieldLabel', 'GyDelayEditField', ...
                                'GyDelayEditFieldLabel', 'GzDelayEditField', 'GzDelayEditFieldLabel', ...
                                'DataOffsetRadialEditField', 'DataOffsetRadialEditFieldLabel'}, true);
                            app.setEnable({'CenterEchoCheckBox', 'CenterEchoCheckBoxLabel'}, false);
                            app.setEnable({'PhaseCorrectCheckBox', 'PhaseCorrectCheckBoxLabel'}, true);
                            app.setEnable({'DensityCorrectCheckBox', 'DensityCorrectCheckBoxLabel', 'DeepLearningButton', ...
                                'ZTEShiftEditField', 'ZTEShiftEditFieldLabel', 'ZTEDeadTimeEditField', ...
                                'ZTEDeadTimeEditFieldLabel', 'ZTEFullEchoCheckBox', 'ZTEFullEchoCheckBoxLabel'}, false);
                            app.CompressedSensingButton.Value = 1;
                            app.TrajMovieButtonsFcn(true);
                            app.Traj3DMovieButtonsFcn(true);

                        case 'ZTE'

                            app.setEnable({'GradDelayCalibrationCheckBox', 'GradDelayCalibrationCheckBoxLabel', 'RingMethodCheckBox', ...
                                'RingMethodCheckBoxLabel', 'GxDelayEditField', 'GxDelayEditFieldLabel', ...
                                'GyDelayEditField', 'GyDelayEditFieldLabel', 'GzDelayEditField', ...
                                'GzDelayEditFieldLabel', 'DataOffsetRadialEditField', 'DataOffsetRadialEditFieldLabel', ...
                                'CenterEchoCheckBox', 'CenterEchoCheckBoxLabel', ...
                                'PhaseCorrectCheckBox', 'PhaseCorrectCheckBoxLabel', 'DensityCorrectCheckBox', ...
                                'DensityCorrectCheckBoxLabel', 'DeepLearningButton'}, false);
                            app.setEnable({'ZTEShiftEditField', 'ZTEShiftEditFieldLabel', 'ZTEDeadTimeEditField', ...
                                'ZTEDeadTimeEditFieldLabel', 'ZTEFullEchoCheckBox', 'ZTEFullEchoCheckBoxLabel'}, true);
                            app.CompressedSensingButton.Value = 1;
                            app.TrajMovieButtonsFcn(true);
                            app.Traj3DMovieButtonsFcn(true);

                        case {'2Dradial','2Dradialms'}

                            app.setEnable({'GradDelayCalibrationCheckBox', 'GradDelayCalibrationCheckBoxLabel', 'RingMethodCheckBox', ...
                                'RingMethodCheckBoxLabel'}, false);
                            app.setEnable({'GxDelayEditField', 'GxDelayEditFieldLabel', 'GyDelayEditField', ...
                                'GyDelayEditFieldLabel', 'GzDelayEditField', 'GzDelayEditFieldLabel'}, true);
                            app.setEnable({'DataOffsetRadialEditField', 'DataOffsetRadialEditFieldLabel'}, false);
                            app.setEnable({'CenterEchoCheckBox', 'CenterEchoCheckBoxLabel', 'PhaseCorrectCheckBox', ...
                                'PhaseCorrectCheckBoxLabel'}, true);
                            app.setEnable({'DensityCorrectCheckBox', 'DensityCorrectCheckBoxLabel', 'DeepLearningButton', ...
                                'ZTEShiftEditField', 'ZTEShiftEditFieldLabel', 'ZTEDeadTimeEditField', ...
                                'ZTEDeadTimeEditFieldLabel', 'ZTEFullEchoCheckBox', 'ZTEFullEchoCheckBoxLabel'}, false);
                            app.CompressedSensingButton.Value = 1;
                            app.TrajMovieButtonsFcn(true);
                            app.Traj3DMovieButtonsFcn(false);

                        otherwise

                            app.setEnable({'GradDelayCalibrationCheckBox', 'GradDelayCalibrationCheckBoxLabel', 'RingMethodCheckBox', ...
                                'RingMethodCheckBoxLabel', 'GxDelayEditField', 'GxDelayEditFieldLabel', ...
                                'GyDelayEditField', 'GyDelayEditFieldLabel', 'GzDelayEditField', ...
                                'GzDelayEditFieldLabel', 'DataOffsetRadialEditField', 'DataOffsetRadialEditFieldLabel', ...
                                'CenterEchoCheckBox', 'CenterEchoCheckBoxLabel', ...
                                'PhaseCorrectCheckBox', 'PhaseCorrectCheckBoxLabel', 'DensityCorrectCheckBox', ...
                                'DensityCorrectCheckBoxLabel', 'ZTEShiftEditField', 'ZTEShiftEditFieldLabel', ...
                                'ZTEDeadTimeEditField', 'ZTEDeadTimeEditFieldLabel', 'ZTEFullEchoCheckBox', ...
                                'ZTEFullEchoCheckBoxLabel'}, false);
                            app.TrajMovieButtonsFcn(false);
                            app.Traj3DMovieButtonsFcn(false);

                    end

                end

                % K-space data sorted and AI reco selected
                if app.validSortFlag && app.DeepLearningButton.Value

                    app.setEnable({'WVxyzEditField', 'WVxyzEditFieldLabel', 'TVxyzEditField', ...
                        'TVxyzEditFieldLabel', 'LLRxyzEditField', 'LLRxyzEditFieldLabel', ...
                        'TVcineEditField', 'TVcineEditFieldLabel', 'ESPIRiTCheckBox', ...
                        'ESPIRiTCheckBoxLabel', 'TVdynEditField', 'TVdynEditFieldLabel'}, false);
                    app.MatlabRecoCheckBox.Value = 0;
                    app.setEnable({'MatlabRecoCheckBox', 'MatlabRecoCheckBoxLabel'}, false);

                end

            end

            % Gibbs ringing suppression assumes Cartesian sampling (STATUS.md, 'Gibbs
            % unringing')
            if on && ismember(app.r.dataType, {'2Dradial','2Dradialms','3Dute','ZTE'})
                app.setEnable({'RingRmCheckBox', 'RingRmCheckBoxLabel'}, false);
                app.RingRmCheckBox.Value = 0;
            end

            % K-space data not sorted
            if ~on

                app.setEnable({'RecoTypeDropDown', 'RecoTypeDropDownLabel', 'FramesEditField', ...
                    'FramesEditFieldLabel', 'DynamicsEditField', 'DynamicsEditFieldLabel', ...
                    'WVxyzEditField', 'WVxyzEditFieldLabel', 'TVxyzEditField', ...
                    'TVxyzEditFieldLabel', 'LLRxyzEditField', 'LLRxyzEditFieldLabel', ...
                    'TVcineEditField', 'TVcineEditFieldLabel', 'ESPIRiTCheckBox', ...
                    'ESPIRiTCheckBoxLabel', 'TVdynEditField', 'TVdynEditFieldLabel', ...
                    'DeepLearningButton', 'CompressedSensingButton', 'NLMFCheckBox', ...
                    'NLMFCheckBoxLabel', 'NLMFfilterStrengthEditField', 'ZeroFillCheckBox', ...
                    'ZeroFillCheckBoxLabel', 'TukeyFilterCheckBox', 'TukeyFilterCheckBoxLabel', ...
                    'RingRmCheckBox', 'RingRmCheckBoxLabel', 'AutoGuessRecoCheckBox', ...
                    'AutoGuessRecoCheckBoxLabel', 'MatlabRecoCheckBox', 'MatlabRecoCheckBoxLabel', ...
                    'ScoutButton', 'SystolicFunctionButton', 'DiastolicFunctionButton', ...
                    'RespFunctionButton', 'RealTimeFunctionButton', 'VfaFunctionButton', ...
                    'MultiEchoButton', 'GradDelayCalibrationCheckBox', 'GradDelayCalibrationCheckBoxLabel', ...
                    'RingMethodCheckBox', 'RingMethodCheckBoxLabel', 'GxDelayEditField', ...
                    'GxDelayEditFieldLabel', 'GyDelayEditField', 'GyDelayEditFieldLabel', ...
                    'GzDelayEditField', 'GzDelayEditFieldLabel', 'DataOffsetRadialEditField', ...
                    'DataOffsetRadialEditFieldLabel', 'CenterEchoCheckBox', ...
                    'CenterEchoCheckBoxLabel', 'PhaseCorrectCheckBox', 'PhaseCorrectCheckBoxLabel', ...
                    'DensityCorrectCheckBox', 'DensityCorrectCheckBoxLabel', 'ZTEShiftEditField', ...
                    'ZTEShiftEditFieldLabel', 'ZTEDeadTimeEditField', 'ZTEDeadTimeEditFieldLabel', ...
                    'ZTEFullEchoCheckBox', 'ZTEFullEchoCheckBoxLabel', 'MatlabRecoCheckBox', ...
                    'MatlabRecoCheckBoxLabel', 'SharingEditField', 'SharingEditFieldLabel'}, false);
                app.TrajMovieButtonsFcn(false);
                app.Traj3DMovieButtonsFcn(false);

            end

            drawnow;

        end % CineRecoButtonsFcn


        % 3D trajectory movie buttons
        function Traj3DMovieButtonsFcn(app, on)

            % Purpose:
            %   Enable or disable the 3D trajectory movie controls.
            %
            % How it works:
            %   The 3D counterpart of TrajMovieButtonsFcn, and enabled for the two types
            %   whose spokes are three dimensional: 3D UTE and ZTE. Sorting has to have run,
            %   since the trajectory comes from it.
            %
            %   The spoke field belongs to this group because a 3D trajectory is drawn a
            %   spoke at a time; anything else leaves the controls off.
            %
            % Inputs:
            %   on - true to enable the group, false to disable it
            %
            % Output:
            %   none - the controls are set on the interface

            if on && app.validSortFlag

                switch app.r.dataType

                    case {'3Dute','ZTE'}

                        app.setEnable({'SpokeEditField', 'SpokeEditFieldLabel'}, true);
                        if app.stop3DMovieFlag
                            app.TrajMovie3DStartButton.Enable = 'on';
                            app.TrajMovie3DStopButton.Enable = 'off';
                        else
                            app.TrajMovie3DStartButton.Enable = 'off';
                            app.TrajMovie3DStopButton.Enable = 'on';
                        end

                    otherwise

                        app.setEnable({'TrajMovie3DStartButton', 'TrajMovie3DStopButton', 'SpokeEditField', 'SpokeEditFieldLabel'}, false);

                end

            else

                app.setEnable({'TrajMovie3DStartButton', 'TrajMovie3DStopButton', 'SpokeEditField', 'SpokeEditFieldLabel'}, false);

            end

        end % Traj3DMovieButtonsFcn


        % Radial movie buttons
        function TrajMovieButtonsFcn(app, on)

            % Purpose:
            %   Enable or disable the 2D radial trajectory movie controls.
            %
            % How it works:
            %   Enabled only for the data types that have a spoke trajectory to animate, and
            %   only once the data has been sorted -- before that the trajectory is not
            %   known. Start and stop are set against each other from the stop flag, as in
            %   MovieButtonsFcn.
            %
            %   A type without a radial trajectory falls to the otherwise branch and the
            %   controls stay off, rather than starting a movie with nothing to draw.
            %
            %   A slice or dynamic box keeps its tick while its controls are on, since
            %   this runs as every movie starts; it is cleared only when its controls go
            %   off. With every box unticked the frames are ticked, so a started movie
            %   always has something to step through.
            %
            % Inputs:
            %   on - true to enable the group, false to disable it
            %
            % Output:
            %   none - the controls are set on the interface

            if on && app.validSortFlag

                switch app.r.dataType

                    case {'2Dradial','2Dradialms'}

                        if app.stopRadialMovieFlag
                            app.TrajMovieStartButton.Enable = 'on';
                            app.TrajMovieStopButton.Enable = 'off';
                        else
                            app.TrajMovieStartButton.Enable = 'off';
                            app.TrajMovieStopButton.Enable = 'on';
                        end
                        app.setEnable({'TrajMovieFrameEditField', 'TrajMovieFrameEditFieldLabel', 'TrajMovieCheckBox1'}, true);
                        if app.r.multiSliceFlag
                            app.setEnable({'TrajMovieSliceEditField', 'TrajMovieSliceEditFieldLabel', 'TrajMovieCheckBox2'}, true);
                        else
                            app.TrajMovieCheckBox2.Value = 0;
                        end
                        if app.r.multiDynamicFlag || app.r.vfaDataFlag || app.r.multiEchoFlag
                            app.setEnable({'TrajMovieDynEditField', 'TrajMovieDynEditFieldLabel', 'TrajMovieCheckBox3'}, true);
                        else
                            app.TrajMovieCheckBox3.Value = 0;
                        end
                        app.TrajMovieCheckBox1.Value = app.TrajMovieCheckBox1.Value || ...
                            ~(app.TrajMovieCheckBox2.Value || app.TrajMovieCheckBox3.Value);


                    case {'3Dute','ZTE'}

                        if app.stopRadialMovieFlag
                            app.TrajMovieStartButton.Enable = 'on';
                            app.TrajMovieStopButton.Enable = 'off';
                        else
                            app.TrajMovieStartButton.Enable = 'off';
                            app.TrajMovieStopButton.Enable = 'on';
                        end
                        app.setEnable({'TrajMovieFrameEditField', 'TrajMovieFrameEditFieldLabel', 'TrajMovieCheckBox1'}, true);
                        app.TrajMovieCheckBox2.Value = 0;
                        app.TrajMovieSliceEditField.Value = 1;
                        app.setEnable({'TrajMovieSliceEditField', 'TrajMovieSliceEditFieldLabel', 'TrajMovieCheckBox2'}, false);
                        if app.r.multiDynamicFlag || app.r.vfaDataFlag || app.r.multiEchoFlag
                            app.setEnable({'TrajMovieDynEditField', 'TrajMovieDynEditFieldLabel', 'TrajMovieCheckBox3'}, true);
                        else
                            app.TrajMovieCheckBox3.Value = 0;
                        end
                        app.TrajMovieCheckBox1.Value = app.TrajMovieCheckBox1.Value || ...
                            ~(app.TrajMovieCheckBox2.Value || app.TrajMovieCheckBox3.Value);

                    otherwise

                        app.setEnable({'TrajMovieStartButton', 'TrajMovieStopButton', 'TrajMovieFrameEditField', ...
                            'TrajMovieFrameEditFieldLabel', 'TrajMovieSliceEditField', 'TrajMovieSliceEditFieldLabel', ...
                            'TrajMovieDynEditField', 'TrajMovieDynEditFieldLabel', 'TrajMovieCheckBox1', ...
                            'TrajMovieCheckBox2', 'TrajMovieCheckBox3'}, false);

                end

            else

                app.setEnable({'TrajMovieStartButton', 'TrajMovieStopButton', 'TrajMovieFrameEditField', ...
                    'TrajMovieFrameEditFieldLabel', 'TrajMovieSliceEditField', 'TrajMovieSliceEditFieldLabel', ...
                    'TrajMovieDynEditField', 'TrajMovieDynEditFieldLabel', 'TrajMovieCheckBox1', ...
                    'TrajMovieCheckBox2', 'TrajMovieCheckBox3'}, false);

            end

        end % TrajMovieButtonsFcn


        % Movie buttons
        function MovieButtonsFcn(app, on)

            % Purpose:
            %   Enable or disable the movie panel controls as a group.
            %
            % How it works:
            %   Nothing is enabled unless there is a reconstruction to show, which is what
            %   validRecoFlag says. The group therefore switches itself off whenever the
            %   reconstruction is invalidated, without each caller having to remember to.
            %
            %   Start and stop are set against each other from the movie stop flag, so
            %   exactly one of the two is available and the pair cannot both be live.
            %
            %   Three controls depend on what the data actually holds rather than on the
            %   group: the coil spinner needs more than one coil, the sense map button needs
            %   a valid sense map, and the dynamics controls need more than one dynamic.
            %   Enabling a view the data cannot fill is how the movie panel ends up showing
            %   an empty axes. The sense map spinners keep the map the user chose while the
            %   maps have it, since this runs at every movie start and stop.
            %
            % Inputs:
            %   on - true to enable the group, false to disable it
            %
            % Output:
            %   none - the controls are set on the interface

            if on && app.validRecoFlag

                if app.stopMovieFlag
                    app.MovieStartButton.Enable = 'on';
                    app.MovieStopButton.Enable = 'off';
                else
                    app.MovieStartButton.Enable = 'off';
                    app.MovieStopButton.Enable = 'on';
                end
                app.setEnable({'SegmentLVButton', 'MovieFrameEditField', 'MovieFrameEditFieldLabel', ...
                    'MovieCheckBox1', 'WindowScaleSlider', 'WindowScaleSliderLabel', ...
                    'LevelScaleSlider', 'LevelScaleSliderLabel', 'AutoWLButton', ...
                    'MovieRotateLabel', 'MoviecwButton', 'MovieccwButton', ...
                    'MovieFrameLabel', 'MovieFrameIncrButton', 'MovieFrameDecrButton', ...
                    'MovieButton', 'AveragesButton', 'kSpaceButton'}, true);
                if app.r.multiCoilFlag
                    app.MovieCoilSpinner.Enable = 'on';
                else
                    app.MovieCoilSpinner.Enable = 'off';
                end

                if app.validSenseMapFlag
                    app.SenseMapButton.Enable = 'on';
                    s1 = size(app.r.senseMap,4);
                    s2 = size(app.r.senseMap,5);
                    app.SenseMapSpinner1.Value = min(app.SenseMapSpinner1.Value, s1);
                    if s1>1
                        app.SenseMapSpinner1.Limits = [1,s1];
                        app.SenseMapSpinner1.Enable = 'on';
                    else
                        app.SenseMapSpinner1.Limits = [1,2];
                        app.SenseMapSpinner1.Enable = 'off';
                    end
                    app.SenseMapSpinner2.Value = min(app.SenseMapSpinner2.Value, s2);
                    if s2>1
                        app.SenseMapSpinner2.Limits = [1,s2];
                        app.SenseMapSpinner2.Enable = 'on';
                    else
                        app.SenseMapSpinner2.Limits = [1,2];
                        app.SenseMapSpinner2.Enable = 'off';
                    end
                else
                    app.setEnable({'SenseMapButton', 'SenseMapSpinner1', 'SenseMapSpinner2'}, false);
                end

                if app.r.multiSliceFlag
                    app.setEnable({'MovieSliceLabel', 'MovieSliceEditField', 'MovieSliceEditFieldLabel', ...
                        'MovieSliceIncrButton', 'MovieSliceDecrButton', 'MovieCheckBox2'}, true);
                end
                if app.r.multiDynamicFlag || app.r.vfaDataFlag || app.r.multiEchoFlag
                    app.setEnable({'MovieDynLabel', 'MovieDynEditField', 'MovieDynEditFieldLabel', ...
                        'MovieDynIncrButton', 'MovieDynDecrButton', 'MovieCheckBox3'}, true);
                end

                if app.extFidNavFlag || app.extEchoNavFlag
                    app.ShowNavButton.Enable = 'on';
                end

            else

                app.setEnable({'MovieStartButton', 'MovieStopButton', 'SegmentLVButton', ...
                    'MovieFrameEditField', 'MovieFrameEditFieldLabel', 'MovieSliceEditField', ...
                    'MovieSliceEditFieldLabel', 'MovieDynEditField', 'MovieDynEditFieldLabel', ...
                    'MovieCheckBox1', 'MovieCheckBox2', 'MovieCheckBox3', ...
                    'WindowScaleSlider', 'WindowScaleSliderLabel', 'LevelScaleSlider', ...
                    'LevelScaleSliderLabel', 'AutoWLButton', 'MovieRotateLabel', ...
                    'MoviecwButton', 'MovieccwButton', 'MovieFrameLabel', ...
                    'MovieFrameIncrButton', 'MovieFrameDecrButton', 'MovieSliceLabel', ...
                    'MovieSliceIncrButton', 'MovieSliceDecrButton', 'MovieDynLabel', ...
                    'MovieDynIncrButton', 'MovieDynDecrButton', 'MovieButton', ...
                    'AveragesButton', 'kSpaceButton', 'MovieCoilSpinner', ...
                    'SenseMapButton', 'SenseMapSpinner1', 'SenseMapSpinner2', ...
                    'ShowNavButton'}, false);

            end

            drawnow;

        end % MovieButtonsFcn


        % Switch off all buttons
        function SwitchOffAllButtonsFcn(app)

            % Purpose:
            %   Put the whole interface into its idle state: every movie stopped and every
            %   group of controls disabled.
            %
            % How it works:
            %   The movie stop flags are raised first, so a running movie loop leaves at its
            %   next iteration rather than drawing into an interface that is being torn
            %   down. Then each group of controls is disabled through the function that owns
            %   it -- dashboard, navigator selection, navigator viewing, cine reconstruction,
            %   and the three movie panels.
            %
            %   Going through those functions rather than touching components directly is
            %   what keeps the knowledge of which control belongs to which group in one
            %   place per group. A new control has to be added once, to its group, and every
            %   caller of this gets it.
            %
            %   Called before an operation that invalidates what is on screen, so that no
            %   control is left offering a step the data can no longer support.
            %
            % Inputs:
            %   none
            %
            % Output:
            %   none - the interface is left idle

            % Stop the movies, all five: a movie left running would go on playing with
            % its start and stop buttons switched off below
            app.stopNavTimeMovieFlag = true;
            app.stopMovieFlag = true;
            app.stopRadialMovieFlag = true;
            app.stop3DMovieFlag = true;
            app.stopRawNavMovieFlag = true;

            % Dashboard buttons
            app.SetDashboardButtonsFcn(false);

            % Navigator selection and filtering
            app.NavSelButtonsFcn(false);

            % Navigator viewing
            app.NavViewingButtonsFcn(false);

            % Cine reconstruction buttons
            app.CineRecoButtonsFcn(false);

            % Movie windows
            app.MovieButtonsFcn(false);
            app.TrajMovieButtonsFcn(false);
            app.Traj3DMovieButtonsFcn(false);

        end % SwitchOffAllButtonsFcn


        % Stop all movies
        function StopAllMoviesFcn(app)

            % Purpose:
            %   Stop every movie that may be running, and re-enable the buttons that start
            %   them.
            %
            % How it works:
            %   The five stop flags are raised together. A movie is a loop that checks its
            %   flag each frame, so this asks it to leave rather than killing it: the loop
            %   finishes the frame it is drawing and exits cleanly, which is what keeps the
            %   axes in a valid state.
            %
            %   All five are raised regardless of which is running, because that costs
            %   nothing and asking which is running invites the answer to be wrong.
            %
            %   The three movie button groups are then re-enabled, since stopping is what
            %   makes starting possible again.
            %
            % Inputs:
            %   none
            %
            % Output:
            %   none - the movies are asked to stop and their buttons re-enabled

            app.stop3DMovieFlag = true;
            app.stopMovieFlag = true;
            app.stopNavTimeMovieFlag = true;
            app.stopRadialMovieFlag = true;
            app.stopRawNavMovieFlag = true;
            app.MovieButtonsFcn(true);
            app.TrajMovieButtonsFcn(true);
            app.Traj3DMovieButtonsFcn(true);
            drawnow;

        end % StopAllMoviesFcn


        % Set the icons, images and title
        function SetAppLayoutPropertiesFcn(app)

            % Purpose:
            %   Set the icons, images and title that do not change while the app runs.
            %
            % How it works:
            %   Every image is named in the settings file rather than embedded, so the two
            %   institutional logos, the title image and the button icons can be replaced
            %   without editing the app. The credit line is built from the version, so it
            %   cannot drift from the version the app reports elsewhere.
            %
            %   Run once at start up: these are fixed properties of the layout, unlike the
            %   colours, which a scheme change reapplies.
            %
            % Inputs:
            %   none
            %
            % Output:
            %   none - the icons and title are set on the interface

            app.Credits.Text = strcat("Gustav Strijkers, Amsterdam UMC, version ",app.appVersion);

            % Icons and title
            app.TulipImage.ImageSource = app.jsonPar.amsterdamUmcIcon;
            app.MRSImage.ImageSource = app.jsonPar.mrsolutionsIcon;
            app.RetrospectiveTitleImage.ImageSource = app.jsonPar.retrospectiveIcon;

            % Button images
            app.LoadMRDButton.Icon = app.jsonPar.diskIcon;
            app.FilterButton.Icon = app.jsonPar.filterIcon;
            app.SortKspaceButton.Icon = app.jsonPar.sortIcon;
            app.ReconstructButton.Icon = app.jsonPar.recoIcon;
            app.ExportDCMButton.Icon = app.jsonPar.diskIcon;
            app.ExportGIFButton.Icon = app.jsonPar.diskIcon;
            app.ExportMRDButton.Icon = app.jsonPar.diskIcon;
            app.ExitButton.Icon = app.jsonPar.exitIcon;
            app.MovieStartButton.Icon = app.jsonPar.playIcon;
            app.MovieStopButton.Icon = app.jsonPar.stopIcon;
            app.SegmentLVButton.Icon = app.jsonPar.segmentIcon;
            app.NavStartButton.Icon = app.jsonPar.playIcon;
            app.NavStopButton.Icon = app.jsonPar.stopIcon;
            app.TrajMovieStartButton.Icon = app.jsonPar.playIcon;
            app.TrajMovie3DStartButton.Icon = app.jsonPar.playIcon;
            app.TrajMovieStopButton.Icon = app.jsonPar.stopIcon;
            app.TrajMovie3DStopButton.Icon = app.jsonPar.stopIcon;
            app.MoviecwButton.Icon = app.jsonPar.cwIcon;
            app.MovieccwButton.Icon = app.jsonPar.ccwIcon;
            app.StatusImage.ImageSource = app.jsonPar.statusOkayIcon;
            app.StatusBackgroundImage.ImageSource = app.jsonPar.squareIcon;
            app.StartMotionButton.Icon = app.jsonPar.playIcon;
            app.StopMotionButton.Icon = app.jsonPar.stopIcon;
            app.AutoWLButton.Icon = app.jsonPar.contrastIcon;
            app.ShowNavButton.Icon = app.jsonPar.navIcon;

            % Define app colors
            app.brightred = app.jsonPar.brightred;
            app.red = app.jsonPar.red;
            app.brightgreen = app.jsonPar.brightgreen;
            app.green = app.jsonPar.green;
            app.yellow = app.jsonPar.yellow;
            app.orange = app.jsonPar.orange;
            app.blue = app.jsonPar.blue;
            app.black = app.jsonPar.black;
            app.lightblue = app.jsonPar.lightblue;
            app.lightgrey = app.jsonPar.lightgrey;
            app.guiBlack = app.jsonPar.guiBlack;
            app.guiGrey = app.jsonPar.guiGrey;
            app.guiMiddleGrey = app.jsonPar.guiMiddleGrey;
            app.guiDarkGrey = app.jsonPar.guiDarkGrey;
            app.guiWhite = app.jsonPar.guiWhite;
            app.guiBlackBlue = app.jsonPar.guiBlackBlue;
            app.traceGreen = app.jsonPar.traceGreen;
            app.traceOrange = app.jsonPar.traceOrange;

            % Some other elements
            app.StartSlider.MajorTicks = [];
            app.StartSlider.MajorTickLabels = {};
            app.StartSlider.MinorTicks = [];
            app.RangeSlider.MajorTicks = [];
            app.RangeSlider.MajorTickLabels = {};
            app.RangeSlider.MinorTicks = [];
            app.IncludeStartSlider.MajorTicks = [];
            app.IncludeStartSlider.MajorTickLabels = {};
            app.IncludeStartSlider.MinorTicks = [];
            app.IncludeEndSlider.MajorTicks = [];
            app.IncludeEndSlider.MajorTickLabels = {};
            app.IncludeEndSlider.MinorTicks = [];
            app.ExcludeStartSlider.MajorTicks = [];
            app.ExcludeStartSlider.MajorTickLabels = {};
            app.ExcludeStartSlider.MinorTicks = [];
            app.ExcludeEndSlider.MajorTicks = [];
            app.ExcludeEndSlider.MajorTickLabels = {};
            app.ExcludeEndSlider.MinorTicks = [];
            app.LevelScaleSlider.MajorTicks = [];
            app.LevelScaleSlider.MajorTickLabels = {};
            app.LevelScaleSlider.MinorTicks = [];
            app.WindowScaleSlider.MajorTicks = [];
            app.WindowScaleSlider.MajorTickLabels = {};
            app.WindowScaleSlider.MinorTicks = [];

            % Set some plot properties
            app.Nav1Fig.Interactions = [];
            app.Nav1Fig.Position = [47,217,246,185];
            app.Nav1Fig.YTick = [];
            app.Nav1Fig.YTickLabel = [];
            app.Nav1Fig.Toolbar.Visible = 'off';
            app.Nav1Fig.PositionConstraint = "innerposition";

            app.Nav2Fig.Interactions = [];
            app.Nav2Fig.Position = [47,7,246,185];
            app.Nav2Fig.YTick = [];
            app.Nav2Fig.YTickLabel = [];
            app.Nav2Fig.Toolbar.Visible = 'off';
            app.Nav2Fig.PositionConstraint = "innerposition";

            app.HeartNavFig.Interactions = [];
            app.HeartNavFig.Position = [37,217,246,185];
            app.HeartNavFig.YTick = [];
            app.HeartNavFig.YTickLabel = [];
            app.HeartNavFig.Toolbar.Visible = 'off';
            app.HeartNavFig.PositionConstraint = "innerposition";

            app.RespNavFig.Interactions = [];
            app.RespNavFig.Position = [326,217,246,185];
            app.RespNavFig.YTick = [];
            app.RespNavFig.YTickLabel = [];
            app.RespNavFig.Toolbar.Visible = 'off';
            app.RespNavFig.PositionConstraint = "innerposition";

            app.HeartRawNavFig.Interactions = [];
            app.HeartRawNavFig.Position = [47,217,246,185];
            app.HeartRawNavFig.YTick = [];
            app.HeartRawNavFig.YTickLabel = [];
            app.HeartRawNavFig.Toolbar.Visible = 'off';
            app.HeartRawNavFig.PositionConstraint = "innerposition";

            app.RespRawNavFig.Interactions = [];
            app.RespRawNavFig.Position = [47,217,246,185];
            app.RespRawNavFig.YTick = [];
            app.RespRawNavFig.YTickLabel = [];
            app.RespRawNavFig.Toolbar.Visible = 'off';
            app.RespRawNavFig.PositionConstraint = "innerposition";

            app.HeartSpectrumFig.Interactions = [];
            app.HeartSpectrumFig.Position = [27,7,266,185];
            app.HeartSpectrumFig.Toolbar.Visible = 'off';
            app.HeartSpectrumFig.PositionConstraint = "innerposition";

            app.RespSpectrumFig.Interactions = [];
            app.RespSpectrumFig.Position = [27,7,266,185];
            app.RespSpectrumFig.Toolbar.Visible = 'off';
            app.RespSpectrumFig.PositionConstraint = "innerposition";

            app.HeartRateTimeFig.Interactions = [];
            app.HeartRateTimeFig.Position = [18,6,265,185];
            app.HeartRateTimeFig.Toolbar.Visible = 'off';
            app.HeartRateTimeFig.PositionConstraint = "innerposition";

            app.RespRateTimeFig.Interactions = [];
            app.RespRateTimeFig.Position = [307,6,264,185];
            app.RespRateTimeFig.Toolbar.Visible = 'off';
            app.RespRateTimeFig.PositionConstraint = "innerposition";

            % The movie zooms with the scroll wheel and with a box dragged over it. Its
            % toolbar, which shows while the mouse is over the movie, holds one button, which
            % returns to the whole frame
            app.MovieFig.Interactions = [zoomInteraction regionZoomInteraction];
            movieToolbar = axtoolbar(app.MovieFig, {});
            axtoolbarbtn(movieToolbar, 'push', 'Icon', 'restoreview', 'Tooltip', 'Whole frame', ...
                'ButtonPushedFcn', @(~, ~) app.ShowWholeMovieFrameFcn);
            app.MovieFig.PositionConstraint = "innerposition";

            app.TrajectoryFig.Interactions = [];
            app.TrajectoryFig.Toolbar.Visible = 'off';
            app.TrajectoryFig.PositionConstraint = "innerposition";

            app.TrajectoryLargeFig.Interactions = [];
            app.TrajectoryLargeFig.Toolbar.Visible = 'off';
            app.TrajectoryLargeFig.PositionConstraint = "innerposition";

            % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');

            % Set initial colors
            app.ColorsButtonGroupSelectionChanged;

            % Some plot parameters
            app.navMovieSpeed = app.jsonPar.navMovieSpeed;
            app.maxNrSpokesPlot = app.jsonPar.maxNrSpokesPlot;
            app.traceLength = app.jsonPar.traceLength;

            % Guess reco
            app.AutoGuessRecoCheckBox.Value = app.jsonPar.AutoGuessRecoCheckBox;

            % Navigator sample positions and counts are whole numbers: a fraction would
            % index between two samples of the readout
            set([app.NavEnd1EditField, app.NavEnd2EditField, app.NavNRPoints1EditField, ...
                app.NavNRPoints2EditField, app.DiscardEditField], 'RoundFractionalValues', 'on');

        end % SetAppLayoutPropertiesFcn


        % Species presets
        function SetSpeciesFcn(app)

            % Purpose:
            %   Load the filter and rate presets for the selected species.
            %
            % How it works:
            %   A mouse and a rat differ by roughly a factor of two in heart rate and in
            %   respiratory rate, so the navigator filter bands, the rate limits and the
            %   respiratory window that suit one are wrong for the other. Selecting the
            %   species sets all of them at once.
            %
            %   The values come from the settings file rather than being written here, so a
            %   site can retune them for its own animals without touching the app, and the
            %   two species blocks in the settings are the single place they live.
            %
            %   Both the object and the corresponding control are set from each value, so
            %   what the reconstruction will use and what the user sees cannot disagree.
            %
            %   These are starting points, not constraints; every one of them remains
            %   editable afterwards.
            %
            % Inputs:
            %   none - the selected species is read from the interface buttons
            %
            % Output:
            %   none - the presets are applied to the object and the controls

            % Mouse preset settings
            if app.MouseButton.Value

                app.HeartEditField.Value = app.jsonPar.mouse.HeartRateEditField;
                app.RespEditField.Value = app.jsonPar.mouse.RespRateEditField;
                app.HeartWidthEditField.Value = app.jsonPar.mouse.bandwidthHR;
                app.r.bandwidthHR = app.HeartWidthEditField.Value;
                app.RespWidthEditField.Value = app.jsonPar.mouse.bandwidthRR;
                app.r.bandwidthRR = app.RespWidthEditField.Value;
                app.RespWindowEditField.Value = app.jsonPar.mouse.respPercentage;
                app.r.respPercentage = app.RespWindowEditField.Value;
                app.MinHeartRateEditField.Value = app.jsonPar.mouse.MinHeartRateEditField;
                app.HeartMinEditField.Value = app.jsonPar.mouse.HeartMinEditField;
                app.MaxHeartRateEditField.Value = app.jsonPar.mouse.MaxHeartRateEditField;
                app.HeartMaxEditField.Value = app.jsonPar.mouse.HeartMaxEditField;
                app.MinRespRateEditField.Value = app.jsonPar.mouse.MinRespRateEditField;
                app.RespMinEditField.Value = app.jsonPar.mouse.RespMinEditField;
                app.MaxRespRateEditField.Value = app.jsonPar.mouse.MaxRespRateEditField;
                app.RespMaxEditField.Value = app.jsonPar.mouse.RespMaxEditField;
                app.FilterOrderEditField.Value = app.jsonPar.mouse.FilterOrderEditField;
                app.RespShiftEditField.Value = app.jsonPar.mouse.RespShiftEditField;

            end

            % Rat preset settings
            if app.RatButton.Value

                app.HeartEditField.Value = app.jsonPar.rat.HeartRateEditField;
                app.RespEditField.Value = app.jsonPar.rat.RespRateEditField;
                app.HeartWidthEditField.Value = app.jsonPar.rat.bandwidthHR;
                app.r.bandwidthHR = app.HeartWidthEditField.Value;
                app.RespWidthEditField.Value = app.jsonPar.rat.bandwidthRR;
                app.r.bandwidthRR = app.RespWidthEditField.Value;
                app.RespWindowEditField.Value = app.jsonPar.rat.respPercentage;
                app.r.respPercentage = app.RespWindowEditField.Value;
                app.MinHeartRateEditField.Value = app.jsonPar.rat.MinHeartRateEditField;
                app.HeartMinEditField.Value = app.jsonPar.rat.HeartMinEditField;
                app.MaxHeartRateEditField.Value = app.jsonPar.rat.MaxHeartRateEditField;
                app.HeartMaxEditField.Value = app.jsonPar.rat.HeartMaxEditField;
                app.MinRespRateEditField.Value = app.jsonPar.rat.MinRespRateEditField;
                app.RespMinEditField.Value = app.jsonPar.rat.RespMinEditField;
                app.MaxRespRateEditField.Value = app.jsonPar.rat.MaxRespRateEditField;
                app.RespMaxEditField.Value = app.jsonPar.rat.RespMaxEditField;
                app.FilterOrderEditField.Value = app.jsonPar.rat.FilterOrderEditField;
                app.RespShiftEditField.Value = app.jsonPar.rat.RespShiftEditField;

            end

            % Zebrafish preset settings
            if app.ZebraFishButton.Value

                app.HeartEditField.Value = app.jsonPar.zebrafish.HeartRateEditField;
                app.RespEditField.Value = app.jsonPar.zebrafish.RespRateEditField;
                app.HeartWidthEditField.Value = app.jsonPar.zebrafish.bandwidthHR;
                app.r.bandwidthHR = app.HeartWidthEditField.Value;
                app.RespWidthEditField.Value = app.jsonPar.zebrafish.bandwidthRR;
                app.r.bandwidthRR = app.RespWidthEditField.Value;
                app.RespWindowEditField.Value = app.jsonPar.zebrafish.respPercentage;
                app.r.respPercentage = app.RespWindowEditField.Value;
                app.MinHeartRateEditField.Value = app.jsonPar.zebrafish.MinHeartRateEditField;
                app.HeartMinEditField.Value = app.jsonPar.zebrafish.HeartMinEditField;
                app.MaxHeartRateEditField.Value = app.jsonPar.zebrafish.MaxHeartRateEditField;
                app.HeartMaxEditField.Value = app.jsonPar.zebrafish.HeartMaxEditField;
                app.MinRespRateEditField.Value = app.jsonPar.zebrafish.MinRespRateEditField;
                app.RespMinEditField.Value = app.jsonPar.zebrafish.RespMinEditField;
                app.MaxRespRateEditField.Value = app.jsonPar.zebrafish.MaxRespRateEditField;
                app.RespMaxEditField.Value = app.jsonPar.zebrafish.RespMaxEditField;
                app.FilterOrderEditField.Value = app.jsonPar.zebrafish.FilterOrderEditField;
                app.RespShiftEditField.Value = app.jsonPar.zebrafish.RespShiftEditField;

            end

        end % SetSpeciesFcn


        % Reconstruction presets
        function SetRecoParametersFcn(app)

            % Purpose:
            %   Load the reconstruction preset for the kind of scan selected.
            %
            % How it works:
            %   Each preset -- scout, cine, and the rest -- sets the frame and dynamic
            %   counts, the view sharing and the four regularisation weights together, since
            %   they only make sense as a set: the regularisation that suits a three-slice
            %   scout is wrong for a long cine of the same heart.
            %
            %   The values come from the settings file, so a site can retune them without
            %   touching the app.
            %
            %   The dynamic count is left alone for variable flip angle data. There the
            %   dynamics are the flip angles, fixed by how the scan was acquired, and a
            %   preset that overwrote the count would ask for a reconstruction of dynamics
            %   that were never measured.
            %
            %   Starting points, all of them still editable afterwards.
            %
            %   The ZTE shift is not a preset's. It decides which sample of every FID
            %   the data were built from, and a new shift in its field builds them
            %   again (ZTEShiftEditFieldValueChanged); a value written here would call
            %   nothing and leave the field showing a shift the data were not built
            %   with.
            %
            % Inputs:
            %   none - the selected preset is read from the interface buttons
            %
            % Output:
            %   none - the reconstruction controls are set

            % Settings for scout acquisition (e.g. 3 orthogonal slices)
            if app.ScoutButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = app.jsonPar.scoutReco.FramesEditField;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = app.jsonPar.scoutReco.DynamicsEditField;
                end
                app.SharingEditField.Value = app.jsonPar.scoutReco.SharingEditField;
                app.WVxyzEditField.Value = app.jsonPar.scoutReco.WVxyzEditField;
                app.TVxyzEditField.Value = app.jsonPar.scoutReco.TVxyzEditField;
                app.LLRxyzEditField.Value = app.jsonPar.scoutReco.LLRxyzEditField;
                app.TVcineEditField.Value = app.jsonPar.scoutReco.TVcineEditField;
                app.TVdynEditField.Value = app.jsonPar.scoutReco.TVdynEditField;
                app.ESPIRiTCheckBox.Value = app.jsonPar.scoutReco.ESPIRiTCheckBox(1);
                if app.r.nrCoils > 1
                    app.ESPIRiTCheckBox.Value = app.jsonPar.scoutReco.ESPIRiTCheckBox(2);
                end
                app.RingRmCheckBox.Value = app.jsonPar.scoutReco.RingRmCheckBox;
                app.NLMFCheckBox.Value = app.jsonPar.scoutReco.NLMFCheckBox;
                app.ZeroFillCheckBox.Value = app.jsonPar.scoutReco.ZeroFillCheckBox;
                app.TukeyFilterCheckBox.Value = app.jsonPar.scoutReco.TukeyFilterCheckBox;
                app.ZTEDeadTimeEditField.Value = app.jsonPar.scoutReco.ZTEDeadTimeEditField;
                app.ZTEFullEchoCheckBox.Value = app.jsonPar.scoutReco.ZTEFullEchoCheckBox;

            end

            % Settings for systolic function
            if app.SystolicFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = app.jsonPar.systolicReco.FramesEditField;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = app.jsonPar.systolicReco.DynamicsEditField;
                end
                app.SharingEditField.Value = app.jsonPar.systolicReco.SharingEditField;
                app.WVxyzEditField.Value = app.jsonPar.systolicReco.WVxyzEditField;
                app.TVxyzEditField.Value = app.jsonPar.systolicReco.TVxyzEditField;
                app.LLRxyzEditField.Value = app.jsonPar.systolicReco.LLRxyzEditField(1);
                if contains(app.r.dataType,["3D","3Dp2roud","3Dute","ZTE"])
                    app.TVcineEditField.Value = app.jsonPar.systolicReco.TVcineEditField(1);
                    if app.gpuPresentFlag
                        app.LLRxyzEditField.Value = app.jsonPar.systolicReco.LLRxyzEditField(2);
                    end
                else
                    app.TVcineEditField.Value = app.jsonPar.systolicReco.TVcineEditField(2);
                    if app.gpuPresentFlag
                        app.LLRxyzEditField.Value = app.jsonPar.systolicReco.LLRxyzEditField(3);
                    end
                end
                app.TVdynEditField.Value = app.jsonPar.systolicReco.TVdynEditField;
                app.ESPIRiTCheckBox.Value = app.jsonPar.systolicReco.ESPIRiTCheckBox(1);
                if app.r.nrCoils > 1
                    app.ESPIRiTCheckBox.Value = app.jsonPar.systolicReco.ESPIRiTCheckBox(2);
                end
                app.RingRmCheckBox.Value = app.jsonPar.systolicReco.RingRmCheckBox;
                app.NLMFCheckBox.Value = app.jsonPar.systolicReco.NLMFCheckBox;
                app.ZeroFillCheckBox.Value = app.jsonPar.systolicReco.ZeroFillCheckBox;
                app.TukeyFilterCheckBox.Value = app.jsonPar.systolicReco.TukeyFilterCheckBox;
                app.ZTEDeadTimeEditField.Value = app.jsonPar.systolicReco.ZTEDeadTimeEditField;
                app.ZTEFullEchoCheckBox.Value = app.jsonPar.systolicReco.ZTEFullEchoCheckBox;

            end

            % Settings for diastolic function
            if app.DiastolicFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = app.jsonPar.diastolicReco.FramesEditField;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = app.jsonPar.diastolicReco.DynamicsEditField;
                end
                app.SharingEditField.Value = app.jsonPar.diastolicReco.SharingEditField;
                app.WVxyzEditField.Value = app.jsonPar.diastolicReco.WVxyzEditField;
                app.TVxyzEditField.Value = app.jsonPar.diastolicReco.TVxyzEditField;
                app.LLRxyzEditField.Value = app.jsonPar.diastolicReco.LLRxyzEditField(1);
                if contains(app.r.dataType,["3D","3Dp2roud","3Dute","ZTE"])
                    app.TVcineEditField.Value = app.jsonPar.diastolicReco.TVcineEditField(1);
                    if app.gpuPresentFlag
                        app.LLRxyzEditField.Value = app.jsonPar.diastolicReco.LLRxyzEditField(2);
                    end
                else
                    app.TVcineEditField.Value = app.jsonPar.diastolicReco.TVcineEditField(2);
                    if app.gpuPresentFlag
                        app.LLRxyzEditField.Value = app.jsonPar.diastolicReco.LLRxyzEditField(3);
                    end
                end
                app.TVdynEditField.Value = app.jsonPar.diastolicReco.TVdynEditField;
                app.ESPIRiTCheckBox.Value = app.jsonPar.diastolicReco.ESPIRiTCheckBox(1);
                if app.r.nrCoils > 1
                    app.ESPIRiTCheckBox.Value = app.jsonPar.diastolicReco.ESPIRiTCheckBox(2);
                end
                app.RingRmCheckBox.Value = app.jsonPar.diastolicReco.RingRmCheckBox;
                app.NLMFCheckBox.Value = app.jsonPar.diastolicReco.NLMFCheckBox;
                app.ZeroFillCheckBox.Value = app.jsonPar.diastolicReco.ZeroFillCheckBox;
                app.TukeyFilterCheckBox.Value = app.jsonPar.diastolicReco.TukeyFilterCheckBox;
                app.ZTEDeadTimeEditField.Value = app.jsonPar.diastolicReco.ZTEDeadTimeEditField;
                app.ZTEFullEchoCheckBox.Value = app.jsonPar.diastolicReco.ZTEFullEchoCheckBox;

            end

            % For reconstruction of respiratory motion
            if app.RespFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'respiratory';
                app.FramesEditField.Value = app.jsonPar.respirationReco.FramesEditField;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = app.jsonPar.respirationReco.DynamicsEditField;
                end
                app.SharingEditField.Value = app.jsonPar.respirationReco.SharingEditField;
                app.WVxyzEditField.Value = app.jsonPar.respirationReco.WVxyzEditField;
                app.TVxyzEditField.Value = app.jsonPar.respirationReco.TVxyzEditField;
                app.LLRxyzEditField.Value = app.jsonPar.respirationReco.LLRxyzEditField(1);
                if contains(app.r.dataType,["3D","3Dp2roud","3Dute","ZTE"])
                    app.TVcineEditField.Value = app.jsonPar.respirationReco.TVcineEditField(1);
                    if app.gpuPresentFlag
                        app.LLRxyzEditField.Value = app.jsonPar.respirationReco.LLRxyzEditField(2);
                    end
                else
                    app.TVcineEditField.Value = app.jsonPar.respirationReco.TVcineEditField(2);
                    if app.gpuPresentFlag
                        app.LLRxyzEditField.Value = app.jsonPar.respirationReco.LLRxyzEditField(3);
                    end
                end
                app.TVdynEditField.Value = app.jsonPar.respirationReco.TVdynEditField;
                app.ESPIRiTCheckBox.Value = app.jsonPar.respirationReco.ESPIRiTCheckBox(1);
                if app.r.nrCoils > 1
                    app.ESPIRiTCheckBox.Value = app.jsonPar.respirationReco.ESPIRiTCheckBox(2);
                end
                app.RingRmCheckBox.Value = app.jsonPar.respirationReco.RingRmCheckBox;
                app.NLMFCheckBox.Value = app.jsonPar.respirationReco.NLMFCheckBox;
                app.ZeroFillCheckBox.Value = app.jsonPar.respirationReco.ZeroFillCheckBox;
                app.TukeyFilterCheckBox.Value = app.jsonPar.respirationReco.TukeyFilterCheckBox;
                app.ZTEDeadTimeEditField.Value = app.jsonPar.respirationReco.ZTEDeadTimeEditField;
                app.ZTEFullEchoCheckBox.Value = app.jsonPar.respirationReco.ZTEFullEchoCheckBox;

            end

            % For 'realtime' reconstruction of X frames / heartbeat
            if app.RealTimeFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'realtime';
                app.FramesEditField.Value = app.jsonPar.realtimeReco.FramesEditField;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = app.jsonPar.realtimeReco.DynamicsEditField;
                end
                app.SharingEditField.Value = app.jsonPar.realtimeReco.SharingEditField;
                app.WVxyzEditField.Value = app.jsonPar.realtimeReco.WVxyzEditField;
                app.TVxyzEditField.Value = app.jsonPar.realtimeReco.TVxyzEditField;
                app.LLRxyzEditField.Value = app.jsonPar.realtimeReco.LLRxyzEditField(1);
                app.TVcineEditField.Value = app.jsonPar.realtimeReco.TVcineEditField(1);
                if app.gpuPresentFlag
                    app.LLRxyzEditField.Value = app.jsonPar.realtimeReco.LLRxyzEditField(2);
                end
                app.TVdynEditField.Value = app.jsonPar.realtimeReco.TVdynEditField;
                app.ESPIRiTCheckBox.Value = app.jsonPar.realtimeReco.ESPIRiTCheckBox(1);
                if app.r.nrCoils > 1
                    app.ESPIRiTCheckBox.Value = app.jsonPar.realtimeReco.ESPIRiTCheckBox(2);
                end
                app.RingRmCheckBox.Value = app.jsonPar.realtimeReco.RingRmCheckBox;
                app.NLMFCheckBox.Value = app.jsonPar.realtimeReco.NLMFCheckBox;
                app.ZeroFillCheckBox.Value = app.jsonPar.realtimeReco.ZeroFillCheckBox;
                app.TukeyFilterCheckBox.Value = app.jsonPar.realtimeReco.TukeyFilterCheckBox;
                app.ZTEDeadTimeEditField.Value = app.jsonPar.realtimeReco.ZTEDeadTimeEditField;
                app.ZTEFullEchoCheckBox.Value = app.jsonPar.realtimeReco.ZTEFullEchoCheckBox;

            end

            % For variable flip-angle reconstruction for T1 mapping
            if app.VfaFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = app.jsonPar.vfaReco.FramesEditField;
                app.SharingEditField.Value = app.jsonPar.vfaReco.SharingEditField;
                app.WVxyzEditField.Value = app.jsonPar.vfaReco.WVxyzEditField;
                app.TVxyzEditField.Value = app.jsonPar.vfaReco.TVxyzEditField;
                app.LLRxyzEditField.Value = app.jsonPar.vfaReco.LLRxyzEditField(1);
                if app.gpuPresentFlag
                    app.LLRxyzEditField.Value = app.jsonPar.vfaReco.LLRxyzEditField(2);
                end
                app.TVcineEditField.Value = app.jsonPar.vfaReco.TVcineEditField;
                app.TVdynEditField.Value = app.jsonPar.vfaReco.TVdynEditField;
                app.ESPIRiTCheckBox.Value = app.jsonPar.vfaReco.ESPIRiTCheckBox(1);
                if app.r.nrCoils > 1
                    app.ESPIRiTCheckBox.Value = app.jsonPar.vfaReco.ESPIRiTCheckBox(2);
                end
                app.RingRmCheckBox.Value = app.jsonPar.vfaReco.RingRmCheckBox;
                app.NLMFCheckBox.Value = app.jsonPar.vfaReco.NLMFCheckBox;
                app.ZeroFillCheckBox.Value = app.jsonPar.vfaReco.ZeroFillCheckBox;
                app.TukeyFilterCheckBox.Value = app.jsonPar.vfaReco.TukeyFilterCheckBox;
                app.ZTEDeadTimeEditField.Value = app.jsonPar.vfaReco.ZTEDeadTimeEditField;
                app.ZTEFullEchoCheckBox.Value = app.jsonPar.vfaReco.ZTEFullEchoCheckBox;

            end

            % For multi-echo reconstruction
            if app.MultiEchoButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = app.jsonPar.multiEchoReco.FramesEditField;
                app.SharingEditField.Value = app.jsonPar.multiEchoReco.SharingEditField;
                app.WVxyzEditField.Value = app.jsonPar.multiEchoReco.WVxyzEditField;
                app.TVxyzEditField.Value = app.jsonPar.multiEchoReco.TVxyzEditField;
                app.LLRxyzEditField.Value = app.jsonPar.multiEchoReco.LLRxyzEditField(1);
                if app.gpuPresentFlag
                    app.LLRxyzEditField.Value = app.jsonPar.multiEchoReco.LLRxyzEditField(2);
                end
                app.TVcineEditField.Value = app.jsonPar.multiEchoReco.TVcineEditField;
                app.TVdynEditField.Value = app.jsonPar.multiEchoReco.TVdynEditField;
                app.ESPIRiTCheckBox.Value = app.jsonPar.multiEchoReco.ESPIRiTCheckBox(1);
                if app.r.nrCoils > 1
                    app.ESPIRiTCheckBox.Value = app.jsonPar.multiEchoReco.ESPIRiTCheckBox(2);
                end
                app.RingRmCheckBox.Value = app.jsonPar.multiEchoReco.RingRmCheckBox;
                app.NLMFCheckBox.Value = app.jsonPar.multiEchoReco.NLMFCheckBox;
                app.ZeroFillCheckBox.Value = app.jsonPar.multiEchoReco.ZeroFillCheckBox;
                app.TukeyFilterCheckBox.Value = app.jsonPar.multiEchoReco.TukeyFilterCheckBox;
                app.ZTEDeadTimeEditField.Value = app.jsonPar.multiEchoReco.ZTEDeadTimeEditField;
                app.ZTEFullEchoCheckBox.Value = app.jsonPar.multiEchoReco.ZTEFullEchoCheckBox;

            end

        end % SetRecoParametersFcn


        % Select a navigator automatically
        function SetNavigatorSelectionFcn(app)

            % Purpose:
            %   Select a navigator source automatically, so a scan arrives with a workable
            %   default rather than nothing chosen.
            %
            % How it works:
            %   The three kinds are tried in a fixed order -- slice FID, then external FID,
            %   then external echo -- and the first the data actually carries is selected for
            %   both navigators.
            %
            %   The order is a preference, not a ranking of quality: the slice FID needs no
            %   extra acquisition and is present most often, so it is the one that lets the
            %   most scans proceed without the user choosing anything.
            %
            %   A scan carrying none of the three leaves the buttons as they are, since there
            %   is nothing to select.
            %
            %   Only a starting point; the selection buttons stay live and the user overrides
            %   it whenever the automatic choice suits the data less well.
            %
            % Inputs:
            %   none
            %
            % Output:
            %   none - the navigator source buttons are set on the interface

            % Set navigators to lowest default possibility

            if app.fidNavFlag
                app.SliceFID1Button.Value = 1;
                app.SliceFID2Button.Value = 1;
            elseif app.extFidNavFlag
                app.ExtFID1Button.Value = 1;
                app.ExtFID2Button.Value = 1;
            elseif app.extEchoNavFlag
                app.ExtECHO1Button.Value = 1;
                app.ExtECHO2Button.Value = 1;
            end

        end % SetNavigatorSelectionFcn


        % ------------------------------------------
        % Image acquisition data parameters
        % ------------------------------------------


        % Set MRD paths
        function SetMRDimportPathFcn(app)

            % Purpose:
            %   Decide which folder the MRD file browser opens on.
            %
            % How it works:
            %   The settings file is asked first and the Windows registry second, which is
            %   where the MR Solutions console records its own data directory. Which of the
            %   two wins, and what happens when neither answers, is decided in
            %   retro.io.mrdImportFolder; this reads the setting out of the interface and
            %   stores the result.
            %
            %   The setting is read through isfield rather than assumed, so a settings file
            %   written by an older version, which has no such key, still starts the app.
            %
            %   The automatic import path starts at the same place, so the folder watcher
            %   and the browser agree until the user moves one of them.
            %
            % Inputs:
            %   none
            %
            % Output:
            %   none - mrdImportPath and mrdAutoImportPath are set on the app

            % Where the file browser starts: the folder named in the settings
            % file, or failing that the one the MR Solutions console records in
            % the Windows registry. Which of the two wins, and what happens when
            % neither answers, is in retro.io.mrdImportFolder.

            settingsPath = '';

            if isfield(app.jsonPar, 'mrdImportPath')
                settingsPath = app.jsonPar.mrdImportPath;
            end

            app.mrdImportPath = retro.io.mrdImportFolder(settingsPath, app.r.reporter);
            app.mrdAutoImportPath = app.mrdImportPath;

        end % SetMRDimportPathFcn


        % Define data parameters
        function DataParametersFcn(app)

            % Purpose:
            %   Copy what the user has set in the interface into the data object, before a
            %   step that acts on it.
            %
            % How it works:
            %   This is the boundary between the two halves of the application. Every
            %   reconstruction parameter the user can change is read from its control and
            %   written onto the object here, so that the object carries a complete and
            %   consistent set and nothing further down has to reach back into a widget.
            %
            %   Gathering them in one place is what makes that boundary checkable: a value
            %   read here and nowhere else cannot be half-updated, which is the failure that
            %   leaves a reconstruction running on the previous setting.
            %
            %   Called before filtering, sorting and reconstruction, so each acts on what the
            %   interface currently says rather than on what it said when the data was
            %   loaded.
            %
            % Inputs:
            %   none
            %
            % Output:
            %   none - the parameters are written onto app.r

            % Heart and respiration parameters
            app.r.bandwidthHR = app.HeartWidthEditField.Value;                      % cardiac bandpass filter bandwidth
            app.r.bandwidthRR = app.RespWidthEditField.Value;                       % respiratory bandpass filter bandwidth
            app.r.meanHeartRate = app.HeartEditField.Value;                         % Heart rate
            app.r.meanRespRate = app.RespEditField.Value;                           % Respiration rate
            app.r.respPercentage = app.RespWindowEditField.Value;                   % percentage of data discarded during respiration, default = 30%
            app.nrFrames = app.FramesEditField.Value;                               % default number of cardiac frames
            app.nrDynamics = app.DynamicsEditField.Value;                           % default number of dynamics

            % Reset the analysis flags
            app.validFilterFlag = false;
            app.validSenseMapFlag = false;
            app.validRecoFlag = false;
            app.validSortFlag = false;
            app.r.multiSliceFlag = false;
            app.r.multiDynamicFlag = false;

            % Navigator GUI parameters
            app.extFidNavFlag = app.r.extNavFidOn;
            app.extEchoNavFlag = app.r.extNavEchoOn;
            app.fidNavFlag = app.r.sliceNav;
            app.NavEnd1EditField.Value = 1;
            app.NavEnd2EditField.Value = 1;
            app.NavNRPoints1EditField.Value = 1;
            app.NavNRPoints2EditField.Value = 1;
            NoNavSamples1 = max([app.ExtFID1Button.Value*app.r.extNavFidNoSamples app.ExtECHO1Button.Value*app.r.extNavEchoNoSamples app.SliceFID1Button.Value*app.r.noSamplesNav]);
            NoNavSamples2 = max([app.ExtFID2Button.Value*app.r.extNavFidNoSamples app.ExtECHO2Button.Value*app.r.extNavEchoNoSamples app.SliceFID2Button.Value*app.r.noSamplesNav]);
            app.NavEnd1EditField.Limits = [1 NoNavSamples1];
            app.NavEnd2EditField.Limits = [1 NoNavSamples2];
            app.NavNRPoints1EditField.Limits = [1 NoNavSamples1];
            app.NavNRPoints2EditField.Limits = [1 NoNavSamples2];
            app.NavEnd1EditField.Value = app.r.primaryNavigatorPoint1;              % primary navigator point
            app.NavEnd2EditField.Value = app.r.primaryNavigatorPoint2;              % primary navigator point
            app.DiscardEditField.Limits = [app.jsonPar.minDiscard app.jsonPar.maxDiscard];
            app.DiscardEditField.Value = app.r.nrNavPointsDiscarded;                % number of nav points discarded between navigator and echo
            app.NavNRPoints1EditField.Value = app.r.nrNavPointsUsed1;               % number of nav points used
            app.NavNRPoints2EditField.Value = app.r.nrNavPointsUsed2;               % number of nav points used
            app.StartEditField.Limits = [0 inf];
            app.RangeEditField.Limits = [0 inf];
            app.StartEditField.Value = 120;                                         % reset the nav viewing start value
            app.RangeEditField.Value = 6;                                           % reset the nav viewing range
            app.StartSlider.Limits = [0 6000];
            app.RangeSlider.Limits = [0 2500];
            app.StartSlider.Value = 3000;                                           % reset the nav viewing start value slider
            app.RangeSlider.Value = 1000;                                           % reset the nav viewing range slider
            app.IncludeCheckBox.Value = 0;                                          % reset the include and exclude sliders
            app.ExcludeCheckBox.Value = 0;

            % Flip angles
            app.NrFlipAnglesViewField.Value = app.r.nrFlipAngles;

            % Data type dependent parameters
            % The third delay field holds the xy cross term for 2D radial and the z
            % delay for 3D UTE, so its label follows the data type. Text and Enable
            % are separate properties, so this does not touch the enable/disable logic.
            app.GzDelayEditFieldLabel.Text = 'Gz delay';

            switch app.r.dataType

                case {'2D','2Dms','3D','3Dp2roud'}

                    app.r = app.r.determineReadoutLength(app);      % #points in readout direction dimx
                    app.r.dimz = size(app.r.data{1},2);             % #points in 3rd dimension (2D multi-slice or 3D)

                    switch app.r.pe1Order

                        case 0

                            % Linear
                            app.TrajectoryViewField.Value = 'linear';
                            app.r.dimy = app.r.noViews;                                                                 % nr phase encoding steps (no_views)
                            app.r.nrKsteps = app.r.dimy;                                                                % nr k-space points per repetition = nr phase encoding steps
                            app.r.nrKlines = app.r.nrRepetitions*app.r.dimy*app.r.dimz;                                 % total number of k-lines that are measured
                            app.r = app.r.getKspaceTrajectory(app);                                                     % get trajectory

                            % Plot the trajectory
                            cla(app.TrajectoryFig);
                            plot(app.TrajectoryFig, app.r.trajectory);
                            xlim(app.TrajectoryFig,[1 length(app.r.trajectory)-1]);
                            ylim(app.TrajectoryFig,[min(app.r.trajectory)-1 max(app.r.trajectory)+1]);

                        case 2

                            % Zigzag
                            app.TrajectoryViewField.Value = 'zigzag';
                            app.r.dimy = app.r.noViews;                                                                 % nr phase encoding steps (no_views)
                            app.r.nrKsteps = app.r.dimy;                                                                % nr k-space points per repetition = nr phase encoding steps
                            app.r.nrKlines = app.r.nrRepetitions*app.r.dimy*app.r.dimz;                                 % total number of k-lines that are measured
                            app.r = app.r.getKspaceTrajectory(app);                                                     % get trajectory

                            % Plot the trajectory
                            cla(app.TrajectoryFig);
                            plot(app.TrajectoryFig, app.r.trajectory);
                            xlim(app.TrajectoryFig,[1 length(app.r.trajectory)-1]);
                            ylim(app.TrajectoryFig,[min(app.r.trajectory)-1 max(app.r.trajectory)+1]);

                        case 3

                            % User defined
                            app.TrajectoryViewField.Value = 'user defined';
                            app.r.dimy = max(app.r.gpVarMul)-min(app.r.gpVarMul)+1;                                     % effective nr phase encoding steps (no_effective_views)
                            app.r.nrKsteps = app.r.noViews;                                                             % nr k-space trajectory points (no_views) in table
                            app.r.nrKlines = app.r.nrRepetitions*app.r.nrKsteps*app.r.dimz;                             % total number of k-lines that are measured
                            app.r = app.r.getKspaceTrajectory(app);                                                     % get trajectory

                            % Plot the trajectory
                            cla(app.TrajectoryFig);
                            plot(app.TrajectoryFig, app.r.trajectory);
                            xlim(app.TrajectoryFig,[1 length(app.r.trajectory)-1]);
                            ylim(app.TrajectoryFig,[min(app.r.trajectory)-1 max(app.r.trajectory)+1]);

                        case {4,5}

                            % P2ROUD acquisition, based on LUT file
                            app.TrajectoryViewField.Value = 'P2ROUD';
                            app.r.dimy = app.r.noViews;                                                                 % nr phase encoding steps (no_views)
                            app.r.nrKsteps = app.r.dimy;                                                                % nr k-space points per repetition = nr phase encoding steps
                            app.r.nrKlines = app.r.nrRepetitions*app.r.nrKsteps*app.r.dimz;                             % total number of k-lines that are measured
                            app.r = app.r.getKspaceTrajectory(app);                                                     % get trajectory

                            % Plot the k-space filling trajectory in the app if available
                            cla(app.TrajectoryFig);
                            if strcmp(app.r.dataType,'3Dp2roud')
                                scatter(app.TrajectoryFig, app.r.trajectory(1:2:end),app.r.trajectory(2:2:end),'s');    % plot the 3D trajectory
                                xlim(app.TrajectoryFig,[min(app.r.trajectory(1:2:end))-1,max(app.r.trajectory(1:2:end))+1]);
                                ylim(app.TrajectoryFig,[min(app.r.trajectory(2:2:end))-1,max(app.r.trajectory(2:2:end))+1]);
                            else
                                text(app.TrajectoryFig,-0.7,1,'MISSING');
                                text(app.TrajectoryFig,-2.4,-0.5,'TRAJECTORY');
                                xlim(app.TrajectoryFig,[-3 9]);
                                ylim(app.TrajectoryFig,[-3 3]);
                            end

                            % Final check if the trajectory has the correct size matching the data
                            if length(app.r.trajectory) ~= 2*app.r.nrKsteps*app.r.dimz
                                app.TextMessage('ERROR: Missing or invalid trajectory file ...');
                                app.SetStatus(2);
                            end

                    end

                case {'2Dradial','2Dradialms'}
                    app.GzDelayEditFieldLabel.Text = 'Sxy delay';


                    % Radial
                    app.TrajectoryViewField.Value = '2D radial';
                    app.r.pe1Order = 8;                                                 % pe1_order = 8 for radial scanning
                    app.DiscardEditField.Value = 0;                                     % no discarded points
                    app.r.nrNavPointsDiscarded = 0;
                    app.SharingEditField.Value = 0;                                     % view sharing not implemented for radial sequences
                    app.r.dimx = app.r.noSamples;                                       % number of samples
                    app.r.dimy = app.r.dimx;                                            % y-dimension equal to number of samples
                    app.r.dimz = app.r.noSlices;                                        % slices
                    app.r.nrKsteps = app.r.noViews;                                     % number of radial spokes per repetition
                    app.r.nrKlines = app.r.nrRepetitions*app.r.nrKsteps*app.r.dimz;     % total number of spokes measured
                    app.r = app.r.getKspaceTrajectory(app);                             % spoke angles from the scan: its increment, or a half circle

                    % Set the parameters for the gradient calibration
                    app.GradDelayCalibrationCheckBox.Value = 1;
                    app.RingMethodCheckBox.Value = 1;
                    app.PhaseCorrectCheckBox.Value = 1;
                    app.CenterEchoCheckBox.Value = 1;
                    app.DensityCorrectCheckBox.Value = 1;

                    % Report on the trajectory in the app
                    cla(app.TrajectoryFig);
                    text(app.TrajectoryFig,-0.7,1,'2D radial');
                    text(app.TrajectoryFig,-0.7,-0.5,'trajectory');
                    xlim(app.TrajectoryFig,[-3 9]);
                    ylim(app.TrajectoryFig,[-3 3]);

                case '3Dute'

                    % 3D UTE
                    app.TrajectoryViewField.Value = '3D UTE';
                    app.r.pe1Order = 9;                                 % pe1_order = 9 for 3D UTE
                    app.DiscardEditField.Value = 0;                     % no discarded points
                    app.r.nrNavPointsDiscarded = 0;
                    app.r.dimx = app.r.noSamples;                       % number of samples
                    app.r.dimy = app.r.dimx;                            % y-dimension equal to number of samples
                    app.r.dimz = app.r.dimx;                            % z-dimension equal to number of samples
                    app.r.nrKsteps = app.r.noSamples;                   % number of points in a radial spoke
                    app.r.nrKlines = app.r.nrRepetitions;               % number of total spokes
                    app.r = app.r.getKspaceTrajectory(app);             % get trajectory

                    % Set the parameters for the gradient calibration. The echo centring
                    % shows off, as P2ROUD has it for 3D UTE: a centre-out spoke has no echo to
                    % centre. The 3D UTE fill and reconstruction do not read it, and its box is
                    % disabled for these scans
                    app.GradDelayCalibrationCheckBox.Value = 0;
                    app.RingMethodCheckBox.Value = 0;
                    app.PhaseCorrectCheckBox.Value = 1;
                    app.CenterEchoCheckBox.Value = 0;
                    app.DensityCorrectCheckBox.Value = 1;

                    % Report on the trajectory in the app
                    cla(app.TrajectoryFig);
                    text(app.TrajectoryFig,-0.2,1,'3D UTE');
                    text(app.TrajectoryFig,-0.7,-0.5,'trajectory');
                    xlim(app.TrajectoryFig,[-3 9]);
                    ylim(app.TrajectoryFig,[-3 3]);

                case 'ZTE'

                    % 3D ZTE
                    app.TrajectoryViewField.Value = '3D ZTE';
                    app.r.pe1Order = 10;                                 % pe1_order = 10 for 3D ZTE
                    app.DiscardEditField.Value = 0;                      % no discarded points
                    app.r.nrNavPointsDiscarded = 0;
                    app.r.dimx = app.r.noSamples;                        % number of samples
                    app.r.dimy = app.r.dimx;                             % y-dimension equal to number of samples
                    app.r.dimz = app.r.dimx;                             % z-dimension equal to number of samples
                    app.r.nrKsteps = app.r.nrSpokes;                     % number of spokes
                    app.r.nrKlines = app.r.nrRepetitions;                % total number of spokes
                    app.r = app.r.getKspaceTrajectory(app);              % get trajectory

                    % Set the parameters for the gradient calibration. The echo centring
                    % shows off, as for 3D UTE; the ZTE fill and reconstruction do not read it,
                    % and its box is disabled for these scans
                    app.GradDelayCalibrationCheckBox.Value = 0;
                    app.RingMethodCheckBox.Value = 0;
                    app.PhaseCorrectCheckBox.Value = 1;
                    app.CenterEchoCheckBox.Value = 0;
                    app.DensityCorrectCheckBox.Value = 1;

                    % Report on the trajectory in the app
                    cla(app.TrajectoryFig);
                    text(app.TrajectoryFig,-0.2,1,'3D ZTE');
                    text(app.TrajectoryFig,-0.7,-0.5,'trajectory');
                    xlim(app.TrajectoryFig,[-3 9]);
                    ylim(app.TrajectoryFig,[-3 3]);

            end

            % Reset include / exclude window
            app.r.includeWindow = ones(app.r.nrKlines,1);
            app.r.excludeWindow = ones(app.r.nrKlines,1);

            % Total acquisition time in seconds
            app.r.acqDur = app.r.nrKlines*app.r.repetitionTime/1000;

            % Display the acquisition parameters in the app
            app.NxViewField.Value = app.r.dimx;
            app.NyViewField.Value = app.r.dimy;
            app.NzViewField.Value = app.r.dimz;
            app.NcViewField.Value = app.r.nrCoils;
            app.RepetitionsViewField.Value = app.r.nrRepetitions;
            app.FieldOfViewViewField.Value = app.r.fov;
            app.NrKlinesViewField.Value = app.r.nrKlines;
            app.TRViewField.Value = app.r.repetitionTime;
            app.TEViewField.Value = app.r.echoTime;
            app.ScanTimeViewField.Value = string(duration(seconds(app.r.acqDur),'Format','hh:mm:ss.SSS'));
            app.TimeDynViewField.Value = string(duration(seconds(app.r.acqDur/app.DynamicsEditField.Value),'Format','hh:mm:ss.SSS'));
            if app.r.vfaSize == 0
                app.FlipAnglesViewField.Value = num2str(app.r.alpha);
            else
                app.FlipAnglesViewField.Value = num2str(app.r.vfaAngles);
            end
            switch app.r.dataType
                case {'3D','3Dp2roud'}
                    app.TrajectorySizeViewField.Value = app.r.nrKsteps*app.r.dimz;
                otherwise
                    app.TrajectorySizeViewField.Value = app.r.nrKsteps;
            end

            % Geometry parameters
            app.FieldOfViewViewField2.Value = app.r.fov;
            app.AspectRatioViewField.Value = app.r.aspectRatio;
            app.SliceThicknessViewField.Value = app.r.sliceThickness;
            app.SliceGapViewField.Value = app.r.sqlSliceGap;
            app.PhaseOrientationViewField.Value = app.r.phaseOrientation;
            app.OffsetXViewField.Value = app.r.sqlOffsetX;
            app.OffsetYViewField.Value = app.r.sqlOffsetY;
            app.OffsetZViewField.Value = app.r.sqlOffsetZ;
            app.AngleXViewField.Value = app.r.sqlAngleX;
            app.AngleYViewField.Value = app.r.sqlAngleY;
            app.AngleZViewField.Value = app.r.sqlAngleZ;

            % Define the ranges of the display range sliders
            app.StartEditField.Limits = [0, app.r.nrKlines-1]*app.r.repetitionTime/1000;
            app.StartSlider.Limits = [0, app.r.nrKlines-1]*app.r.repetitionTime/1000;
            app.StartEditField.Value = app.r.nrKlines*app.r.repetitionTime/2000;
            app.StartSlider.Value = app.StartEditField.Value;
            maxRange = 2500; % max range (samples)
            maxRange(maxRange > (app.r.nrKlines-1)) = app.r.nrKlines - 1;
            app.RangeEditField.Limits = [0, maxRange]*app.r.repetitionTime/1000;
            app.RangeSlider.Limits = [0, maxRange]*app.r.repetitionTime/1000;
            tmpRange = 600; % preferred range (samples), but smaller if less data available
            tmpRange(tmpRange > round(app.r.nrKlines/2 -1)) = round(app.r.nrKlines/4);
            if (tmpRange*app.r.repetitionTime/1000) < 1
                tmpRange = 2500;
            end
            tmpRange(tmpRange > maxRange) = maxRange;       % never beyond the range's limits
            app.RangeEditField.Value = tmpRange*app.r.repetitionTime/1000;
            app.RangeSlider.Value = app.RangeEditField.Value;

            % Data limits
            app.IncludeStartSlider.Limits = [0, app.r.acqDur];
            app.IncludeEndSlider.Limits = [0, app.r.acqDur];
            app.IncludeStartSlider.Value = 0;
            app.IncludeEndSlider.Value = app.r.acqDur;
            app.ExcludeStartSlider.Limits = [0, app.r.acqDur];
            app.ExcludeEndSlider.Limits = [0, app.r.acqDur];
            app.ExcludeStartSlider.Value = 0;
            app.ExcludeEndSlider.Value = app.r.acqDur;
            app.SenseMapSpinner1.Value = 1;
            app.SenseMapSpinner2.Value = 1;
            app.ESPIRiTCheckBox.Value = 0;
            app.WindowScaleSlider.Limits = [0, app.r.maxImageValue];
            app.LevelScaleSlider.Limits = [0, app.r.maxImageValue];

            % Case of multicoil data
            if app.r.multiCoilFlag
                if app.HeartCoilSpinner1.Value > app.r.nrCoils
                    app.HeartCoilSpinner1.Value = 1;
                    app.MovieCoilSpinner.Value = 1;
                end
                if app.HeartCoilSpinner2.Value > app.r.nrCoils
                    app.HeartCoilSpinner2.Value = 1;
                    app.MovieCoilSpinner.Value = 1;
                end
                if app.RespCoilSpinner1.Value > app.r.nrCoils
                    app.RespCoilSpinner1.Value = 1;
                    app.MovieCoilSpinner.Value = 1;
                end
                if app.RespCoilSpinner2.Value > app.r.nrCoils
                    app.RespCoilSpinner2.Value = 1;
                    app.MovieCoilSpinner.Value = 1;
                end
                app.HeartCoilSpinner1.Limits = [1, app.r.nrCoils];
                app.RespCoilSpinner1.Limits = [1, app.r.nrCoils];
                app.HeartCoilSpinner2.Limits = [1, app.r.nrCoils];
                app.RespCoilSpinner2.Limits = [1, app.r.nrCoils];
                app.MovieCoilSpinner.Limits = [1, app.r.nrCoils];
            else
                app.HeartCoilSpinner1.Value = 1;
                app.HeartCoilSpinner1.Limits = [1, 2];
                app.RespCoilSpinner1.Value = 1;
                app.RespCoilSpinner1.Limits = [1, 2];
                app.HeartCoilSpinner2.Value = 1;
                app.HeartCoilSpinner2.Limits = [1, 2];
                app.RespCoilSpinner2.Value = 1;
                app.RespCoilSpinner2.Limits = [1, 2];
                app.MovieCoilSpinner.Value = 1;
                app.MovieCoilSpinner.Limits = [1, 2];
            end

            % Case of variable flip-angle data
            if app.r.vfaDataFlag
                app.DynamicsEditFieldLabel.Text = '#flip-angles';
                app.MovieDynLabel.Text = 'flip-angle';
                app.MovieDynEditFieldLabel.Text = 'flip-angle';
                app.DynamicsEditField.Editable = 'off';
                app.DynamicsEditField.Value = app.r.vfaSize;
                app.nrDynamics = app.r.vfaSize;
            elseif app.r.multiEchoFlag
                app.DynamicsEditFieldLabel.Text = '#echos';
                app.MovieDynLabel.Text = 'echo';
                app.MovieDynEditFieldLabel.Text = 'echo';
                app.DynamicsEditField.Editable = 'on';
                app.DynamicsEditField.Value = 1;
                app.nrDynamics = app.r.noEchoes;
            else
                app.DynamicsEditFieldLabel.Text = '#dynamics';
                app.MovieDynLabel.Text = 'dynamic';
                app.MovieDynEditFieldLabel.Text = 'dynamic';
                app.DynamicsEditField.Editable = 'on';
                app.DynamicsEditField.Value = 1;
                app.nrDynamics = 1;
            end

            % Set initial reconstruction parameters, based on the data
            if app.AutoGuessRecoCheckBox.Value
                app.r = app.r.guessRecoType();

                switch app.r.recoGuess
                    case 'scout'
                        app.ScoutButton.Value = 1;
                    case 'systolic function'
                        app.SystolicFunctionButton.Value = 1;
                    case 'diastolic function'
                        app.DiastolicFunctionButton.Value = 1;
                    case 'variable flip-angle'
                        app.VfaFunctionButton.Value = 1;
                    case 'multi-echo'
                        app.MultiEchoButton.Value = 1;
                end

                app.SetRecoParametersFcn;
            end

        end % DataParametersFcn


        % ------------------------------------------
        % Other functions
        % ------------------------------------------


        % Calculates the power spectrum of the raw navigator signal within the boundaries of the data-window
        function CalculateNavigatorSpectrumFcn(app)

            % Purpose:
            %   Measure the heart and respiratory rates from the navigator, and bring the
            %   filter controls into a state consistent with them.
            %
            % How it works:
            %   The filter settings are gathered into the object and the work is done by
            %   retroData.determinePowerSpectrumPCA, which combines the coils and reads the
            %   two rates off the power spectrum. The detected rates are then shown.
            %
            %   Both width fields are clamped afterwards, and that is the point of doing this
            %   here rather than only displaying the result: a filter band wider than the
            %   rate it is centred on reaches down through zero frequency, so it no longer
            %   selects a band at all. The clamp keeps each width at or below its rate, and
            %   not below the field its own lower limit allows.
            %
            %   The rates are what the filtering and the binning are built on, so this runs
            %   before either.
            %
            % Inputs:
            %   none
            %
            % Output:
            %   none - the detected rates and the filter widths are set

            % Determine the power spectrum of the navigator
            app.r.physioFilterSettings = [app.MinHeartRateEditField.Value app.MaxHeartRateEditField.Value app.MinRespRateEditField.Value app.MaxRespRateEditField.Value app.FilterOrderEditField.Value app.RespShiftEditField.Value];
            app.r = app.r.determinePowerSpectrumPCA();

            % Update the detected heart rate and respiration rate fields
            app.HeartEditField.Value = app.r.detectedHR;
            app.RespEditField.Value = app.r.detectedRR;

            % Heart rate window cannot be larger than the heart rate
            app.HeartWidthEditField.Value(app.HeartWidthEditField.Value > app.HeartEditField.Value) = max([app.HeartWidthEditField.Limits(1) app.HeartEditField.Value]);

            % Repiration rate window cannot be larger than the respiration rate
            app.RespWidthEditField.Value(app.RespWidthEditField.Value > app.RespEditField.Value) = max([app.RespWidthEditField.Limits(1) app.RespEditField.Value]);

        end % CalculateNavigatorSpectrumFcn


        % ------------------------------------------
        % Plotting figures and images
        % ------------------------------------------

        % Plot the raw navigator signals
        function PlotRawNavigatorFcn(app)

            % Purpose:
            %   Draw the two raw navigator traces over the selected stretch of the
            %   acquisition.
            %
            % How it works:
            %   The visible stretch is converted from seconds to k-lines through the
            %   repetition time, since the navigator is sampled once per acquisition and
            %   has no time axis of its own. Both ends are clamped to the data, so
            %   dragging the range past either edge shortens the view instead of
            %   indexing outside it.
            %
            %   Each trace has its mean removed before drawing. The navigator sits on a large
            %   offset -- it is a signal amplitude, not a deviation -- and against that offset
            %   the motion is a ripple too small to see. Removing it is what makes the
            %   variation fill the axes.
            %
            %   The coil is chosen per trace, so a coil that sees the heart well and one that
            %   sees respiration well can be compared side by side.
            %
            %   Raw here means before filtering: this is what the filter settings are judged
            %   against.
            %
            %   Wrapped, because a plot that cannot be drawn must not stop the step that
            %   produced the data.
            %
            % Inputs:
            %   none - what is shown is read from the interface controls
            %
            % Output:
            %   none - the raw navigator axes are drawn

            try

                % Display x-range
                startPoint = round(app.StartEditField.Value*1000/app.r.repetitionTime);
                endPoint = round((app.StartEditField.Value + app.RangeEditField.Value)*1000/app.r.repetitionTime);
                startPoint(startPoint<1) = 1;
                endPoint(endPoint>app.r.nrKlines) = app.r.nrKlines;

                % Extract part of the navigator that will be shown
                trace1 = app.r.navAmplitude1{app.HeartCoilSpinner2.Value}(startPoint:endPoint);
                trace1 = trace1 - mean(trace1(:));
                trace2 = app.r.navAmplitude2{app.RespCoilSpinner2.Value}(startPoint:endPoint);
                trace2 = trace2 - mean(trace2(:));
                timePoints = (startPoint:endPoint)*app.r.repetitionTime/1000;

                % Display y-range
                maxPlot1 = 1.25*max(trace1);
                minPlot1 = min(trace1)-0.25*maxPlot1;
                maxPlot1(maxPlot1<minPlot1) = minPlot1 + 1;
                maxPlot2 = 1.25*max(trace2);
                minPlot2 = min(trace2)-0.25*maxPlot2;
                maxPlot2(maxPlot2<minPlot2) = minPlot2 + 1;

                % Plot the navigator 1
                plot(app.HeartRawNavFig, timePoints, trace1, 'LineWidth',1, 'Color',app.blue);
                xlim(app.HeartRawNavFig, [min(timePoints)-app.r.repetitionTime/1000 max(timePoints)+app.r.repetitionTime/1000]);
                ylim(app.HeartRawNavFig, [minPlot1 maxPlot1+0.001]);
                drawnow;

                % Plot the navigator 2
                plot(app.RespRawNavFig, timePoints, trace2, 'LineWidth',1, 'Color',app.blue);
                xlim(app.RespRawNavFig, [min(timePoints)-app.r.repetitionTime/1000 max(timePoints)+app.r.repetitionTime/1000]);
                ylim(app.RespRawNavFig, [minPlot2 maxPlot2+0.001]);
                drawnow;

                % Extract the part of the raw navigator FID or ECHO to be shown
                mid1 = round(size(app.r.rawNav1{app.HeartCoilSpinner1.Value},1)/2);
                trace3 = squeeze(abs(app.r.rawNav1{app.HeartCoilSpinner1.Value}(mid1,:)));
                mid2 = round(size(app.r.rawNav2{app.RespCoilSpinner1.Value},1)/2);
                trace4 = squeeze(abs(app.r.rawNav2{app.RespCoilSpinner1.Value}(mid2,:)));

                % Display y-range
                maxPlot3 = max(abs(app.r.rawNav1{app.HeartCoilSpinner1.Value}(mid1,:)));
                maxPlot4 = max(abs(app.r.rawNav2{app.RespCoilSpinner1.Value}(mid2,:)));

                % Used navigator points
                x3 = app.NavEnd1EditField.Value - app.NavNRPoints1EditField.Value + 1:app.NavEnd1EditField.Value;
                x4 = app.NavEnd2EditField.Value - app.NavNRPoints2EditField.Value + 1:app.NavEnd2EditField.Value;

                % Plot the raw navigator 1 FID or ECHO
                cla(app.Nav1Fig);
                hold(app.Nav1Fig,"on");
                plot(app.Nav1Fig, trace3, 'o-' ,'LineWidth' ,1 , 'Color', app.blue);
                plot(app.Nav1Fig, x3, trace3(x3), 'o', 'Color', app.red, 'MarkerFaceColor', app.green);
                plot(app.Nav1Fig, x3(end), trace3(x3(end)), 'o', 'Color', app.red, 'MarkerFaceColor', app.traceOrange);
                xlim(app.Nav1Fig, [0 length(trace3)+1]);
                ylim(app.Nav1Fig, [0 1.1*maxPlot3+1]);
                hold(app.Nav1Fig,"off");
                drawnow;

                % Plot the raw navigator 2 FID or ECHO
                cla(app.Nav2Fig);
                hold(app.Nav2Fig,"on");
                plot(app.Nav2Fig, trace4, 'o-', 'LineWidth' ,1 , 'Color', app.blue);
                plot(app.Nav2Fig, x4, trace4(x4), 'o', 'Color', app.red, 'MarkerFaceColor', app.green);
                plot(app.Nav2Fig, x4(end), trace4(x4(end)), 'o', 'Color', app.red, 'MarkerFaceColor', app.traceOrange);
                xlim(app.Nav2Fig, [0 length(trace4)+1]);
                ylim(app.Nav2Fig, [0 1.1*maxPlot4+1]);
                hold(app.Nav2Fig,"off");
                drawnow;

            catch ME

                app.TextMessage(ME.message,2);

            end

        end % PlotRawNavigatorFcn


        % Plot the navigator with cardiac filtering
        function PlotHeartNavigatorFcn(app)

            % Purpose:
            %   Draw the cardiac navigator after filtering, with the detected triggers on it.
            %
            % How it works:
            %   Nothing is drawn until filtering has run, since before that there is no
            %   cardiac trace to show -- the axes are cleared and left empty rather than
            %   showing the raw signal, which would look like a result.
            %
            %   The visible stretch is converted from seconds to k-lines through the
            %   repetition time, since the navigator is sampled once per acquisition and
            %   has no time axis of its own. Both ends are clamped to the data, so
            %   dragging the range past either edge shortens the view instead of
            %   indexing outside it.
            %
            %   The trace shown is the PCA-combined, band-filtered navigator, with its mean
            %   removed and scaled to the axes.
            %
            %   The trigger points are drawn on top of it. That is what the display is for:
            %   a trigger on every beat and none between them is the check that the filter
            %   band and the flip switch are right, and it is far easier to see than to
            %   infer from the rates.
            %
            % Inputs:
            %   none - what is shown is read from the interface controls
            %
            % Output:
            %   none - the cardiac navigator axes are drawn

            try

                % Clear the image
                cla(app.HeartNavFig);
                hold(app.HeartNavFig,'on');

                if app.validFilterFlag

                    % Display x-range
                    startPoint = round(app.StartEditField.Value*1000/app.r.repetitionTime);
                    endPoint = round((app.StartEditField.Value + app.RangeEditField.Value)*1000/app.r.repetitionTime);
                    startPoint(startPoint<1) = 1;
                    endPoint(endPoint>app.r.nrKlines) = app.r.nrKlines;

                    % Extract part of the navigator that will be shown and scale for viewing
                    trace1 = app.r.pcaNav1(startPoint:endPoint);
                    trace1 = trace1 - mean(trace1(:));
                    trace2 = app.r.heartNav(startPoint:endPoint);
                    trace2 = 0.25*max(trace1)*(trace2 - mean(trace2(:))) + median(trace1(:));
                    timePoints = (startPoint:endPoint)*app.r.repetitionTime/1000;

                    % Display y-range
                    maxPlot = 1.25*max(trace1);
                    minPlot = min(trace1)-0.25*maxPlot;
                    maxPlot(maxPlot<minPlot) = minPlot + 1;

                    % Show the detected trigger points (only when movie is not running)
                    if app.stopNavTimeMovieFlag
                        triggerPoints = app.r.heartTrigPoints((app.r.heartTrigPoints>=startPoint) & (app.r.heartTrigPoints<=endPoint))*app.r.repetitionTime/1000;
                        if length(triggerPoints) < 50
                            for cnt = 1:length(triggerPoints)
                                win=plot(app.HeartNavFig,[triggerPoints(cnt),triggerPoints(cnt)],[minPlot,maxPlot+0.001],'black','LineWidth',1);
                                win.Color(4) = 0.2; % transparency
                            end
                        end
                    end

                    % Plot the raw navigator
                    plot(app.HeartNavFig, timePoints, trace1, 'LineWidth',1, 'Color',app.blue);

                    % Plot the navigator filtered for cardiac motion
                    plot(app.HeartNavFig, timePoints, trace2, 'LineWidth',0.75, 'Color',app.red);

                    % Set plot axes
                    xlim(app.HeartNavFig, [min(timePoints)-app.r.repetitionTime/1000 max(timePoints)+app.r.repetitionTime/1000]);
                    ylim(app.HeartNavFig, [minPlot maxPlot+0.001]);

                end

                hold(app.HeartNavFig,'off');
                drawnow limitrate;

            catch ME

                app.TextMessage(ME.message,2);

            end

        end % PlotHeartNavigatorFcn


        % Plot the navigator with respiratory filtering
        function PlotRespirationNavigatorFcn(app)

            % Purpose:
            %   Draw the respiratory navigator after filtering, with its triggers.
            %
            % How it works:
            %   The respiratory counterpart of PlotHeartNavigatorFcn, on the second
            %   navigator and the respiratory band, and drawn only once filtering has run.
            %
            %   The respiratory trace is the slower of the two by about an order of
            %   magnitude, so the same stretch of acquisition shows far fewer cycles. Its
            %   triggers mark end expiration, which is the phase the respiratory window
            %   keeps.
            %
            % Inputs:
            %   none - what is shown is read from the interface controls
            %
            % Output:
            %   none - the respiratory navigator axes are drawn

            try

                % Clear the image
                cla(app.RespNavFig);
                hold(app.RespNavFig,'on');

                if app.validFilterFlag

                    % Display x-range
                    startPoint = round(app.StartEditField.Value*1000/app.r.repetitionTime);
                    endPoint = round((app.StartEditField.Value+app.RangeEditField.Value)*1000/app.r.repetitionTime);
                    startPoint(startPoint<1) = 1;
                    endPoint(endPoint>app.r.nrKlines) = app.r.nrKlines;

                    % Extract part of the navigator that will be shown and scale for viewing
                    trace1 = app.r.pcaNav2(startPoint:endPoint);
                    trace1 = trace1 - mean(trace1(:));
                    trace2 = app.r.respNav(startPoint:endPoint);
                    trace2 = trace2 - mean(trace2(:));
                    trace2 = 0.3*max(trace1) * trace2 / max(rmoutliers(abs(trace2(:)),"percentiles",[0 80]));
                    timePoints = (startPoint:endPoint)*app.r.repetitionTime/1000;

                    % Display range
                    maxPlot = 1.25*max(trace1(:));
                    minPlot = min(trace1)-0.25*maxPlot;
                    maxPlot(maxPlot<minPlot) = minPlot + 1;

                    % Plot respiratory window masks in transparent red
                    steps = 4;
                    if app.stopNavTimeMovieFlag
                        steps = 1;
                    end
                    for cnt = startPoint:steps:endPoint
                        if app.r.respWindow(cnt)==1
                            win=line(app.RespNavFig,[cnt;cnt]*app.r.repetitionTime/1000,[app.r.respWindow(cnt)*minPlot;app.r.respWindow(cnt)*maxPlot],'Color',app.red,'LineWidth',1);
                            win.Color(4) = 0.1*steps; % transparancy
                        end
                    end

                    % Show the detected trigger points (only when movie is not running)
                    if app.stopNavTimeMovieFlag
                        triggerpoints = app.r.respTrigPoints((app.r.respTrigPoints>=startPoint) & (app.r.respTrigPoints<=endPoint))*app.r.repetitionTime/1000;
                        if length(triggerpoints) < 10
                            for cnt = 1:length(triggerpoints)
                                win=plot(app.RespNavFig,[triggerpoints(cnt),triggerpoints(cnt)],[minPlot,maxPlot+0.001],'black','LineWidth',1);
                                win.Color(4) = 0.2; % transparancy
                            end
                        end
                    end

                    % Plot raw navigator
                    plot(app.RespNavFig, timePoints, trace1, 'LineWidth',1, 'Color',app.blue);

                    % Plot navigator signal filtered for respiratory motion
                    plot(app.RespNavFig, timePoints, trace2, 'LineWidth',0.75, 'Color',app.red);

                    % Set plot axes
                    xlim(app.RespNavFig, [min(timePoints)-app.r.repetitionTime/1000 max(timePoints)+app.r.repetitionTime/1000]);
                    ylim(app.RespNavFig, [minPlot maxPlot+0.001]);

                end

                hold(app.RespNavFig,'off');
                drawnow limitrate;

            catch ME

                app.TextMessage(ME.message,2);

            end

        end % PlotRespirationNavigatorFcn


        % Plot the time-heartrate and time-respirationrate plots
        function PlotRatesFcn(app)

            % Purpose:
            %   Draw heart rate and respiratory rate against time.
            %
            % How it works:
            %   One point per detected interval, so these axes show what the trigger
            %   detection produced rather than a smoothed summary: a missed trigger appears
            %   as a rate at half its neighbours and a spurious one at double, which is the
            %   signature to look for.
            %
            %   The time axis is the acquisition length, computed from the repetition time
            %   and the number of navigator samples.
            %
            %   The rate axis is twice the mean rate, clamped to a floor and a ceiling. Twice
            %   the mean keeps the trace in the middle of the axes whatever the species, and
            %   the clamps stop a wild mean -- from a navigator the detector could not read --
            %   producing an axis on which nothing is visible.
            %
            %   Drawn only after filtering, there being no rates before it.
            %
            % Inputs:
            %   none - what is shown is read from the interface controls
            %
            % Output:
            %   none - the two rate axes are drawn

            try

                % Clear the images
                cla(app.HeartRateTimeFig);
                cla(app.RespRateTimeFig);

                if app.validFilterFlag

                    % X-scale
                    maxHeartXscale = app.r.repetitionTime*length(app.r.navAmplitude1{1})/1000;
                    maxRespXscale = app.r.repetitionTime*length(app.r.navAmplitude2{1})/1000;

                    % Y-scale
                    maxHeartYscale = round(2*app.r.meanHeartRate);
                    maxRespYscale = round(2*app.r.meanRespRate);
                    maxHeartYscale(maxHeartYscale<100) = 100;
                    maxHeartYscale(maxHeartYscale>2000) = 2000;
                    maxRespYscale(maxRespYscale<50) = 50;
                    maxRespYscale(maxRespYscale>1000) = 1000;
                    if ~isfinite(maxHeartYscale)
                        maxHeartYscale = 1000;
                    end
                    if ~isfinite(maxRespYscale)
                        maxRespYscale = 100;
                    end

                    % Show the time-heartrate plot
                    hold(app.HeartRateTimeFig,'on');
                    if ~isempty(app.r.heartRateTime) && length(app.r.heartTrigPoints)==length(app.r.heartRateTime)+1
                        plot(app.HeartRateTimeFig, app.r.repetitionTime*app.r.heartTrigPoints(1:end-1)'/1000, app.r.heartRateTime, '.', 'Color',app.blue);                    % heart rate
                    end
                    if ~isempty(app.r.heartRateTimeFiltered) && length(app.r.heartTrigPoints)==length(app.r.heartRateTimeFiltered)+1
                        plot(app.HeartRateTimeFig, app.r.repetitionTime*app.r.heartTrigPoints(1:end-1)'/1000, app.r.heartRateTimeFiltered, 'LineWidth',2, 'Color',app.red);   % trend line
                    end
                    axis(app.HeartRateTimeFig,[0 maxHeartXscale 0 maxHeartYscale]);
                    hold(app.HeartRateTimeFig,'off');

                    % Show the time-respirationrate plot
                    hold(app.RespRateTimeFig,'on');
                    if ~isempty(app.r.respRateTime) && length(app.r.respTrigPoints)==length(app.r.respRateTime)+1
                        plot(app.RespRateTimeFig, app.r.repetitionTime*app.r.respTrigPoints(1:end-1)'/1000, app.r.respRateTime, '.', 'Color',app.blue);                        % respiration rate
                    end
                    if ~isempty(app.r.respRateTimeFiltered) && length(app.r.respTrigPoints)==length(app.r.respRateTimeFiltered)+1
                        plot(app.RespRateTimeFig, app.r.repetitionTime*app.r.respTrigPoints(1:end-1)'/1000, app.r.respRateTimeFiltered, 'LineWidth',2, 'Color',app.red);       % trend line
                    end
                    axis(app.RespRateTimeFig,[0 maxRespXscale 0 maxRespYscale]);
                    hold(app.RespRateTimeFig,'off');

                    % Plot the dynamic or multi-slice windows
                    app.PlotDynamicsWindowsFcn;
                    app.PlotMultiSliceWindowsFcn;

                end

            catch ME

                app.TextMessage(ME.message,2);

            end

        end % PlotRatesFcn


        % Plot the separation into dynamics in the time-heart-rate and time-respiration-rate plots
        function PlotDynamicsWindowsFcn(app)

            % Purpose:
            %   Show, on the rate plots, how the acquisition is divided into dynamics.
            %
            % How it works:
            %   Nothing is drawn for a single dynamic, since there is no division to show.
            %
            %   The previous shapes are deleted rather than the axes cleared, because the
            %   rate traces underneath them are expensive to redraw and do not change when
            %   the dynamic count does. The handles are kept for exactly that reason.
            %
            %   The divisions are shaded areas with a label per dynamic, so the user can see
            %   which part of the acquisition each reconstructed dynamic comes from. The
            %   sort gives every dynamic an equal share of the k-lines of the whole
            %   acquisition, so the divisions split the time from start to end, the span
            %   of the rate axes, into equal parts.
            %
            %   The label reads "fa" instead of "d" for variable flip angle data, where the
            %   dynamics are flip angles rather than time points -- the same axis, a
            %   different meaning.
            %
            % Inputs:
            %   none - what is shown is read from the interface controls
            %
            % Output:
            %   none - the dynamic windows are drawn on the rate axes

            try

                % Delete previous windows
                delete(app.dPlot1);
                delete(app.dPlot2);
                delete(app.dPlot1Txt);
                delete(app.dPlot2Txt);

                if app.DynamicsEditField.Value > 1

                    % Y-scale
                    maxHeart = round(2*app.r.meanHeartRate);
                    maxResp = round(2*app.r.meanRespRate);
                    maxHeart(maxHeart<100) = 100;
                    maxResp(maxResp<50) = 50;

                    dynamicLabel = 'd';
                    if app.r.vfaDataFlag
                        dynamicLabel = 'fa';
                    end

                    hold(app.HeartRateTimeFig,'on');

                    % Show shaded areas in the time-heartrate plot
                    step = app.r.acqDur / app.DynamicsEditField.Value;
                    pgon = polyshape();
                    for cnt = 1:2:app.DynamicsEditField.Value
                        xStart = (cnt-1)*step;
                        xEnd = cnt*step;
                        pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[5 maxHeart-5 maxHeart-5 5]));
                    end
                    app.dPlot1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                    % Add dynamic frame numbers
                    if app.DynamicsEditField.Value < 15
                        cnt = 1:app.DynamicsEditField.Value;
                        txtPosX = (cnt - 0.5 - app.DynamicsEditField.Value / 25)*step;
                        txtPosY = 0.95*maxHeart*ones(app.DynamicsEditField.Value,1)';
                        txtStr = strcat(dynamicLabel,num2str(cnt(:)));
                        app.dPlot1Txt = text(app.HeartRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                    end

                    hold(app.HeartRateTimeFig,'off');

                    hold(app.RespRateTimeFig,'on');

                    % Show in the time-respirationrate plot
                    step = app.r.acqDur / app.DynamicsEditField.Value;
                    pgon = polyshape();
                    for cnt = 1:2:app.DynamicsEditField.Value
                        xStart = (cnt-1)*step;
                        xEnd = cnt*step;
                        pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[0.5 maxResp-0.5 maxResp-0.5 0.5]));
                    end
                    app.dPlot2 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                    % Add dynamic frame numbers
                    if app.DynamicsEditField.Value < 15
                        cnt = 1:app.DynamicsEditField.Value;
                        txtPosX = (cnt - 0.5 - app.DynamicsEditField.Value / 25)*step;
                        txtPosY = 0.95*maxResp*ones(app.DynamicsEditField.Value,1)';
                        txtStr = strcat(dynamicLabel,num2str(cnt(:)));
                        app.dPlot2Txt = text(app.RespRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                    end

                    hold(app.RespRateTimeFig,'off');

                end

            catch ME

                app.TextMessage(ME.message,2);

            end

        end % PlotDynamicsWindowsFcn


        % Plot the separation into slices for the time-heartrate and time-respirationrate plots
        function PlotMultiSliceWindowsFcn(app)

            % Purpose:
            %   Show, on the rate plots, how the acquisition is divided between slices.
            %
            % How it works:
            %   The multi-slice counterpart of PlotDynamicsWindowsFcn, and drawn the same
            %   way: previous shapes deleted by handle, the traces left alone.
            %
            %   In a multi-slice scan the slices are acquired one after the other, each all
            %   its repetitions, so each slice owns an equal, contiguous share of the
            %   acquisition, and the divisions split the time from start to end, the span
            %   of the rate axes, into equal parts. Showing that is what makes the
            %   per-slice navigator intelligible -- and it is the reason the rates are
            %   computed per slice rather than over the pooled triggers.
            %
            % Inputs:
            %   none - what is shown is read from the interface controls
            %
            % Output:
            %   none - the slice windows are drawn on the rate axes

            try

                % Delete previous windows
                delete(app.msPlot1);
                delete(app.msPlot2);
                delete(app.msPlot1Txt);
                delete(app.msPlot2Txt);

                if strcmp(app.r.dataType,'2Dms') || strcmp(app.r.dataType,'2Dradialms')

                    % Y-scale
                    maxHeart = round(2*app.r.meanHeartRate);
                    maxResp = round(2*app.r.meanRespRate);
                    maxHeart(maxHeart<100) = 100;
                    maxResp(maxResp<50) = 50;

                    hold(app.HeartRateTimeFig,'on');

                    % Show shaded areas in the time-heartrate plot
                    step = app.r.acqDur / app.NzViewField.Value;
                    pgon = polyshape();
                    for cnt = 1:2:app.NzViewField.Value
                        xStart = (cnt-1)*step;
                        xEnd = cnt*step;
                        pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[5 maxHeart-5 maxHeart-5 5]));
                    end
                    app.msPlot1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                    % Add dynamic frame numbers
                    if app.NzViewField.Value < 15
                        cnt = 1:app.NzViewField.Value;
                        txtPosX = (cnt - 0.5 - app.NzViewField.Value / 50)*step;
                        txtPosY = 0.95*maxHeart*ones(app.NzViewField.Value,1)';
                        txtStr = strcat('s',num2str(cnt(:)));
                        app.msPlot1Txt = text(app.HeartRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                    end

                    hold(app.HeartRateTimeFig,'off');

                    hold(app.RespRateTimeFig,'on');

                    % Show in the time-respirationrate plot
                    step = app.r.acqDur / app.NzViewField.Value;
                    pgon = polyshape();
                    for cnt = 1:2:app.NzViewField.Value
                        xStart = (cnt-1)*step;
                        xEnd = cnt*step;
                        pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[0.5 maxResp-0.5 maxResp-0.5 0.5]));
                    end
                    app.msPlot2 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                    % Add dynamic frame numbers
                    if app.NzViewField.Value < 15
                        cnt = 1:app.NzViewField.Value;
                        txtPosX = (cnt - 0.5 - app.NzViewField.Value / 50)*step;
                        txtPosY = 0.95*maxResp*ones(app.NzViewField.Value,1)';
                        txtStr = strcat('s',num2str(cnt(:)));
                        app.msPlot2Txt = text(app.RespRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                    end

                    hold(app.RespRateTimeFig,'off');

                end

            catch ME

                app.TextMessage(ME.message,2);

            end

        end % PlotMultiSliceWindowsFcn


        % Plot windows in time-heartrate and time-respirationrate plots which indicate the range of data shown in the navigator plots
        function PlotRateLimitsFcn(app)

            % Purpose:
            %   Mark, on the rate plots, the stretch of acquisition the navigator display is
            %   currently showing.
            %
            % How it works:
            %   A rectangle from the start field across the range field, on both rate axes,
            %   so the navigator traces above can be located within the whole acquisition.
            %   Without it the navigator view is a few seconds with nothing to say where.
            %
            %   Redrawn by deleting the previous rectangle by handle and drawing a new one,
            %   which is what lets it follow the sliders smoothly while they are dragged.
            %
            %   The rate axis is scaled as in PlotRatesFcn, so the rectangle spans the full
            %   height whatever the rates are.
            %
            % Inputs:
            %   none - what is shown is read from the interface controls
            %
            % Output:
            %   none - the inspection window is drawn on the rate axes

            try

                % Delete previous windows
                delete(app.cardRateWindow);
                delete(app.respRateWindow);

                % Start and ending of the window
                xStart = app.StartEditField.Value;
                xEnd = xStart + app.RangeEditField.Value;

                % Y-scale
                maxHeart = round(2*app.r.meanHeartRate);
                maxResp = round(2*app.r.meanRespRate);
                maxHeart(maxHeart<100) = 100;
                maxResp(maxResp<50) = 50;

                % Time-heartrate plot navigator inspection window
                hold(app.HeartRateTimeFig,'on');
                pgon = polyshape([xStart xStart xEnd xEnd],[5 maxHeart-5 maxHeart-5 5]);
                app.cardRateWindow = plot(app.HeartRateTimeFig, pgon,'FaceColor','black','FaceAlpha',0.25,'LineStyle','none');
                hold(app.HeartRateTimeFig,'off');

                % Time-respirationrate plot navigator inspection window
                hold(app.RespRateTimeFig,'on');
                pgon = polyshape([xStart xStart xEnd xEnd],[0.5 maxResp-0.5 maxResp-0.5 0.5]);
                app.respRateWindow = plot(app.RespRateTimeFig, pgon,'FaceColor','black','FaceAlpha',0.25,'LineStyle','none');
                hold(app.RespRateTimeFig,'off');

            catch ME

                app.TextMessage(ME.message,2);

            end

        end % PlotRateLimitsFcn


        % Plot the data window in time-heartrate and time-respirationrate plots
        function PlotDataWindowFcn(app)

            % Purpose:
            %   Show which part of the acquisition is being included or excluded from the
            %   reconstruction.
            %
            % How it works:
            %   Up to four shapes: the include window and the exclude window, each drawn on
            %   both rate axes, and each only when its checkbox is ticked.
            %
            %   They are polyshapes rather than rectangles so they can be filled and drawn
            %   semi-transparent over the rate traces, which have to stay readable
            %   underneath -- the point of the display is to judge the window against the
            %   rates.
            %
            %   Deleted by handle and redrawn, as with the other overlays, so dragging a
            %   slider does not redraw the traces. The axes span the whole acquisition, as
            %   the rate plots and the window sliders do, so a window is seen where it lies,
            %   also after the last trigger.
            %
            %   This is what the user judges a window by: data excluded here never reaches
            %   k-space, and the consequence is visible in the filling percentage rather
            %   than in any error.
            %
            % Inputs:
            %   none - what is shown is read from the interface controls
            %
            % Output:
            %   none - the data windows are drawn on the rate axes

            try

            % Delete previous windows
            delete(app.dWin1);
            delete(app.dWin2);
            delete(app.dWin3);
            delete(app.dWin4);

            % Y-scale
            maxHeart = round(2*app.r.meanHeartRate);
            maxResp = round(2*app.r.meanRespRate);
            maxHeart(maxHeart<100) = 100;
            maxResp(maxResp<50) = 50;

            if app.IncludeCheckBox.Value

                % Time-heartrate plot window
                hold(app.HeartRateTimeFig,'on');
                pgon = polyshape([app.IncludeStartSlider.Value app.IncludeStartSlider.Value app.IncludeEndSlider.Value app.IncludeEndSlider.Value],[5 maxHeart-5 maxHeart-5 5]);
                app.dWin1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.brightgreen,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.HeartRateTimeFig,[0 app.r.acqDur 0 maxHeart]);
                hold(app.HeartRateTimeFig,'off');

                % Time-respirationrate plot window
                hold(app.RespRateTimeFig,'on');
                pgon = polyshape([app.IncludeStartSlider.Value app.IncludeStartSlider.Value app.IncludeEndSlider.Value app.IncludeEndSlider.Value],[0.5 maxResp-0.5 maxResp-0.5 0.5]);
                app.dWin2 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.brightgreen,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.RespRateTimeFig,[0 app.r.acqDur 0 maxResp]);
                hold(app.RespRateTimeFig,'off');
                drawnow;

            end

            if app.ExcludeCheckBox.Value

                % Time-heartrate plot window
                hold(app.HeartRateTimeFig,'on');
                pgon = polyshape([app.ExcludeStartSlider.Value app.ExcludeStartSlider.Value app.ExcludeEndSlider.Value app.ExcludeEndSlider.Value],[5 maxHeart-5 maxHeart-5 5]);
                app.dWin3 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.red,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.HeartRateTimeFig,[0 app.r.acqDur 0 maxHeart]);
                hold(app.HeartRateTimeFig,'off');

                % Time-respirationrate plot window
                hold(app.RespRateTimeFig,'on');
                pgon = polyshape([app.ExcludeStartSlider.Value app.ExcludeStartSlider.Value app.ExcludeEndSlider.Value app.ExcludeEndSlider.Value],[0.5 maxResp-0.5 maxResp-0.5 0.5]);
                app.dWin4 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.red,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.RespRateTimeFig,[0 app.r.acqDur 0 maxResp]);
                hold(app.RespRateTimeFig,'off');
                drawnow;

            end

            % Set the acquisition parameters
            app.NrKlinesViewField.Value = nnz(app.r.includeWindow.*app.r.excludeWindow);
            app.RepetitionsViewField.Value = round(app.NrKlinesViewField.Value/app.TrajectorySizeViewField.Value);
            app.ScanTimeViewField.Value = string(duration(seconds(app.NrKlinesViewField.Value*app.r.repetitionTime/1000),'Format','hh:mm:ss.SSS'));
            app.TimeDynViewField.Value = string(duration(seconds(app.r.acqDur/app.DynamicsEditField.Value),'Format','hh:mm:ss.SSS'));

            catch ME

                app.TextMessage(ME.message,2);

            end

        end % PlotDataWindowFcn


        % Plot the power spectrum of the raw navigator signal
        function PlotNavigatorSpectrumFcn(app)

            % Purpose:
            %   Draw the power spectrum of each navigator, with the filter band marked on it.
            %
            % How it works:
            %   This is where the filter settings are judged. The cardiac and respiratory
            %   peaks are visible in the spectrum, and the band drawn over each one shows
            %   whether the filter will actually pass the motion it is meant to.
            %
            %   Each spectrum can be shown linear or logarithmic, from its own button. The
            %   log view is what makes a weak peak visible at all, since the spectrum is
            %   dominated by the low frequency drift; the linear view is the one to judge
            %   relative peak heights on.
            %
            %   The frequency range shown is taken from the rate limit fields, so the axes
            %   cover the band being searched rather than the whole spectrum, most of which
            %   is empty.
            %
            %   Wrapped, as with the other plots: a spectrum that cannot be drawn must not
            %   stop the analysis that produced it.
            %
            % Inputs:
            %   none - what is shown is read from the interface controls
            %
            % Output:
            %   none - the two spectrum axes are drawn

            try

                % Linear or log plot spectrum 1
                if strcmp(app.HeartLogLinButton.Text,'lin')
                    intensity1 = app.r.powerSpectrum1;
                else
                    intensity1 = log(app.r.powerSpectrum1);
                end

                % Linear or log plot spectrum 2
                if strcmp(app.RespLogLinButton.Text,'lin')
                    intensity2 = app.r.powerSpectrum2;
                else
                    intensity2 = log(app.r.powerSpectrum2);
                end

                % Limits
                startFreq = find(app.r.frequency > app.HeartMinEditField.Value,1);
                endFreq   = find(app.r.frequency > app.HeartMaxEditField.Value,1);

                % Set the x and y limits of spectrum 1
                yMax1 = 1.3*max(intensity1(startFreq:endFreq));
                yMin1 = -0.05*yMax1;
                if isinf(yMax1) || isinf(yMin1) || yMax1==0
                    yMin1 = -50;
                    yMax1 = 1000;
                end
                yMax1(yMax1<yMin1) = yMin1+1;

                cla(app.HeartSpectrumFig);
                hold(app.HeartSpectrumFig,'on');
                xlim(app.HeartSpectrumFig, [app.HeartMinEditField.Value app.HeartMaxEditField.Value]);
                ylim(app.HeartSpectrumFig, [yMin1, yMax1+1]);

                % Limits
                startFreq = find(app.r.frequency > app.RespMinEditField.Value,1);
                endFreq   = find(app.r.frequency > app.RespMaxEditField.Value,1);

                % Set the x and y limits of spectrum 2
                yMax2 = 1.3*max(intensity2(startFreq:endFreq));
                yMin2 = -0.05*yMax2;
                yMax2(yMax2<yMin2) = yMin2+1;
                if isinf(yMax2) || isinf(yMin2) || yMax2==0
                    yMin2 = -50;
                    yMax2 = 1000;
                end
                cla(app.RespSpectrumFig);
                hold(app.RespSpectrumFig,'on');
                xlim(app.RespSpectrumFig, [app.RespMinEditField.Value app.RespMaxEditField.Value]);
                ylim(app.RespSpectrumFig, [yMin2, yMax2+1]);

                % Plot the spectra 1 and 2
                plot(app.HeartSpectrumFig,app.r.frequency,intensity1,'Color',app.blue);
                plot(app.RespSpectrumFig,app.r.frequency,intensity2,'Color',app.blue);

                % Heart and respiration bandwidth values
                app.r.bandwidthHR = app.HeartWidthEditField.Value;
                app.r.bandwidthRR = app.RespWidthEditField.Value;

                % Plot the butter filter for cardiac motion filtering
                flt = retro.util.filterShape(app.r, app, 'heart');
                area(app.HeartSpectrumFig,flt(:,1),0.95*yMax1*flt(:,2),'FaceColor',app.brightgreen,'FaceAlpha',0.1,'EdgeColor',app.brightgreen,'LineStyle', 'none');
                plot(app.HeartSpectrumFig,[app.r.detectedHR,app.r.detectedHR],[0,yMax1],'Color',app.brightgreen,'LineWidth',1,'LineStyle','--');
                text(app.HeartSpectrumFig,app.r.detectedHR+10,0.9*yMax1,[num2str(app.r.detectedHR),' bpm']);

                % Plot the butter filter for the repiration motion filtering
                try
                    flt = retro.util.filterShape(app.r, app, 'resp');
                    area(app.RespSpectrumFig,flt(:,1),0.95*yMax2*flt(:,2),'FaceColor',app.brightgreen,'FaceAlpha',0.1,'EdgeColor',app.brightgreen,'LineStyle', 'none');
                    plot(app.RespSpectrumFig,[app.r.detectedRR,app.r.detectedRR],[0,yMax2],'Color',app.brightgreen,'LineWidth',1,'LineStyle','--');
                    text(app.RespSpectrumFig,app.r.detectedRR+10,0.9*yMax2,[num2str(app.r.detectedRR),' bpm']);
                catch ME
                    app.TextMessage(ME.message);
                end

                hold(app.HeartSpectrumFig,'off');
                hold(app.RespSpectrumFig,'off');
                drawnow;

            catch
            end

        end % PlotNavigatorSpectrumFcn


        % Shift the movie one frame, and everything that has to move with it
        function ShiftMovieFrameFcn(app, shiftValue)

            % Purpose:
            %   Move the movie one frame along the frame axis, with everything that has to
            %   move with it.
            %
            % How it works:
            %   Which slice is on screen is the only part of this the interface knows.
            %   Whether that slice may move on its own is a fact about the data and the
            %   settings, so retroData answers it -- see
            %   retroData.framesAreIndependentPerSlice. The slice is therefore passed
            %   unconditionally rather than being cleared here first.
            %
            %   The shifting itself is the data own business: the movie, the k-space it came
            %   from, the averages and the segmentation labels all carry a frame axis and all
            %   have to move together, which retroData.shiftFrames does in one place.
            %
            %   The two movie frame buttons reach this with opposite signs, so the direction
            %   is the only thing they differ in.
            %
            %   The reconstruction lamps are reset afterwards: shifting the frames changes
            %   what an export would contain, so the exported result no longer matches what
            %   was last reconstructed.
            %
            % Inputs:
            %   shiftValue - +1 or -1, the direction the button asks for
            %
            % Output:
            %   none - the data is shifted and the frame redrawn

            % Which slice is on screen is the only part of this the interface
            % knows; whether that slice may move on its own is a fact about the
            % data and the settings, so retroData answers it. See
            % retroData.framesAreIndependentPerSlice. The movie frame buttons
            % reach this with opposite signs.

            app.r = app.r.shiftFrames(shiftValue, app.sliceNr, app.validSegmentationFlag, app);

            % Show the movie frame
            app.PlotMovieFrameFcn;

            % Reset buttons: every export holds the frames as they were, the MRD
            % export too (it writes the movie back as k-space)
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;
            app.Lamp7.Color = app.brightred;

        end


        % Number of slices in the displayed view
        function n = MovieSliceCountFcn(app)

            % Purpose:
            %   The number of slices the currently displayed view holds.
            %
            % How it works:
            %   K-space and the averages are held per measured slice, the images
            %   and the sense map per reconstructed slice, and for a 3D
            %   acquisition those two counts are independent: the partitions
            %   measured and the slices asked for are separate numbers.
            %
            %   Every place that bounds the slice counter asks here, so the
            %   stepper, the edit field and the view switch cannot disagree about
            %   what the last slice is.
            %
            %   Floored at one, so an empty object still gives a usable range.
            %
            % Inputs:
            %   none - the view is read from the movie button group
            %
            % Output:

            %   n - number of slices available in the current view, at least 1
            if app.kSpaceButton.Value == 1 || app.AveragesButton.Value == 1
                n = app.r.nrRawSlices;
            else
                n = app.nrSlices;
            end
            n = max(n, 1);

        end


        % Bring the slice counter and its field into line with the view
        function SyncMovieSliceFieldFcn(app)

            % Purpose:
            %   Put the slice counter, the edit field and the field's own limits
            %   into agreement with the view on screen.
            %
            % How it works:
            %   The limits are widened before the value is set and narrowed after.
            %   Both halves matter: assigning a value above the current limits
            %   raises, and so does narrowing the limits below the current value,
            %   so neither order works on its own when the count changes in either
            %   direction.
            %
            %   The counter is then clamped into the new range. That is what makes
            %   switching views safe: k-space may hold more slices than the
            %   reconstruction or fewer, and a counter left over from the other
            %   view would either be refused by the field or index past the end of
            %   the array being drawn.
            %
            % Inputs:
            %   none - the view is read from the movie button group
            %
            % Output:
            %   none - app.sliceNr and the slice field are left consistent

            n = app.MovieSliceCountFcn;
            app.MovieSliceEditField.Limits = [1 Inf];
            app.sliceNr = min(max(round(app.sliceNr), 1), n);
            app.MovieSliceEditField.Value = app.sliceNr;
            app.MovieSliceEditField.Limits = [1 n];

        end


        % Step the displayed movie slice, wrapping at the ends
        function StepMovieSliceFcn(app, step)

            % Purpose:
            %   Step the displayed slice by one, wrapping at the ends.
            %
            % How it works:
            %   Which slice is on screen, and where the counter wraps, is the interface own
            %   state, so it stays here. The only thing asked of the data is how many slices
            %   were measured, through retroData.nrRawSlices.
            %
            %   The limit differs with what is being shown. K-space and the averages are
            %   displayed per measured slice, the images per reconstructed slice, and for a
            %   3D acquisition those two counts are not the same -- the partitions measured
            %   and the slices reconstructed are independent numbers.
            %
            %   Only the end being moved towards is wrapped, which is what the two buttons
            %   did separately before they shared this. Wrapping both ends here would drag a
            %   counter that is already out of range to the other end of the stack instead of
            %   leaving it where it was.
            %
            % Inputs:
            %   step - +1 or -1, the direction the button asks for
            %
            % Output:
            %   none - the slice counter and the displayed frame are updated

            % Which slice is on screen, and where the counter wraps, is the
            % interface's own state, so all of this stays here. The only thing
            % asked of the data is how many slices were measured.
            %
            % The limit differs with what is being shown: k-space and the averages
            % are displayed per measured slice, the images per reconstructed slice,
            % and for a 3D acquisition those two counts are not the same.
            lastSlice = app.MovieSliceCountFcn;
            app.sliceNr = app.sliceNr + step;

            % Only the end being moved towards is wrapped, which is what the two
            % buttons did separately. Wrapping both ends here instead would drag a
            % counter that is already out of range to the other end of the stack
            % rather than leaving it where it was.
            if step > 0
                app.sliceNr(app.sliceNr > lastSlice) = 1;
            else
                app.sliceNr(app.sliceNr < 1) = lastSlice;
            end

            % The field's own limits follow the view, so they are set together
            % with the value rather than assumed to already allow it
            app.SyncMovieSliceFieldFcn;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end


        % Step the displayed dynamic, wrapping at the ends
        function StepMovieDynFcn(app, step)

            % Purpose:
            %   Step the displayed dynamic by one, wrapping at the ends.
            %
            % How it works:
            %   The same shape as StepMovieSliceFcn and there for the same reason: which
            %   dynamic is on screen is interface state, not the data.
            %
            %   One limit rather than two, because dynamics are counted the same way whatever
            %   is being displayed -- unlike slices, where the measured and reconstructed
            %   counts differ for 3D.
            %
            %   Only the end being moved towards is wrapped, as in StepMovieSliceFcn.
            %
            % Inputs:
            %   step - +1 or -1, the direction the button asks for
            %
            % Output:
            %   none - the dynamic counter and the displayed frame are updated

            % The same shape as StepMovieSliceFcn and there for the same reason:
            % which dynamic is on screen is interface state, not the data's. There
            % is one limit rather than two, because dynamics are counted the same
            % way whatever is being displayed.

            app.dynamicNr = app.dynamicNr + step;

            % Only the end being moved towards is wrapped, as the two buttons did
            if step > 0
                app.dynamicNr(app.dynamicNr > app.nrDynamics) = 1;
            else
                app.dynamicNr(app.dynamicNr < 1) = app.nrDynamics;
            end

            app.MovieDynEditField.Value = app.dynamicNr;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end


        % Plot the current movie frame
        function PlotMovieFrameFcn(app)

            % Purpose:
            %   Draw the current movie frame in full: the frame, and what belongs over it in the
            %   view.
            %
            % How it works:
            %   One branch per view -- the reconstructed movie, the averages, k-space and the
            %   sense map -- because they differ in more than their data: the colour map, the
            %   scaling, and whether orientation labels apply are all view specific.
            %
            %   The movie branch does the most. The frame is taken for the current slice and
            %   dynamic, rotated by the accumulated rotation and by the phase orientation,
            %   scaled by window and level, and drawn; then the LV contour lines are created,
            %   and the navigator plane outline is drawn over it when an external navigator
            %   is being shown.
            %
            %   The contour lines are created here and only updated afterwards, by
            %   UpdateMovieContoursFcn. That split is what makes playback fast: this is the
            %   expensive path, and it runs when the view changes rather than per frame.
            %
            %   What is in the axes is reused. The image takes the new frame when it can
            %   (DrawMovieImageFcn), and the contour lines and the color bar of the averages
            %   are only created when they are not there; what does not belong over the view
            %   is removed first.
            %
            %   A zoom is kept. The limits the axes showed are put back when the redraw shows
            %   the same view of a frame of the same size of the same scan, at the same
            %   rotation, so that stepping through frames, slices and dynamics, or a new
            %   window and level, leaves a zoom as it was. Anything else shows the whole frame,
            %   as does the home button of the movie toolbar (ShowWholeMovieFrameFcn).
            %
            %   The averages branch reserves black for points that hold nothing at all,
            %   which is why its colour map starts with an explicit black entry.
            %
            %   Orientation labels are set through SetOrientationLabelsFcn in every branch,
            %   so a label cannot be left standing over a view it does not describe.
            %
            % Inputs:
            %   none - the view, slice, dynamic and frame are read from the interface
            %
            % Output:
            %   none - the movie axes are drawn

            try

                % The limits shown now, which a zoom may have changed
                shownLimits = [app.MovieFig.XLim app.MovieFig.YLim];

                % The color bar stays while the averages, which have one, are drawn again
                if app.AveragesButton.Value ~= 1
                    delete(app.cbar);
                end

                % What does not belong over this view goes. The frame stays, to be reused, and
                % so do the contour lines over the movie. Until 2026-09-23 imshow cleared the
                % axes at every redraw, and the clean-up that stood here never ran: isfield is
                % false for any property of an object
                keep = {'movieFrame'};
                if app.MovieButton.Value == 1
                    keep{end+1} = 'movieContour';
                end

                drawn = app.MovieFig.Children;
                delete(drawn(arrayfun(@(h) ~any(strcmp(h.Tag, keep)), drawn)));
                app.navLines = {};
                app.navArea = [];

                if app.MovieButton.Value == 1

                    % MRI movie frame
                    im = squeeze(app.r.movieApp(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));

                    % Rotate the image (cc and ccw rotation button)
                    for k=1:app.imageRot
                        im = rot90(im);
                    end

                    % Size of the image
                    [xl,yl] = size(im);
                    recoAspectRatio = (1/app.r.aspectRatio)*yl/xl;
                    if app.r.phaseOrientation
                        im = rot90(im,-1);
                        [xl,yl] = size(im);
                        recoAspectRatio = app.r.aspectRatio*yl/xl;
                    end

                    % Window and Level
                    if app.AutoWLButton.Value
                        im1 = squeeze(app.r.movieApp(:,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));

                        % [app.window, app.level] = app.r.autoWindowLevel(app, im1);
                        app.level = 0.4*max(im1(:));
                        app.window = 0.8*max(im1(:));
                        app.LevelScaleSlider.Value = double(app.level);
                        app.WindowScaleSlider.Value = double(app.window);
                    end

                    % Scale image to range [0 1] using level and window
                    im = (1/app.window)*(im - app.level + app.window/2);
                    im(im<0) = 0;
                    im(im>1) = 1;

                    % Plot the movie frame
                    app.DrawMovieImageFcn(rot90(im), [0 1], gray, 'bilinear');
                    daspect(app.MovieFig,[1 recoAspectRatio 1]);
                    xlim(app.MovieFig,[0, xl + 1]);
                    ylim(app.MovieFig,[0, yl + 1]);

                    % The LV segmentation contour lines, created when they are not there. Only their
                    % data is updated afterwards, which is what keeps the movie playback fast.
                    contours = [app.epiContour app.endoContour];
                    if numel(contours) < 2 || ~all(isvalid(contours))
                        delete(contours);
                        hold(app.MovieFig,'on');
                        app.epiContour  = plot(app.MovieFig, NaN, NaN, '-', 'Color', app.blue, 'LineWidth', 1.5, 'Tag', 'movieContour');
                        app.endoContour = plot(app.MovieFig, NaN, NaN, '-', 'Color', app.orange, 'LineWidth', 1.5, 'Tag', 'movieContour');
                        hold(app.MovieFig,'off');
                    end

                    app.UpdateMovieContoursFcn;

                    % Plot external navigator
                    if app.showExtNavFlag && (app.ExtFID1Button.Value || app.ExtFID2Button.Value || app.ExtECHO1Button.Value || app.ExtECHO2Button.Value)

                        % Calculate the intersection of the navigator plane with the image
                        [lines, area] = retro.util.calculatePlaneIntersection(app.r, app, xl, yl);
                        centers = retro.util.calculatePlaneMidlineProjection(app.r, app, xl, yl);

                        if ~isempty(lines)

                            hold(app.MovieFig, "on");

                            % Plot the navigator outlines
                            app.navLines = {};
                            for k = 1:2
                                clipped_segments = lines{k};
                                for v = 1:numel(clipped_segments)
                                    xy = clipped_segments{v};
                                    app.navLines{end+1} = plot(app.MovieFig, xy(:,1), xy(:,2), '-', 'Color', [1, 0.5, 0.0, 0.5], 'LineWidth', 1);
                                end
                            end

                            % Fill the area
                            app.navArea = fill(app.MovieFig, area{1}, area{2}, [1, 0.5, 0], 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                            uistack(app.navArea, 'top');

                            if app.extEchoNavFlag

                                % Keep points within bounds
                                in_bounds = centers(:,1) >= 0 & centers(:,1) <= xl+1 & centers(:,2) >= 0 & centers(:,2) <= yl+1;

                                % Plot visible points
                                scatter(app.MovieFig, centers(in_bounds,1), centers(in_bounds,2), 25, [1, 0.5, 0]);

                                % Plot filled circles at selected nav points only if also in bounds
                                idx1 = app.NavEnd1EditField.Value;
                                if app.ExtECHO1Button.Value && in_bounds(idx1)
                                    scatter(app.MovieFig, centers(idx1,1), centers(idx1,2), 25, [1, 0.5, 0], 'filled');
                                end

                                idx2 = app.NavEnd2EditField.Value;
                                if app.ExtECHO2Button.Value && in_bounds(idx2)
                                    scatter(app.MovieFig, centers(idx2,1), centers(idx2,2), 25, [1, 0.5, 0], 'filled');
                                end

                            end

                            hold(app.MovieFig, "off");

                        end

                    end

                    % Orientation labels belong on this view
                    app.SetOrientationLabelsFcn(true);

                end

                % Averages
                if app.AveragesButton.Value == 1

                    % Averages scale and colour
                    %
                    % Black is reserved for points that hold nothing at all. This used to be
                    % summer(maxScale) with maxScale rounded to a whole number, giving about twelve
                    % colour bins, so everything below one average fell in the first bin and rendered
                    % black -- indistinguishable from empty. That was fine while the averages were
                    % integer counts, but view sharing makes them fractional, and a line holding 0.8
                    % of an average then looked exactly like a missing line. With 256 colours across
                    % the true range, only an exact zero or a value below roughly 0.02 comes out black.
                    maxScale = max(app.r.kSpaceAvg(:));
                    c = [0 0 0 ; summer(256)];

                    % Averages movie frame
                    im = squeeze(app.r.kSpaceAvg(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));

                    % Size of the image
                    [xl,yl] = size(im);
                    if app.r.phaseOrientation
                        im = rot90(im,-1);
                        [xl,yl] = size(im);
                    end
                    recoAspectRatio = yl/xl;

                    % Not a picture of the subject, so no orientation labels
                    app.SetOrientationLabelsFcn(false);

                    % Plot the averages movie frame, with its color bar
                    app.DrawMovieImageFcn(rot90(im), [0, maxScale], c, 'nearest');
                    if isempty(app.cbar) || ~isvalid(app.cbar)
                        app.cbar = colorbar(app.MovieFig,'location','eastoutside','TickLabelsMode','auto','FontSize',12);
                    end
                    daspect(app.MovieFig,[1 recoAspectRatio 1]);
                    xlim(app.MovieFig,[0, xl + 1]);
                    ylim(app.MovieFig,[0, yl + 1]);

                end

                % Raw k-space
                if app.kSpaceButton.Value == 1

                    % K-space movie frame
                    im = squeeze(abs(app.r.kSpace{app.MovieCoilSpinner.Value}(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value)));

                    % Size and orientation of the image
                    [xl,yl] = size(im);
                    if app.r.phaseOrientation
                        im = rot90(im,-1);
                        [xl,yl] = size(im);
                    end
                    recoAspectRatio = yl/xl;

                    % Window and Level
                    im = im/max(im(:));
                    if app.AutoWLButton.Value
                        lvl = 0.4;
                        wdw = 0.8;
                        app.WindowScaleSlider.Value = double(app.r.maxKspaceValue*wdw);
                        app.LevelScaleSlider.Value = double(app.r.maxKspaceValue*lvl);
                    else
                        lvl = app.LevelScaleSlider.Value/app.r.maxKspaceValue;
                        wdw = app.WindowScaleSlider.Value/app.r.maxKspaceValue;
                    end
                    im = (1/wdw)*(im - lvl + wdw/2);
                    im(im<0) = 0;
                    im(im>1) = 1;

                    % Not a picture of the subject, so no orientation labels
                    app.SetOrientationLabelsFcn(false);

                    % Plot the k-space movie frame
                    app.DrawMovieImageFcn(rot90(im), [0 1], gray, 'nearest');
                    daspect(app.MovieFig,[1 recoAspectRatio 1]);
                    xlim(app.MovieFig,[0, xl + 1]);
                    ylim(app.MovieFig,[0, yl + 1]);

                end

                % Sense map
                if (app.SenseMapButton.Value == 1) && (app.validSenseMapFlag)

                    % Sense map movie frame
                    im = squeeze(abs(app.r.senseMap(:,:,app.MovieSliceEditField.Value,app.SenseMapSpinner1.Value,app.SenseMapSpinner2.Value)));

                    % Rotate the image (cc and ccw rotation button)
                    for k=1:app.imageRot
                        im = rot90(im);
                    end

                    % Size and orientation of the image
                    [xl,yl] = size(im);
                    recoAspectRatio = (1/app.r.aspectRatio)*yl/xl;
                    if app.r.phaseOrientation
                        im = rot90(im,-1);
                        [xl,yl] = size(im);
                        recoAspectRatio = app.r.aspectRatio*yl/xl;
                    end

                    % Window and level. The automatic window is P2ROUD's for sense maps: level 0.75 and
                    % window 1 of the maximum, so values below a quarter of it are black and the
                    % maximum is drawn at three quarters grey. A window over the top half cut the maps
                    % off at 45 %, and one over the whole range drew most of a map close to white
                    im = im/max(im(:));
                    if app.AutoWLButton.Value
                        lvl = 0.75;
                        wdw = 1.0;
                        app.WindowScaleSlider.Value = double(app.r.maxImageValue*wdw);
                        app.LevelScaleSlider.Value = double(app.r.maxImageValue*lvl);
                    else
                        lvl = app.LevelScaleSlider.Value/app.r.maxImageValue;
                        wdw = app.WindowScaleSlider.Value/app.r.maxImageValue;
                    end
                    im = (1/wdw)*(im - lvl + wdw/2);
                    im(im<0) = 0;
                    im(im>1) = 1;

                    % On the image grid and in the image orientation, so labels apply
                    app.SetOrientationLabelsFcn(true);

                    % Plot the new sense map movie frame
                    app.DrawMovieImageFcn(rot90(im), [0 1], gray, 'nearest');
                    daspect(app.MovieFig,[1 recoAspectRatio 1]);
                    xlim(app.MovieFig,[0, xl + 1]);
                    ylim(app.MovieFig,[0, yl + 1]);

                end

                % The whole frame, or the zoom that was shown when this redraw shows the same
                % view of a frame of the same size of the same scan at the same rotation
                movieView = {find([app.MovieButton.Value app.AveragesButton.Value app.kSpaceButton.Value ...
                    app.SenseMapButton.Value]), app.imageRot, app.r.phaseOrientation, [xl yl], app.mrdFile};
                whole = [0 xl+1 0 yl+1];
                inside = shownLimits(1) >= whole(1) && shownLimits(2) <= whole(2) && ...
                    shownLimits(3) >= whole(3) && shownLimits(4) <= whole(4);

                if isequal(movieView, app.movieZoomView) && inside
                    xlim(app.MovieFig, shownLimits(1:2));
                    ylim(app.MovieFig, shownLimits(3:4));
                else
                    xlim(app.MovieFig, whole(1:2));
                    ylim(app.MovieFig, whole(3:4));
                end

                app.movieZoomView = movieView;

                drawnow;

            catch ME
                app.TextMessage(ME.message,2);
            end

        end % PlotMovieFrameFcn


        % Put a frame into the movie axes, reusing the image already there
        function DrawMovieImageFcn(app, frame, displayRange, colors, interpolation)

            % Purpose:
            %   Show a frame in the movie axes for a full redraw, in the image already there
            %   when it can take it.
            %
            % How it works:
            %   The image on display is reused when it is in the movie axes, has the size of
            %   the frame and is drawn with the same interpolation. Its data is set, as playback
            %   does (UpdateMovieFrameFcn), and the display range and color map where they
            %   changed. Otherwise imshow draws the frame anew.
            %
            %   Until 2026-09-23 every full redraw drew a new image, which rebuilt the axes
            %   contents for what is mostly a new frame of the same size.
            %
            %   Before R2023a an image has no Interpolation property, and the frame is drawn
            %   anew every time, as it always was.
            %
            % Inputs:
            %   frame         - the frame as shown, rotated and scaled
            %   displayRange  - [low high], the values drawn black and white
            %   colors        - the color map
            %   interpolation - 'bilinear' for the movie, 'nearest' for the other views
            %
            % Output:
            %   none - app.moviePlot shows the frame

            shown = app.moviePlot;
            reuse = ~isempty(shown) && isvalid(shown) && isequal(shown.Parent, app.MovieFig) && ...
                isprop(shown, 'Interpolation') && strcmp(shown.Interpolation, interpolation) && ...
                isequal(size(shown.CData), size(frame));

            if reuse

                shown.CData = frame;

                if ~isequal(app.MovieFig.CLim, displayRange)
                    app.MovieFig.CLim = displayRange;
                end

                if ~isequal(app.MovieFig.Colormap, colors)
                    colormap(app.MovieFig, colors);
                end

            else

                app.moviePlot = imshow(frame, displayRange, 'Colormap', colors, 'Interpolation', interpolation, ...
                    'InitialMagnification', 'fit', 'Parent', app.MovieFig);
                app.moviePlot.Tag = 'movieFrame';

            end

        end % DrawMovieImageFcn


        % Show the whole movie frame again after a zoom
        function ShowWholeMovieFrameFcn(app)

            % Purpose:
            %   Show the whole movie frame again after a zoom; the home button of the movie
            %   toolbar.
            %
            % How it works:
            %   The limits are the ones PlotMovieFrameFcn gives a frame, taken from the extent
            %   of the image on display, so the button needs no state of its own and suits
            %   every view.
            %
            % Inputs:
            %   none - the image is the one on display
            %
            % Output:
            %   none - the movie axes show the whole frame

            if ~isempty(app.moviePlot) && isvalid(app.moviePlot)
                xlim(app.MovieFig, app.moviePlot.XData + [-1 1]);
                ylim(app.MovieFig, app.moviePlot.YData + [-1 1]);
            end

        end % ShowWholeMovieFrameFcn


        % Show or clear the image orientation labels
        function SetOrientationLabelsFcn(app, show)

            % Purpose:
            %   Show or clear the four anatomical orientation labels around the
            %   movie, according to the view being drawn.
            %
            % How it works:
            %   The labels name anatomical directions, so they belong only on a
            %   view that shows anatomy: the reconstructed movie and the sense map,
            %   which is on the image grid and in the image orientation. K-space
            %   and the averages are not pictures of the subject, and a label left
            %   standing over them reads as a statement about what is on screen.
            %
            %   Two conditions, and both have to hold. 'show' is whether the view
            %   being drawn takes labels at all, and app.orientationsFlag is the
            %   user's own toggle, the 'o' key.
            %
            %   Labels are per slice -- the rows of orientationLabels are slices
            %   and its columns the four sides -- so the row is the slice on
            %   screen. The array is empty when the geometry could not be read, and
            %   can be shorter than the stack, so both are checked rather than
            %   indexed into.
            %
            %   Every view calls this, which is what keeps a label from one view
            %   appearing over another.
            %
            % Inputs:
            %   show - true when the view being drawn takes orientation labels
            %
            % Output:
            %   none - the four labels are set or cleared on the interface

            haveLabels = ~isempty(app.r.orientationLabels) && ...
                app.MovieSliceEditField.Value <= size(app.r.orientationLabels,1);
            if show && app.orientationsFlag && haveLabels
                slice = app.MovieSliceEditField.Value;
                wanted = {app.r.orientationLabels(slice,1), app.r.orientationLabels(slice,2), ...
                    app.r.orientationLabels(slice,3), app.r.orientationLabels(slice,4)};
            else
                wanted = {'', '', '', ''};
            end

            % Written only when they change. This runs per frame during playback,
            % where the slice can advance, and assigning a label its existing text
            % still costs a graphics update.
            labels = {app.OrientationLabel1, app.OrientationLabel2, ...
                app.OrientationLabel3, app.OrientationLabel4};
            for k = 1:4
                if ~strcmp(labels{k}.Text, wanted{k})
                    labels{k}.Text = wanted{k};
                end
            end

            % Their places follow the image as it is drawn
            app.PlaceOrientationLabelsFcn();

        end % SetOrientationLabelsFcn


        % Place the image orientation labels on the image as it is drawn
        function PlaceOrientationLabelsFcn(app)

            % Purpose:
            %   Put each orientation label centred on its own edge of the movie image as it is
            %   drawn, the same few pixels inside it on every side.
            %
            % How it works:
            %   The image's extent is that of the frame on display (moviePlot), its pixel
            %   centres widened by half a pixel. retro.util.orientationLabelPositions fits the
            %   plot box into the axes' inner position with their data aspect ratio, maps the
            %   part of the image inside their limits onto it and returns the four positions,
            %   so the labels follow the image's shape, a zoom, a pan and a rotation. A
            %   listener on the axes' limits calls this (StartupFcn), and so does
            %   SetOrientationLabelsFcn after every drawing of a view.
            %
            %   A label is moved only when its place changes, since this runs for every frame
            %   during playback. Without a frame on display nothing moves.
            %
            % Inputs:
            %   none - the movie axes, the frame on display and the labels are read from the
            %          interface
            %
            % Output:
            %   none - the labels' positions are set

            if isempty(app.moviePlot) || ~isvalid(app.moviePlot)
                return
            end

            try
                labels = {app.OrientationLabel1, app.OrientationLabel2, ...
                    app.OrientationLabel3, app.OrientationLabel4};
                letters = cellfun(@(label) char(label.Text), labels, 'UniformOutput', false);
                extent = [app.moviePlot.XData([1 end]) + [-0.5 0.5], app.moviePlot.YData([1 end]) + [-0.5 0.5]];
                positions = retro.util.orientationLabelPositions(app.MovieFig.InnerPosition, ...
                    [app.MovieFig.XLim app.MovieFig.YLim], app.MovieFig.DataAspectRatio, ...
                    strcmp(app.MovieFig.YDir, 'reverse'), extent, letters, ...
                    app.OrientationLabel1.Position(3:4), app.OrientationLabel1.FontSize);
                for k = 1:4
                    if all(isfinite(positions(k,:))) && any(abs(labels{k}.Position - positions(k,:)) > 0.5)
                        labels{k}.Position = positions(k,:);
                    end
                end
            catch ME
                app.TextMessage(ME.message, 2);
            end

        end % PlaceOrientationLabelsFcn


        % Fast update the current movie frame
        function UpdateMovieFrameFcn(app)

            % Purpose:
            %   Update the movie frame in place, without recreating the axes contents.
            %
            % How it works:
            %   The fast counterpart of PlotMovieFrameFcn, and the reason a movie plays
            %   smoothly. Only the image data is set on the existing plot object; the axes,
            %   the colour map, the limits and the contour lines all stay as they are.
            %
            %   The same four view branches, each preparing its frame the way its full
            %   counterpart does -- the same rotations, the same window and level -- so the
            %   two paths cannot disagree about what a frame looks like.
            %
            %   The orientation labels are not touched here. They do not change from frame to
            %   frame, only when the view or the slice does, and both of those go through the
            %   full plot.
            %
            %   Automatic window and level is recomputed from the whole slice rather than the
            %   frame, so the brightness does not flicker as the movie plays.
            %
            % Inputs:
            %   none - the view, slice, dynamic and frame are read from the interface
            %
            % Output:
            %   none - the movie image data is updated in place

            % MRI movie
            if app.MovieButton.Value == 1

                % MRI movie frame
                im = squeeze(app.r.movieApp(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));

                % Rotate the image (cc and ccw rotation button)
                for i=1:app.imageRot
                    im = rot90(im);
                end

                % Phase orientation
                if app.r.phaseOrientation
                    im = rot90(im,-1);
                end

                % Window and Level
                if app.AutoWLButton.Value
                    im1 = squeeze(app.r.movieApp(:,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));
                    app.level = 0.4*max(im1(:));
                    app.window = 0.8*max(im1(:));
                    app.LevelScaleSlider.Value = double(app.level);
                    app.WindowScaleSlider.Value = double(app.window);
                end
                im = (1/app.window)*(im - app.level + app.window/2);
                im(im<0) = 0;
                im(im>1) = 1;

                % Plot the new frame
                set(app.moviePlot, 'CData', rot90(im));

                % The slice can advance while the movie plays, and the labels are
                % per slice, so they are refreshed here as well as in the full plot
                app.SetOrientationLabelsFcn(true);

                % Update the LV contours - line data only, the objects already exist
                app.UpdateMovieContoursFcn;

            end

            % Averages
            if app.AveragesButton.Value == 1

                % Averages movie frame
                im = squeeze(app.r.kSpaceAvg(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));

                % Orientation
                if app.r.phaseOrientation
                    im = rot90(im,-1);
                end

                % Not a picture of the subject, so no orientation labels
                app.SetOrientationLabelsFcn(false);

                % Plot the new movie frame
                set(app.moviePlot, 'CData', rot90(im));

            end

            % Raw k-space
            if app.kSpaceButton.Value == 1

                % K-space movie frame
                im = squeeze(abs(app.r.kSpace{app.MovieCoilSpinner.Value}(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value)));

                % Orientation
                if app.r.phaseOrientation
                    im = rot90(im,-1);
                end

                % Not a picture of the subject, so no orientation labels
                app.SetOrientationLabelsFcn(false);

                % Window and Level
                im = im/max(im(:));
                if app.AutoWLButton.Value
                    lvl = 0.4;
                    wdw = 0.8;
                    app.WindowScaleSlider.Value = double(app.r.maxKspaceValue*wdw);
                    app.LevelScaleSlider.Value = double(app.r.maxKspaceValue*lvl);
                else
                    lvl = app.LevelScaleSlider.Value/app.r.maxKspaceValue;
                    wdw = app.WindowScaleSlider.Value/app.r.maxKspaceValue;
                end
                im = (1/wdw)*(im - lvl + wdw/2);
                im(im<0) = 0;
                im(im>1) = 1;

                % Plot the new movie frame
                set(app.moviePlot, 'CData', rot90(im));

            end

            % Sense map

            if (app.SenseMapButton.Value == 1) && (app.validSenseMapFlag)

                % Sense map movie frame
                im = squeeze(abs(app.r.senseMap(:,:,app.MovieSliceEditField.Value,app.SenseMapSpinner1.Value,app.SenseMapSpinner2.Value)));

                % On the image grid and in the image orientation, so labels apply
                app.SetOrientationLabelsFcn(true);

                % Rotate the image (cc and ccw rotation button)
                for i=1:app.imageRot
                    im = rot90(im);
                end

                % Phase orienation
                if app.r.phaseOrientation
                    im = rot90(im,-1);
                end

                % Window and level. The automatic window is P2ROUD's for sense maps: level 0.75 and
                % window 1 of the maximum, so values below a quarter of it are black and the
                % maximum is drawn at three quarters grey. A window over the top half cut the maps
                % off at 45 %, and one over the whole range drew most of a map close to white
                im = im/max(im(:));
                if app.AutoWLButton.Value
                    lvl = 0.75;
                    wdw = 1.0;
                    app.WindowScaleSlider.Value = double(app.r.maxImageValue*wdw);
                    app.LevelScaleSlider.Value = double(app.r.maxImageValue*lvl);
                else
                    lvl = app.LevelScaleSlider.Value/app.r.maxImageValue;
                    wdw = app.WindowScaleSlider.Value/app.r.maxImageValue;
                end
                im = (1/wdw)*(im - lvl + wdw/2);
                im(im<0) = 0;
                im(im>1) = 1;

                % Plot the new movie frame
                set(app.moviePlot, 'CData', rot90(im));

            end

            drawnow;

        end % UpdateMovieFrameFcn


        % Fast update of the LV segmentation contours
        function UpdateMovieContoursFcn(app)

            % Purpose:
            %   Move the LV contour lines to the frame on screen, without redrawing the
            %   image under them.
            %
            % How it works:
            %   The two lines are created once, in PlotMovieFrameFcn, and only their data is
            %   set here. That is what keeps movie playback fast: creating a plot object per
            %   frame is what makes a movie stutter.
            %
            %   Two states are handled before that. The lines can be gone -- imshow replaces
            %   the axes contents, so between a redraw and their recreation there is nothing
            %   to set -- which returns rather than erroring on an invalid handle.
            %
            %   And with no valid segmentation the lines are set to NaN rather than left as
            %   they were. NaN draws nothing, so the contours disappear; leaving them would
            %   show the previous scan contours over the new images, which looks like a
            %   segmentation rather than like a mistake.
            %
            %   The contour coordinates come from retro.util.lvContours, which traces them
            %   from the label map for the frame, slice and dynamic on screen.
            %
            % Inputs:
            %   none - the frame, slice and dynamic are read from the interface
            %
            % Output:
            %   none - the contour lines are updated in place

            % The lines are created in PlotMovieFrameFcn, and imshow replaces the axes
            % contents, so between a redraw and their recreation they can be gone
            if isempty(app.epiContour) || ~isvalid(app.epiContour)
                return
            end

            % Clear rather than leave stale contours behind, for instance after a new
            % reconstruction has invalidated the previous segmentation
            if ~app.validSegmentationFlag
                set(app.epiContour,  'XData', NaN, 'YData', NaN);
                set(app.endoContour, 'XData', NaN, 'YData', NaN);
                return
            end

            [epiX, epiY, endoX, endoY] = retro.util.lvContours(app.r, app, ...
                app.MovieFrameEditField.Value, app.MovieSliceEditField.Value, app.MovieDynEditField.Value);

            set(app.epiContour,  'XData', epiX,  'YData', epiY);
            set(app.endoContour, 'XData', endoX, 'YData', endoY);

        end % UpdateMovieContoursFcn


    end % Methods (Access = private)


    % ----------------------------------------------
    % Public functions, available outside the app
    % ----------------------------------------------


    methods (Access = public)


        % Automatic reconstruction of a new MRD file
        function autoRecoProcessMRD(app, fullPath)

            % Purpose:
            %   Take a newly appeared MRD file all the way through to an exported MRD,
            %   without anyone at the keyboard.
            %
            % How it works:
            %   The same button callbacks the user would press, called in order: load,
            %   filter, sort, reconstruct, export.
            %
            %   Each step is gated on the flag the previous one sets, so the chain stops at
            %   the first step that did not succeed rather than running the next against
            %   data that is not there. That is why it reads as nested ifs rather than a
            %   sequence.
            %
            %   Driving the callbacks rather than the underlying methods is deliberate: the
            %   automatic path then does exactly what the manual one does, including the
            %   interface updates, and cannot drift away from it.
            %
            %   The pause before loading lets the writer finish flushing. The watcher fires
            %   when the file appears, which is before the scanner has finished writing it,
            %   and reading it too early gets a truncated file.
            %
            % Inputs:
            %   fullPath - the MRD file the folder watcher found
            %
            % Output:
            %   none - the pipeline is run and its results exported

            app.TextMessage('New MRD file found ...');

            app.mrdAutoFile = fullPath;

            pause(1);                                   % let file writer finish flushing
            app.LoadMRDfileButtonPushed;                % Load data

            if app.r.validDataFlag
                app.FilterButtonPushed;                 % Filter navigator data

                if app.validFilterFlag
                    app.SortKspaceButtonPushed;         % Sort into k-space

                    if app.validSortFlag
                        app.RecoMoviesButtonPushed;     % Reconstruct images/movie

                        if app.validRecoFlag
                            app.ExportMRDButtonPushed;  % Export new MRD
                        end

                    end

                end

            end

        end % autoRecoProcessMRD


        % Text messages in the app
        function TextMessage(app, message, varargin)

            % Purpose:
            %   Write one line to the message pane and to the log file.
            %
            % How it works:
            %   The text is normalised first: joined into a single string, stripped of line
            %   breaks, and given a trailing ellipsis if it has none. Every message therefore
            %   looks the same whatever raised it, including the ones that arrive as an error
            %   message from somewhere else.
            %
            %   The log is written first and separately, inside its own guard, so a message
            %   reaches the file even when the pane cannot take it -- which is the case for
            %   anything reported while the interface is being torn down.
            %
            %   The level decides what is shown: a message above the spinner setting is
            %   logged but not displayed, which is what makes the pane readable at level 1
            %   while the log keeps everything.
            %
            %   A message identical to the one above it is dropped rather than repeated. A
            %   loop reporting per slice or per coil would otherwise fill the pane with the
            %   same line and push the messages that differ out of sight.
            %
            %   Newest first, so the last thing that happened is what the user sees without
            %   scrolling.
            %
            % Inputs:
            %   message  - the text, string or char, or an array to be joined
            %   varargin - message level, 1 = always shown, higher = more verbose; default 1
            %
            % Output:
            %   none - the message is written to the pane and the log

            % Ensure input is a single string
            if ~isstring(message)
                message = string(message);
            end
            message = strjoin(message, " ");

            % Append "..." if not already present
            if ~endsWith(message, "...")
                message = message + " ...";
            end

            % Remove line breaks
            message = strrep(message, newline, "");
            message = strrep(message, " : ", "");

            % Write all messages to log
            try
                fprintf(app.logFile, "%s \n", message);
            catch
            end

            % Write message to message box
            try

                if ~app.blockMessagesFlag

                    % If no message level input, message level = 1
                    debugLevel = 1;
                    if nargin > 2
                        debugLevel = varargin{1};
                    end

                    if debugLevel <= app.MessageLevelSpinner.Value

                        % Skip if identical to the previous message
                        currentMessages = app.MessageBox.Value;
                        if ~isempty(currentMessages) && strcmp(currentMessages{1}, message)
                            return;
                        end

                        % Add new text
                        newText = [message ; currentMessages];

                        % Limit the history of the messages in the message box to 100 lines
                        if size(newText, 1) > 100
                            newText = newText(1:100, 1);
                        end

                        % Write the message in the app message window
                        app.MessageBox.Value = newText;
                        drawnow;

                    end

                end

            catch
            end

        end % TextMessage


        % Status icon
        function SetStatus(app, status)

            % Purpose:
            %   Show the state of the application as an icon, a label and, optionally, a
            %   sound.
            %
            % How it works:
            %   Four states -- OK, warning, error and busy -- each setting the icon, the
            %   label text and its colour together, so the three cannot disagree about what
            %   is being reported.
            %
            %   The icons come from the settings file, as elsewhere in the layout.
            %
            %   A sound is played for the states worth interrupting for, and only when the
            %   user has asked for sounds. A long reconstruction is the case this exists
            %   for: it finishes while the operator is looking elsewhere, and the status
            %   icon alone is easy to miss.
            %
            %   Each state also writes to the message pane at level 2, so the log records
            %   what the icon showed and when.
            %
            % Inputs:
            %   status - 0 OK, 1 warning, 2 error, 3 busy
            %
            % Output:
            %   none - the status icon, label and sound are set

            % BUSY marks a running step until a final status ends it; a WARNING leaves
            % the flag as it was. A close request made meanwhile waits for the step
            if status == 3
                app.busyFlag = true;
            elseif ismember(status, [0 2 4])
                app.busyFlag = false;
            end

            switch status

                case 0 % Status OK
                    app.StatusImage.Position = [609,65,30,30];
                    app.StatusImage.ImageSource = app.jsonPar.statusOkayIcon;
                    app.StatusLabel.Text = 'OK';
                    app.StatusLabel.FontColor = app.green;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.TextMessage("Status: OK ...",2);

                case 1 % Status Warning
                    app.StatusImage.Position = [609,65,30,30];
                    app.StatusImage.ImageSource = app.jsonPar.statusWarningIcon;
                    app.StatusLabel.Text = 'Warning';
                    app.StatusLabel.FontColor = app.orange;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 11;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.warningSound,48000);
                    end
                    app.TextMessage("Status: WARNING ...",2);

                case 2 % Status Error
                    app.StatusImage.Position = [609,65,30,30];
                    app.StatusImage.ImageSource = app.jsonPar.statusErrorIcon;
                    app.StatusLabel.Text = 'ERROR';
                    app.StatusLabel.FontColor = app.brightred;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.errorSound,48000);
                    end
                    app.TextMessage("Status: ERROR ...",2);

                case 3 % Status Busy
                    app.StatusImage.Position = [607,63,33,33];
                    app.StatusImage.ImageSource = app.jsonPar.statusBusyIcon;
                    app.StatusLabel.Text = 'BUSY';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.TextMessage("Status: BUSY ...",2);

                case 4 % Status auto reconstruction
                    app.StatusImage.Position = [609,65,30,30];
                    app.StatusImage.ImageSource = app.jsonPar.statusAutoIcon;
                    app.StatusLabel.Text = 'AUTO';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.TextMessage("Status: AUTO ...",2);

            end

            drawnow;

        end % SetStatus

        % Shut the app down
        function CloseAppFcn(app)

            % Purpose:
            %   Shut the app down, whether the exit button or the window's close box was
            %   used, also while the app is still busy, and leave the MATLAB session as it
            %   was found.
            %
            % How it works:
            %   While app code is still running -- the startup, a step or a loop -- deleting
            %   the app would make that code fail on a deleted object. The close is then
            %   deferred: the window is hidden, what runs is asked to stop, and a timer
            %   finishes the close once no app code runs any more (DeferredCloseFcn). The
            %   timer has no limit on its ticks, since a step can take longer than any limit.
            %
            %   A question in the window that still waits for an answer keeps the window
            %   visible, since hiding it would leave a question nobody can see or answer.
            %
            %   Otherwise each step is wrapped separately rather than the whole sequence
            %   together, so that one that cannot run does not prevent the rest: movies
            %   stopped, the folder watcher stopped, the python caches and logs removed,
            %   the added paths removed, the log closed and the warning state restored.
            %   Shutdown runs when things are already in an unusual state, and a single
            %   guard around everything would leave the later steps undone whenever an
            %   earlier one failed.
            %
            %   The watcher is stopped explicitly, also when the close has to wait, because
            %   it is a timer that would otherwise go on firing against a deleted app and
            %   start new reconstructions. Figures other than the app's own are left open.
            %
            %   The figure is deleted before the app. Deleting only the app from the close
            %   box left the figure registered as the running app, and the app could then
            %   not be started again. Both go before the app's folders leave the search
            %   path: deleting the figure runs the app's destructor, which MATLAB cannot
            %   find once they are gone. What the restoring needs is kept first.
            %
            % Inputs:
            %   app - the running app
            %
            % Output:
            %   none - the app is closed, or its close is scheduled

            % Still busy: stop the movies and the watcher, and close once the step has
            % finished
            if app.AppCodeRunningFcn

                if ~app.closeRequested
                    app.closeRequested = true;
                    app.stop3DMovieFlag = true;
                    app.stopMovieFlag = true;
                    app.stopNavTimeMovieFlag = true;
                    app.stopRadialMovieFlag = true;
                    app.stopRawNavMovieFlag = true;
                    try
                        mrdAutoRecoManager(app, 'stop');
                    catch
                    end
                    app.TextMessage('Closing when the current step has finished ...',1);
                    if ~app.DialogWaitingFcn
                        app.UIFigure.Visible = 'off';
                    end
                    app.closeTimer = timer('Name', 'retrospective10CloseTimer', ...
                        'ExecutionMode', 'fixedSpacing', 'Period', 0.5, 'StartDelay', 0.5, ...
                        'TimerFcn', @(t,~) isvalid(app) && app.DeferredCloseFcn(t));
                    start(app.closeTimer);
                end

                return

            end

            % Stop all movies
            try
                app.StopAllMoviesFcn;
            catch
            end

            % Stop the auto reco manager
            try
                mrdAutoRecoManager(app, 'stop');
            catch
            end

            % Remove the python caches and logs of the reconstruction networks, from the
            % folder their code is in and the model folders the settings name
            if ~isdeployed
                drimFolder = fullfile(app.appPath, 'functions', 'drim');
                try delete(fullfile(drimFolder, '__pycache__', '*.pyc')); catch end
                try
                    for k = 1:numel(app.jsonPar.trainNumbers)
                        try delete(fullfile(drimFolder, app.jsonPar.trainNumbers{k}, 'logs', '*.txt')); catch end
                    end
                catch
                end
            end

            % Close log file
            try
                fclose(app.logFile);
            catch
            end

            % Keep what the restoring needs; the app is deleted before it is used
            appRoot = app.appPath;
            startFolder = app.oldPath;
            startWarnings = app.oldWarningState;

            % Delete the figure, then the app, while the app folders are still on the
            % search path: deleting the figure runs the app's destructor
            try
                delete(app.UIFigure);
            catch
            end
            try
                delete(app);
            catch
            end

            % Remove the app folders from the search path, and return to the folder MATLAB
            % was in
            if ~isdeployed
                try
                    rmpath(genpath(appRoot));
                    cd(startFolder);
                catch
                end
            end

            % Restore the warning state MATLAB had
            try
                if ~isempty(startWarnings)
                    warning(startWarnings);
                end
            catch
            end

        end % CloseAppFcn


        % Finish a close that had to wait
        function done = DeferredCloseFcn(app, t)

            % Purpose:
            %   Complete a close that was requested while app code was still running.
            %
            % How it works:
            %   The close timer calls this twice a second. While app code still runs
            %   nothing else happens. Once it has stopped, one more tick is waited, so
            %   that the callback that ended the step has returned as well, and the app is
            %   then closed the normal way.
            %
            % Inputs:
            %   app - the app being closed
            %   t   - the close timer
            %
            % Output:
            %   done - always true, since the timer's function handle needs a value

            done = true;

            try

                % Keep the window hidden, unless a question in it waits for an answer; a dialog
                % that returned may have raised it again
                if ~app.DialogWaitingFcn
                    app.UIFigure.Visible = 'off';
                end

                % Still running: wait for the next tick
                if app.AppCodeRunningFcn
                    t.UserData = 0;
                    return
                end

                % Idle for the first time: wait one more tick
                if isempty(t.UserData) || t.UserData < 1
                    t.UserData = 1;
                    return
                end

                % Idle twice in a row: close
                stop(t);
                delete(t);
                app.closeTimer = [];
                app.CloseAppFcn;

            catch

                % A timer that fails is removed rather than left firing
                try
                    stop(t);
                    delete(t);
                catch
                end

            end

        end % DeferredCloseFcn


        % Check whether app code is running
        function running = AppCodeRunningFcn(app)

            % Purpose:
            %   Tell whether closing the app now would break code that is still running.
            %
            % How it works:
            %   Any method of the app still on the call stack -- a callback, the startup,
            %   a loop -- is running underneath the close request, or underneath the timer
            %   that asks. The closing methods themselves do not count, and neither do
            %   anonymous functions, whose names carry the method that created them.
            %
            %   Where the stack cannot be read, the BUSY status that every step sets is
            %   used instead.
            %
            % Inputs:
            %   app - the running app
            %
            % Output:
            %   running - true while app code other than the closing methods runs

            running = app.busyFlag;

            % The methods that do the closing
            closing = ["CloseAppFcn", "DeferredCloseFcn", "AppCodeRunningFcn", ...
                "ExitButtonPushed", "UIFigureCloseRequest"];

            % The app's own methods on the call stack
            try
                names = string({dbstack.name});
                mine = startsWith(names, "retrospective10.");
                if any(mine)
                    running = any(mine & ~contains(names, "@(") & ~contains(names, closing));
                end
            catch
            end

        end % AppCodeRunningFcn


        % Check whether a question in the window waits for an answer
        function waiting = DialogWaitingFcn(~)

            % Purpose:
            %   Tell whether a question asked in the app window is still unanswered.
            %
            % How it works:
            %   A uiconfirm stays on the call stack until it is answered. Hiding the window
            %   while it waits would leave a question that nobody can see or answer, so a
            %   close request keeps the window visible until then.
            %
            % Inputs:
            %   none
            %
            % Output:
            %   waiting - true while a uiconfirm is on the call stack

            waiting = false;
            try
                waiting = any(contains(string({dbstack.name}), "uiconfirm"));
            catch
            end

        end % DialogWaitingFcn


        % Plot trajectory for radial scanning
        function PlotTrajectoryMovieFrameFcn(app,xLines,yLines,zLines,dimX,firstTime)

            % Purpose:
            %   Draw one frame of the k-space trajectory animation.
            %
            % How it works:
            %   The trajectory is animated so the sampling pattern can be watched as it is
            %   laid down, which is how a wrong ordering is spotted -- it is visible as a
            %   pattern that does not fill k-space evenly, long before it shows up as an
            %   artefact in an image.
            %
            %   The first frame and the rest are handled differently, which is what
            %   firstTime is for. The first creates the plot object and sets the limits,
            %   ticks and aspect ratio; every frame after it only sets the line data on the
            %   object that already exists. Recreating the plot per frame is what makes an
            %   animation stutter.
            %
            %   The limits are set slightly wider than the matrix, so the outermost spokes
            %   are not drawn against the edge of the axes.
            %
            %   The branch is on data type because the trajectories differ in kind: the 2D
            %   radial types draw spokes in a plane, while UTE and ZTE draw them on a sphere
            %   and need a third axis and a viewing angle.
            %
            % Inputs:
            %   xLines, yLines, zLines - the trajectory coordinates to draw
            %   dimX      - matrix size, which sets the axis limits
            %   firstTime - true to create the plot, false to update the existing one
            %
            % Output:
            %   none - the trajectory axes are drawn

            try

                switch app.r.dataType

                    case {'2Dradial','2Dradialms'}

                        if firstTime

                            % First time plot, set the plot limits and properties
                            reset(app.TrajectoryLargeFig);
                            app.trajPlot = plot(app.TrajectoryLargeFig,xLines,yLines,'LineWidth',0.001);
                            hold(app.TrajectoryLargeFig,'on');
                            xlim(app.TrajectoryLargeFig,[-0.55*dimX 0.55*dimX]);
                            ylim(app.TrajectoryLargeFig,[-0.55*dimX 0.55*dimX]);
                            set(app.TrajectoryLargeFig,'XTickLabel',[]);
                            set(app.TrajectoryLargeFig,'YTickLabel',[]);
                            set(app.TrajectoryLargeFig,'XTick',[]);
                            set(app.TrajectoryLargeFig,'YTick',[]);
                            app.TrajectoryLargeFig.Interactions = [];
                            app.TrajectoryLargeFig.Toolbar.Visible = 'off';
                            hold(app.TrajectoryLargeFig,'off');

                        else

                            % For all other times, only update the lines
                            for cnt = 1:app.maxNrSpokesPlot
                                app.trajPlot(cnt,1).XData = xLines(:,cnt);
                                app.trajPlot(cnt,1).YData = yLines(:,cnt);
                            end

                        end

                        % With limitrate the plotting is faster
                        drawnow limitrate;

                    case {'3Dute','ZTE'}

                        if firstTime

                            % First time plot, set the plot limits and properties
                            reset(app.TrajectoryLargeFig);
                            app.trajPlot = plot3(app.TrajectoryLargeFig,xLines,yLines,zLines,'LineWidth',0.001);
                            xlim(app.TrajectoryLargeFig,[-0.55*dimX 0.55*dimX]);
                            ylim(app.TrajectoryLargeFig,[-0.55*dimX 0.55*dimX]);
                            zlim(app.TrajectoryLargeFig,[-0.55*dimX 0.55*dimX]);
                            set(app.TrajectoryLargeFig,'XTickLabel',[]);
                            set(app.TrajectoryLargeFig,'YTickLabel',[]);
                            set(app.TrajectoryLargeFig,'ZTickLabel',[]);
                            box(app.TrajectoryLargeFig,'on');
                            app.TrajectoryLargeFig.Interactions = rotateInteraction;
                            app.TrajectoryLargeFig.Toolbar.Visible = 'off';

                        else

                            % For all other times, only update the lines
                            for cnt = 1:app.maxNrSpokesPlot
                                app.trajPlot(cnt,1).XData = xLines(:,cnt);
                                app.trajPlot(cnt,1).YData = yLines(:,cnt);
                                app.trajPlot(cnt,1).ZData = zLines(:,cnt);
                            end

                        end

                        % With limitrate the plotting is faster
                        drawnow limitrate;

                end

            catch ME

                app.TextMessage(ME.message,1);

            end

        end % PlotTrajectoryMovieFrameFcn

    end % Methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)

            % Purpose:
            %   Bring the application up: paths, log file, settings, layout, and the
            %   state that has to exist before anything can be loaded.
            %
            % How it works:
            %   The order matters and is not arbitrary.
            %
            %   The subfolders go on the path first, since everything after this
            %   depends on code that lives in them. It is skipped when deployed, where
            %   the compiler has already resolved what is needed and there is no source
            %   tree to add.
            %
            %   The log file is opened next, so that a failure in any later step is
            %   recorded rather than lost. That is why it comes before the settings,
            %   which is the step most likely to fail on a machine being set up.
            %
            %   The settings are read through retro.io.readSettingsFile, which merges
            %   the user file over the shipped defaults, so a key added since that user
            %   file was created still has a value.
            %
            %   Then the layout: colours, icons, species presets, and the initial button
            %   state, which is everything disabled except loading -- nothing else is
            %   possible before there is data.
            %
            %   The version check runs last of the network-facing work, and can be
            %   switched off in the settings, so an isolated console does not wait on it
            %   at every start.
            %
            %   Each step is guarded separately rather than the whole sequence together:
            %   a log file that cannot be created, or a settings file that cannot be
            %   read, must not stop the application from starting.
            %
            % Inputs:
            %   app - the application being started
            %
            % Output:
            %   none - the application is left ready to load a file

            % The window is already showing, so closing it during the startup waits for
            % the startup to finish, as it does during any other step
            app.busyFlag = true;

            try

                % Disable unnecessary warnings; the state MATLAB had is put back on closing
                app.oldWarningState = warning;
                warning off;
                clc;

                % Set app theme to light
                theme(app.UIFigure,"light");

                % Add subfolders to search path
                if ~isdeployed
                    app.oldPath = pwd;
                    info = dir(which('retrospective10.mlapp'));
                    app.appPath = info.folder;
                    cd(app.appPath);
                    addpath(genpath(strcat(pwd,filesep,'functions')));
                    addpath(genpath(strcat(pwd,filesep,'gui')));
                end

                % Open new log file
                try
                    app.TextMessage("Creating log file ...",1);
                    [logFileName, app.logFile] = retro.io.makeLogFile(app.appVersion, AppReporter(app));
                    app.TextMessage(strcat("log file = ",logFileName," ..."),3);
                catch ME
                    app.TextMessage(ME.message,2);
                end

                % Read the JSON parameters
                try
                    app.TextMessage("Reading JSON parameter file ...",1);
                    app.jsonPar = retro.io.readSettingsFile(app.appVersion, AppReporter(app));
                catch ME
                    app.TextMessage(ME.message,2)
                end

                % Put the settings that change how the app behaves into force.
                % Done here, in one place directly after they are read, so that
                % an edited value is in effect before anything reads it.
                try

                    % How much detail the message box shows. The log keeps every
                    % message whatever this is set to.
                    app.MessageLevelSpinner.Value = app.jsonPar.messageLevel;

                    % How long each step of building the python environment may
                    % take, which depends on the connection rather than on the app.
                    retro.python.pythonSetupBudget('set', app.jsonPar.pythonTimeouts);

                    % Old logs, now that the number to keep is known. The log for
                    % this run is the newest of them and is never removed.
                    retro.io.pruneLogFiles(logFileName, app.jsonPar.logFilesToKeep, AppReporter(app));

                catch ME

                    app.TextMessage(ME.message,2)

                end

                % Create empty objects of classes
                app.r = retroData(app);

                % Empty file import and export paths
                app.mrdImportPath = '';
                app.mrdAutoImportPath = '';
                app.mrdAutoFile = '';

                % Where exports start from. A site that always writes to the same
                % share names it once in the settings file; left empty, the app
                % asks for a folder as before.
                exportPath = '';
                try
                    if ~isempty(app.jsonPar.defaultExportPath) && isfolder(app.jsonPar.defaultExportPath)
                        exportPath = char(app.jsonPar.defaultExportPath);
                    end
                catch
                    exportPath = '';
                end
                app.dicomExportPath = exportPath;
                app.gifExportPath = exportPath;

                % Initialize flags
                app.validFilterFlag = false;
                app.validSortFlag = false;
                app.validRecoFlag = false;
                app.validSenseMapFlag = false;
                app.validSegmentationFlag = false;
                app.showExtNavFlag = false;

                % Stop all movies
                app.StopAllMoviesFcn;

                % Load all icons & colors
                app.SetAppLayoutPropertiesFcn;
                app.SetStatus(3);

                % The orientation labels follow the movie image when a zoom or a pan changes
                % the limits of its axes
                addlistener(app.MovieFig, {'XLim', 'YLim'}, 'PostSet', @(~, ~) app.PlaceOrientationLabelsFcn());

                % Report on the computer platform
                try
                    if ismac()
                        [~,result] = system('uname -v');
                        isAppleSiliconMac = any(strfind(result,'ARM64'));
                        if isAppleSiliconMac
                            app.pcType = "AppleSilicon";
                            app.TextMessage('Apple arm64 platform ...');
                        else
                            app.pcType = "AppleIntel";
                            app.TextMessage('Apple Intel platform ...');
                        end
                    elseif ispc()
                        app.pcType = "Windows";
                        app.TextMessage('PC Intel platform ...');
                    elseif isunix()
                        app.pcType = "Unix";
                        app.TextMessage('Unix platform ...');
                        app.aiModuleFlag = false;
                    end
                catch ME
                    app.TextMessage(ME.message,2);
                end

                % Determine if BART is available by requesting its version number
                app.bartDetectedFlag = false;

                try
                    app.bartVersion = "none";
                    bart(app,'version');
                    if contains(app.bartVersion,'v')
                        app.bartDetectedFlag = true;
                        app.TextMessage('BART toolbox detected ...');
                    end
                catch ME
                    app.bartDetectedFlag = false;
                    app.TextMessage(ME.message,2);
                    app.TextMessage('WARNING: BART toolbox not available ...');
                    app.SetStatus(1);
                end

                % Check if Bart was compiled with GPU support
                app.gpuPresentFlag = false;

                if app.bartDetectedFlag
                    try
                        app.blockMessagesFlag = true;
                        rec = bart(app,'pics -l2 -g ',[0 1 0 ; 1 1 1 ; 0 1 0],[1 1 1 ; 1 1 1 ; 1 1 1]);
                        app.blockMessagesFlag = false;
                        app.TextMessage('BART compiled with GPU support ...');
                        app.gpuPresentFlag = true;
                    catch
                        app.blockMessagesFlag = false;
                        app.TextMessage('BART compiled without GPU support ...');
                    end
                end

                % Check whether python, pip and/or conda modules are available for deep learning reconstruction
                app.aiModuleFlag = app.jsonPar.AImodule;
                app.aiDetectedFlag = false;
                if app.aiModuleFlag
                    retro.python.checkForAIsoftware(app.r);
                end

                % Determine the current directory in case of deployed app
                % One could put additional input files here
                if isdeployed
                    try
                        [~,result] = system('path');
                        app.currentPath = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                        app.TextMessage(strcat("Path: ",app.currentPath));
                    catch
                        app.currentPath = '';
                    end
                end

                % Read the graddelays.txt file
                retro.io.readGradDelays(app.r);

                % Set the import directories
                app.SetMRDimportPathFcn;

                % The final MATLAB version: no version check, since later versions are the
                % Python apps (the owner, 2026-10-02). CheckVersionFnc is kept, not called
                app.TextMessage("This is the final MATLAB version of Retrospective (" + app.appVersion + ") ...", 1);
                app.TextMessage("Later versions are Python apps: https://github.com/Moby1971/MRI-apps ...", 1);

                % No parallel pool is started. Until 2026-09-23 one was, of maxNumCores worker
                % processes, and eight of them took about 4.5 GB while they waited for three
                % loops: the absolute binning and the 3D Tukey filter, both vectorized since, and
                % the NLMF denoising, which uses a pool when one is open and otherwise runs here
                % with every core in the filter

                % Set buttons
                app.setEnable({'LoadMRDButton', 'ExitButton'}, true);
                app.SetStatus(0);
                if ~app.closeRequested
                    figure(app.UIFigure);
                end
                app.TextMessage("Initialization completed ...");
                app.TextMessage("Ready ...");

            catch ME

                % The window was closed during initialization. Every drawnow -- in
                % TextMessage and in StopAllMoviesFcn -- dispatches the queued click on
                % the window's X, UIFigureCloseRequest deletes the app, and the next
                % statement here runs against a deleted handle. There is nothing left
                % to initialize, so stop quietly. Anything else is a real fault and is
                % rethrown, including an invalid handle while the app itself is alive.
                if isvalid(app) || ~strcmp(ME.identifier, 'MATLAB:class:InvalidHandle')
                    rethrow(ME)
                end

            end

        end

        % Selection changed function: ColorsButtonGroup
        function ColorsButtonGroupSelectionChanged(app, event)

            % Purpose:
            %   Apply the colour scheme the user picked.
            %
            % How it works:
            %   Each scheme is a list of eleven colours in the order SetAppColorsFcn unpacks
            %   them, built from the named colours the app already holds so the schemes share
            %   one palette rather than each inventing its own.
            %
            %   Only the list is chosen here; applying it is SetAppColorsFcn, so adding a
            %   scheme is one case in this switch and nothing else.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            selectedButton = app.ColorsButtonGroup.SelectedObject;

            switch lower(selectedButton.Text)

                case "classic"

                    guiColors = { ...
                        app.guiGrey, ...                % backgroundColor
                        app.guiBlack, ...               % fontColor
                        app.guiDarkGrey, ...            % boxColor
                        app.blue, ...                   % gaugeColor
                        app.guiWhite, ...               % gaugeBackgroundColor
                        app.guiWhite, ...               % figureColor
                        app.guiMiddleGrey, ...          % movieFigureColor
                        app.guiDarkGrey, ...            % gridColor
                        app.lightblue, ...              % autoRecoColor
                        app.guiGrey, ...                % manualRecoColor
                        app.guiBlack ...                % button font color
                        };

                case "black/white"

                    guiColors = { ...
                        app.guiBlack, ...               % backgroundColor
                        app.guiWhite, ...               % fontColor
                        app.guiDarkGrey, ...            % boxColor
                        app.blue, ...                   % gaugeColor
                        app.guiDarkGrey, ...            % gaugeBackgroundColor
                        app.guiGrey, ...                % figureColor
                        app.guiDarkGrey, ...            % movieFigureColor
                        app.guiDarkGrey, ...            % gridColor
                        app.guiBlackBlue, ...           % autoRecoColor
                        app.guiBlack, ...               % manualRecoColor
                        app.guiBlack ...                % button font color
                        };

            end

            app.SetAppColorsFcn(guiColors);

        end

        % Callback function: DataFile, LoadMRDButton
        function LoadMRDfileButtonPushed(app, event)

            % Purpose:
            %   Load an MRD file and everything that comes with it, and bring the interface
            %   into the state that data allows.
            %
            % How it works:
            %   The longest path in the app, and it runs in a fixed order because each step
            %   depends on the one before.
            %
            %   First the interface is put into a known state: movies stopped, lamps cleared,
            %   every button disabled, status busy. That happens before anything is read, so
            %   a failure part way through cannot leave controls offering steps against
            %   half-loaded data.
            %
            %   A fresh retroData object is then built, since loading a new scan must not
            %   inherit anything from the previous one.
            %
            %   The data is read, the acquisition classified, and the validity checks run --
            %   enough k-lines, one average, a non-zero TR, a single slab. Those set
            %   validDataFlag, and the rest of the interface follows from it.
            %
            %   What is offered afterwards is then decided from what was actually loaded: the
            %   navigator sources present, the reconstruction presets that suit the type, the
            %   export formats that apply. That is why the button groups are enabled from the
            %   data rather than unconditionally.
            %
            %   The file is chosen before any of that, unless automatic reconstruction hands
            %   it over: a cancelled choice leaves the scan that is open, and the interface,
            %   as they were.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Choose the file, unless automatic reconstruction has one
            if ~app.AutoRecoCheckBox.Value
                f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                [selectedFile, selectedPath] = uigetfile(strcat(app.mrdImportPath,filesep,{'*.mrd;*.orig;*.job0'}));
                delete(f);
                if ~app.closeRequested
                    figure(app.UIFigure);  % Put the app back in the foreground
                end
                if ~ischar(selectedFile)
                    app.TextMessage('User cancel ...');
                    return
                end
            end

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SetLightsFcn(0);
            app.SwitchOffAllButtonsFcn;
            app.Lamp1.Color = app.yellow;
            app.SetStatus(3);

            % Reset object of class retro
            clear app.r;
            app.r = retroData(app);
            tempPath = app.mrdImportPath;

            % Reset flags & some parameters
            app.validFilterFlag = false;
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.validSenseMapFlag = false;
            app.validSegmentationFlag = false;
            app.fidNavFlag = false;
            app.extFidNavFlag = false;
            app.extEchoNavFlag = false;
            app.orientationsFlag = true;
            app.showExtNavFlag = false;
            app.HeartNavFlipSwitch.Value = 'Up';
            app.RespNavFlipSwitch.Value = 'Up';
            app.ProgressGauge.Value = 0;
            cla(app.TrajectoryLargeFig);
            app.ReconstructionTabGroup.SelectedTab = app.RecoTab;
            app.ParametersTabGroup.SelectedTab = app.SequenceTab;
            app.NavTabGroup.SelectedTab = app.NavTab;
            app.DiscardEditField.Value = app.jsonPar.defaultDiscard;
            app.DiscardCheckBox.Value = 0;
            drawnow;

            try

                % Select a DATA file automatically or manually
                if app.AutoRecoCheckBox.Value

                    [filePath,name,ext] = fileparts(app.mrdAutoFile);
                    app.mrdImportPath = filePath;
                    app.mrdFileName = strcat(name,ext);
                    if ~endsWith(app.mrdImportPath, filesep)
                        app.mrdImportPath = [app.mrdImportPath filesep];
                    end

                else

                    % The file chosen above
                    app.mrdFileName = selectedFile;
                    app.mrdImportPath = selectedPath;

                end

                % Import the data
                % File selected
                app.mrdFile = [app.mrdImportPath, app.mrdFileName];

                if strcmp(app.mrdFileName,"fid.orig") || strcmp(app.mrdFileName,"rawdata.job0")

                    % ------------------------------------------------------------------------
                    % B-type scanner data import
                    % ------------------------------------------------------------------------
                    [app.r, data, parameters] = app.r.importB(app);
                    app.DataFile.Value = app.mrdFileName;
                    app.r.rprFlag = false;

                    % The scan is named for its exports by its experiment folder, as an MR
                    % Solutions scan is by its file; the tag of the scan loaded before would
                    % otherwise name this one's exports
                    [~, app.tag] = fileparts(fileparts(app.mrdFile));

                    % Construct the retro parameter class, which then holds the only reference to
                    % the data, so that the reordered copy acquisitionType makes does not leave the
                    % data as read alive beside it
                    app.r = retroData(app, parameters, data, data);
                    clear data;

                    % Check if the data is a valid file with a motion navigator acquisition
                    app.r = app.r.acquisitionType(app);

                else

                    % ------------------------------------------------------------------------
                    % MR Solutions data import
                    % ------------------------------------------------------------------------
                    % The coils are <scan>_<coil>.MRD: the underscore belongs to the
                    % pattern, or scan 1234 took 12345's files as coils too; a name
                    % without one is the file alone; the extension is the file's own, so
                    % a .mrd is found on a case-sensitive disk (found by the Python ports:
                    % T2mapping F40, ADCmapping A16; 2026-09-30)
                    [~, ~, mrdExt] = fileparts(app.mrdFileName);
                    if contains(app.mrdFileName,'_')
                        mrdStart = extractBefore(app.mrdFileName,'_');
                        fList = dir(fullfile(app.mrdImportPath,[mrdStart,'_*',mrdExt]));
                    else
                        fList = dir(fullfile(app.mrdImportPath,app.mrdFileName));
                    end

                    % Check whether the filelist contains already reconstructed MRD file(s), remove these from the list
                    if ~isempty(fList)
                        for coil = length(fList):-1:1
                            if contains(fList(coil).name,app.r.ignoreFiles)
                                fList(coil) = [];
                            end
                        end
                    end

                    if ~isempty(fList)

                        % Filename tag
                        app.DataFile.Value = fList(1).name;
                        [~,app.tag,~] = fileparts([fList(1).folder,filesep,fList(1).name]);

                        % Load the data
                        app.TextMessage('Loading k-space data ...');
                        nrCoils = length(fList);
                        data = cell(1, nrCoils);
                        udata = cell(1, nrCoils);
                        for coil = 1:nrCoils
                            app.TextMessage(strcat("Loading coil ",num2str(coil)," ..."));
                            [data{coil},dimData,parameters,udata{coil}] = retro.io.importMRD(fullfile(fList(coil).folder, fList(coil).name), 'seq', 'seq');
                        end
                        parameters.dimData = dimData;
                        parameters.coil_scaling = ones(nrCoils,1);      % Assume coil scaling is 1 for all coils
                        parameters.nr_coils = nrCoils;                  % Number of RF receiver coils

                        % Construct the retro parameter class, which then holds the only references to
                        % the data. Kept here as well until this function returns, they would keep the
                        % data as read alive beside the reordered copy acquisitionType makes, and the
                        % unsorted copy it drops for anything but ZTE
                        app.r = retroData(app, parameters, data, udata);
                        clear data udata;

                        % Read the full MRD file footer

                        mrdFooterFileName = fullfile(fList(1).folder, fList(1).name);
                        app.r = app.r.readMrdFooter(mrdFooterFileName);

                        % Read the RPR file
                        rprFolder = fList(1).folder;
                        app.r = app.r.readRPRfile(app, rprFolder);

                        % Read the SQL file
                        sqlFilename = [];
                        sqlFilenameList = dir(fullfile([fList(1).folder, filesep, '*.SQL']));
                        if ~isempty(sqlFilenameList)
                            sqlFilename = [sqlFilenameList(1).folder, filesep, sqlFilenameList(1).name];
                        end
                        app.r = app.r.readSQLfile(sqlFilename);

                        % Check if the data is a valid file with a motion navigator acquisition
                        app.r = app.r.acquisitionType(app);

                    end

                end

                % ------------------------------------------------------------------------
                % Some additional checks on the data
                % ------------------------------------------------------------------------

                % Check if there are sufficient number of experiments
                app.r = app.r.checkNumberOfExperiments();

                % Check for averaging
                app.r = app.r.checkNumberOfAverages();

                % 3D multi-slab data is not supported
                app.r = app.r.checkForMultiSlab();

                % Nor is a Cartesian phase encoding order without a trajectory
                app.r = app.r.checkPhaseEncodingOrder();

                % Determine whether 3D data has multiple flip-angles
                app.r = app.r.checkForVFA();

                % Determine whether data has multiple echos
                app.r = app.r.checkForMultiEcho();

                % Check that TR is not equal to zero
                app.r = app.r.checkTR();

                % Try to get some parameters from the SQL file if present
                app.r = app.r.sqlParameters();
                app.r = app.r.imageOrientLabels();

            catch ME

                % If something unknown goes wrong during reading of the data,
                % report the error to the app
                app.TextMessage(ME.message,2);
                app.r.validDataFlag = false;

            end

            if app.r.validDataFlag

                % Initialize
                % A new data set starts from zero gradient delays: a calibration belongs
                % to the scan it was measured on, and carrying it over to the next one
                % silently is not what anybody means to do.
                app.GxDelayEditField.Value = 0;
                app.GyDelayEditField.Value = 0;
                app.GzDelayEditField.Value = 0;
                app.DataParametersFcn;                                                      % Set initial data parameters
                app.SetSpeciesFcn;                                                          % Set initial filtering values for mouse/rat
                app.RespToggleCheckBox.Value = 0;                                           % Set reconstruction to expiration
                app.IgnoreRespToggleCheckBox.Value = 0;                                     % Switch ignore respiration off
                app.r = app.r.determineFlashVersion();                                   % Determine flash sequence version
                app.SetNavigatorSelectionFcn;
                app.NavButtonGroupSelectionChanged;
                app.r = app.r.autoDiscardNav();                                          % Automatically set the number of discarded points

                % Reset buttons
                app.SetLightsFcn(1);
                app.NavViewingButtonsFcn(false);
                app.CineRecoButtonsFcn(false);
                app.MovieButtonsFcn(false);
                app.NavSelButtonsFcn(true);
                app.SetStatus(0);
                app.SetDashboardButtonsFcn(131);

            else

                % Reset buttons: Load and Exit, and the automatic reconstruction box with
                % Load, so an automatic reconstruction can still be switched off
                app.SetDashboardButtonsFcn(129);
                app.Lamp1.Color = app.brightred;
                app.NavSelButtonsFcn(false);
                app.NavViewingButtonsFcn(false);
                app.CineRecoButtonsFcn(false);
                app.MovieButtonsFcn(false);
                app.SetStatus(2);

                % Reset some parameters
                app.mrdImportPath = tempPath;
                app.mrdAutoImportPath = tempPath;
                app.SetMRDimportPathFcn;

                % Error message
                app.TextMessage('ERROR: Not a valid data file ...');

            end

        end

        % Value changed function: DiscardCheckBox, DiscardEditField, 
        % ...and 6 other components
        function NavPointEditFieldValueChanged(app, event)

            % Purpose:
            %   Take a change to the navigator sample positions and re-derive everything that
            %   depends on it.
            %
            % How it works:
            %   The point counts are clamped to their end points first: a navigator cannot
            %   use more samples than precede its end, and the clamp is here rather than at
            %   the field limits because the two fields constrain each other.
            %
            %   The values are then written onto the object and the navigator is extracted,
            %   filtered and replotted, because moving the navigator point changes the signal
            %   itself rather than just the display.
            %
            %   Movies are stopped first, since they draw from arrays this invalidates.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop all movies
            try

                app.StopAllMoviesFcn;

                % Update the used navigator point and the number of navigator points used
                app.NavNRPoints1EditField.Value(app.NavNRPoints1EditField.Value > app.NavEnd1EditField.Value) = app.NavEnd1EditField.Value;
                app.NavNRPoints2EditField.Value(app.NavNRPoints2EditField.Value > app.NavEnd2EditField.Value) = app.NavEnd2EditField.Value;

                app.r.primaryNavigatorPoint1 = app.NavEnd1EditField.Value;
                app.r.primaryNavigatorPoint2 = app.NavEnd2EditField.Value;
                app.r.nrNavPointsUsed1 = app.NavNRPoints1EditField.Value;
                app.r.nrNavPointsUsed2 = app.NavNRPoints2EditField.Value;
                app.r.nrNavPointsDiscarded = app.DiscardEditField.Value;

                % Update the data parameters
                app.DataParametersFcn;

                % Switch the data-windows off
                app.IncludeCheckBox.Value = false;
                app.ExcludeCheckBox.Value = false;
                app.PlotDataWindowFcn;

                % Determine the value of the switch to flip the navigator
                app.r = app.r.readFlipSwitch(app);

                % Extract the navigator signals
                app.r = app.r.extractNavigator(app);

                % Plot the raw navigator signal
                app.PlotRawNavigatorFcn;

                % Determine and plot the power spectrum of the raw navigator signal
                app.CalculateNavigatorSpectrumFcn;
                app.PlotNavigatorSpectrumFcn;

                % Plot or clear the other navigator graphs
                app.PlotHeartNavigatorFcn;
                app.PlotRespirationNavigatorFcn;
                cla(app.HeartRateTimeFig);
                cla(app.RespRateTimeFig);

                % Reset buttons
                app.SetLightsFcn(1);
                app.SwitchOffAllButtonsFcn;
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.ProgressGauge.Value = 0;

            catch ME

                % Without this the whole interface stayed disabled. This callback switches
                % the buttons off before it starts and switches them back on only at the end
                % of a path an exception skips, so an error here left nothing clickable and
                % the app had to be restarted.
                app.TextMessage(ME.message, 2);
                app.TextMessage('ERROR: Something went wrong while reading the navigator points ...');

                % Reset buttons
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.SetStatus(2);

            end

        end

        % Selection changed function: Nav1ButtonGroup, Nav2ButtonGroup
        function NavButtonGroupSelectionChanged(app, event)

            % Purpose:
            %   Switch to a different navigator source and set the sample positions that suit
            %   it.
            %
            % How it works:
            %   Each source sits in a different place in the readout, so changing it changes
            %   where the navigator samples are. The positions are recomputed here rather
            %   than left for the user to fix, so the new source arrives usable.
            %
            %   The arithmetic differs by data type and by source: an external echo is
            %   centred in its readout, which is why its primary point is the midpoint plus
            %   one, while an FID starts at the beginning.
            %
            %   The navigator is then re-extracted, filtered and replotted, as when the
            %   points themselves change.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop all movies
            try

                app.StopAllMoviesFcn;

                % Reset the navigator points
                switch app.r.dataType

                    case {'2D','2Dms'}

                        NoNavSamples1 = max([app.ExtFID1Button.Value*app.r.extNavFidNoSamples app.ExtECHO1Button.Value*app.r.extNavEchoNoSamples app.SliceFID1Button.Value*app.r.noSamplesNav]);
                        NoNavSamples2 = max([app.ExtFID2Button.Value*app.r.extNavFidNoSamples app.ExtECHO2Button.Value*app.r.extNavEchoNoSamples app.SliceFID2Button.Value*app.r.noSamplesNav]);
                        if app.ExtECHO1Button.Value
                            app.r.primaryNavigatorPoint1 = round(NoNavSamples1/2)+1;
                            app.r.nrNavPointsUsed1 = 1;
                        else
                            app.r.primaryNavigatorPoint1 = NoNavSamples1;
                            app.r.nrNavPointsUsed1 = 5;
                        end
                        if app.ExtECHO2Button.Value
                            app.r.primaryNavigatorPoint2 = round(NoNavSamples2/2)+1;
                            app.r.nrNavPointsUsed2 = 1;
                        else
                            app.r.primaryNavigatorPoint2 = NoNavSamples2;
                            app.r.nrNavPointsUsed2 = 5;
                        end

                    case {'3D','3Dp2roud'}

                        NoNavSamples1 = max([app.ExtFID1Button.Value*app.r.extNavFidNoSamples app.ExtECHO1Button.Value*app.r.extNavEchoNoSamples app.SliceFID1Button.Value*app.r.noSamplesNav]);
                        NoNavSamples2 = max([app.ExtFID2Button.Value*app.r.extNavFidNoSamples app.ExtECHO2Button.Value*app.r.extNavEchoNoSamples app.SliceFID2Button.Value*app.r.noSamplesNav]);
                        if app.ExtECHO1Button.Value
                            app.r.primaryNavigatorPoint1 = round(NoNavSamples1/2)+1;
                            app.r.nrNavPointsUsed1 = 1;
                        else
                            app.r.primaryNavigatorPoint1 = NoNavSamples1;
                            app.r.nrNavPointsUsed1 = 5;
                        end
                        if app.ExtECHO2Button.Value
                            app.r.primaryNavigatorPoint2 = round(NoNavSamples2/2)+1;
                            app.r.nrNavPointsUsed2 = 1;
                        else
                            app.r.primaryNavigatorPoint2 = NoNavSamples2;
                            app.r.nrNavPointsUsed2 = 5;
                        end

                    case {'2Dradial','2Dradialms'}

                        app.r.primaryNavigatorPoint1 = round(app.r.noSamples/2)+1;
                        app.r.nrNavPointsUsed1 = 1;
                        app.r.primaryNavigatorPoint2 = round(app.r.noSamples/2)+1;
                        app.r.nrNavPointsUsed2 = 1;

                    case {'3Dute','ZTE'}

                        app.r.primaryNavigatorPoint1 = 1;
                        app.r.nrNavPointsUsed1 = 1;
                        app.r.primaryNavigatorPoint2 = 1;
                        app.r.nrNavPointsUsed2 = 1;

                end

                % Show the external navigator outline if one of the ext navs is selected
                if app.ExtECHO1Button.Value || app.ExtECHO2Button.Value || app.ExtFID1Button.Value || app.ExtFID2Button.Value
                    app.showExtNavFlag = true;
                end

                % Check that nr of navpoints is not bigger than the available navigator points
                app.r.nrNavPointsUsed1(app.r.nrNavPointsUsed1 > app.r.primaryNavigatorPoint1) = app.r.primaryNavigatorPoint1;
                app.r.nrNavPointsUsed2(app.r.nrNavPointsUsed2 > app.r.primaryNavigatorPoint2) = app.r.primaryNavigatorPoint2;

                % Update the data parameters
                app.DataParametersFcn;

                % Switch the data-windows off
                app.IncludeCheckBox.Value = false;
                app.ExcludeCheckBox.Value = false;
                app.PlotDataWindowFcn;

                % Determine the value of the switch to flip the navigator
                app.r = app.r.readFlipSwitch(app);

                % Extract the navigator signals
                app.r = app.r.extractNavigator(app);

                % Plot the raw navigator signal
                app.PlotRawNavigatorFcn;

                % Determine and plot the power spectrum of the raw navigator signal
                app.CalculateNavigatorSpectrumFcn;
                app.PlotNavigatorSpectrumFcn;

                % Plot or clear the other navigator graphs
                app.PlotHeartNavigatorFcn;
                app.PlotRespirationNavigatorFcn;
                cla(app.HeartRateTimeFig);
                cla(app.RespRateTimeFig);

                % Reset buttons
                app.SetLightsFcn(1);
                app.SwitchOffAllButtonsFcn;
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.ProgressGauge.Value = 0;

            catch ME

                % Without this the whole interface stayed disabled. This callback switches
                % the buttons off before it starts and switches them back on only at the end
                % of a path an exception skips, so an error here left nothing clickable and
                % the app had to be restarted.
                app.TextMessage(ME.message, 2);
                app.TextMessage('ERROR: Something went wrong while changing the navigator selection ...');

                % Reset buttons
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.SetStatus(2);

            end

        end

        % Button pushed function: StartMotionButton
        function StartRawNavButtonPushed(app, event)

            % Purpose:
            %   Play the raw navigator readouts as a movie, so the navigator can be judged
            %   against the readout it is taken from.
            %
            % How it works:
            %   Other movies are stopped first, since they share the axes and the stop flags.
            %
            %   The loop draws one readout at a time, starting from the middle of the
            %   acquisition, and checks its stop flag each pass -- which is how the stop
            %   button ends it, rather than by interrupting.
            %
            %   It is wrapped, because the app can be closed while the movie is running and
            %   the axes it draws into then no longer exist.
            %
            %   What this shows is where the navigator point sits within the readout, which
            %   is what the sample position fields are set against.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop all movies
            app.StopAllMoviesFcn;
            app.TextMessage("Starting the raw navigator movie ...");

            % Navigator movie is running
            app.stopRawNavMovieFlag = false;

            try

                % To catch error during exit, while movie is still running
                % Initial raw navigator values
                frame1 = round(size(app.r.rawNav1{app.HeartCoilSpinner1.Value},1)/2);
                frame2 = round(size(app.r.rawNav2{app.RespCoilSpinner1.Value},1)/2);
                maxFrame1 = size(app.r.rawNav1{app.HeartCoilSpinner1.Value},1);
                maxFrame2 = size(app.r.rawNav2{app.RespCoilSpinner1.Value},1);
                maxPlot3 = max(abs(app.r.rawNav1{app.HeartCoilSpinner1.Value}(frame1,:)));
                maxPlot4 = max(abs(app.r.rawNav2{app.RespCoilSpinner1.Value}(frame2,:)));
                tsl = app.traceLength;                                                              % Trace length
                for cnt = 1:tsl
                    alphaValues(cnt) = 1 - (cnt-1)/tsl/1.2;                                         % Trace line transparancy
                    lw(cnt) = 2 - 2*(cnt-1)/tsl;                                                    % Trace line thickness
                end

                % Trace starting values and x-coordinates
                app.navTrace3 = repelem(abs(app.r.rawNav1{app.HeartCoilSpinner1.Value}(frame1, app.NavEnd1EditField.Value)),tsl);
                app.navTrace4 = repelem(abs(app.r.rawNav2{app.RespCoilSpinner1.Value}(frame2, app.NavEnd2EditField.Value)),tsl);
                navl1 = length(app.r.rawNav1{app.HeartCoilSpinner1.Value}(frame1,:));
                navl2 = length(app.r.rawNav2{app.RespCoilSpinner1.Value}(frame2,:));
                step1 = 0.5*navl1/tsl;
                step2 = 0.5*navl2/tsl;
                x3x = app.NavEnd1EditField.Value:-step1:app.NavEnd1EditField.Value-tsl*step1+step1;
                x4x = app.NavEnd2EditField.Value:-step2:app.NavEnd2EditField.Value-tsl*step2+step2;
                step = 3;
                minTimePerNavFrame = 0.04;

                % Repeat until navigator movie is stopped
                tA = tic;
                cnt2 = 0;

                while isvalid(app) && ~app.stopRawNavMovieFlag

                    tB = tic;

                    % Stop after a while in test mode
                    if app.testMode && toc(tA)>5
                        app.StopRawNavButtonPushed;
                    end

                    % Update the trace counter
                    cnt2 = cnt2 + 1;
                    cnt2(cnt2>tsl) = tsl;

                    % Extract the part of the raw navigator FID or ECHO to be shown
                    trace3 = squeeze(abs(app.r.rawNav1{app.HeartCoilSpinner1.Value}(frame1,:)));
                    trace4 = squeeze(abs(app.r.rawNav2{app.RespCoilSpinner1.Value}(frame2,:)));

                    % Used navigator points
                    x3 = app.NavEnd1EditField.Value - app.NavNRPoints1EditField.Value + 1:app.NavEnd1EditField.Value;
                    x4 = app.NavEnd2EditField.Value - app.NavNRPoints2EditField.Value + 1:app.NavEnd2EditField.Value;

                    % Update max plots if needed
                    maxPlot3 = 0.999*maxPlot3;
                    maxPlot4 = 0.999*maxPlot4;
                    maxPlot3(maxPlot3 < max(trace3(:))) = max(trace3(:));
                    maxPlot4(maxPlot4 < max(trace4(:))) = max(trace4(:));

                    % Plot the raw navigator 1 FID or ECHO
                    if cnt2 < tsl

                        cla(app.Nav1Fig);
                        hold(app.Nav1Fig,"on");
                        cnt3 = cnt2;
                        cnt3(cnt3>(tsl-step)) = tsl-step;
                        for cnt = 1:step:cnt3
                            pt1(cnt) = plot(app.Nav1Fig, x3x(cnt:cnt+step), app.navTrace3(cnt:cnt+step), 'Color', [app.traceOrange' alphaValues(cnt)], 'LineWidth', lw(cnt));
                        end
                        p1a = plot(app.Nav1Fig, trace3, 'o-','LineWidth' ,1 , 'Color', app.blue);
                        p1b = plot(app.Nav1Fig, x3, trace3(x3), 'o', 'Color', app.red, 'MarkerFaceColor', app.green);
                        p1c = plot(app.Nav1Fig, x3(end), trace3(x3(end)), 'o', 'Color', app.red, 'MarkerFaceColor', app.traceOrange);
                        xlim(app.Nav1Fig, [0 length(trace3)+1]);
                        ylim(app.Nav1Fig, [0 1.1*maxPlot3+1]);
                        hold(app.Nav1Fig,"off");

                    else

                        % Once the full trace has been drawn, use faster method
                        set(p1a,'YData',trace3);
                        set(p1b,'XData',x3);
                        set(p1b,'YData',trace3(x3));
                        set(p1c,'XData',x3(end));
                        set(p1c,'YData',trace3(x3(end)));
                        for cnt = 1:step:tsl-step
                            set(pt1(cnt),'XData',x3x(cnt:cnt+step));
                            set(pt1(cnt),'YData',app.navTrace3(cnt:cnt+step));
                        end
                        xlim(app.Nav1Fig, [0 length(trace3)+1]);
                        ylim(app.Nav1Fig, [0 1.1*maxPlot3+1]);

                    end

                    % Plot the raw navigator 2 FID or ECHO
                    if cnt2 < tsl

                        cla(app.Nav2Fig);
                        hold(app.Nav2Fig,"on");
                        cnt3 = cnt2;
                        cnt3(cnt3>tsl-step) = tsl-step;
                        for cnt = 1:step:cnt3
                            pt2(cnt) = plot(app.Nav2Fig, x4x(cnt:cnt+step), app.navTrace4(cnt:cnt+step), 'Color', [app.traceOrange' alphaValues(cnt)], 'LineWidth', lw(cnt));
                        end
                        p2a = plot(app.Nav2Fig, trace4, 'o-','LineWidth' ,1 , 'Color', app.blue);
                        p2b = plot(app.Nav2Fig, x4, trace4(x4), 'o', 'Color', app.red, 'MarkerFaceColor', app.green);
                        p2c = plot(app.Nav2Fig, x4(end), trace4(x4(end)), 'o', 'Color', app.red, 'MarkerFaceColor', app.traceOrange);
                        xlim(app.Nav2Fig, [0 length(trace4)+1]);
                        ylim(app.Nav2Fig, [0 1.1*maxPlot4+1]);
                        hold(app.Nav2Fig,"off");

                    else

                        % Once the full trace has been drawn, use faster method
                        set(p2a,'YData',trace4);
                        set(p2b,'XData',x4);
                        set(p2b,'YData',trace4(x4));
                        set(p2c,'XData',x4(end));
                        set(p2c,'YData',trace4(x4(end)));
                        for cnt = 1:step:tsl-step
                            set(pt2(cnt),'XData',x4x(cnt:cnt+step));
                            set(pt2(cnt),'YData',app.navTrace4(cnt:cnt+step));
                        end
                        xlim(app.Nav2Fig, [0 length(trace4)+1]);
                        ylim(app.Nav2Fig, [0 1.1*maxPlot4+1]);

                    end

                    drawnow;

                    % Increase the frames
                    frame1 = frame1 + 1;
                    frame1(frame1>maxFrame1) = 1;
                    frame2 = frame2 + 1;
                    frame2(frame2>maxFrame2) = 1;

                    % Update nav trace
                    app.navTrace3 = circshift(app.navTrace3,1);
                    app.navTrace3(1) = abs(app.r.rawNav1{app.HeartCoilSpinner1.Value}(frame1, app.NavEnd1EditField.Value));
                    app.navTrace4 = circshift(app.navTrace4,1);
                    app.navTrace4(1) = abs(app.r.rawNav2{app.RespCoilSpinner1.Value}(frame2, app.NavEnd2EditField.Value));

                    % Slow movie a bit down with small number of frames
                    mvTime = toc(tB);
                    if mvTime < minTimePerNavFrame
                        pause(minTimePerNavFrame-mvTime);
                    end

                end

            catch ME

                if isvalid(app)
                    app.TextMessage(ME.message,2);
                end

            end

        end

        % Button pushed function: StopMotionButton
        function StopRawNavButtonPushed(app, event)

            % Purpose:
            %   Stop the raw navigator movie.
            %
            % How it works:
            %   Raises the stop flag the movie loop checks each pass, so the loop finishes
            %   the readout it is drawing and leaves cleanly rather than being interrupted
            %   part way through an update.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            app.TextMessage("Stopping the raw navigator movie ...");
            app.stopRawNavMovieFlag = true;
            drawnow;

        end

        % Value changed function: HeartCoilSpinner1, HeartCoilSpinner2, 
        % ...and 2 other components
        function NavCoilSpinnerValueChanged(app, event)

            % Purpose:
            %   Show the raw navigator of a different coil.
            %
            % How it works:
            %   Only the plot is redrawn. The navigator itself is extracted per coil already,
            %   so choosing a coil changes which trace is displayed and nothing about the
            %   data -- which is what makes it cheap enough to be a spinner.
            %
            %   Comparing coils is the point: they differ in how well each sees the motion,
            %   and the one to filter on is chosen by looking.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Plot the raw navigator signal

            app.PlotRawNavigatorFcn;

        end

        % Value changed function: HeartEditField, HeartWidthEditField, 
        % ...and 3 other components
        function FilterEditFieldValueChanged(app, event)

            % Purpose:
            %   Take a change to the filter settings and refilter the navigator.
            %
            % How it works:
            %   validFilterFlag is cleared first, so anything downstream that depends on the
            %   filtered navigator knows it is out of date before the new filtering starts.
            %
            %   Each bandwidth is then clamped to its rate. A band wider than the rate it is
            %   centred on reaches down through zero frequency and stops selecting a band at
            %   all, so the clamp is what keeps the setting meaningful rather than merely
            %   in range.
            %
            %   The values are written onto the object and the navigator refiltered, since a
            %   filter setting changes the signal every later step is built on.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Reset the valid filter flag to false

            app.validFilterFlag = false;

            % Limit the filter width values.
            %
            % Held at the width field's own lower limit as well as at the rate. A
            % rate below that limit is allowed -- the rate fields start at 1 bpm,
            % the width fields at 20 and 5 -- so clamping to the rate alone asks
            % the width field for a value it refuses, and the assignment raises
            % rather than clamping. Because several fields share this callback,
            % the app then throws on every later edit in the panel until the rate
            % is put back up.
            app.HeartWidthEditField.Value(app.HeartWidthEditField.Value > app.HeartEditField.Value) = ...
                max([app.HeartWidthEditField.Limits(1) app.HeartEditField.Value]);
            app.RespWidthEditField.Value(app.RespWidthEditField.Value > app.RespEditField.Value) = ...
                max([app.RespWidthEditField.Limits(1) app.RespEditField.Value]);

            % Get the filter values from the app
            app.r.detectedHR = app.HeartEditField.Value;
            app.r.bandwidthHR = app.HeartWidthEditField.Value;
            app.r.detectedRR = app.RespEditField.Value;
            app.r.bandwidthRR = app.RespWidthEditField.Value;
            app.r.respPercentage = app.RespWindowEditField.Value;

            % Filter the navigator data
            app.FilterButtonPushed;

            % Plot the navigator spectrum
            app.PlotNavigatorSpectrumFcn;

        end

        % Button pushed function: ResetFilterValuesButton
        function ResetFilterValuesButtonPushed(app, event)

            % Purpose:
            %   Put the filter settings back to the presets for the selected species and
            %   refilter.
            %
            % How it works:
            %   The species presets are reloaded, the spectrum recomputed, the navigator
            %   refiltered and the spectrum redrawn -- the same sequence a species change
            %   runs, which is why several other callbacks simply call this one.
            %
            %   This is the way back when hand tuning has gone wrong: it restores a known
            %   starting point rather than an empty one.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Reset filter values

            app.validFilterFlag = false;
            app.SetSpeciesFcn;
            app.CalculateNavigatorSpectrumFcn;
            app.FilterButtonPushed;
            app.PlotNavigatorSpectrumFcn;

        end

        % Selection changed function: SpeciesButton
        function SpeciesButtonSelectionChanged(app, event)

            % Purpose:
            %   Load the presets for the newly selected species.
            %
            % How it works:
            %   Delegates to ResetFilterValuesButtonPushed, which reloads the presets and
            %   refilters. Changing species and resetting the filter values are the same
            %   operation -- the presets are per species -- so they share one implementation.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Execute the reset filter values function

            app.ResetFilterValuesButtonPushed;

        end

        % Button down function: SpeciesButton
        function SpeciesButtonButtonDown(app, event)

            % Purpose:
            %   Load the species presets when the button is pressed rather than selected.
            %
            % How it works:
            %   The same delegation as SpeciesButtonSelectionChanged. Both exist because a
            %   press on the already-selected button raises ButtonDown and not
            %   SelectionChanged; without this, re-selecting the current species would do
            %   nothing, which is exactly when a user is trying to reset it.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Execute the reset filter values function

            app.ResetFilterValuesButtonPushed;

        end

        % Value changed function: IgnoreRespToggleCheckBox, 
        % ...and 1 other component
        function RespirationToggleCheckBoxValueChanged(app, event)

            % Purpose:
            %   Refilter when respiratory gating is switched on or off.
            %
            % How it works:
            %   Delegates to ResetFilterValuesButtonPushed. Whether respiration is being
            %   used changes what the filter settings have to achieve, so the presets are
            %   reapplied rather than the previous hand tuning being kept.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Execute the reset filter values function

            app.ResetFilterValuesButtonPushed;

        end

        % Selection changed function: RecoPresetsButtonGroup
        function RecoPresetsButtonGroupSelectionChanged(app, event)

            % Purpose:
            %   Load the reconstruction preset for the kind of scan selected.
            %
            % How it works:
            %   SetRecoParametersFcn loads the values, then CinePanelValueChanged brings the
            %   panel into a state consistent with them -- the preset changes frame and
            %   dynamic counts, and what those allow has to follow.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Update the reconstruction parameters

            app.SetRecoParametersFcn;
            app.CinePanelValueChanged;

        end

        % Button down function: RecoPresetsButtonGroup
        function RecoPresetsButtonGroupButtonDown(app, event)

            % Purpose:
            %   Load the reconstruction preset when the button is pressed rather than
            %   selected.
            %
            % How it works:
            %   Delegates to RecoPresetsButtonGroupSelectionChanged, for the same reason as
            %   the species pair: pressing the already-selected preset raises ButtonDown
            %   only, and that is when a user means to reload it.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Execute the reco preset change function

            app.RecoPresetsButtonGroupSelectionChanged;

        end

        % Value changed function: AutoRecoCheckBox
        function AutoRecoCheckBoxValueChanged(app, event)

            % Purpose:
            %   Start or stop the folder watcher that reconstructs new scans automatically.
            %
            % How it works:
            %   Ticking it stops any running movie, starts the watcher on the import folder,
            %   and switches the window to the automatic colour so the mode is visible at a
            %   glance -- an app that reconstructs on its own must not look like one waiting
            %   for input.
            %
            %   Sounds are switched off with it and back on when it stops, since an unattended
            %   run would otherwise sound the status tone for every scan that arrives.
            %
            %   Unticking stops the watcher and restores both.
            %
            %   Wrapped, so a watcher that cannot be started leaves the interface consistent
            %   rather than half switched: such a watcher unticks the box again, and the
            %   sounds come back with it.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            try
                if app.AutoRecoCheckBox.Value
                    app.StopMovieButtonPushed;
                    app.AutoSoundsCheckBox.Value = 0;
                    app.SetMRDimportPathFcn;
                    mrdAutoRecoManager(app, 'start');
                    if ~app.AutoRecoCheckBox.Value
                        app.AutoSoundsCheckBox.Value = 1;
                    end
                else
                    mrdAutoRecoManager(app, 'stop');
                    app.AutoSoundsCheckBox.Value = 1;
                    app.UIFigure.Color = app.manualRecoColor;
                    app.SetStatus(0);
                end
            catch ME
                app.TextMessage("Error during auto reconstruction ...",2);
                app.TextMessage(ME.message,2);
                if ~app.AutoRecoCheckBox.Value
                    app.AutoSoundsCheckBox.Value = 1;
                end
            end

        end

        % Value changed function: FilterOrderEditField, 
        % ...and 5 other components
        function FilterSettingsChanged(app, event)

            % Purpose:
            %   Take a change to the rate search limits and redo the detection.
            %
            % How it works:
            %   Each minimum is clamped to its maximum first, so the search range cannot be
            %   inverted -- a range with its ends crossed finds nothing and reports a rate of
            %   zero, which then propagates into the binning.
            %
            %   The spectrum is then recomputed, the navigator refiltered and the spectrum
            %   redrawn, because the limits decide which peak is taken as the rate.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Limit the values, min cannot be larger than max value
            app.MinRespRateEditField.Value(app.MinRespRateEditField.Value > app.MaxRespRateEditField.Value) = app.MaxRespRateEditField.Value;
            app.MinHeartRateEditField.Value(app.MinHeartRateEditField.Value > app.MaxHeartRateEditField.Value) = app.MaxHeartRateEditField.Value;

            % Calculate the new spectrum, filter the navigator, plot the spectrum
            app.CalculateNavigatorSpectrumFcn;
            app.FilterButtonPushed;
            app.PlotNavigatorSpectrumFcn;

        end

        % Button pushed function: HeartLogLinButton
        function HeartLogLinButtonPushed(app, event)

            % Purpose:
            %   Switch the cardiac spectrum between linear and logarithmic display.
            %
            % How it works:
            %   The button text carries the state and is toggled, then the spectrum is
            %   redrawn; PlotNavigatorSpectrumFcn reads that text to decide which scale to
            %   draw.
            %
            %   Both views are worth having. The log view makes a weak cardiac peak visible
            %   against the low frequency drift that dominates the spectrum; the linear view
            %   is the one to judge relative peak heights on.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Switch between linear or logarithmic scale
            if strcmp(app.HeartLogLinButton.Text,'log')
                app.HeartLogLinButton.Text = 'lin';
            else
                app.HeartLogLinButton.Text = 'log';
            end

            % Replot the spectrum
            app.PlotNavigatorSpectrumFcn;

        end

        % Button pushed function: RespLogLinButton
        function RespLogLinButtonPushed(app, event)

            % Purpose:
            %   Switch the respiratory spectrum between linear and logarithmic display.
            %
            % How it works:
            %   The respiratory counterpart of HeartLogLinButtonPushed, on its own button and
            %   its own axes, so the two spectra can be viewed on different scales at the
            %   same time.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Switch between linear or logaritmic scale
            if strcmp(app.RespLogLinButton.Text,'log')
                app.RespLogLinButton.Text = 'lin';
            else
                app.RespLogLinButton.Text = 'log';
            end

            % Replot the spectrum
            app.PlotNavigatorSpectrumFcn;

        end

        % Value changed function: HeartMaxEditField, HeartMinEditField, 
        % ...and 2 other components
        function SpectrumEditFieldValueChanged(app, event)

            % Purpose:
            %   Take a change to the spectrum display limits and keep them usable.
            %
            % How it works:
            %   Each minimum is held at least ten below its maximum, so the range cannot
            %   collapse to nothing or invert -- either leaves an axis on which no peak can
            %   be seen and a search that finds nothing.
            %
            %   A negative minimum is reset to a small positive range instead of being
            %   clamped to zero alone, since a spectrum starting at zero is dominated by the
            %   drift term and hides the peaks entirely.
            %
            %   The spectrum is redrawn afterwards, the limits being what it is drawn over.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Keep values between limits
            min1 = app.HeartMinEditField.Value;
            max1 = app.HeartMaxEditField.Value;
            min1(min1 > max1-10) = max1 - 10;
            if min1 < 0
                min1 = 0;
                max1 = 10;
            end
            app.HeartMinEditField.Value = min1;
            app.HeartMaxEditField.Value = max1;
            min2 = app.RespMinEditField.Value;
            max2 = app.RespMaxEditField.Value;
            min2(min2 > max2-10) = max2 - 10;
            if min2 < 0
                min2 = 0;
                max2 = 10;
            end
            app.RespMinEditField.Value = min2;
            app.RespMaxEditField.Value = max2;

            % Plot the spectrum with the new axes
            app.PlotNavigatorSpectrumFcn;

        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)

            % Purpose:
            %   Filter the navigator: combine the coils, isolate the two motions, and find
            %   the triggers.
            %
            % How it works:
            %   The interface is put into its busy state first -- movies stopped, lamps
            %   cleared, buttons disabled -- so nothing offers a step against a navigator
            %   that is being rebuilt.
            %
            %   Then the sequence the rest of the pipeline depends on: the navigator is
            %   extracted for the selected source, the coils combined by PCA, the cardiac and
            %   respiratory bands filtered out, the trigger points found and the rates
            %   computed from them.
            %
            %   The plots are redrawn at the end, because they are how the result is judged:
            %   a trigger on every beat and none between them is the check that the settings
            %   are right.
            %
            %   validFilterFlag is what the sort and reconstruct steps test, so it is set only
            %   once all of that has succeeded.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SetLightsFcn(1);
            app.Lamp2.Color = app.yellow;
            app.SetStatus(3);
            app.SwitchOffAllButtonsFcn;

            % Message the app
            app.TextMessage('Filtering the navigator ...');

            % Reset some parameters
            app.validFilterFlag = false;
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.validSenseMapFlag = false;
            app.ProgressGauge.Value = 0;

            try

                % Filter twice
                for cnt = 1:2

                    % Filter settings from app
                    app.r.physioFilterSettings = [app.MinHeartRateEditField.Value ...
                        app.MaxHeartRateEditField.Value app.MinRespRateEditField.Value ...
                        app.MaxRespRateEditField.Value app.FilterOrderEditField.Value app.RespShiftEditField.Value];

                    % Filter for cardiac and respiratory motion
                    app.r = app.r.filterNavPCA(app);
                    app.RespEditField.Value = app.r.detectedRR;

                    % Extract cardiac trigger points
                    app.r = app.r.trigPointsHeart();

                    % Calculate heart-time curves, trend-lines, and mean heart-rate
                    app.r = app.r.calcHeartRate();
                    app.FinalHeartViewField.Value = app.r.meanHeartRate;
                    app.HeartEditField.Value = app.r.meanHeartRate;

                    % Extract respiratory trigger points
                    app.r = app.r.trigPointsResp();

                    % Calculate resp-time curves, trend-lines, and mean resp-rate
                    app.r = app.r.calcRespRate();
                    app.FinalRespViewField.Value = app.r.meanRespRate;

                end

                % Make the respiration exclusion windows
                app.r = app.r.makeRespWindow(app);

                % Let the app know the nav data is filtered
                app.validFilterFlag = true;

                % Plot the datawindow in the time-heartrate and time-respirationrate plots and datawindow
                app.PlotRatesFcn;
                app.PlotRateLimitsFcn;
                app.PlotDataWindowFcn;

                % Show the samples-navigator plots
                app.PlotHeartNavigatorFcn;
                app.PlotRespirationNavigatorFcn;

                % Reset buttons
                app.SetLightsFcn(3);
                app.SetDashboardButtonsFcn(135);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(false);
                app.SetStatus(0);

            catch ME

                % Error message
                app.TextMessage(ME.message,2);
                app.TextMessage('ERROR: Something went wrong during filtering of the navigator ...')

                % Let the app know the nav data is not filtered
                app.validFilterFlag = false;

                % Reset buttons
                app.SetLightsFcn(1);
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(false);
                app.SetStatus(2);

            end

        end

        % Value changed function: RangeEditField, StartEditField
        function DisplayEditFieldValuesChanged(app, event)

            % Purpose:
            %   Take a change to the navigator display range and keep it inside the data.
            %
            % How it works:
            %   Both values are converted from seconds to k-lines through the repetition
            %   time, the navigator having no time axis of its own.
            %
            %   Three constraints are applied. The range is at least twenty repetitions, so
            %   there is something to see. It is at most 2500, because a longer stretch
            %   draws so many points that the plot becomes slow and unreadable rather than
            %   more informative. And the start is pulled back if start plus range would run
            %   past the end of the acquisition, which keeps the full range visible instead
            %   of truncating it.
            %
            %   The navigator plots and the window marker on the rate plots are then
            %   redrawn.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Get the new range values from the app
            value1 = round(app.StartEditField.Value*1000/app.r.repetitionTime);
            value2 = round(app.RangeEditField.Value*1000/app.r.repetitionTime);

            % Range should be at least 20*TR
            value2(value2<20) = 20;

            % Range should not exceed 2000, else plot gets really slow and cluttered
            value2(value2>2500) = 2500;

            % Start + range should not exceed the number of available k-lines
            if value1 + value2 > app.r.nrKlines
                value1 = app.r.nrKlines - value2;
            end

            % Report the values back to the user-interface
            app.StartEditField.Value = value1*app.r.repetitionTime/1000;
            app.RangeEditField.Value = value2*app.r.repetitionTime/1000;

            % Adjust the sliders also
            app.StartSlider.Value = app.StartEditField.Value;
            app.RangeSlider.Value = app.RangeEditField.Value;

            % Plot the raw navigator, cardiac filtered, and respiration filtered
            app.PlotRawNavigatorFcn;
            app.PlotHeartNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            app.PlotRateLimitsFcn;

        end

        % Value changed function: RangeSlider, StartSlider
        function DisplaySlidersValueChanged(app, event)

            % Purpose:
            %   Follow the display range sliders.
            %
            % How it works:
            %   The slider values are copied into the edit fields and the field handler is
            %   then called, so dragging a slider and typing a number take exactly the same
            %   path -- including the clamps, which is what stops a slider from reaching a
            %   range the fields would have refused.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Set the field values to match the sliders
            app.StartEditField.Value = app.StartSlider.Value;
            app.RangeEditField.Value = app.RangeSlider.Value;

            % Proceed as if the field values were changed
            app.DisplayEditFieldValuesChanged;

        end

        % Button pushed function: NavStartButton
        function StartNavTimeButtonPushed(app, event)

            % Purpose:
            %   Play the navigator against time as a movie, so a long acquisition can be
            %   watched rather than read in one static plot.
            %
            % How it works:
            %   Other movies are stopped first, since they share the axes and the flags.
            %
            %   The window advances through the acquisition and the navigator plots are
            %   redrawn at each step, with the stop flag checked every pass so the stop
            %   button ends it cleanly.
            %
            %   Wrapped, because the app can be closed while the movie runs and the axes it
            %   draws into then no longer exist.
            %
            %   This is how drift and intermittent artefacts are spotted: they are visible as
            %   a change over the run and not in any single window.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop all movies
            app.StopAllMoviesFcn;
            app.TextMessage('Starting the navigator - time movie ...');

            % Navigator movie is running
            app.stopNavTimeMovieFlag = false;

            try % To catch error during exit, while movie is still running

                tA = tic;

                % Repeat until navigator movie is stopped
                while isvalid(app) && ~app.stopNavTimeMovieFlag

                    if app.testMode && toc(tA)>3
                        app.StopNavTimeButtonPushed;
                    end
                    value1 = round(app.StartEditField.Value*1000/app.r.repetitionTime);
                    value2 = round(app.RangeEditField.Value*1000/app.r.repetitionTime);

                    % Add app.navMovieSpeed value to display the next frame
                    value1 = value1 + app.navMovieSpeed;

                    % At the end of the data, reset to beginning
                    if value1 + value2 > app.r.nrKlines
                        value1 = 1;
                    end

                    % Show the values in the app
                    app.StartEditField.Value = value1*app.r.repetitionTime/1000;
                    app.StartSlider.Value = value1*app.r.repetitionTime/1000;

                    % Plot the range of navigators in the 4 plots
                    app.PlotRawNavigatorFcn;
                    app.PlotHeartNavigatorFcn;
                    app.PlotRespirationNavigatorFcn;
                    app.PlotRateLimitsFcn;

                end

            catch
            end

        end

        % Button pushed function: NavStopButton
        function StopNavTimeButtonPushed(app, event)

            % Purpose:
            %   Stop the navigator-time movie and leave the plots in a settled state.
            %
            % How it works:
            %   The stop flag is raised, then all four plots are redrawn once.
            %
            %   That final redraw matters: the movie leaves the axes wherever its last frame
            %   happened to fall, and redrawing puts them back to the window the controls
            %   actually say is selected.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            app.TextMessage('Stopping the navigator - time movie ...');

            % Set the stop navigator movie flag to true
            app.stopNavTimeMovieFlag = true;

            % Plot the raw navigators, cardiac filtered, and respiration filtered
            app.PlotRawNavigatorFcn;
            app.PlotHeartNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            app.PlotRateLimitsFcn;

        end

        % Value changed function: NavFastSlowSwitch
        function NavFastSlowSwitchValueChanged(app, event)

            % Purpose:
            %   Set how fast the navigator movie advances.
            %
            % How it works:
            %   Two speeds, held as the number of k-lines the window moves per frame. The
            %   slow setting is for following the navigator beat by beat; the fast one is for
            %   covering a long acquisition in reasonable time.
            %
            %   Only the speed is set here; the running movie reads it each pass, so the
            %   change takes effect without restarting. Slow is the speed in the settings
            %   file (navMovieSpeed, which the movie starts with), fast four times that.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Navigator movie speed is slow or fast
            if app.NavFastSlowSwitch.Value == "slow"
                app.navMovieSpeed = app.jsonPar.navMovieSpeed;
            else
                app.navMovieSpeed = 4*app.jsonPar.navMovieSpeed;
            end

        end

        % Value changed function: IncludeCheckBox
        function IncludeCheckBoxValueChanged(app, event)

            % Purpose:
            %   Switch the include window on or off.
            %
            % How it works:
            %   Switching it on runs the slider handler, so the window is built from where
            %   the sliders already are rather than from nothing.
            %
            %   Switching it off resets the window to all ones -- include everything -- which
            %   is the identity for the mask, since the fill functions multiply the include
            %   and exclude windows together. Leaving the old window in place would go on
            %   excluding data with the control switched off.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Include window check-box on or off
            try

                if app.IncludeCheckBox.Value == 1

                    % Include window is on
                    % Execute IncludeSliderValueChanged function
                    app.IncludeSliderValueChanged;

                else

                    % Reset the window to zeros
                    app.r.includeWindow = ones(app.r.nrKlines,1);
                    app.PlotDataWindowFcn;
                    app.CalculateNavigatorSpectrumFcn;
                    app.PlotNavigatorSpectrumFcn;

                    % Reset buttons
                    app.SetLightsFcn(1);
                    app.SwitchOffAllButtonsFcn;
                    app.SetDashboardButtonsFcn(131);
                    app.NavSelButtonsFcn(true);
                    app.NavViewingButtonsFcn(true);
                    app.stopMovieFlag = true;
                    app.SetStatus(0);

                end

            catch ME

                % Without this the whole interface stayed disabled. This callback switches
                % the buttons off before it starts and switches them back on only at the end
                % of a path an exception skips, so an error here left nothing clickable and
                % the app had to be restarted.
                app.TextMessage(ME.message, 2);
                app.TextMessage('ERROR: Something went wrong while changing the include window ...');

                % Reset buttons
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.SetStatus(2);

            end

        end

        % Value changed function: IncludeEndSlider, IncludeStartSlider
        function IncludeSliderValueChanged(app, event)

            % Purpose:
            %   Build the include window from the two sliders.
            %
            % How it works:
            %   The values are in seconds and are constrained before use. The window is at
            %   least ten repetitions long, since anything shorter contributes almost no
            %   k-lines, and it is kept inside the acquisition -- if the end would run past
            %   it, the whole window is moved back rather than truncated, so its length is
            %   preserved.
            %
            %   The window is then converted to a per-k-line mask on the object, which is
            %   what the fill functions actually read, and drawn on the rate plots so the
            %   selection can be judged against the rates.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Read the sliders
            try

                value1 = app.IncludeStartSlider.Value;      % Start of the include-window in units of seconds
                value2 = app.IncludeEndSlider.Value;        % End of the include-window in units of seconds

                % End should be bigger than Start and length of the window should be at least 10 (arbitrary) repetitions
                if (value2 - value1) < round(10 * app.r.nrKsteps * app.r.repetitionTime / 1000)
                    value2 = value1 + round(10 * app.r.nrKsteps * app.r.repetitionTime / 1000);
                end

                % End should be smaller than total acquisition time and length of the window should be at least 10 (arbitrary) repetitions
                if value2 > app.r.acqDur
                    value2 = app.r.acqDur;
                    value1 = value2 - (10 * app.r.nrKsteps * app.r.repetitionTime / 1000);
                end

                % Set the values and sliders
                app.IncludeStartSlider.Value = value1;
                app.IncludeEndSlider.Value = value2;

                % Set the value of the include window
                app.r.includeWindow = zeros(app.r.nrKlines,1);
                value1k = round(1000*value1/app.r.repetitionTime)+1;    % Start of the window in nr k-lines
                value2k = round(1000*value2/app.r.repetitionTime);      % End of the window in nr k-lines
                value2k(value2k > app.r.nrKlines) = app.r.nrKlines;
                app.r.includeWindow(value1k:value2k) = 1;

                % Plot the datawindow in the time-heartrate and time-respirationrate plots
                app.PlotDataWindowFcn;

                % Calculate and plot the power spectrum of the raw navigator signal
                app.CalculateNavigatorSpectrumFcn;
                app.PlotNavigatorSpectrumFcn;

                % Reset buttons
                app.SetLightsFcn(1);
                app.SwitchOffAllButtonsFcn;
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.stopMovieFlag = true;

                % Check whether there is sufficient data left to do a reconstruction
                numberOfKlines = nnz(app.r.includeWindow.*app.r.excludeWindow);
                if numberOfKlines < 512
                    app.TextMessage('ERROR: Not enough k-lines left for reconstruction, adjust data windows ...');
                    app.FilterButton.Enable = 'off';
                    app.SetStatus(2);
                else
                    app.SetStatus(0);
                end

            catch ME

                % Without this the whole interface stayed disabled. This callback switches
                % the buttons off before it starts and switches them back on only at the end
                % of a path an exception skips, so an error here left nothing clickable and
                % the app had to be restarted.
                app.TextMessage(ME.message, 2);
                app.TextMessage('ERROR: Something went wrong while changing the include window ...');

                % Reset buttons
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.SetStatus(2);

            end

        end

        % Value changed function: ExcludeCheckBox
        function ExcludeCheckBoxValueChanged(app, event)

            % Purpose:
            %   Switch the exclude window on or off.
            %
            % How it works:
            %   The mirror of IncludeCheckBoxValueChanged: on runs the slider handler, off
            %   resets the mask to all ones, which for this window means excluding nothing.
            %
            %   The two windows are separate masks multiplied together, so each has to be
            %   neutral rather than empty when it is switched off.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Exclude window check-box on or off
            try

                if app.ExcludeCheckBox.Value == 1

                    % Exclude window is on
                    % Execute ExcludeSliderValueChanged function
                    app.ExcludeSliderValueChanged;

                else

                    % Reset the window to zeros
                    app.r.excludeWindow = ones(app.r.nrKlines,1);

                    % Plot the new windows
                    app.PlotDataWindowFcn;
                    app.CalculateNavigatorSpectrumFcn;
                    app.PlotNavigatorSpectrumFcn;

                    % Reset buttons
                    app.StopAllMoviesFcn;
                    app.SetLightsFcn(1);
                    app.SwitchOffAllButtonsFcn;
                    app.SetDashboardButtonsFcn(131);
                    app.NavSelButtonsFcn(true);
                    app.NavViewingButtonsFcn(true);
                    app.SetStatus(0);

                end

            catch ME

                % Without this the whole interface stayed disabled. This callback switches
                % the buttons off before it starts and switches them back on only at the end
                % of a path an exception skips, so an error here left nothing clickable and
                % the app had to be restarted.
                app.TextMessage(ME.message, 2);
                app.TextMessage('ERROR: Something went wrong while changing the exclude window ...');

                % Reset buttons
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.SetStatus(2);

            end

        end

        % Value changed function: ExcludeEndSlider, ExcludeStartSlider
        function ExcludeSliderValueChanged(app, event)

            % Purpose:
            %   Build the exclude window from the two sliders.
            %
            % How it works:
            %   The same construction and the same constraints as IncludeSliderValueChanged
            %   -- at least ten repetitions, kept inside the acquisition by moving rather
            %   than truncating -- producing the second per-k-line mask.
            %
            %   This is the one to use for a stretch that is visibly bad: the k-lines inside
            %   it never reach k-space, and the cost appears in the filling percentage.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Read the sliders
            try

                value1 = app.ExcludeStartSlider.Value;          % Start of the exclude-window in units of seconds
                value2 = app.ExcludeEndSlider.Value;            % End of the exclude-window in units of seconds

                % End should be bigger than Start and length of the window should be at least 10 (arbitrary) repetitions
                if (value2 - value1) < round(10 * app.r.nrKsteps * app.r.repetitionTime / 1000)
                    value2 = value1 + round(10 * app.r.nrKsteps * app.r.repetitionTime / 1000);
                end

                % End should be smaller than total acquisition time and length of the window should be at least 10 (arbitrary) repetitions
                if value2 > app.r.acqDur
                    value2 = app.r.acqDur;
                    value1 = value2 - (10 * app.r.nrKsteps * app.r.repetitionTime / 1000);
                end

                % Set the values and sliders
                app.ExcludeStartSlider.Value = value1;
                app.ExcludeEndSlider.Value = value2;

                % Set the value of the exclude window
                app.r.excludeWindow = ones(app.r.nrKlines,1);
                value1k = round(1000*value1/app.r.repetitionTime)+1;        % Start of the window in nr k-lines
                value2k = round(1000*value2/app.r.repetitionTime);          % End of the window in nr k-lines
                value2k(value2k > app.r.nrKlines) = app.r.nrKlines;
                app.r.excludeWindow(value1k:value2k) = 0;

                % Plot the datawindow in the time-heartrate and time-respirationrate plots
                app.PlotDataWindowFcn;

                % Calculate and plot the power spectrum of the raw navigator signal
                app.CalculateNavigatorSpectrumFcn;
                app.PlotNavigatorSpectrumFcn;

                % Reset buttons
                app.StopAllMoviesFcn;
                app.SetLightsFcn(1);
                app.SwitchOffAllButtonsFcn;
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);

                % Check whether there is sufficient data left to do a reconstruction
                numberOfKlines = nnz(app.r.includeWindow.*app.r.excludeWindow);
                if numberOfKlines < 512
                    app.TextMessage('ERROR: Not enough k-lines left for reconstruction, adjust data windows ...');
                    app.FilterButton.Enable = 'off';
                    app.SetStatus(2);
                else
                    app.SetStatus(0);
                end

            catch ME

                % Without this the whole interface stayed disabled. This callback switches
                % the buttons off before it starts and switches them back on only at the end
                % of a path an exception skips, so an error here left nothing clickable and
                % the app had to be restarted.
                app.TextMessage(ME.message, 2);
                app.TextMessage('ERROR: Something went wrong while changing the exclude window ...');

                % Reset buttons
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.SetStatus(2);

            end

        end

        % Value changed function: DynamicsEditField, FramesEditField, 
        % ...and 4 other components
        function CinePanelValueChanged(app, event)

            % Purpose:
            %   Take a change to the frame or dynamic counts and invalidate everything that
            %   depended on them.
            %
            % How it works:
            %   Movies are stopped and the four validity flags cleared, because the frame and
            %   dynamic counts decide the shape of the sorted k-space: everything from the
            %   sort onwards is now describing an arrangement that no longer applies.
            %
            %   The lamps and dashboard are set back to the state that allows sorting but not
            %   reconstruction or export, so the interface offers exactly the steps that are
            %   now valid.
            %
            %   Clearing the sense map and segmentation flags as well is deliberate: both are
            %   computed per reconstruction and would otherwise be drawn over images they do
            %   not belong to.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % If running, stop any movies
            app.StopMovieButtonPushed;
            app.StopTrajMovieButtonPushed;
            app.StopTrajMovie3DButtonPushed;
            app.validRecoFlag = false;
            app.validSortFlag = false;
            app.validSenseMapFlag = false;
            app.validSegmentationFlag = false;

            % Sort is offered for a filtered navigator only. Stopping the movies above
            % switched their controls on again; they go off with the reconstruction and
            % the sort they showed
            if app.validFilterFlag
                app.SetDashboardButtonsFcn(135);
            else
                app.SetDashboardButtonsFcn(131);
            end
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;
            app.MovieButtonsFcn(false);
            app.TrajMovieButtonsFcn(false);
            app.Traj3DMovieButtonsFcn(false);

            % New number of frames
            app.nrFrames = app.FramesEditField.Value;
            app.nrDynamics = app.DynamicsEditField.Value;

            % No include and exclude windows for multi-dynamic reconstructions
            if app.nrDynamics > 1
                app.TimeDynViewField.Value = string(duration(seconds(app.r.acqDur/app.DynamicsEditField.Value),'Format','hh:mm:ss.SSS'));
                app.IncludeCheckBox.Value = 0;
                app.IncludeCheckBoxValueChanged;
                app.ExcludeCheckBox.Value = 0;
                app.ExcludeCheckBoxValueChanged;
                app.FilterButtonPushed;
            else
                app.TimeDynViewField.Value = app.ScanTimeViewField.Value;
            end

            % Plot the dynamic windows in the rate plots
            app.PlotDynamicsWindowsFcn;

        end

        % Value changed function: ZTEShiftEditField
        function ZTEShiftEditFieldValueChanged(app, event)

            % Purpose:
            %   Take a change to the ZTE shift and re-derive everything that follows from it.
            %
            % How it works:
            %   The shift says how many samples at the start of each spoke fall inside the
            %   dead time and were never recorded, so it changes where the centre of k-space
            %   is. That is not a display setting: the acquisition has to be reclassified and
            %   the navigator set up again from scratch, which is why this runs the whole
            %   chain rather than just storing the value.
            %
            %   Respiratory gating is switched off as part of that reset, so the
            %   reconstruction starts from a known configuration rather than inheriting one
            %   that suited the previous shift.
            %
            %   The lamps are reset, since sorting and reconstruction no longer apply.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            app.r.shiftZTE = app.ZTEShiftEditField.Value;
            app.r = app.r.acquisitionType(app);
            app.DataParametersFcn;                                                      % Set initial data parameters
            app.SetSpeciesFcn;                                                          % Set initial filtering values for mouse/rat
            app.RespToggleCheckBox.Value = 0;                                           % Set reconstruction to expiration
            app.IgnoreRespToggleCheckBox.Value = 0;                                     % Switch ignore respiration off
            app.r = app.r.determineFlashVersion();                                      % Determine flash sequence version
            app.SetNavigatorSelectionFcn;
            app.NavButtonGroupSelectionChanged;
            app.r = app.r.autoDiscardNav();                                             % Automatically set the number of discarded points

            % Reset buttons
            app.SetLightsFcn(1);
            app.NavViewingButtonsFcn(false);
            app.CineRecoButtonsFcn(false);
            app.MovieButtonsFcn(false);
            app.NavSelButtonsFcn(true);
            app.SetStatus(0);
            app.SetDashboardButtonsFcn(131);

        end

        % Button pushed function: SortKspaceButton
        function SortKspaceButtonPushed(app, event)

            % Purpose:
            %   Sort the acquired k-lines into k-space, binned by cardiac phase, respiratory
            %   phase and dynamic.
            %
            % How it works:
            %   The interface is put into its busy state first -- movies stopped, lamps
            %   cleared, buttons disabled -- so nothing offers a step against data that is
            %   being rebuilt.
            %
            %   The trajectory is worked out first, then the bin times and the bin numbers,
            %   and then the fill function for this data type places every line where it
            %   belongs. Which fill function that is follows from the data type, so the
            %   branch is on the data and not on anything the user selects.
            %
            %   The filling statistics are computed at the end and shown: the percentage of
            %   k-space that received at least one line, and the mean number of averages
            %   where it did. Those two answer different questions -- how much was measured
            %   at all, and how well -- and they are what the reconstruction settings should
            %   be judged against.
            %
            %   validSortFlag gates reconstruction, so it is set only once all of that has
            %   succeeded.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SetLightsFcn(3);
            app.Lamp3.Color = app.yellow;
            app.SetStatus(3);
            app.SwitchOffAllButtonsFcn;

            % Report sorting status to app
            app.TextMessage('Sorting ...');

            % Reset some parameters
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.validSenseMapFlag = false;
            app.validSegmentationFlag = false;
            app.ProgressGauge.Value = 0;

            % The gradient delay calibration is ticked again at every sort, except for
            % 3D UTE. Its spokes run from the centre outward and meet only at k = 0, so
            % the data cannot fix a delay common to all axes and the calibration drifts
            % (see reco3Dute); it runs there only when the user ticks it. The 3D UTE data
            % offset is estimated at every sort regardless (fillKspace3Dute).
            if ~strcmp(app.r.dataType, '3Dute')
                app.GradDelayCalibrationCheckBox.Value = 1;
            end

            try

                % Extract all the raw k-space imaging data
                app.r = app.r.extractData();

                % Sort by cardiac phase/relative binning, cardiac absolute binning, respiratory phase binning, or realtime
                switch app.RecoTypeDropDown.Value

                    case 'cardiac'

                        app.nrCardFrames = app.nrFrames;
                        app.nrRespFrames = 1;
                        app.r = app.r.assignBinTimes(app);
                        app.r = app.r.assignBinFrames(app);

                    case 'cardiac abs'

                        app.nrCardFrames = app.nrFrames;
                        app.nrRespFrames = 1;
                        app.r = app.r.assignBinTimesAbs(app);
                        app.r = app.r.assignBinFramesAbs(app);

                    case 'respiratory'

                        app.nrCardFrames = 1;
                        app.nrRespFrames = app.nrFrames;
                        app.r = app.r.assignBinTimes(app);
                        app.r = app.r.assignBinFrames(app);

                    case 'realtime'

                        app.nrCardFrames = app.nrFrames;
                        app.nrRespFrames = 1;
                        app.nrDynamics = 1;    % Will be calculated later depending on the number of heartbeats
                        app.r = app.r.assignBinTimes(app);
                        app.r = app.r.assignBinFrames(app);

                end

                % Sort k-space
                switch app.r.dataType

                    case {'2D','2Dms'}
                        if strcmp(app.RecoTypeDropDown.Value,'realtime')
                            % Realtime sorting
                            app.r = app.r.fillKspace2Drealtime(app);
                            app.DynamicsEditField.Value = app.nrDynamics;
                            app.TimeDynViewField.Value = string(duration(seconds(app.r.acqDur/app.DynamicsEditField.Value),'Format','hh:mm:ss.SSS'));
                        else
                            % 2D and 2D multi-slice sorting
                            app.r = app.r.fillKspace2D(app);
                        end

                    case '3D'
                        % Sorting 3D data
                        app.r = app.r.fillKspace3D(app);

                    case '3Dp2roud'
                        % Sorting 3D proud data
                        app.r = app.r.fillKspace3Dp2roud(app);

                    case {'2Dradial','2Dradialms'}
                        % Sorting 2D radial data: every spoke kept apart when the scan gives
                        % the angle between its spokes, the repetitions of each angle averaged
                        % on a half circle
                        if app.r.radialUserAngle
                            app.r = app.r.fillKspace2Dradial(app);
                        else
                            app.r = app.r.fillKspace2DradialReg(app);
                        end

                        app.ReconstructionTabGroup.SelectedTab = app.RadialRecoTab;

                    case '3Dute'
                        % Sorting 3D UTE data
                        app.r = app.r.fillKspace3Dute(app);
                        app.ReconstructionTabGroup.SelectedTab = app.RadialRecoTab;

                    case 'ZTE'
                        % Sorting 3D UTE data
                        app.r = app.r.fillKspace3DZTE(app);
                        app.ReconstructionTabGroup.SelectedTab = app.RadialRecoTab;

                end

                % Reshape to either cardiac or respiratory CINE
                app.r = app.r.reshapeKspace();

                % Normalize k-space
                app.r = app.r.normKspace();

                % Let the app know the data is sorted
                app.validSortFlag = true;

                % K-space statistics
                app.r = app.r.kSpaceStats(app);

                % Reset buttons
                app.SetLightsFcn(7);
                app.SetDashboardButtonsFcn(143);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(false);
                app.TrajMovieButtonsFcn(true);
                app.SetStatus(0);

            catch ME

                % Let the app know the data is not sorted
                app.validSortFlag = false;

                % Reset buttons
                app.SetLightsFcn(3);
                app.SetDashboardButtonsFcn(135);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(false);
                app.MovieButtonsFcn(false);
                app.SetStatus(2);

                % Error message
                app.TextMessage('ERROR: Something went wrong during sorting of the data into k-space ...')
                app.TextMessage(ME.message);

            end

        end

        % Selection changed function: ReconstructionTypeButtonGroup
        function ReconstructionTypeButtonGroupSelectionChanged(app, event)

            % Purpose:
            %   Take a change of reconstruction type and put the interface back to the state
            %   before reconstruction.
            %
            % How it works:
            %   Movies are stopped, the progress gauge cleared and the button groups reset so
            %   that sorting and filtering are available and the movie panel is not.
            %
            %   The type decides which reconstruction runs and which settings apply, so any
            %   existing reconstruction is no longer the one the interface describes. Rather
            %   than reconstructing again automatically, the interface is returned to the
            %   point where the user can -- reconstruction is the expensive step and is not
            %   started by a radio button.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop movie & reset progress bar
            try

                app.StopAllMoviesFcn;
                app.ProgressGauge.Value = 0;

                % Reset buttons to the steps the pipeline validly holds: a change here
                % only affects the next reconstruction
                app.SetValidStepsFcn;

            catch ME

                % Without this the whole interface stayed disabled. This callback switches
                % the buttons off before it starts and switches them back on only at the end
                % of a path an exception skips, so an error here left nothing clickable and
                % the app had to be restarted.
                app.TextMessage(ME.message, 2);
                app.TextMessage('ERROR: Something went wrong while changing the reconstruction type ...');

                % Reset buttons
                app.SetValidStepsFcn;
                app.SetStatus(2);

            end

        end

        % Value changed function: ESPIRiTCheckBox, LLRxyzEditField, 
        % ...and 10 other components
        function RecoParEditFieldValueChanged(app, event)

            % Purpose:
            %   Take a change to a reconstruction parameter and invalidate the existing
            %   reconstruction.
            %
            % How it works:
            %   The same reset as ReconstructionTypeButtonGroupSelectionChanged, and for the
            %   same reason: the images on screen were produced with the previous value and
            %   no longer correspond to what the controls say.
            %
            %   The reconstruction is not rerun automatically. These are the regularisation
            %   weights and iteration counts, which are usually adjusted several times in a
            %   row, and reconstructing on each keystroke would make that impossible.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop movie & reset progress bar
            try

                app.StopAllMoviesFcn;
                app.ProgressGauge.Value = 0;

                % Reset buttons to the steps the pipeline validly holds: a change here
                % only affects the next reconstruction
                app.SetValidStepsFcn;

            catch ME

                % Without this the whole interface stayed disabled. This callback switches
                % the buttons off before it starts and switches them back on only at the end
                % of a path an exception skips, so an error here left nothing clickable and
                % the app had to be restarted.
                app.TextMessage(ME.message, 2);
                app.TextMessage('ERROR: Something went wrong while changing a reconstruction parameter ...');

                % Reset buttons
                app.SetValidStepsFcn;
                app.SetStatus(2);

            end

        end

        % Value changed function: CenterEchoCheckBox, 
        % ...and 2 other components
        function TrajectorySettingsEditFieldValueChanged(app, event)

            % Purpose:
            %   Re-sort after a change to the trajectory settings.
            %
            % How it works:
            %   The trajectory decides where each acquired line belongs in k-space, so a
            %   change to it makes the existing sort wrong -- not merely out of date.
            %   Sorting is therefore rerun rather than just invalidated.
            %
            %   Only when the navigator has been filtered, since the sort depends on the bin
            %   numbers that filtering produces.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Resort the data
            if app.validFilterFlag
                app.SortKspaceButtonPushed;
            end

        end

        % Button pushed function: ReconstructButton
        function RecoMoviesButtonPushed(app, event)

            % Purpose:
            %   Reconstruct the images from the sorted k-space and show them.
            %
            % How it works:
            %   The interface is put into its busy state first -- movies stopped, lamps
            %   cleared, buttons disabled -- so nothing offers a step against data that is
            %   being rebuilt.
            %
            %   The view is switched to the reconstruction tab, so the result is where the
            %   user is looking when it arrives.
            %
            %   Which reconstruction runs follows from the data type and from whether
            %   compressed sensing was asked for; the parameters are gathered from the
            %   interface into the object first, so the reconstruction reads a struct and
            %   never a widget.
            %
            %   Afterwards the images are scaled, the pixel size computed and the off-centre
            %   shift applied, and the movie panel is enabled against what was actually
            %   produced -- the coil spinner only for multiple coils, the sense map button
            %   only if a sense map exists.
            %
            %   validRecoFlag gates the exports, so it is set only once the images are there.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SetLightsFcn(7);
            app.Lamp4.Color = app.yellow;
            app.SetStatus(3);
            app.SwitchOffAllButtonsFcn;
            app.ReconstructionTabGroup.SelectedTab = app.RecoTab;

            % Reset some parameters. The declined flag too: only the deep learning
            % reconstruction sets it, and left from a declined one it would call a later
            % failure of another reconstruction declined
            app.validRecoFlag = false;
            app.recoDeclinedFlag = false;
            app.r.multiSliceFlag = false;
            app.r.multiDynamicFlag = false;
            app.validSenseMapFlag = false;
            app.validSegmentationFlag = false;
            app.ProgressGauge.Value = 0;

            app.sliceNr = 1;
            app.dynamicNr = 1;
            app.MovieSliceEditField.Value = 1;
            app.MovieFrameEditField.Value = 1;
            app.MovieDynEditField.Value = 1;
            app.MovieCheckBox1.Value = 1;
            app.MovieCheckBox2.Value = 0;
            app.MovieCheckBox3.Value = 0;

            % A new reconstruction is shown unrotated: the rotation buttons turn the
            % aspect ratio with the image, so it goes back with them
            app.imageRot = 0;
            app.r.aspectRatio = app.r.aspectRatioOriginal;

            % Reconstruction
            try

                switch app.r.dataType

                    case {'2D','2Dms'}
                        % Reconstruction of 2D and 2D multi-slice data
                        if app.CompressedSensingButton.Value
                            app.r = app.r.reco2DCS(app);
                        else
                            app.r = app.r.reco2DAI(app);
                        end
                    case {'3D','3Dp2roud'}
                        % Reconstruction of 3D Cartesian data
                        app.r = app.r.reco3D(app);
                    case {'2Dradial','2Dradialms'}
                        % Reconstruction of 2D radial data
                        app.r = app.r.reco2Dradial(app);
                    case '3Dute'
                        % Reconstruction of 3D UTE data
                        app.r = app.r.reco3Dute(app);
                    case 'ZTE'
                        % Reconstruction of 3D ZTE data
                        app.r = app.r.reco3DZTE(app);

                end

            catch ME

                app.TextMessage(ME.message);
                app.validRecoFlag = false;
                app.validSenseMapFlag = false;
                app.validSegmentationFlag = false;

            end

            % Successful reco or not
            if app.validRecoFlag

                % Gibbs ringing suppression
                if app.RingRmCheckBox.Value
                    app.r = app.r.unRing();
                end

                % Denoising filter
                if app.NLMFCheckBox.Value
                    app.r = app.r.NLMFdenoise(app);
                end

                % Shift image on sub-pixel level for certain data
                try
                    switch app.r.dataType
                        case {'2Dradial','2Dradialms'}
                            app.r = app.r.shiftImages2D();
                        case {'3Dp2roud','3Dute','ZTE'}
                            app.r = app.r.shiftImages3D();
                    end
                catch ME
                    app.TextMessage(ME.message);
                end

                % Normalize image intensity somewhat
                app.r = app.r.normImages();

                % Set multislice, multidynamic, or multi-echo flags
                app.r = app.r.determineMultiDimensions();

                % Number of slices and starting movie slice
                app.nrSlices = size(app.r.movieApp,4);
                app.MovieSliceEditField.Limits = [1 app.nrSlices];
                app.MovieSliceEditField.Value = round(app.nrSlices/2);
                app.sliceNr = app.MovieSliceEditField.Value;

                % Info on phase orientation
                app.PhaseOrientationViewField.Value = app.r.phaseOrientation;
                if app.r.phaseOrientation
                    app.TextMessage('INFO: phase orientation = 1 / horizontal ... ')
                else
                    app.TextMessage('INFO: phase orientation = 0 / vertical ... ')
                end
                app.r = app.r.imageOrientLabels();

                % Reset buttons
                app.SetLightsFcn(15);
                app.SetDashboardButtonsFcn(255);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(true);
                app.SetStatus(0);

                % Start the movie
                app.AutoWLButton.Value = 1;
                app.MovieButton.Value = 1;
                app.MovieButtonGroupSelectionChanged;
                app.PlotMovieFrameFcn;
                if ~app.AutoRecoCheckBox.Value && app.nrFrames > 1
                    app.StartMovieButtonPushed;
                end

            else

                % Inform the app that the reco was not successful
                app.validRecoFlag = false;
                app.validSenseMapFlag = false;
                app.validSegmentationFlag = false;

                % Reset buttons
                %
                % 143 and not 135. The k-space is still sorted -- sorting is what enabled
                % this button in the first place and nothing here unsorts it -- so the
                % Reconstruct button stays available and the reconstruction can be tried
                % again with different settings. 135 forced a pointless re-sort first,
                % which a declined reconstruction runs into every time.
                app.SetLightsFcn(7);
                app.SwitchOffAllButtonsFcn;
                app.SetDashboardButtonsFcn(143);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(false);

                % A reconstruction that was declined is not a failure. The reason has
                % already been reported by whichever step declined it, so this only has
                % to avoid calling it an error and stopping on the red lamp.
                if app.recoDeclinedFlag
                    app.TextMessage('Reconstruction was not performed ...');
                    app.SetStatus(1);
                else
                    app.TextMessage('ERROR: There was a problem with reconstruction ...');
                    app.SetStatus(2);
                end

            end

        end

        % Button pushed function: MovieStartButton
        function StartMovieButtonPushed(app, event)

            % Purpose:
            %   Play the reconstructed movie.
            %
            % How it works:
            %   No movie starts while the app is closing: the close waits until no app code
            %   runs, and a movie runs until it is stopped. A reconstruction and an LV
            %   segmentation end by starting it, also when the app was closed meanwhile.
            %
            %   Other movies are stopped first, since they share the axes.
            %
            %   The first frame goes through the full plot, which creates the image object
            %   and the contour lines; every frame after it goes through the fast update,
            %   which only sets data on what already exists. That split is what makes
            %   playback smooth rather than stuttering.
            %
            %   The loop checks its stop flag each pass, so the stop button ends it between
            %   frames and the axes are left in a valid state.
            %
            %   The navigator overlay is switched on, so the navigator plane is visible over
            %   the moving images where an external navigator was used.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % No movie in an app that is closing
            if app.closeRequested
                return
            end

            % Stop all movies
            app.StopAllMoviesFcn;

            % Buttons
            app.TrajMovieButtonsFcn(true);
            app.Traj3DMovieButtonsFcn(true);

            % Movie is running
            app.stopMovieFlag = false;
            app.MovieButtonsFcn(true);
            app.showExtNavFlag = true;
            app.TextMessage('Movie playing ...');

            % Show the movie frame
            app.MovieFig.Position = [154,15,280,280];
            app.MovieButtonGroupSelectionChanged;

            % Set the counters
            counter1 = app.MovieFrameEditField.Value;
            counter2 = app.sliceNr;
            counter3 = app.dynamicNr;

            try % To catch error during exit while movie is still running
                tA = tic;

                % Play the movie continuously until app.stopMovieFlag==true
                while isvalid(app) && app.stopMovieFlag == false
                    % Time
                    tB = tic;

                    % Stop movie after 5 seconds in test mode
                    if app.testMode && toc(tA)>3
                        app.StopMovieButtonPushed;
                    end

                    % Calculate movie speed
                    if strcmp(app.RecoTypeDropDown.Value,'realtime')
                        minTimePerFrame = 1/(app.MovieCheckBox1.Value * app.nrFrames + app.MovieCheckBox2.Value*app.nrSlices + app.MovieCheckBox3.Value * app.nrDynamics);
                    else
                        minTimePerFrame = 1/(app.MovieCheckBox1.Value * app.nrFrames);
                        if app.MovieCheckBox1.Value == 0
                            minTimePerFrame = 1/(app.MovieCheckBox2.Value * app.nrSlices + app.MovieCheckBox3.Value * app.nrDynamics);
                        end
                    end

                    % Show the movie frame
                    app.UpdateMovieFrameFcn;
                    chk = false;
                    if app.MovieCheckBox1.Value == 1
                        counter1 = counter1 + 1;
                        chk = true;
                        if counter1 > app.nrFrames
                            counter1 = 1;
                            chk = false;
                        end
                        app.MovieFrameEditField.Value = counter1;
                    end
                    if (app.MovieCheckBox2.Value == 1) && (chk == false)
                        counter2 = counter2 + 1;
                        chk = true;
                        if counter2 > app.MovieSliceCountFcn    % the slices of the view shown
                            counter2 = 1;
                            chk = false;
                        end
                        app.MovieSliceEditField.Value = counter2;
                        app.sliceNr = counter2;
                    end
                    if (app.MovieCheckBox3.Value == 1) && (chk == false)
                        counter3 = counter3 + 1;
                        chk = true; %#ok<*NASGU>
                        if counter3 > app.nrDynamics
                            counter3 = 1;
                            chk = false;
                        end
                        app.MovieDynEditField.Value = counter3;
                        app.dynamicNr = counter3;
                    end

                    % Slow movie a bit down with small number of frames
                    mvTime = toc(tB);
                    if mvTime < minTimePerFrame
                        pause(minTimePerFrame-mvTime);
                    end
                end

                app.PlotMovieFrameFcn;

            catch ME

                if isvalid(app)
                    app.TextMessage(ME.message,2);
                end

            end

        end

        % Button pushed function: MovieStopButton
        function StopMovieButtonPushed(app, event)

            % Purpose:
            %   Stop the reconstructed movie.
            %
            % How it works:
            %   Raises the stop flag the loop checks each frame, then re-enables the movie
            %   buttons and the exit button.
            %
            %   Exit is re-enabled here because it is disabled while a movie runs: closing
            %   the app from under a loop that is still drawing into its axes is what leaves
            %   the error the movie loops are wrapped against.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop the movie
            app.stopMovieFlag = true;
            app.TextMessage('Stopping the movie ...');

            % Reset buttons
            app.MovieButtonsFcn(true);
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Button pushed function: ShowNavButton
        function ShowNavButtonPushed(app, event)

            % Purpose:
            %   Turn the navigator plane overlay on or off.
            %
            % How it works:
            %   The movie is stopped first, since the overlay is created by the full plot and
            %   not by the fast update, so it cannot appear or disappear mid-playback.
            %
            %   The flag is toggled only when an external navigator exists. There is nothing
            %   to draw for a slice FID -- it has no plane of its own -- so the flag is forced
            %   off rather than toggled, which is what stops the button from appearing to do
            %   nothing.
            %
            %   The frame is redrawn immediately when the movie is not running, so the change
            %   is visible at once.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            app.StopMovieButtonPushed;
            if app.extEchoNavFlag || app.extFidNavFlag
                app.showExtNavFlag = ~app.showExtNavFlag;
            else
                app.showExtNavFlag = false;
            end

            if app.stopMovieFlag
                app.PlotMovieFrameFcn;
            end

        end

        % Value changed function: MovieFrameEditField
        function MovieFrameEditFieldValueChanged(app, event)

            % Purpose:
            %   Show the frame the user typed.
            %
            % How it works:
            %   The value is clamped to the number of frames before use. A typed number is
            %   not constrained by anything the way a button press is, and indexing past the
            %   end of the movie would error rather than simply showing nothing.
            %
            %   The frame is then redrawn through the full plot, since changing frame by hand
            %   is not part of a running movie.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Check if input value <= number of cardiac frames
            app.MovieFrameEditField.Value(app.MovieFrameEditField.Value > app.nrFrames) = app.nrFrames;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Value changed function: MovieSliceEditField
        function MovieSliceEditFieldValueChanged(app, event)

            % Purpose:
            %   Show the slice the user typed.
            %
            % How it works:
            %   Clamped to the slice count, as with the frame field, then stored in
            %   app.sliceNr and drawn.
            %
            %   The counter is kept on the app as well as in the field because the increment
            %   buttons and the frame shifting both work from it; the field is where it is
            %   shown, not where it lives.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Clamped against the view being displayed rather than against the
            % reconstructed count: k-space and the averages are held per measured
            % slice, which for a 3D acquisition is a different number
            app.sliceNr = app.MovieSliceEditField.Value;
            app.SyncMovieSliceFieldFcn;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Value changed function: MovieDynEditField
        function MovieDynEditFieldValueChanged(app, event)

            % Purpose:
            %   Show the dynamic the user typed.
            %
            % How it works:
            %   Clamped to the dynamic count and stored in app.dynamicNr, the same
            %   arrangement as the slice field and for the same reason.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Check if input value <= number of dynamics
            app.MovieDynEditField.Value(app.MovieDynEditField.Value > app.nrDynamics) = app.nrDynamics;

            app.dynamicNr = app.MovieDynEditField.Value;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Value changed function: MovieCheckBox1, MovieCheckBox2, 
        % ...and 1 other component
        function MovieCheckBoxValueChanged(app, event)

            % Purpose:
            %   Keep at least one movie axis selected.
            %
            % How it works:
            %   If all three boxes end up unticked the first is ticked again. With none
            %   selected the movie has nothing to play in, so the button appears to do
            %   nothing at all -- forcing one on makes the control always lead somewhere.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % At least frame movie checkbox values should be active
            if (app.MovieCheckBox1.Value == 0) && (app.MovieCheckBox2.Value == 0) && (app.MovieCheckBox3.Value == 0)
                app.MovieCheckBox1.Value = 1;
            end

        end

        % Value changed function: WindowScaleSlider
        function MovieWindowScaleSliderValueChanged(app, event)

            % Purpose:
            %   Set the display window by hand.
            %
            % How it works:
            %   Automatic window and level is switched off first, because the two cannot both
            %   hold: automatic recomputes the window on every frame and would immediately
            %   overwrite what the slider just set.
            %
            %   The frame is redrawn so the change is visible as it is dragged.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            app.AutoWLButton.Value = 0;
            value = app.WindowScaleSlider.Value;
            app.window = value;
            app.PlotMovieFrameFcn;

        end

        % Value changed function: LevelScaleSlider
        function MovieLevelScaleSliderValueChanged(app, event)

            % Purpose:
            %   Set the display level by hand.
            %
            % How it works:
            %   The counterpart of the window slider, switching automatic off for the same
            %   reason and redrawing the frame as it is dragged.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            app.AutoWLButton.Value = 0;
            value = app.LevelScaleSlider.Value;
            app.level = value;
            app.PlotMovieFrameFcn;

        end

        % Value changed function: AutoWLButton
        function AutoWLButtonValueChanged(app, event)

            % Purpose:
            %   Switch automatic window and level on or off.
            %
            % How it works:
            %   Only the frame is redrawn; the plot functions read the button themselves and
            %   recompute the window and level from the data when it is on.
            %
            %   Automatic scales from the whole slice rather than the frame on screen, so the
            %   brightness does not flicker as a movie plays.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            app.PlotMovieFrameFcn;

        end

        % Value changed function: MovieCoilSpinner, SenseMapSpinner1, 
        % ...and 1 other component
        function MovieCoilSpinnerValueChanged(app, event)

            % Purpose:
            %   Show the k-space of a different coil.
            %
            % How it works:
            %   Only the frame is redrawn. The k-space is held per coil already, so this
            %   chooses which one is displayed and changes nothing about the data.
            %
            %   It applies to the k-space and averages views, where the coils differ; the
            %   reconstructed movie is already coil combined.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Button pushed function: MoviecwButton
        function MovieRotateMoviecwButtonPushed(app, event)

            % Purpose:
            %   Rotate the displayed movie a quarter turn clockwise.
            %
            % How it works:
            %   Three things move together. The aspect ratio is inverted, since a quarter
            %   turn exchanges the two image dimensions. The rotation count is stepped and
            %   wrapped to 0..3, so it stays a number of quarter turns rather than growing
            %   without limit. And the orientation labels are shifted round.
            %
            %   The label shift is along dimension 2, the four sides of a slice; the rows are
            %   slices, so a shift without the dimension rotates the stack instead of the
            %   labels.
            %
            %   The frame is then redrawn, which is what applies the new rotation and sets
            %   the labels for whichever view is up.
            %
            %   The reconstruction lamps are reset, the rotation changing what an export
            %   would contain.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Rotate movie clock-wise
            app.r.aspectRatio = 1/app.r.aspectRatio;
            app.imageRot = app.imageRot - 1;
            app.imageRot(app.imageRot==-1) = 3;

            % Adjust orientation labels. The shift is along dimension 2, the four
            % sides of a slice; the rows are slices, so a shift without the
            % dimension rotates the stack instead of the labels.
            app.r.orientationLabels = circshift(app.r.orientationLabels,-1,2);

            % Show the movie frame, which sets the labels for whichever view is up
            app.PlotMovieFrameFcn;

            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;

        end

        % Button pushed function: MovieccwButton
        function MovieRotateMovieccwButtonPushed(app, event)

            % Purpose:
            %   Rotate the displayed movie a quarter turn counter-clockwise.
            %
            % How it works:
            %   The mirror of MovieRotateMoviecwButtonPushed: the same aspect ratio
            %   inversion, the rotation count stepped the other way and wrapped, and the
            %   labels shifted in the opposite direction along dimension 2.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Rotate movie counter-clock-wise
            app.r.aspectRatio = 1/app.r.aspectRatio;
            app.imageRot = app.imageRot + 1;
            app.imageRot(app.imageRot==4) = 0;

            % Adjust orientation labels. The shift is along dimension 2, the four
            % sides of a slice; the rows are slices, so a shift without the
            % dimension rotates the stack instead of the labels.
            app.r.orientationLabels = circshift(app.r.orientationLabels,1,2);

            % Show the movie frame, which sets the labels for whichever view is up
            app.PlotMovieFrameFcn;

            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;

        end

        % Button pushed function: MovieFrameIncrButton
        function MovieFrameIncrButtonPushed(app, event)

            % Purpose:
            %   Shift the movie one frame forward along the frame axis.
            %
            % How it works:
            %   Delegates to ShiftMovieFrameFcn, which moves the movie, the k-space, the
            %   averages and the labels together.
            %
            %   The sign is negative because shifting the data back by one brings the next
            %   frame to the front: this moves the data under the display, not the display
            %   over the data.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Shift movie +1 frame along the time/frame axis
            app.ShiftMovieFrameFcn(-1);

        end

        % Button pushed function: MovieFrameDecrButton
        function MovieFrameDecrButtonPushed(app, event)

            % Purpose:
            %   Shift the movie one frame backward along the frame axis.
            %
            % How it works:
            %   The counterpart of MovieFrameIncrButtonPushed with the opposite sign; the
            %   two differ in nothing else, which is why the work is in ShiftMovieFrameFcn.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Shift movie -1 frame along the time/frame axis
            app.ShiftMovieFrameFcn(1);

        end

        % Button pushed function: MovieSliceIncrButton
        function MovieSliceIncrButtonPushed(app, event)

            % Purpose:
            %   Show the next slice.
            %
            % How it works:
            %   Delegates to StepMovieSliceFcn, which knows where the counter wraps and that
            %   the limit differs between the image views and the k-space views.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Increment the slice number by 1
            app.StepMovieSliceFcn(1);

        end

        % Button pushed function: MovieSliceDecrButton
        function MovieSliceDecrButtonPushed(app, event)

            % Purpose:
            %   Show the previous slice.
            %
            % How it works:
            %   The counterpart of MovieSliceIncrButtonPushed. Only the end being moved
            %   towards is wrapped, which is why the direction is passed rather than each
            %   button wrapping for itself.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Decrease the slice number by 1
            app.StepMovieSliceFcn(-1);

        end

        % Button pushed function: MovieDynIncrButton
        function MovieDynIncrButtonPushed(app, event)

            % Purpose:
            %   Show the next dynamic.
            %
            % How it works:
            %   Delegates to StepMovieDynFcn, the dynamic counterpart of the slice stepper.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Increment the dynamic number by 1
            app.StepMovieDynFcn(1);

        end

        % Button pushed function: MovieDynDecrButton
        function MovieDynDecrButtonPushed(app, event)

            % Purpose:
            %   Show the previous dynamic.
            %
            % How it works:
            %   The counterpart of MovieDynIncrButtonPushed, with the direction passed.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Decrease the dynamic number by 1
            app.StepMovieDynFcn(-1);

        end

        % Selection changed function: ButtonGroup
        function MovieButtonGroupSelectionChanged(app, event)

            % Purpose:
            %   Switch between the movie, averages, k-space and sense map views.
            %
            % How it works:
            %   The frame is redrawn through the full plot, which is what each view needs:
            %   they differ in colour map, scaling and whether orientation labels apply, so
            %   they go through the full plot rather than the fast frame update.
            %
            %   The slice counter is brought into range first. The views count slices
            %   differently -- measured for k-space and the averages, reconstructed for the
            %   images and the sense map -- so a counter carried over from the other view
            %   can be past the end of the array about to be drawn.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % What a slice number means depends on the view
            app.SyncMovieSliceFieldFcn;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Button pushed function: TrajMovieStartButton
        function StartTrajMovieButtonPushed(app, event)

            % Purpose:
            %   Animate the 2D radial k-space trajectory, spoke by spoke.
            %
            % How it works:
            %   Every other movie is stopped first, since they share the axes and the flags.
            %
            %   The spokes are drawn in the order they were acquired, which is the point: a
            %   golden angle ordering fills k-space evenly from any starting point, and a
            %   wrong ordering shows up here as a pattern that leaves gaps -- visible long
            %   before it appears as a streak in an image.
            %
            %   The first frame creates the plot and sets the limits; the rest update the line
            %   data, as in the image movie and for the same reason.
            %
            %   The loop checks its stop flag each pass, and is wrapped against the app being
            %   closed while it runs.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop all movies
            app.StopAllMoviesFcn;

            % Movie is running
            app.stopRadialMovieFlag = false;
            app.StopMovieButtonPushed;
            app.StopTrajMovie3DButtonPushed;

            % Buttons
            app.MovieButtonsFcn(true);
            app.TrajMovieButtonsFcn(true);
            app.Traj3DMovieButtonsFcn(true);

            % Determine limits of the dimensions
            dimF = size(app.r.kSpaceTraj,1);
            dimZ = size(app.r.kSpaceTraj,4);
            dimD = size(app.r.kSpaceTraj,5);
            dimX = size(app.r.data{1},4);

            % Prefill the trajectory array to increase display speed
            app.trajPlotData = {};
            totalNrSpokes = 0;
            shownNrSpokes = 0;
            app.TextMessage('Preparing movie ...');

            for cnt1 = 1:dimF

                for cnt2 = 1:dimZ

                    for cnt3 = 1:dimD

                        % The spokes from each dimension
                        xCrds = squeeze(app.r.kSpaceTraj(cnt1,:,:,cnt2,cnt3,1))';
                        yCrds = squeeze(app.r.kSpaceTraj(cnt1,:,:,cnt2,cnt3,2))';
                        zCrds = squeeze(app.r.kSpaceTraj(cnt1,:,:,cnt2,cnt3,3))';

                        % Remove all zero entries
                        notZeroElements = ~((xCrds(:,end)==0) & (yCrds(:,end)==0) & (zCrds(:,end)==0));
                        xCrds = xCrds(notZeroElements,:);
                        yCrds = yCrds(notZeroElements,:);
                        zCrds = zCrds(notZeroElements,:);
                        totalNrSpokes = totalNrSpokes + size(xCrds,1);

                        % Restrict number of visible spokes to maxNrSpokesPlot per frame
                        trajSize = size(xCrds,1);
                        skip = ceil(trajSize/app.maxNrSpokesPlot);
                        xCrds = xCrds(1:skip:end,[1 end])';
                        yCrds = yCrds(1:skip:end,[1 end])';
                        zCrds = zCrds(1:skip:end,[1 end])';
                        shownNrSpokes = shownNrSpokes + size(xCrds,2);

                        % Make sure that all dimensions are equal
                        xCrdsSl = zeros(2,app.maxNrSpokesPlot);
                        yCrdsSl = zeros(2,app.maxNrSpokesPlot);
                        zCrdsSl = zeros(2,app.maxNrSpokesPlot);
                        xCrdsSl(:,1:size(xCrds,2)) = xCrds(:,:);
                        yCrdsSl(:,1:size(yCrds,2)) = yCrds(:,:);
                        zCrdsSl(:,1:size(zCrds,2)) = zCrds(:,:);

                        % Fill the existing trajectory plot array
                        app.trajPlotData{cnt1,cnt2,cnt3,1} = xCrdsSl;
                        app.trajPlotData{cnt1,cnt2,cnt3,2} = yCrdsSl;
                        app.trajPlotData{cnt1,cnt2,cnt3,3} = zCrdsSl;

                    end

                end

            end

            % Report number of spokes visualized in the movie
            app.TextMessage(strcat("Total number of spokes = ",num2str(totalNrSpokes)," ..."));
            app.TextMessage(strcat("Number of spokes visualized = ",num2str(shownNrSpokes), " ..."));
            app.TextMessage('Radial trajectory movie playing ...');

            % Set the counter
            cnt1 = app.TrajMovieFrameEditField.Value;
            cnt2 = app.TrajMovieSliceEditField.Value;
            cnt3 = app.TrajMovieDynEditField.Value;

            % Reset to 1 if counters exceed max values
            cnt1(cnt1>dimF) = 1;
            cnt2(cnt2>dimZ) = 1;
            cnt3(cnt3>dimD) = 1;

            % Set limits in app
            nFramesLimits = dimF;
            nFramesLimits(nFramesLimits == 1) = 2;
            nSlicesLimits = dimZ;
            nSlicesLimits(nSlicesLimits == 1) = 2;
            nDynamicsLimits = dimD;
            nDynamicsLimits(nDynamicsLimits == 1) = 2;
            app.TrajMovieFrameEditField.Limits = [1 nFramesLimits];
            app.TrajMovieSliceEditField.Limits = [1 nSlicesLimits];
            app.TrajMovieDynEditField.Limits = [1 nDynamicsLimits];

            % Image dimension
            dimX = 2*max(app.r.kSpaceTraj(:));

            % Plot the first movie frame
            app.PlotTrajectoryMovieFrameFcn(app.trajPlotData{cnt1,cnt2,cnt3,1},app.trajPlotData{cnt1,cnt2,cnt3,2},app.trajPlotData{cnt1,cnt2,cnt3,3},dimX,true);

            try % To catch error during exit while movie is running
                % Play the movie continuously until app.stopRadialMovieFlag==true
                while isvalid(app) && app.stopRadialMovieFlag == false
                    % Time
                    tic

                    % Calculate movie speed
                    if strcmp(app.RecoTypeDropDown.Value,'realtime')
                        minTimePerTrajFrame = 1/(app.TrajMovieCheckBox1.Value * dimF + app.TrajMovieCheckBox2.Value*dimZ + app.TrajMovieCheckBox3.Value * dimD);
                    else
                        minTimePerTrajFrame = 1/(app.TrajMovieCheckBox1.Value * dimF);
                        if app.TrajMovieCheckBox1.Value == 0
                            minTimePerTrajFrame = 1/(app.TrajMovieCheckBox2.Value * dimZ + app.TrajMovieCheckBox3.Value * dimD);
                        end
                    end
                    chk = false;
                    if app.TrajMovieCheckBox1.Value == 1
                        cnt1 = cnt1 + 1;
                        chk = true;
                        if cnt1 > dimF
                            cnt1 = 1;
                            chk = false;
                        end
                        app.TrajMovieFrameEditField.Value = cnt1;
                    end
                    if (app.TrajMovieCheckBox2.Value == 1) && (chk == false)
                        cnt2 = cnt2 + 1;
                        chk = true;
                        if cnt2 > dimZ
                            cnt2 = 1;
                            chk = false;
                        end
                        app.TrajMovieSliceEditField.Value = cnt2;
                    end
                    if (app.TrajMovieCheckBox3.Value == 1) && (chk == false)
                        cnt3 = cnt3 + 1;
                        chk = true; %#ok<*NASGU>
                        if cnt3 > dimD
                            cnt3 = 1;
                            chk = false;
                        end
                        app.TrajMovieDynEditField.Value = cnt3;
                    end

                    % Plot the new movie frame
                    app.PlotTrajectoryMovieFrameFcn(app.trajPlotData{cnt1,cnt2,cnt3,1},app.trajPlotData{cnt1,cnt2,cnt3,2},app.trajPlotData{cnt1,cnt2,cnt3,3},dimX,false);

                    % Slow movie a bit down with small number of frames
                    mvTime = toc;
                    if mvTime < minTimePerTrajFrame
                        pause(minTimePerTrajFrame-mvTime);
                    end
                    drawnow;
                end

            catch ME

                if isvalid(app)
                    app.TextMessage(ME.message,2);
                end

            end

        end

        % Button pushed function: TrajMovieStopButton
        function StopTrajMovieButtonPushed(app, event)

            % Purpose:
            %   Stop the 2D trajectory movie.
            %
            % How it works:
            %   Raises the stop flag the loop checks each pass and re-enables the trajectory
            %   buttons and exit. Exit is disabled while a movie runs, so it is restored
            %   here.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop the radial trajectory movie
            app.stopRadialMovieFlag = true;

            % Reset buttons
            app.TrajMovieButtonsFcn(true);
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Value changed function: TrajMovieDynEditField, 
        % ...and 2 other components
        function TrajMovieDimEditFieldValueChanged(app, event)

            % Purpose:
            %   Take a change to which part of the trajectory is animated.
            %
            % How it works:
            %   Only for the data types that have a trajectory to draw, and only once sorting
            %   has produced one.
            %
            %   The frame, slice and dynamic counters select which spokes are shown, and the
            %   image dimension is derived from the trajectory extent itself rather than from
            %   the matrix size, so the axes fit whatever was actually acquired.
            %
            %   The counters are read together because a trajectory is drawn for one
            %   combination of the three at a time.
            %
            %   A frame chosen while the movie plays stops the movie first: the movie
            %   writes into the lines drawn here, one for each shown spoke of its own
            %   frames, and would overwrite the fields again.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            if app.validSortFlag

                switch app.r.dataType

                    case {'2Dradial','2Dradialms','3Dute','ZTE'}

                        % A playing movie stops
                        if ~app.stopRadialMovieFlag
                            app.StopTrajMovieButtonPushed;
                        end

                        % Image dimension
                        dimx = 2*max(app.r.kSpaceTraj(:));

                        % Set the counter
                        cnt1 = app.TrajMovieFrameEditField.Value;
                        cnt2 = app.TrajMovieSliceEditField.Value;
                        cnt3 = app.TrajMovieDynEditField.Value;

                        % Check limits of the dimensions
                        dimF = size(app.r.kSpaceTraj,1);
                        dimZ = size(app.r.kSpaceTraj,4);
                        dimD = size(app.r.kSpaceTraj,5);
                        cnt1(cnt1>dimF) = 1;
                        cnt2(cnt2>dimZ) = 1;
                        cnt3(cnt3>dimD) = 1;
                        app.TrajMovieFrameEditField.Value = cnt1;
                        app.TrajMovieSliceEditField.Value = cnt2;
                        app.TrajMovieDynEditField.Value = cnt3;

                        % Data
                        xc = squeeze(app.r.kSpaceTraj(cnt1,:,:,cnt2,cnt3,1))';
                        yc = squeeze(app.r.kSpaceTraj(cnt1,:,:,cnt2,cnt3,2))';
                        zc = squeeze(app.r.kSpaceTraj(cnt1,:,:,cnt2,cnt3,3))';

                        % Remove all zero entries
                        ze = ~((xc(:,end)==0) & (yc(:,end)==0) & (zc(:,end)==0));
                        xc = xc(ze,[1 end])';
                        yc = yc(ze,[1 end])';
                        zc = zc(ze,[1 end])';

                        % Message
                        app.TextMessage('Displaying all spokes ...');

                        % Plot the new movie frame
                        app.PlotTrajectoryMovieFrameFcn(xc,yc,zc,dimx,true);

                end

            end

        end

        % Value changed function: TrajMovieCheckBox1, TrajMovieCheckBox2, 
        % ...and 1 other component
        function TrajMovieCheckBoxValueChanged(app, event)

            % Purpose:
            %   Keep at least one trajectory axis selected.
            %
            % How it works:
            %   If all three boxes end up unticked the first is ticked again, the same rule as
            %   the movie checkboxes: with none selected the animation has nowhere to draw
            %   and the start button appears to do nothing.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % At least first dimension of the trajectory movie should be active
            if (app.TrajMovieCheckBox1.Value == 0) && (app.TrajMovieCheckBox2.Value == 0) && (app.TrajMovieCheckBox3.Value == 0)
                app.TrajMovieCheckBox1.Value = 1;
            end

        end

        % Button pushed function: TrajMovie3DStartButton
        function StartTrajMovie3DButtonPushed(app, event)

            % Purpose:
            %   Animate the 3D k-space trajectory, for UTE and ZTE.
            %
            % How it works:
            %   The 3D counterpart of StartTrajMovieButtonPushed. The spokes run outward on a
            %   sphere rather than in a plane, so the plot carries a third axis and a viewing
            %   angle.
            %
            %   Watching it is how the coverage of the sphere is judged -- whether the
            %   directions are spread evenly or clustered, which is what decides how the
            %   reconstruction behaves at the periphery.
            %
            %   The same structure as the other movies: other movies stopped first, the plot
            %   created on the first frame and updated thereafter, the stop flag checked each
            %   pass.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop all movies
            app.StopAllMoviesFcn;

            % Stop movie is false
            app.stop3DMovieFlag = false;

            % Stop other movies
            app.StopTrajMovieButtonPushed;
            app.StopMovieButtonPushed;

            % Buttons
            app.MovieButtonsFcn(true);
            app.TrajMovieButtonsFcn(true);
            app.Traj3DMovieButtonsFcn(true);

            % Initialize
            numberOfPoints = 10;
            pointList = zeros(numberOfPoints,3);
            trajectoryPoints = app.r.trajectory'/max(app.r.trajectory(:));
            maxLength = size(trajectoryPoints,1);
            minTimePerTraj3DFrame = 0.005;

            % Initialize figure
            cla(app.TrajectoryLargeFig);
            reset(app.TrajectoryLargeFig);
            plot3(app.TrajectoryLargeFig,[],[],[]);
            view(app.TrajectoryLargeFig,[-37.5 30]);
            xlim(app.TrajectoryLargeFig,[-1.1 1.1]);
            ylim(app.TrajectoryLargeFig,[-1.1 1.1]);
            zlim(app.TrajectoryLargeFig,[-1.1 1.1]);
            set(app.TrajectoryLargeFig,'XTickLabel',[]);
            set(app.TrajectoryLargeFig,'YTickLabel',[]);
            set(app.TrajectoryLargeFig,'ZTickLabel',[]);
            box(app.TrajectoryLargeFig,'on');
            app.TrajectoryLargeFig.Interactions = rotateInteraction;
            app.TrajectoryLargeFig.Toolbar.Visible = 'off';

            % Create the plot elements
            hold(app.TrajectoryLargeFig,'on');
            traj3DPlot1a = plot3(app.TrajectoryLargeFig,pointList(1:5,1),pointList(1:5,2),pointList(1:5,3),'LineWidth',2,'Color',app.blue);
            traj3DPlot1b = plot3(app.TrajectoryLargeFig,pointList(5:8,1),pointList(5:8,2),pointList(5:8,3),'LineWidth',1,'Color',app.blue);
            traj3DPlot1c = plot3(app.TrajectoryLargeFig,pointList(8:10,1),pointList(8:10,2),pointList(8:10,3),'LineWidth',0.5,'Color',app.blue);
            traj3DPlot2 = plot3(app.TrajectoryLargeFig,[0 pointList(1,1)],[0 pointList(1,2)],[0 pointList(1,3)],'LineWidth',3,'Color',app.red);
            traj3DPlot3 = plot3(app.TrajectoryLargeFig,[-1.1 1.1],[0 0],[0 0],':','LineWidth',1,'Color',app.green);
            traj3DPlot4 = plot3(app.TrajectoryLargeFig,[0 0],[-1.1 1.1],[0 0],':','LineWidth',1,'Color',app.green);
            traj3DPlot5 = plot3(app.TrajectoryLargeFig,[0 0],[0 0],[-1.1 1.1],':','LineWidth',1,'Color',app.green);
            hold(app.TrajectoryLargeFig,'off');

            app.traj3Dcnt = app.SpokeEditField.Value;
            app.traj3Dcnt(app.traj3Dcnt > maxLength) = 1;

            try % To catch error during exit, while movie is still running
                while isvalid(app) && ~app.stop3DMovieFlag
                    % Timing
                    tic;

                    % FIFO
                    pointList = circshift(pointList,1);
                    pointList(1,:) = trajectoryPoints(app.traj3Dcnt,:);

                    % Update the animated plot elements
                    set(traj3DPlot1a ,'XData',pointList(1:5,1));
                    set(traj3DPlot1a ,'YData',pointList(1:5,2));
                    set(traj3DPlot1a ,'ZData',pointList(1:5,3));
                    set(traj3DPlot1b ,'XData',pointList(5:8,1));
                    set(traj3DPlot1b ,'YData',pointList(5:8,2));
                    set(traj3DPlot1b ,'ZData',pointList(5:8,3));
                    set(traj3DPlot1c ,'XData',pointList(8:10,1));
                    set(traj3DPlot1c ,'YData',pointList(8:10,2));
                    set(traj3DPlot1c ,'ZData',pointList(8:10,3));
                    set(traj3DPlot2 ,'XData',[0 pointList(1,1)]);
                    set(traj3DPlot2 ,'YData',[0 pointList(1,2)]);
                    set(traj3DPlot2 ,'ZData',[0 pointList(1,3)]);
                    app.traj3Dcnt = app.traj3Dcnt + 1;
                    app.traj3Dcnt(app.traj3Dcnt > maxLength) = 1;
                    app.SpokeEditField.Value = app.traj3Dcnt;

                    % Limit speed
                    mvTime = toc;
                    if mvTime < minTimePerTraj3DFrame
                        pause(minTimePerTraj3DFrame-mvTime);
                    end
                    drawnow;

                end

            catch ME

                if isvalid(app)
                    app.TextMessage(ME.message,2);
                end

            end

        end

        % Button pushed function: TrajMovie3DStopButton
        function StopTrajMovie3DButtonPushed(app, event)

            % Purpose:
            %   Stop the 3D trajectory movie.
            %
            % How it works:
            %   Raises its stop flag and restores the trajectory buttons and exit, as the 2D
            %   counterpart does.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            app.stop3DMovieFlag = true;

            % Reset buttons
            app.Traj3DMovieButtonsFcn(true);
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Button pushed function: SegmentLVButton
        function SegmentLVButtonPushed(app, event)

            % Purpose:
            %   Segment the left ventricle in the reconstructed cine.
            %
            % How it works:
            %   The interface is put into its busy state and the flag cleared first, so no
            %   contour from a previous segmentation is drawn over the new images while this
            %   runs.
            %
            %   The work is retroData.segmentLV, which writes the images to a temporary file,
            %   runs the network as a separate process and reads the labels back.
            %
            %   validSegmentationFlag is set straight after the call returns, which is why
            %   segmentLV raises on failure rather than reporting and returning: a quiet
            %   return would mark an empty segmentation as a valid one.
            %
            %   The contours appear on the movie as soon as the flag is set, being drawn by
            %   the movie plot from the label maps.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            app.validSegmentationFlag = false;

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SwitchOffAllButtonsFcn;
            app.SetStatus(3);

            try
                % Do the segmentation
                app.r = app.r.segmentLV(app);
                app.r = app.r.lvVolumetry(app);

                % Segmentation successful
                app.validSegmentationFlag = true;
                app.SetStatus(0);

            catch ME
                % Segmentation not successful
                app.TextMessage('ERROR: Segmentation failed ...');
                app.TextMessage(ME.message);
                app.SetStatus(2);
            end

            % Reset buttons
            app.SetDashboardButtonsFcn(255);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtonsFcn(true);
            app.StartMovieButtonPushed;

        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)

            % Purpose:
            %   Export the reconstructed images as DICOM.
            %
            % How it works:
            %   The scanner own DICOM files are looked for first. When they are found they
            %   supply the header, so the export keeps the patient and study identity and
            %   lands in the same study; when they are not, the header is built from the MRD
            %   parameters instead.
            %
            %   Which of the two runs is decided by whether the search found anything, not by
            %   a setting -- there is nothing for the user to choose here, only what exists.
            %
            %   The geometry written into each header comes from the reconstruction rather
            %   than the source, so the exported stack sits where it was reconstructed.
            %
            %   The LV segmentation's files (the volumetry workbook, the Medis and Horos
            %   contours, and in exportNifti the label map) go with the images only when the
            %   segmentation belongs to them (validSegmentationFlag): labels left from before
            %   a new reconstruction, a cine change or a failed segmentation describe others.
            %
            %   Wrapped, with the busy state cleared either way, so a failed export leaves the
            %   interface usable rather than stuck on busy.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SwitchOffAllButtonsFcn;
            app.Lamp5.Color = app.yellow;
            app.SetStatus(3);

            try

                % Search for dicom file
                app.TextMessage('DICOM export ...');

                % Where the scanner's images could be, and where an export of them
                % should go. The candidates carry both, so nothing here has to know
                % how the layouts differ; see retro.io.dicomSourceCandidates.
                [dcmCandidates, btypeFolders] = retro.io.dicomSourceCandidates(app.mrdImportPath, app.r.scanner);

                % ParaVision writes its images without an extension and the search
                % asks for *.dcm, so give them one first. This renames the user's
                % files, which is why it is its own line here rather than something
                % that happens quietly while the candidates are built.
                if app.r.scanner == "B-type"
                    app.TextMessage('Searching B-type scanner dicom images ...',3);

                    for fn = 1:numel(btypeFolders)
                        try
                            app.TextMessage(strcat("Searching B-type scanner dicom folder = ",btypeFolders{fn}),3);
                            renamed = retro.io.addDcmExtension(btypeFolders{fn}, app.r.reporter);
                            if renamed > 0
                                app.TextMessage(strcat("Added dcm extension to ",num2str(renamed)," file(s)"),3);
                            end
                        catch ME1
                            app.TextMessage(ME1.message,2);
                            app.TextMessage("WARNING: B-type DICOM folder search failed: ");
                        end
                    end
                end

                % First candidate that holds images wins
                [dcmExist, dcmPath, foundExportPath] = retro.io.findDicomSource(dcmCandidates);

                % Export to DICOM
                if dcmExist
                    % The export folder is only taken from a hit. With no hit the
                    % previous one stays, because it is what the folder picker in
                    % the other branch opens on.
                    app.dicomExportPath = foundExportPath;

                    % Export, reading dicom information from dcmPath
                    if ~retro.io.isWritableFolder(app.dicomExportPath)
                        app.dicomExportPath = askForFolder(app, app.dicomExportPath, "DICOM export folder");
                    end
                    app.r.exportPath = retro.io.exportDicomDcm(app.r, retro.asParams(app), app.r.reporter, dcmPath);
                    app.r.exportRecoParametersFcn(app);
                    if app.validSegmentationFlag
                        retro.io.exportSegmentationResults(app.r, app, app.r.exportPath);
                        retro.io.exportSegmentationContours(app.r, app, app.r.exportPath);
                    end
                else
                    % Export without image orientation information
                    app.dicomExportPath = askForFolder(app, app.dicomExportPath, "DICOM export folder");
                    app.TextMessage('WARNING: DICOM image orientation information not available ...');
                    app.SetStatus(1);
                    app.r.exportPath = retro.io.exportDicomMat(app.r, retro.asParams(app), app.r.reporter);
                    app.r.exportRecoParametersFcn(app);
                    if app.validSegmentationFlag
                        retro.io.exportSegmentationResults(app.r, app, app.r.exportPath);
                        retro.io.exportSegmentationContours(app.r, app, app.r.exportPath);
                    end
                end

                % Export successful
                app.Lamp5.Color = app.brightgreen;
                app.SetStatus(0);

                % Try additional nifti export
                niftiPath = '';
                try
                    niftiPath = retro.io.exportNifti(app.r, retro.asParams(app), app.r.reporter);
                    if app.validSegmentationFlag
                        retro.io.exportSegmentationResults(app.r, app, niftiPath);
                    end
                catch ME1
                    app.TextMessage(ME1.message);
                    app.TextMessage("ERROR: NIfti export failed ...");
                end

                % The gating traces in the format BIDS has for them, beside the NIfTI
                % they belong with, or beside the DICOM where no NIfTI was written
                physioPath = niftiPath;
                if ~isfolder(physioPath)
                    physioPath = app.r.exportPath;
                end
                retro.io.exportBidsPhysio(app.r, physioPath, app.tag, app.r.reporter);

            catch ME

                % Export not successful
                app.TextMessage('ERROR: DICOM export failed ...');
                app.TextMessage(ME.message);
                app.dicomExportPath = '';
                app.Lamp5.Color = app.brightred;
                app.SetStatus(2);

            end

            % Reset buttons
            app.SetDashboardButtonsFcn(255);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtonsFcn(true);

        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)

            % Purpose:
            %   Export the reconstructed movie as animated GIFs.
            %
            % How it works:
            %   One GIF per slice and echo, animated over the frames, which is the axis along
            %   which the same thing changes over time.
            %
            %   The export folder is established first and inside its own guard, since that is
            %   the step that fails when the path is not writable, and failing there should
            %   report the folder rather than the export.
            %
            %   The images are scaled to the 8 bit range GIF allows and enlarged, a
            %   reconstructed matrix being small on screen and a GIF having no zoom.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SwitchOffAllButtonsFcn;
            app.Lamp6.Color = app.yellow;
            app.SetStatus(3);
            app.TextMessage('Animated GIF export ...');

            % Establish export folder
            try
                % Where the GIFs go: beside the data when that folder can be worked out
                % and will take a file, otherwise wherever the user says.
                %
                % Deriving the folder and judging whether it is usable are two separate
                % questions. Joined in one try they gave the same answer for a path that
                % could not be built as for one that exists and cannot be written to, and
                % the message said the second in both cases.
                try
                    gifexportPath = retro.io.gifExportFolder(app.mrdImportPath, app.r.scanner);
                catch ME1
                    app.TextMessage(ME1.message, 3);
                    gifexportPath = '';
                end
                if ~retro.io.isWritableFolder(gifexportPath)
                    gifexportPath = askForFolder(app, app.gifExportPath, 'GIF export path');
                end

                % Store export path before export so it is available if export errors partway
                app.gifExportPath = gifexportPath;

                % Export to animated gif
                app.TextMessage('GIF export ...');
                app.r.exportPath = retro.io.exportGif(app.r, retro.asParams(app), app.r.reporter);
                app.r.exportRecoParametersFcn(app);
                app.Lamp6.Color = app.brightgreen;
                app.SetStatus(0);
            catch ME
                app.TextMessage('ERROR: GIF export failed ...');
                app.TextMessage(ME.message,2);
                app.gifExportPath = '';
                app.Lamp6.Color = app.brightred;
                app.SetStatus(2);
            end

            % Reset buttons
            app.SetDashboardButtonsFcn(255);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtonsFcn(true);

        end

        % Button pushed function: ExportMRDButton
        function ExportMRDButtonPushed(app, event)

            % Purpose:
            %   Export the reconstruction as MRD, and as SUR files where the scanner
            %   software can produce them.
            %
            % How it works:
            %   The images are transformed back to k-space first, because MRD stores k-space
            %   rather than images. What is written is not the k-space that was measured: it
            %   is a complete, regularly sampled k-space computed from the finished images,
            %   so the binning, the sharing and any filtering are already in it and the file
            %   reloads as an ordinary dataset.
            %
            %   The footer and the RPR file are rewritten to describe the reconstruction
            %   rather than the acquisition, since the dimensions and the sampling have both
            %   changed. Leaving them would have the exported data read back with the wrong
            %   shape.
            %
            %   The SUR export is attempted only where the MR Solutions reconstruction server
            %   is available, and its absence is not a failure of the MRD export.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SwitchOffAllButtonsFcn;
            app.Lamp7.Color = app.yellow;
            app.SetStatus(3);
            app.TextMessage('MRD and SUR file export ...');

            try
                % Make new k-space from the movie
                app.r = app.r.backToKspace();

                % Set new parameters for MRD footer
                [app.r, par] = retro.io.newMrdParameters(app.r, app);

                % Create new MRD footer
                app.r = app.r.makeMrdFooter(par);

                % Create new RPR data
                if app.r.rprFlag
                    app.r = app.r.makeRprFile(par);
                end

                % Export the new MRD and rpr files to the original MRD path
                app.mrdExportPath = app.mrdImportPath;

                % Check if directory is actually writable, else ask for different folder
                % Write where the data came from, or ask when that folder will not take a file
                if ~retro.io.isWritableFolder(app.mrdExportPath)
                    app.mrdExportPath = askForFolder(app, app.mrdExportPath, 'MRD export folder');
                end

                % Construct final MRD and RPR path/filenames
                mrdExportFilename = strcat(app.mrdExportPath,filesep,'retro_',num2str(par.NoExperiments),'_',num2str(par.NoEchoes),'_',app.tag,'.MRD');
                rprExportFilename = strcat(app.mrdExportPath,filesep,'retro_',num2str(par.NoExperiments),'_',num2str(par.NoEchoes),'_',app.tag,'.rpr');

                % Write new MRD file
                retro.io.writeDataToMrd(app.r, mrdExportFilename, par);

                % Write new RPR file
                if app.r.rprFlag
                    retro.io.writeToRprFile(app.r, rprExportFilename);
                end

                % Export physiological logs
                retro.io.writePhysLog(app.r, app.mrdExportPath);

                % Export reconstruction parameters log
                app.r.exportPath = app.mrdExportPath;
                app.r = app.r.exportRecoParametersFcn(app);

                % If PC, try to autoreconstruct and export SUR files
                if ispc
                    % Determine SUR directory
                    surList = retro.io.surCandidates(app.mrdImportPath, ...
                        app.jsonPar.regEntriesSur, app.r.reporter);
                    [surPathFound, surPath, surSource] = retro.io.findSurFolder(surList);

                    % If SUR / image path successfully constructed
                    if surPathFound
                        app.TextMessage(strcat("SUR directory = ",surPath," (from the ",surSource,")"));

                        try
                            % SUR file names
                            surSuffix = extractBefore(app.tag,'_');

                            % Make and save the new SUR files
                            retro.io.recoSurFiles(app.r, surPath,surSuffix,mrdExportFilename,rprExportFilename);

                            % Create new DCM files from SUR files
                            retro.io.surToDcm(app.r, app, surPath);
                        catch ME1
                            app.TextMessage('WARNING: Automatic reconstruction of SUR and DCM files failed ...');
                            app.TextMessage(ME1.message,2);
                            app.SetStatus(1);
                        end
                    end
                end

                % Export data to Mat file
                app.r = retro.io.exportToMatFile(app.r, app, par);

                % Completed MRD, SUR, and k-space export successfully
                app.Lamp7.Color = app.brightgreen;
                app.SetStatus(0);

            catch ME

                % No success
                app.TextMessage('ERROR: MRD export failed ...');
                app.TextMessage(ME.message,2);
                app.mrdExportPath = '';
                app.Lamp7.Color = app.brightred;
                app.SetStatus(2);

            end

            % Reset buttons
            app.SetDashboardButtonsFcn(255);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtonsFcn(true);

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)

            % Purpose:
            %   Handle the keyboard shortcuts.
            %
            % How it works:
            %   Two keys. "o" toggles the orientation labels, which is the quickest way to
            %   take them off an image that is being read closely, and "s" saves the whole
            %   interface as a png.
            %
            %   Each reports what it did to the message pane, since a keystroke has no
            %   control to show its state.
            %
            %   Wrapped, because a key press reaching the app at an awkward moment -- during
            %   a reconstruction, or while the figure is being torn down -- must not raise.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the interface is updated

            key = event.Key;

            try
                switch key
                    case 'o'
                        % Toggle image orientations labels flag
                        app.orientationsFlag = ~app.orientationsFlag;
                        if app.orientationsFlag
                            app.TextMessage('Image orientation labels on ...');
                        else
                            app.TextMessage('Image Orientation labels off ...');
                        end
                    case 's'
                        % Save the app ui in a png image
                        app.TextMessage('Saving UI ...');
                        exportapp(app.UIFigure,"retrospective10UI.png");
                end
            catch ME
                app.TextMessage(ME.message);
            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)

            % Purpose:
            %   Shut the application down cleanly.
            %
            % How it works:
            %   Hands over to CloseAppFcn, which closing the window uses as well, so that both
            %   shut down the same way: movies stopped, the folder watcher stopped, the added
            %   paths removed, the log closed, the warning state restored and the app deleted.
            %   While the app is still busy, the close waits for that to finish.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the app is closed, or its close is scheduled

            app.CloseAppFcn;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            % Purpose:
            %   Shut down when the window is closed rather than the exit button pressed.
            %
            % How it works:
            %   Closing the window bypasses the exit button entirely, so this hands over to
            %   the same CloseAppFcn; without it the movies, the folder watcher and the log
            %   file would be left running.
            %
            % Inputs:
            %   app, event - the standard callback arguments
            %
            % Output:
            %   none - the app is closed, or its close is scheduled

            app.CloseAppFcn;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Ricon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Credits = uilabel(app.UIFigure);
            app.Credits.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.Credits.Interpreter = 'html';
            app.Credits.Text = 'Gustav Strijkers, Amsterdam UMC, Version X.X.X';
            app.TulipImage = uiimage(app.UIFigure);
            app.MRSImage = uiimage(app.UIFigure);
            app.StatusBackgroundImage = uiimage(app.UIFigure);
            app.StatusBackgroundBorder = uieditfield(app.UIFigure, 'text');
            app.StatusImage = uiimage(app.UIFigure);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'status';
            app.ReconstructionTabGroup = uitabgroup(app.UIFigure);
            app.RecoTab = uitab(app.ReconstructionTabGroup);
            app.RecoTab.Title = 'Reco';
            app.MovieFig = uiaxes(app.RecoTab);
            app.MovieFig.XTick = [];
            app.MovieFig.YTick = [];
            app.MovieStopButton = uibutton(app.RecoTab, 'push');
            app.MovieStopButton.ButtonPushedFcn = createCallbackFcn(app, @StopMovieButtonPushed, true);
            app.MovieStopButton.Enable = 'off';
            app.MovieStopButton.Tooltip = {'Stop the movie'};
            app.MovieStopButton.Text = 'Stop';
            app.MovieStartButton = uibutton(app.RecoTab, 'push');
            app.MovieStartButton.ButtonPushedFcn = createCallbackFcn(app, @StartMovieButtonPushed, true);
            app.MovieStartButton.Enable = 'off';
            app.MovieStartButton.Tooltip = {'Start the movie'};
            app.MovieStartButton.Text = 'Start';
            app.ScaleLabel = uilabel(app.RecoTab);
            app.ScaleLabel.Tooltip = {'Level and Window'};
            app.ScaleLabel.Text = 'Scale';
            app.MoviecwButton = uibutton(app.RecoTab, 'push');
            app.MoviecwButton.ButtonPushedFcn = createCallbackFcn(app, @MovieRotateMoviecwButtonPushed, true);
            app.MoviecwButton.Enable = 'off';
            app.MoviecwButton.Tooltip = {'Rotate movie cw'};
            app.MoviecwButton.Text = '';
            app.MovieccwButton = uibutton(app.RecoTab, 'push');
            app.MovieccwButton.ButtonPushedFcn = createCallbackFcn(app, @MovieRotateMovieccwButtonPushed, true);
            app.MovieccwButton.Enable = 'off';
            app.MovieccwButton.Tooltip = {'Rotate movie cc'};
            app.MovieccwButton.Text = '';
            app.MovieRotateLabel = uilabel(app.RecoTab);
            app.MovieRotateLabel.Enable = 'off';
            app.MovieRotateLabel.Tooltip = {'Rotate movie'};
            app.MovieRotateLabel.Text = 'rotate';
            app.MovieFrameIncrButton = uibutton(app.RecoTab, 'push');
            app.MovieFrameIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieFrameIncrButtonPushed, true);
            app.MovieFrameIncrButton.Enable = 'off';
            app.MovieFrameIncrButton.Tooltip = {'Frame number +1'};
            app.MovieFrameIncrButton.Text = '+1';
            app.MovieFrameLabel = uilabel(app.RecoTab);
            app.MovieFrameLabel.Enable = 'off';
            app.MovieFrameLabel.Tooltip = {'Frame'};
            app.MovieFrameLabel.Text = 'frame';
            app.MovieFrameDecrButton = uibutton(app.RecoTab, 'push');
            app.MovieFrameDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieFrameDecrButtonPushed, true);
            app.MovieFrameDecrButton.Enable = 'off';
            app.MovieFrameDecrButton.Tooltip = {'Frame number -1'};
            app.MovieFrameDecrButton.Text = '-1';
            app.MovieSliceIncrButton = uibutton(app.RecoTab, 'push');
            app.MovieSliceIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieSliceIncrButtonPushed, true);
            app.MovieSliceIncrButton.Enable = 'off';
            app.MovieSliceIncrButton.Tooltip = {'Slice number +1'};
            app.MovieSliceIncrButton.Text = '+1';
            app.MovieSliceLabel = uilabel(app.RecoTab);
            app.MovieSliceLabel.Enable = 'off';
            app.MovieSliceLabel.Tooltip = {'Slice'};
            app.MovieSliceLabel.Text = 'slice';
            app.MovieSliceDecrButton = uibutton(app.RecoTab, 'push');
            app.MovieSliceDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieSliceDecrButtonPushed, true);
            app.MovieSliceDecrButton.Enable = 'off';
            app.MovieSliceDecrButton.Tooltip = {'Slice number -1'};
            app.MovieSliceDecrButton.Text = '-1';
            app.ButtonGroup = uibuttongroup(app.RecoTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MovieButtonGroupSelectionChanged, true);
            app.MovieButton = uiradiobutton(app.ButtonGroup);
            app.MovieButton.Enable = 'off';
            app.MovieButton.Tooltip = {'Movie display'};
            app.MovieButton.Text = 'movie';
            app.MovieButton.Interpreter = 'html';
            app.MovieButton.Value = true;
            app.AveragesButton = uiradiobutton(app.ButtonGroup);
            app.AveragesButton.Enable = 'off';
            app.AveragesButton.Tooltip = {'Map of the number of k-space signal averages'};
            app.AveragesButton.Text = 'averages';
            app.AveragesButton.Interpreter = 'html';
            app.kSpaceButton = uiradiobutton(app.ButtonGroup);
            app.kSpaceButton.Enable = 'off';
            app.kSpaceButton.Tooltip = {'Absolute value of k-space'};
            app.kSpaceButton.Text = '|k-space|';
            app.kSpaceButton.Interpreter = 'html';
            app.SenseMapButton = uiradiobutton(app.ButtonGroup);
            app.SenseMapButton.Enable = 'off';
            app.SenseMapButton.Tooltip = {'Coil sensitivity maps'};
            app.SenseMapButton.Text = 'sense-map';
            app.SenseMapButton.Interpreter = 'html';
            app.MovieCoilSpinner = uispinner(app.RecoTab);
            app.MovieCoilSpinner.Limits = [1 16];
            app.MovieCoilSpinner.ValueDisplayFormat = '%.0f';
            app.MovieCoilSpinner.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.MovieCoilSpinner.Enable = 'off';
            app.MovieCoilSpinner.Value = 1;
            app.SenseMapSpinner1 = uispinner(app.RecoTab);
            app.SenseMapSpinner1.Limits = [1 16];
            app.SenseMapSpinner1.ValueDisplayFormat = '%.0f';
            app.SenseMapSpinner1.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.SenseMapSpinner1.Enable = 'off';
            app.SenseMapSpinner1.Value = 1;
            app.SenseMapSpinner2 = uispinner(app.RecoTab);
            app.SenseMapSpinner2.Limits = [1 16];
            app.SenseMapSpinner2.ValueDisplayFormat = '%.0f';
            app.SenseMapSpinner2.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.SenseMapSpinner2.Enable = 'off';
            app.SenseMapSpinner2.Value = 1;
            app.MovieDynIncrButton = uibutton(app.RecoTab, 'push');
            app.MovieDynIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieDynIncrButtonPushed, true);
            app.MovieDynIncrButton.Enable = 'off';
            app.MovieDynIncrButton.Tooltip = {'Dynamic number +1'};
            app.MovieDynIncrButton.Text = '+1';
            app.MovieDynLabel = uilabel(app.RecoTab);
            app.MovieDynLabel.Enable = 'off';
            app.MovieDynLabel.Tooltip = {'Dynamic'};
            app.MovieDynLabel.Text = 'dynamic';
            app.MovieDynDecrButton = uibutton(app.RecoTab, 'push');
            app.MovieDynDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieDynDecrButtonPushed, true);
            app.MovieDynDecrButton.Enable = 'off';
            app.MovieDynDecrButton.Tooltip = {'Dynamic number -1'};
            app.MovieDynDecrButton.Text = '-1';
            app.MovieCheckBox3 = uicheckbox(app.RecoTab);
            app.MovieCheckBox3.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox3.Enable = 'off';
            app.MovieCheckBox3.Text = '';
            app.MovieCheckBox2 = uicheckbox(app.RecoTab);
            app.MovieCheckBox2.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox2.Enable = 'off';
            app.MovieCheckBox2.Text = '';
            app.MovieCheckBox1 = uicheckbox(app.RecoTab);
            app.MovieCheckBox1.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox1.Enable = 'off';
            app.MovieCheckBox1.Text = '';
            app.MovieCheckBox1.Value = true;
            app.MovieFrameEditFieldLabel = uilabel(app.RecoTab);
            app.MovieFrameEditFieldLabel.Enable = 'off';
            app.MovieFrameEditFieldLabel.Tooltip = {'CINE frame number'};
            app.MovieFrameEditFieldLabel.Text = 'frame';
            app.MovieFrameEditField = uieditfield(app.RecoTab, 'numeric');
            app.MovieFrameEditField.Limits = [1 Inf];
            app.MovieFrameEditField.ValueDisplayFormat = '%.0f';
            app.MovieFrameEditField.ValueChangedFcn = createCallbackFcn(app, @MovieFrameEditFieldValueChanged, true);
            app.MovieFrameEditField.Enable = 'off';
            app.MovieFrameEditField.Tooltip = {'CINE frame number'};
            app.MovieFrameEditField.Value = 1;
            app.FinalHeartViewFieldLabel = uilabel(app.RecoTab);
            app.FinalHeartViewFieldLabel.Tooltip = {'Average heart rate.'};
            app.FinalHeartViewFieldLabel.Text = 'heart (bpm)';
            app.FinalHeartViewField = uieditfield(app.RecoTab, 'numeric');
            app.FinalHeartViewField.AllowEmpty = 'on';
            app.FinalHeartViewField.Editable = 'off';
            app.FinalHeartViewField.Tooltip = {'Heart rate (bpm)'};
            app.FinalRespViewFieldLabel = uilabel(app.RecoTab);
            app.FinalRespViewFieldLabel.Tooltip = {'Average respiration rate.'};
            app.FinalRespViewFieldLabel.Text = 'resp (bpm)';
            app.FinalRespViewField = uieditfield(app.RecoTab, 'numeric');
            app.FinalRespViewField.AllowEmpty = 'on';
            app.FinalRespViewField.Editable = 'off';
            app.FinalRespViewField.Tooltip = {'Respiration rate (bpm)'};
            app.MovieSliceEditFieldLabel = uilabel(app.RecoTab);
            app.MovieSliceEditFieldLabel.Enable = 'off';
            app.MovieSliceEditFieldLabel.Tooltip = {'Slice number'};
            app.MovieSliceEditFieldLabel.Text = 'slice';
            app.MovieSliceEditField = uieditfield(app.RecoTab, 'numeric');
            app.MovieSliceEditField.Limits = [1 Inf];
            app.MovieSliceEditField.ValueDisplayFormat = '%.0f';
            app.MovieSliceEditField.ValueChangedFcn = createCallbackFcn(app, @MovieSliceEditFieldValueChanged, true);
            app.MovieSliceEditField.Enable = 'off';
            app.MovieSliceEditField.Tooltip = {'Slice number'};
            app.MovieSliceEditField.Value = 1;
            app.WindowScaleSliderLabel = uilabel(app.RecoTab);
            app.WindowScaleSliderLabel.Enable = 'off';
            app.WindowScaleSliderLabel.Tooltip = {'Intensity scale adjustment.'};
            app.WindowScaleSliderLabel.Text = 'W';
            app.WindowScaleSlider = uislider(app.RecoTab);
            app.WindowScaleSlider.Limits = [0 32767];
            app.WindowScaleSlider.MajorTicks = [];
            app.WindowScaleSlider.ValueChangedFcn = createCallbackFcn(app, @MovieWindowScaleSliderValueChanged, true);
            app.WindowScaleSlider.MinorTicks = [0 4681 9362 14043 18724 23405 28086 32767];
            app.WindowScaleSlider.Enable = 'off';
            app.WindowScaleSlider.Tooltip = {'Window'};
            app.WindowScaleSlider.Value = 16384;
            app.LevelScaleSliderLabel = uilabel(app.RecoTab);
            app.LevelScaleSliderLabel.Enable = 'off';
            app.LevelScaleSliderLabel.Tooltip = {'Intensity scale adjustment.'};
            app.LevelScaleSliderLabel.Text = 'L';
            app.LevelScaleSlider = uislider(app.RecoTab);
            app.LevelScaleSlider.Limits = [0 32767];
            app.LevelScaleSlider.MajorTicks = [];
            app.LevelScaleSlider.ValueChangedFcn = createCallbackFcn(app, @MovieLevelScaleSliderValueChanged, true);
            app.LevelScaleSlider.MinorTicks = [0 4681 9362 14043 18724 23405 28086 32767];
            app.LevelScaleSlider.Enable = 'off';
            app.LevelScaleSlider.Tooltip = {'Level'};
            app.LevelScaleSlider.Value = 16384;
            app.MovieDynEditField = uieditfield(app.RecoTab, 'numeric');
            app.MovieDynEditField.Limits = [1 Inf];
            app.MovieDynEditField.ValueDisplayFormat = '%.0f';
            app.MovieDynEditField.ValueChangedFcn = createCallbackFcn(app, @MovieDynEditFieldValueChanged, true);
            app.MovieDynEditField.Enable = 'off';
            app.MovieDynEditField.Tooltip = {'Dynamic or flip-angle number'};
            app.MovieDynEditField.Value = 1;
            app.MovieDynEditFieldLabel = uilabel(app.RecoTab);
            app.MovieDynEditFieldLabel.Enable = 'off';
            app.MovieDynEditFieldLabel.Tooltip = {'Dynamic or flip-angle number'};
            app.MovieDynEditFieldLabel.Text = 'dynamic';
            app.AutoWLButton = uibutton(app.RecoTab, 'state');
            app.AutoWLButton.ValueChangedFcn = createCallbackFcn(app, @AutoWLButtonValueChanged, true);
            app.AutoWLButton.Tooltip = {'Auto window and level'};
            app.AutoWLButton.Enable = 'off';
            app.AutoWLButton.Text = 'Auto';
            app.AutoWLButton.Value = true;
            app.OrientationLabel2 = uilabel(app.RecoTab);
            app.OrientationLabel2.Text = '';
            app.OrientationLabel1 = uilabel(app.RecoTab);
            app.OrientationLabel1.Text = '';
            app.OrientationLabel3 = uilabel(app.RecoTab);
            app.OrientationLabel3.Text = '';
            app.OrientationLabel4 = uilabel(app.RecoTab);
            app.OrientationLabel4.Text = '';
            app.ShowNavButton = uibutton(app.RecoTab, 'push');
            app.ShowNavButton.ButtonPushedFcn = createCallbackFcn(app, @ShowNavButtonPushed, true);
            app.ShowNavButton.Enable = 'off';
            app.ShowNavButton.Tooltip = {'Show or hide the external navigator slice'};
            app.ShowNavButton.Text = 'Nav';
            app.SegmentLVButton = uibutton(app.RecoTab, 'push');
            app.SegmentLVButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentLVButtonPushed, true);
            app.SegmentLVButton.Enable = 'off';
            app.SegmentLVButton.Tooltip = {'Segment short-axis LV myocardium'};
            app.SegmentLVButton.Text = 'Segment LV';
            app.RadialRecoTab = uitab(app.ReconstructionTabGroup);
            app.RadialRecoTab.Title = 'Radial';
            app.TrajectoryLargeFig = uiaxes(app.RadialRecoTab);
            app.TrajectoryLargeFig.XTick = [];
            app.TrajectoryLargeFig.YTick = [];
            app.TrajMovieDynEditFieldLabel = uilabel(app.RadialRecoTab);
            app.TrajMovieDynEditFieldLabel.Enable = 'off';
            app.TrajMovieDynEditFieldLabel.Tooltip = {'Dynamic number'};
            app.TrajMovieDynEditFieldLabel.Text = 'dynamic';
            app.TrajMovieSliceEditFieldLabel = uilabel(app.RadialRecoTab);
            app.TrajMovieSliceEditFieldLabel.Enable = 'off';
            app.TrajMovieSliceEditFieldLabel.Tooltip = {'Slice number'};
            app.TrajMovieSliceEditFieldLabel.Text = 'slice';
            app.TrajMovieFrameEditFieldLabel = uilabel(app.RadialRecoTab);
            app.TrajMovieFrameEditFieldLabel.Enable = 'off';
            app.TrajMovieFrameEditFieldLabel.Tooltip = {'CINE frame number'};
            app.TrajMovieFrameEditFieldLabel.Text = 'frame';
            app.TrajMovieCheckBox1 = uicheckbox(app.RadialRecoTab);
            app.TrajMovieCheckBox1.ValueChangedFcn = createCallbackFcn(app, @TrajMovieCheckBoxValueChanged, true);
            app.TrajMovieCheckBox1.Enable = 'off';
            app.TrajMovieCheckBox1.Text = '';
            app.TrajMovieCheckBox1.Value = true;
            app.TrajMovieCheckBox2 = uicheckbox(app.RadialRecoTab);
            app.TrajMovieCheckBox2.ValueChangedFcn = createCallbackFcn(app, @TrajMovieCheckBoxValueChanged, true);
            app.TrajMovieCheckBox2.Enable = 'off';
            app.TrajMovieCheckBox2.Text = '';
            app.TrajMovieCheckBox3 = uicheckbox(app.RadialRecoTab);
            app.TrajMovieCheckBox3.ValueChangedFcn = createCallbackFcn(app, @TrajMovieCheckBoxValueChanged, true);
            app.TrajMovieCheckBox3.Enable = 'off';
            app.TrajMovieCheckBox3.Text = '';
            app.TrajMovieFrameEditField = uieditfield(app.RadialRecoTab, 'numeric');
            app.TrajMovieFrameEditField.Limits = [1 240];
            app.TrajMovieFrameEditField.ValueDisplayFormat = '%.0f';
            app.TrajMovieFrameEditField.ValueChangedFcn = createCallbackFcn(app, @TrajMovieDimEditFieldValueChanged, true);
            app.TrajMovieFrameEditField.Enable = 'off';
            app.TrajMovieFrameEditField.Tooltip = {'CINE frame number'};
            app.TrajMovieFrameEditField.Value = 1;
            app.TrajMovieSliceEditField = uieditfield(app.RadialRecoTab, 'numeric');
            app.TrajMovieSliceEditField.Limits = [1 1024];
            app.TrajMovieSliceEditField.ValueDisplayFormat = '%.0f';
            app.TrajMovieSliceEditField.ValueChangedFcn = createCallbackFcn(app, @TrajMovieDimEditFieldValueChanged, true);
            app.TrajMovieSliceEditField.Enable = 'off';
            app.TrajMovieSliceEditField.Tooltip = {'Slice number'};
            app.TrajMovieSliceEditField.Value = 1;
            app.TrajMovieDynEditField = uieditfield(app.RadialRecoTab, 'numeric');
            app.TrajMovieDynEditField.Limits = [1 1024];
            app.TrajMovieDynEditField.ValueDisplayFormat = '%.0f';
            app.TrajMovieDynEditField.ValueChangedFcn = createCallbackFcn(app, @TrajMovieDimEditFieldValueChanged, true);
            app.TrajMovieDynEditField.Enable = 'off';
            app.TrajMovieDynEditField.Tooltip = {'Dynamic number'};
            app.TrajMovieDynEditField.Value = 1;
            app.TrajMovieStartButton = uibutton(app.RadialRecoTab, 'push');
            app.TrajMovieStartButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrajMovieButtonPushed, true);
            app.TrajMovieStartButton.Enable = 'off';
            app.TrajMovieStartButton.Tooltip = {'Start the trajectory movie'};
            app.TrajMovieStartButton.Text = 'Start';
            app.TrajMovieStopButton = uibutton(app.RadialRecoTab, 'push');
            app.TrajMovieStopButton.ButtonPushedFcn = createCallbackFcn(app, @StopTrajMovieButtonPushed, true);
            app.TrajMovieStopButton.Enable = 'off';
            app.TrajMovieStopButton.Tooltip = {'Stop the trajectory movie'};
            app.TrajMovieStopButton.Text = 'Stop';
            app.SpokeEditField = uieditfield(app.RadialRecoTab, 'numeric');
            app.SpokeEditField.Limits = [1 Inf];
            app.SpokeEditField.ValueDisplayFormat = '%.0f';
            app.SpokeEditField.Enable = 'off';
            app.SpokeEditField.Tooltip = {'Spoke number'};
            app.SpokeEditField.Value = 1;
            app.SpokeEditFieldLabel = uilabel(app.RadialRecoTab);
            app.SpokeEditFieldLabel.Enable = 'off';
            app.SpokeEditFieldLabel.Text = 'spoke';
            app.Traj3DLabel = uilabel(app.RadialRecoTab);
            app.Traj3DLabel.Tooltip = {'3D radial trajectory'};
            app.Traj3DLabel.Text = '3D trajectory animation';
            app.TrajMovie3DStartButton = uibutton(app.RadialRecoTab, 'push');
            app.TrajMovie3DStartButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrajMovie3DButtonPushed, true);
            app.TrajMovie3DStartButton.Enable = 'off';
            app.TrajMovie3DStartButton.Tooltip = {'Start the trajectory movie'};
            app.TrajMovie3DStartButton.Text = 'Start';
            app.TrajMovie3DStopButton = uibutton(app.RadialRecoTab, 'push');
            app.TrajMovie3DStopButton.ButtonPushedFcn = createCallbackFcn(app, @StopTrajMovie3DButtonPushed, true);
            app.TrajMovie3DStopButton.Enable = 'off';
            app.TrajMovie3DStopButton.Tooltip = {'Stop the trajectory movie'};
            app.TrajMovie3DStopButton.Text = 'Stop';
            app.ParametersTabGroup = uitabgroup(app.UIFigure);
            app.SequenceTab = uitab(app.ParametersTabGroup);
            app.SequenceTab.Title = 'Sequence';
            app.TrajectoryFig = uiaxes(app.SequenceTab);
            app.TrajectoryFig.XTick = [];
            app.TrajectoryFig.YTick = [];
            app.DataFile = uitextarea(app.SequenceTab);
            app.DataFile.ValueChangedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.DataFile.Editable = 'off';
            app.DataFile.Tooltip = {'Data file'};
            app.DataFile.Value = {' '};
            app.DataFileLabel = uilabel(app.SequenceTab);
            app.DataFileLabel.Text = 'data file';
            app.NyViewFieldLabel = uilabel(app.SequenceTab);
            app.NyViewFieldLabel.Tooltip = {'Number of pixels in phase-encoding direction'};
            app.NyViewFieldLabel.Text = 'phase  Ny';
            app.NyViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NyViewField.Limits = [0 Inf];
            app.NyViewField.ValueDisplayFormat = '%.0f';
            app.NyViewField.Editable = 'off';
            app.NyViewField.Tooltip = {'Number of pixels in phase-encoding direction'};
            app.NxViewFieldLabel = uilabel(app.SequenceTab);
            app.NxViewFieldLabel.Tooltip = {'Number of pixels in readout direction'};
            app.NxViewFieldLabel.Text = 'freq  Nx';
            app.NxViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NxViewField.Limits = [0 Inf];
            app.NxViewField.ValueDisplayFormat = '%.0f';
            app.NxViewField.Editable = 'off';
            app.NxViewField.Tooltip = {'Number of pixels in readout direction'};
            app.TrajectorySizeViewFieldLabel = uilabel(app.SequenceTab);
            app.TrajectorySizeViewFieldLabel.Tooltip = {'Length of the trajectory'};
            app.TrajectorySizeViewFieldLabel.Text = 'size';
            app.TrajectorySizeViewField = uieditfield(app.SequenceTab, 'numeric');
            app.TrajectorySizeViewField.Limits = [0 Inf];
            app.TrajectorySizeViewField.ValueDisplayFormat = '%.0f';
            app.TrajectorySizeViewField.Editable = 'off';
            app.TrajectorySizeViewField.Tooltip = {'Size of the trajectory'};
            app.RepetitionsViewFieldLabel = uilabel(app.SequenceTab);
            app.RepetitionsViewFieldLabel.Tooltip = {'Number of k-space repetitions'};
            app.RepetitionsViewFieldLabel.Text = '#repetitions';
            app.RepetitionsViewField = uieditfield(app.SequenceTab, 'numeric');
            app.RepetitionsViewField.Limits = [0 Inf];
            app.RepetitionsViewField.RoundFractionalValues = 'on';
            app.RepetitionsViewField.ValueDisplayFormat = '%.0f';
            app.RepetitionsViewField.Editable = 'off';
            app.RepetitionsViewField.Tooltip = {'Number of k-space repetitions'};
            app.NrKlinesViewFieldLabel = uilabel(app.SequenceTab);
            app.NrKlinesViewFieldLabel.Tooltip = {'Total number of acquired/reconstructed k-lines'};
            app.NrKlinesViewFieldLabel.Text = ' #k-lines';
            app.NrKlinesViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NrKlinesViewField.Limits = [0 Inf];
            app.NrKlinesViewField.ValueDisplayFormat = '%.0f';
            app.NrKlinesViewField.Editable = 'off';
            app.NrKlinesViewField.Tooltip = {'Total number of acquired/reconstructed k-lines'};
            app.AveragesViewFieldLabel = uilabel(app.SequenceTab);
            app.AveragesViewFieldLabel.Tooltip = {'Mean number of signal averages'};
            app.AveragesViewFieldLabel.Text = 'averages';
            app.AveragesViewField = uieditfield(app.SequenceTab, 'numeric');
            app.AveragesViewField.Limits = [0 Inf];
            app.AveragesViewField.ValueDisplayFormat = '%.2f';
            app.AveragesViewField.Editable = 'off';
            app.AveragesViewField.Tooltip = {'Mean number of signal averages'};
            app.FillingViewFieldLabel = uilabel(app.SequenceTab);
            app.FillingViewFieldLabel.Tooltip = {'K-space filling percentage'};
            app.FillingViewFieldLabel.Text = 'filling (%)';
            app.FillingViewField = uieditfield(app.SequenceTab, 'numeric');
            app.FillingViewField.Limits = [0 100];
            app.FillingViewField.ValueDisplayFormat = '%.0f';
            app.FillingViewField.Editable = 'off';
            app.FillingViewField.Tooltip = {'K-space filling percentage'};
            app.TRViewFieldLabel = uilabel(app.SequenceTab);
            app.TRViewFieldLabel.Tooltip = {'Repetition time'};
            app.TRViewFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceTab, 'numeric');
            app.TRViewField.Limits = [0 Inf];
            app.TRViewField.ValueDisplayFormat = '%.2f';
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.TrajectoryViewFieldLabel = uilabel(app.SequenceTab);
            app.TrajectoryViewFieldLabel.Tooltip = {'K-space filling trajectory'};
            app.TrajectoryViewFieldLabel.Text = 'trajectory';
            app.TrajectoryViewField = uieditfield(app.SequenceTab, 'text');
            app.TrajectoryViewField.Editable = 'off';
            app.TrajectoryViewField.Tooltip = {'K-space filling trajectory'};
            app.TrajectoryViewField.Value = '-';
            app.NzViewFieldLabel = uilabel(app.SequenceTab);
            app.NzViewFieldLabel.Tooltip = {'Number of slices'};
            app.NzViewFieldLabel.Text = 'slices  Nz';
            app.NzViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NzViewField.Limits = [0 Inf];
            app.NzViewField.ValueDisplayFormat = '%.0f';
            app.NzViewField.Editable = 'off';
            app.NzViewField.Tooltip = {'Number of slices / 2nd phase-encoding direction'};
            app.NcViewFieldLabel = uilabel(app.SequenceTab);
            app.NcViewFieldLabel.Tooltip = {'Number of receiver coils'};
            app.NcViewFieldLabel.Text = 'coils  Nc';
            app.NcViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NcViewField.Limits = [0 Inf];
            app.NcViewField.ValueDisplayFormat = '%.0f';
            app.NcViewField.Editable = 'off';
            app.NcViewField.Tooltip = {'Number of receiver coils'};
            app.ScanTimeViewFieldLabel = uilabel(app.SequenceTab);
            app.ScanTimeViewFieldLabel.Tooltip = {'Total scan time'};
            app.ScanTimeViewFieldLabel.Text = 'scan time';
            app.ScanTimeViewField = uieditfield(app.SequenceTab, 'text');
            app.ScanTimeViewField.Editable = 'off';
            app.ScanTimeViewField.Tooltip = {'Total scan time'};
            app.ScanTimeViewField.Value = '-';
            app.TimeDynViewFieldLabel = uilabel(app.SequenceTab);
            app.TimeDynViewFieldLabel.Tooltip = {'Scan time per dynamic'};
            app.TimeDynViewFieldLabel.Text = 'time / dynamic';
            app.TimeDynViewField = uieditfield(app.SequenceTab, 'text');
            app.TimeDynViewField.Editable = 'off';
            app.TimeDynViewField.Tooltip = {'Scan time per dynamic'};
            app.TimeDynViewField.Value = '-';
            app.NrFlipAnglesViewFieldLabel = uilabel(app.SequenceTab);
            app.NrFlipAnglesViewFieldLabel.Tooltip = {'Number of flip-angles for T1-mapping'};
            app.NrFlipAnglesViewFieldLabel.Text = '#flip angles';
            app.NrFlipAnglesViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NrFlipAnglesViewField.Limits = [0 100];
            app.NrFlipAnglesViewField.ValueDisplayFormat = '%.0f';
            app.NrFlipAnglesViewField.Editable = 'off';
            app.NrFlipAnglesViewField.Tooltip = {'Number of flip-angles for T1-mapping'};
            app.FieldOfViewViewFieldLabel = uilabel(app.SequenceTab);
            app.FieldOfViewViewFieldLabel.Tooltip = {'Field of view'};
            app.FieldOfViewViewFieldLabel.Text = 'FOV (mm)';
            app.FieldOfViewViewField = uieditfield(app.SequenceTab, 'numeric');
            app.FieldOfViewViewField.Limits = [0 Inf];
            app.FieldOfViewViewField.ValueDisplayFormat = '%.2f';
            app.FieldOfViewViewField.Editable = 'off';
            app.FieldOfViewViewField.Tooltip = {'Field of view'};
            app.TEViewFieldLabel = uilabel(app.SequenceTab);
            app.TEViewFieldLabel.Tooltip = {'Echo time'};
            app.TEViewFieldLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.SequenceTab, 'numeric');
            app.TEViewField.Limits = [0 Inf];
            app.TEViewField.ValueDisplayFormat = '%.2f';
            app.TEViewField.Editable = 'off';
            app.TEViewField.Tooltip = {'Echo time'};
            app.FlipAnglesViewFieldLabel = uilabel(app.SequenceTab);
            app.FlipAnglesViewFieldLabel.Tooltip = {'Flip angle(s)'};
            app.FlipAnglesViewFieldLabel.Text = 'flip angle(s)';
            app.FlipAnglesViewField = uieditfield(app.SequenceTab, 'text');
            app.FlipAnglesViewField.Editable = 'off';
            app.FlipAnglesViewField.Tooltip = {'Flip angle(s)'};
            app.FlipAnglesViewField.Value = '-';
            app.GeometryTab = uitab(app.ParametersTabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.PhaseOrientationViewFieldLabel = uilabel(app.GeometryTab);
            app.PhaseOrientationViewFieldLabel.Tooltip = {'Phase orientation horizontal (1) or vertical (0)'};
            app.PhaseOrientationViewFieldLabel.Text = 'phase orientation';
            app.PhaseOrientationViewField = uieditfield(app.GeometryTab, 'numeric');
            app.PhaseOrientationViewField.Limits = [0 Inf];
            app.PhaseOrientationViewField.ValueDisplayFormat = '%.0f';
            app.PhaseOrientationViewField.Editable = 'off';
            app.PhaseOrientationViewField.Tooltip = {'Phase orientation horizontal (1) or vertical (0)'};
            app.SliceThicknessViewFieldLabel = uilabel(app.GeometryTab);
            app.SliceThicknessViewFieldLabel.Tooltip = {'Slice thickness'};
            app.SliceThicknessViewFieldLabel.Text = 'slice thickness (mm)';
            app.SliceThicknessViewField = uieditfield(app.GeometryTab, 'numeric');
            app.SliceThicknessViewField.Limits = [0 Inf];
            app.SliceThicknessViewField.ValueDisplayFormat = '%.2f';
            app.SliceThicknessViewField.Editable = 'off';
            app.SliceThicknessViewField.Tooltip = {'Slice thickness'};
            app.AspectRatioViewFieldLabel = uilabel(app.GeometryTab);
            app.AspectRatioViewFieldLabel.Tooltip = {'Image aspect ratio'};
            app.AspectRatioViewFieldLabel.Text = 'aspect ratio';
            app.AspectRatioViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AspectRatioViewField.Limits = [0 Inf];
            app.AspectRatioViewField.ValueDisplayFormat = '%.2f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Image aspect ratio'};
            app.FieldOfViewViewField2Label = uilabel(app.GeometryTab);
            app.FieldOfViewViewField2Label.Tooltip = {'Field of view'};
            app.FieldOfViewViewField2Label.Text = 'FOV (mm)';
            app.FieldOfViewViewField2 = uieditfield(app.GeometryTab, 'numeric');
            app.FieldOfViewViewField2.Limits = [0 Inf];
            app.FieldOfViewViewField2.ValueDisplayFormat = '%.2f';
            app.FieldOfViewViewField2.Editable = 'off';
            app.FieldOfViewViewField2.Tooltip = {'Field of view'};
            app.AngleZViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleZViewFieldLabel.Tooltip = {'Z angulation'};
            app.AngleZViewFieldLabel.Text = 'angle Z (deg)';
            app.AngleZViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleZViewField.ValueDisplayFormat = '%.0f';
            app.AngleZViewField.Editable = 'off';
            app.AngleZViewField.Tooltip = {'Z angulation'};
            app.AngleYViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleYViewFieldLabel.Tooltip = {'Y angulation'};
            app.AngleYViewFieldLabel.Text = 'angle Y (deg)';
            app.AngleYViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleYViewField.ValueDisplayFormat = '%.0f';
            app.AngleYViewField.Editable = 'off';
            app.AngleYViewField.Tooltip = {'Y angulation'};
            app.AngleXViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleXViewFieldLabel.Tooltip = {'X angulation'};
            app.AngleXViewFieldLabel.Text = 'angle X (deg)';
            app.AngleXViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleXViewField.ValueDisplayFormat = '%.0f';
            app.AngleXViewField.Editable = 'off';
            app.AngleXViewField.Tooltip = {'X angulation'};
            app.OffsetZViewFieldLabel = uilabel(app.GeometryTab);
            app.OffsetZViewFieldLabel.Tooltip = {'Slice / 2nd phase-encoding offset'};
            app.OffsetZViewFieldLabel.Text = 'offset Z (mm)';
            app.OffsetZViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetZViewField.ValueDisplayFormat = '%.2f';
            app.OffsetZViewField.Editable = 'off';
            app.OffsetZViewField.Tooltip = {'Slice / 2nd phase-encoding offset'};
            app.OffsetYViewFieldLabel = uilabel(app.GeometryTab);
            app.OffsetYViewFieldLabel.Tooltip = {'Phase-encoding offset'};
            app.OffsetYViewFieldLabel.Text = 'offset Y (mm)';
            app.OffsetYViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetYViewField.ValueDisplayFormat = '%.2f';
            app.OffsetYViewField.Editable = 'off';
            app.OffsetYViewField.Tooltip = {'Phase-encoding offset'};
            app.OffsetXViewFieldLabel = uilabel(app.GeometryTab);
            app.OffsetXViewFieldLabel.Tooltip = {'Readout offset'};
            app.OffsetXViewFieldLabel.Text = 'offset X (mm)';
            app.OffsetXViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetXViewField.ValueDisplayFormat = '%.2f';
            app.OffsetXViewField.Editable = 'off';
            app.OffsetXViewField.Tooltip = {'Readout offset'};
            app.SliceGapViewFieldLabel = uilabel(app.GeometryTab);
            app.SliceGapViewFieldLabel.Tooltip = {'Slice gap'};
            app.SliceGapViewFieldLabel.Text = 'slice gap (mm)';
            app.SliceGapViewField = uieditfield(app.GeometryTab, 'numeric');
            app.SliceGapViewField.ValueDisplayFormat = '%.2f';
            app.SliceGapViewField.Editable = 'off';
            app.SliceGapViewField.Tooltip = {'Slice gap'};
            app.RecoParametersTab = uitab(app.ParametersTabGroup);
            app.RecoParametersTab.Title = 'Reco';
            app.WVxyzEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.WVxyzEditField.Limits = [0 20];
            app.WVxyzEditField.ValueDisplayFormat = '%.4f';
            app.WVxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.WVxyzEditField.Enable = 'off';
            app.WVxyzEditField.Tooltip = {'Wavelet L1 regularization in spatial domain'};
            app.TVxyzEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.TVxyzEditField.Limits = [0 20];
            app.TVxyzEditField.ValueDisplayFormat = '%.4f';
            app.TVxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVxyzEditField.Enable = 'off';
            app.TVxyzEditField.Tooltip = {'Total Variation regularization in spatial domain'};
            app.LLRxyzEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.LLRxyzEditField.Limits = [0 20];
            app.LLRxyzEditField.ValueDisplayFormat = '%.4f';
            app.LLRxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.LLRxyzEditField.Enable = 'off';
            app.LLRxyzEditField.Tooltip = {'Locally Low-Rank regularization in spatial domain'};
            app.TVcineEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.TVcineEditField.Limits = [0 20];
            app.TVcineEditField.ValueDisplayFormat = '%.4f';
            app.TVcineEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVcineEditField.Enable = 'off';
            app.TVcineEditField.Tooltip = {'Total Variation regularization in CINE dimension'};
            app.TVcineEditField.Value = 0.1;
            app.TVdynEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.TVdynEditField.Limits = [0 20];
            app.TVdynEditField.ValueDisplayFormat = '%.4f';
            app.TVdynEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVdynEditField.Enable = 'off';
            app.TVdynEditField.Tooltip = {'Total Variation regularization in dynamic dimension'};
            app.ESPIRiTCheckBox = uicheckbox(app.RecoParametersTab);
            app.ESPIRiTCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.ESPIRiTCheckBox.Tooltip = {'ESPIRiT reconstruction of multi-coil data'};
            app.ESPIRiTCheckBox.Enable = 'off';
            app.ESPIRiTCheckBox.Text = '';
            app.NLMFCheckBox = uicheckbox(app.RecoParametersTab);
            app.NLMFCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.NLMFCheckBox.Tooltip = {'Non-local means filter image de-noising'};
            app.NLMFCheckBox.Enable = 'off';
            app.NLMFCheckBox.Text = '';
            app.NLMFfilterStrengthEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.NLMFfilterStrengthEditField.Limits = [0.0001 1];
            app.NLMFfilterStrengthEditField.ValueDisplayFormat = '%.4f';
            app.NLMFfilterStrengthEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.NLMFfilterStrengthEditField.Enable = 'off';
            app.NLMFfilterStrengthEditField.Tooltip = {'Non-local means filter image de-noising strength'};
            app.NLMFfilterStrengthEditField.Value = 0.005;
            app.RingRmCheckBox = uicheckbox(app.RecoParametersTab);
            app.RingRmCheckBox.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.RingRmCheckBox.Tooltip = {'Gibbs ringing suppression'};
            app.RingRmCheckBox.Enable = 'off';
            app.RingRmCheckBox.Text = '';
            app.ZeroFillCheckBox = uicheckbox(app.RecoParametersTab);
            app.ZeroFillCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.ZeroFillCheckBox.Tooltip = {'Automatic zero-filling to next power of 2'};
            app.ZeroFillCheckBox.Enable = 'off';
            app.ZeroFillCheckBox.Text = '';
            app.TukeyFilterCheckBox = uicheckbox(app.RecoParametersTab);
            app.TukeyFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.TukeyFilterCheckBox.Tooltip = {'Apply k-space Tukey filter'};
            app.TukeyFilterCheckBox.Enable = 'off';
            app.TukeyFilterCheckBox.Text = '';
            app.SharingEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.SharingEditField.Limits = [0 30];
            app.SharingEditField.ValueDisplayFormat = '%.0f';
            app.SharingEditField.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.SharingEditField.Enable = 'off';
            app.SharingEditField.Tooltip = {'Sharing of k-lines from neighboring frames to improve k-space filling at the expense of temporal smoothing.'};
            app.AutoGuessRecoCheckBox = uicheckbox(app.RecoParametersTab);
            app.AutoGuessRecoCheckBox.Tooltip = {'Automatically guess of type of reconstruction'};
            app.AutoGuessRecoCheckBox.Text = '';
            app.MatlabRecoCheckBox = uicheckbox(app.RecoParametersTab);
            app.MatlabRecoCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.MatlabRecoCheckBox.Tooltip = {'Force Matlab reconstruction'};
            app.MatlabRecoCheckBox.Text = '';
            app.RecoPresetsButtonGroup = uibuttongroup(app.RecoParametersTab);
            app.RecoPresetsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RecoPresetsButtonGroupSelectionChanged, true);
            app.RecoPresetsButtonGroup.Tooltip = {'Preset reconstruction settings'};
            app.RecoPresetsButtonGroup.ButtonDownFcn = createCallbackFcn(app, @RecoPresetsButtonGroupButtonDown, true);
            app.ScoutButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.ScoutButton.Enable = 'off';
            app.ScoutButton.Tooltip = {'Single frame scout reconstruction'};
            app.ScoutButton.Text = 'scout';
            app.ScoutButton.Interpreter = 'html';
            app.SystolicFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.SystolicFunctionButton.Enable = 'off';
            app.SystolicFunctionButton.Tooltip = {'Suggested reconstruction settings for measuring systolic function'};
            app.SystolicFunctionButton.Text = 'systolic function';
            app.SystolicFunctionButton.Interpreter = 'html';
            app.SystolicFunctionButton.Value = true;
            app.DiastolicFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.DiastolicFunctionButton.Enable = 'off';
            app.DiastolicFunctionButton.Tooltip = {'Suggested reconstruction settings for measuring diastolic function'};
            app.DiastolicFunctionButton.Text = 'diastolic function';
            app.DiastolicFunctionButton.Interpreter = 'html';
            app.RespFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.RespFunctionButton.Enable = 'off';
            app.RespFunctionButton.Tooltip = {'Suggested reconstruction settings for measuring respiratory motion'};
            app.RespFunctionButton.Text = 'respiratory function';
            app.RespFunctionButton.Interpreter = 'html';
            app.RealTimeFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.RealTimeFunctionButton.Enable = 'off';
            app.RealTimeFunctionButton.Tooltip = {'Suggested reconstruction settings for realtime imaging = CINE movie per heartbeat'};
            app.RealTimeFunctionButton.Text = 'realtime';
            app.RealTimeFunctionButton.Interpreter = 'html';
            app.VfaFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.VfaFunctionButton.Enable = 'off';
            app.VfaFunctionButton.Tooltip = {'Suggested reconstruction settings for T1-mapping with the variable-flip-angle method'};
            app.VfaFunctionButton.Text = 'variable flip-angle';
            app.VfaFunctionButton.Interpreter = 'html';
            app.MultiEchoButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.MultiEchoButton.Enable = 'off';
            app.MultiEchoButton.Text = 'multi-echo';
            app.MultiEchoButton.Interpreter = 'html';
            app.WVxyzEditFieldLabel = uilabel(app.RecoParametersTab);
            app.WVxyzEditFieldLabel.Enable = 'off';
            app.WVxyzEditFieldLabel.Tooltip = {'Wavelet L1-norm regularization in the spatial dimensions'};
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.TVxyzEditFieldLabel = uilabel(app.RecoParametersTab);
            app.TVxyzEditFieldLabel.Enable = 'off';
            app.TVxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.LLRxyzEditFieldLabel = uilabel(app.RecoParametersTab);
            app.LLRxyzEditFieldLabel.Enable = 'off';
            app.LLRxyzEditFieldLabel.Tooltip = {'Locally Low-Rank regularization in spatial domain'};
            app.LLRxyzEditFieldLabel.Text = 'LLRxyz';
            app.TVcineEditFieldLabel = uilabel(app.RecoParametersTab);
            app.TVcineEditFieldLabel.Enable = 'off';
            app.TVcineEditFieldLabel.Tooltip = {'Total-variation regularization in the CINE dimension'};
            app.TVcineEditFieldLabel.Text = 'TVcine';
            app.TVdynEditFieldLabel = uilabel(app.RecoParametersTab);
            app.TVdynEditFieldLabel.Enable = 'off';
            app.TVdynEditFieldLabel.Tooltip = {'Total-variation regularization in the dynamic dimension'};
            app.TVdynEditFieldLabel.Text = 'TVdyn';
            app.ESPIRiTCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.ESPIRiTCheckBoxLabel.Enable = 'off';
            app.ESPIRiTCheckBoxLabel.Tooltip = {'ESPIRiT reconstruction of multi-coil data'};
            app.ESPIRiTCheckBoxLabel.Text = 'ESPIRiT';
            app.NLMFCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.NLMFCheckBoxLabel.Enable = 'off';
            app.NLMFCheckBoxLabel.Tooltip = {'Non-local means filter image de-noising'};
            app.NLMFCheckBoxLabel.Text = 'NLMF';
            app.RingRmCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.RingRmCheckBoxLabel.Enable = 'off';
            app.RingRmCheckBoxLabel.Tooltip = {'Gibbs ringing suppression'};
            app.RingRmCheckBoxLabel.Text = 'de-ring';
            app.ZeroFillCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.ZeroFillCheckBoxLabel.Enable = 'off';
            app.ZeroFillCheckBoxLabel.Tooltip = {'Automatic zero-filling to next power of 2'};
            app.ZeroFillCheckBoxLabel.Text = 'zero-fill';
            app.TukeyFilterCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.TukeyFilterCheckBoxLabel.Enable = 'off';
            app.TukeyFilterCheckBoxLabel.Tooltip = {'Apply k-space Tukey filter'};
            app.TukeyFilterCheckBoxLabel.Text = 'Tukey filter';
            app.SharingEditFieldLabel = uilabel(app.RecoParametersTab);
            app.SharingEditFieldLabel.Enable = 'off';
            app.SharingEditFieldLabel.Text = '#frames';
            app.AutoGuessRecoCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.AutoGuessRecoCheckBoxLabel.Tooltip = {'Automatically guess of type of reconstruction'};
            app.AutoGuessRecoCheckBoxLabel.Text = 'guess reco';
            app.MatlabRecoCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.MatlabRecoCheckBoxLabel.Tooltip = {'Force Matlab reconstruction'};
            app.MatlabRecoCheckBoxLabel.Text = 'Matlab reco';
            app.CSrecoLabel = uilabel(app.RecoParametersTab);
            app.CSrecoLabel.Text = 'CS reco';
            app.FramesharingLabel = uilabel(app.RecoParametersTab);
            app.FramesharingLabel.Text = 'Frame sharing';
            app.ReconstructionPresetsLabel = uilabel(app.RecoParametersTab);
            app.ReconstructionPresetsLabel.Tooltip = {'Preset reconstruction settings'};
            app.ReconstructionPresetsLabel.Text = 'Reconstruction presets';
            app.VariousLabel = uilabel(app.RecoParametersTab);
            app.VariousLabel.Text = 'Various';
            app.FilteringLabel = uilabel(app.RecoParametersTab);
            app.FilteringLabel.Text = 'Filtering';
            app.RadialTab = uitab(app.ParametersTabGroup);
            app.RadialTab.Title = 'Radial';
            app.GradDelayCalibrationCheckBox = uicheckbox(app.RadialTab);
            app.GradDelayCalibrationCheckBox.Tooltip = {'Automatic calibration of gradient delays'};
            app.GradDelayCalibrationCheckBox.Enable = 'off';
            app.GradDelayCalibrationCheckBox.Text = '';
            app.GradDelayCalibrationCheckBoxLabel = uilabel(app.RadialTab);
            app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
            app.GradDelayCalibrationCheckBoxLabel.Tooltip = {'Automatic calibration of gradient delays'};
            app.GradDelayCalibrationCheckBoxLabel.Text = 'calibration';
            app.GradientdelaysLabel = uilabel(app.RadialTab);
            app.GradientdelaysLabel.Tooltip = {'Gradient delay settings'};
            app.GradientdelaysLabel.Text = 'Gradient delays';
            app.GxDelayEditFieldLabel = uilabel(app.RadialTab);
            app.GxDelayEditFieldLabel.Enable = 'off';
            app.GxDelayEditFieldLabel.Text = 'Gx delay';
            app.GxDelayEditField = uieditfield(app.RadialTab, 'numeric');
            app.GxDelayEditField.Limits = [-10 10];
            app.GxDelayEditField.ValueDisplayFormat = '%.5f';
            app.GxDelayEditField.Enable = 'off';
            app.GxDelayEditField.Tooltip = {'X-gradient delay'};
            app.GyDelayEditFieldLabel = uilabel(app.RadialTab);
            app.GyDelayEditFieldLabel.Enable = 'off';
            app.GyDelayEditFieldLabel.Text = 'Gy delay';
            app.GyDelayEditField = uieditfield(app.RadialTab, 'numeric');
            app.GyDelayEditField.Limits = [-10 10];
            app.GyDelayEditField.ValueDisplayFormat = '%.5f';
            app.GyDelayEditField.Enable = 'off';
            app.GyDelayEditField.Tooltip = {'Y-gradient delay'};
            app.GzDelayEditFieldLabel = uilabel(app.RadialTab);
            app.GzDelayEditFieldLabel.Enable = 'off';
            app.GzDelayEditFieldLabel.Text = 'Gz delay';
            app.GzDelayEditField = uieditfield(app.RadialTab, 'numeric');
            app.GzDelayEditField.Limits = [-10 10];
            app.GzDelayEditField.ValueDisplayFormat = '%.5f';
            app.GzDelayEditField.Enable = 'off';
            app.GzDelayEditField.Tooltip = {'Z-gradient delay'};
            app.DataOffsetRadialEditFieldLabel = uilabel(app.RadialTab);
            app.DataOffsetRadialEditFieldLabel.Enable = 'off';
            app.DataOffsetRadialEditFieldLabel.Tooltip = {'Number of discarded datapoints at the beginning of UTE readout'};
            app.DataOffsetRadialEditFieldLabel.Text = 'data offset';
            app.DataOffsetRadialEditField = uieditfield(app.RadialTab, 'numeric');
            app.DataOffsetRadialEditField.Limits = [0 10];
            app.DataOffsetRadialEditField.ValueDisplayFormat = '%.0f';
            app.DataOffsetRadialEditField.ValueChangedFcn = createCallbackFcn(app, @TrajectorySettingsEditFieldValueChanged, true);
            app.DataOffsetRadialEditField.Enable = 'off';
            app.DataOffsetRadialEditField.Tooltip = {'Number of discarded datapoints at the beginning of UTE readout'};
            app.RingMethodCheckBox = uicheckbox(app.RadialTab);
            app.RingMethodCheckBox.Tooltip = {'Gradient delays estimated with Bart estdelay, the method of Block and Uecker (not RING), for 2D radial data'};
            app.RingMethodCheckBox.Enable = 'off';
            app.RingMethodCheckBox.Text = '';
            app.RingMethodCheckBoxLabel = uilabel(app.RadialTab);
            app.RingMethodCheckBoxLabel.Enable = 'off';
            app.RingMethodCheckBoxLabel.Tooltip = {'Gradient delays estimated with Bart estdelay, the method of Block and Uecker (not RING), for 2D radial data'};
            app.RingMethodCheckBoxLabel.Text = 'ring method';
            app.RadialTrajectoryLabel = uilabel(app.RadialTab);
            app.RadialTrajectoryLabel.Tooltip = {'2D radial trajectory'};
            app.RadialTrajectoryLabel.Text = 'Radial trajectory';
            app.PhaseCorrectCheckBox = uicheckbox(app.RadialTab);
            app.PhaseCorrectCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrajectorySettingsEditFieldValueChanged, true);
            app.PhaseCorrectCheckBox.Tooltip = {'Check to apply phase correction'};
            app.PhaseCorrectCheckBox.Enable = 'off';
            app.PhaseCorrectCheckBox.Text = '';
            app.PhaseCorrectCheckBox.Value = true;
            app.PhaseCorrectCheckBoxLabel = uilabel(app.RadialTab);
            app.PhaseCorrectCheckBoxLabel.Enable = 'off';
            app.PhaseCorrectCheckBoxLabel.Tooltip = {'Check to apply phase correction'};
            app.PhaseCorrectCheckBoxLabel.Text = 'phase correction';
            app.CenterEchoCheckBox = uicheckbox(app.RadialTab);
            app.CenterEchoCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrajectorySettingsEditFieldValueChanged, true);
            app.CenterEchoCheckBox.Tooltip = {'Check to center the echo'};
            app.CenterEchoCheckBox.Enable = 'off';
            app.CenterEchoCheckBox.Text = '';
            app.CenterEchoCheckBoxLabel = uilabel(app.RadialTab);
            app.CenterEchoCheckBoxLabel.Enable = 'off';
            app.CenterEchoCheckBoxLabel.Tooltip = {'Check to center the echo'};
            app.CenterEchoCheckBoxLabel.Text = 'center echo';
            app.DensityCorrectCheckBox = uicheckbox(app.RadialTab);
            app.DensityCorrectCheckBox.Tooltip = {'Check to apply k-space density correction'};
            app.DensityCorrectCheckBox.Enable = 'off';
            app.DensityCorrectCheckBox.Text = '';
            app.DensityCorrectCheckBoxLabel = uilabel(app.RadialTab);
            app.DensityCorrectCheckBoxLabel.Enable = 'off';
            app.DensityCorrectCheckBoxLabel.Tooltip = {'Check to apply k-space density correction'};
            app.DensityCorrectCheckBoxLabel.Text = 'density correction';
            app.ZTEFullEchoCheckBox = uicheckbox(app.RadialTab);
            app.ZTEFullEchoCheckBox.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.ZTEFullEchoCheckBox.Tooltip = {'ZTE full echo reconstruction'};
            app.ZTEFullEchoCheckBox.Enable = 'off';
            app.ZTEFullEchoCheckBox.Text = '';
            app.ZTEFullEchoCheckBoxLabel = uilabel(app.RadialTab);
            app.ZTEFullEchoCheckBoxLabel.Enable = 'off';
            app.ZTEFullEchoCheckBoxLabel.Tooltip = {'ZTE full echo reconstruction'};
            app.ZTEFullEchoCheckBoxLabel.Text = 'full echo';
            app.ZTEDeadTimeEditField = uieditfield(app.RadialTab, 'numeric');
            app.ZTEDeadTimeEditField.Limits = [0 20];
            app.ZTEDeadTimeEditField.ValueDisplayFormat = '%.1f';
            app.ZTEDeadTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.ZTEDeadTimeEditField.Enable = 'off';
            app.ZTEDeadTimeEditField.Tooltip = {'ZTE receiver dead time'};
            app.ZTEDeadTimeEditField.Value = 14;
            app.ZTEDeadTimeEditFieldLabel = uilabel(app.RadialTab);
            app.ZTEDeadTimeEditFieldLabel.Enable = 'off';
            app.ZTEDeadTimeEditFieldLabel.Text = 'dead time (μs)';
            app.ZTEShiftEditField = uieditfield(app.RadialTab, 'numeric');
            app.ZTEShiftEditField.Limits = [1 7];
            app.ZTEShiftEditField.RoundFractionalValues = 'on';
            app.ZTEShiftEditField.ValueDisplayFormat = '%.0f';
            app.ZTEShiftEditField.ValueChangedFcn = createCallbackFcn(app, @ZTEShiftEditFieldValueChanged, true);
            app.ZTEShiftEditField.Enable = 'off';
            app.ZTEShiftEditField.Tooltip = {'ZTE sample shift'};
            app.ZTEShiftEditField.Value = 4;
            app.ZTEShiftEditFieldLabel = uilabel(app.RadialTab);
            app.ZTEShiftEditFieldLabel.Enable = 'off';
            app.ZTEShiftEditFieldLabel.Text = 'shift';
            app.ZTELabel = uilabel(app.RadialTab);
            app.ZTELabel.Tooltip = {'2D radial trajectory'};
            app.ZTELabel.Text = 'ZTE';
            app.NavAnalysisTabGroup = uitabgroup(app.UIFigure);
            app.NavAnalysisTab = uitab(app.NavAnalysisTabGroup);
            app.NavAnalysisTab.Title = 'Navigator analysis';
            app.RespRateTimeFig = uiaxes(app.NavAnalysisTab);
            title(app.RespRateTimeFig, 'Respiration rate')
            xlabel(app.RespRateTimeFig, 'Time (s)')
            ylabel(app.RespRateTimeFig, 'Rate (bpm)')
            app.HeartRateTimeFig = uiaxes(app.NavAnalysisTab);
            title(app.HeartRateTimeFig, 'Heart rate')
            xlabel(app.HeartRateTimeFig, 'Time (s)')
            ylabel(app.HeartRateTimeFig, 'Rate (bpm)')
            app.RespNavFig = uiaxes(app.NavAnalysisTab);
            title(app.RespNavFig, 'Respiration navigator')
            xlabel(app.RespNavFig, 'Time (s)')
            ylabel(app.RespNavFig, 'Signal')
            app.RespNavFig.YTick = [];
            app.HeartNavFig = uiaxes(app.NavAnalysisTab);
            title(app.HeartNavFig, 'Heart navigator')
            xlabel(app.HeartNavFig, 'Time (s)')
            ylabel(app.HeartNavFig, 'Signal')
            app.HeartNavFig.YTick = [];
            app.DisplayRangeLabel = uilabel(app.NavAnalysisTab);
            app.DisplayRangeLabel.Tooltip = {'Selects the starting point and range of the heart- and respiratory-navigator windows'};
            app.DisplayRangeLabel.Text = 'Display range';
            app.startsLabel = uilabel(app.NavAnalysisTab);
            app.startsLabel.Enable = 'off';
            app.startsLabel.Tooltip = {''};
            app.startsLabel.Text = 'start (s)';
            app.StartEditField = uieditfield(app.NavAnalysisTab, 'numeric');
            app.StartEditField.Limits = [0 Inf];
            app.StartEditField.ValueDisplayFormat = '%.2f';
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayEditFieldValuesChanged, true);
            app.StartEditField.Enable = 'off';
            app.StartEditField.Tooltip = {'Start of plotted navigator window'};
            app.StartEditField.Value = 120;
            app.StartSlider = uislider(app.NavAnalysisTab);
            app.StartSlider.Limits = [0 6000];
            app.StartSlider.MajorTicks = [];
            app.StartSlider.ValueChangedFcn = createCallbackFcn(app, @DisplaySlidersValueChanged, true);
            app.StartSlider.MinorTicks = [0 300 600 900 1200 1500 1800 2100 2400 2700 3000 3300 3600 3900 4200 4500 4800 5100 5400 5700 6000];
            app.StartSlider.Enable = 'off';
            app.StartSlider.Tooltip = {'Start slider'};
            app.StartSlider.Value = 3000;
            app.RangeEditFieldLabel = uilabel(app.NavAnalysisTab);
            app.RangeEditFieldLabel.Enable = 'off';
            app.RangeEditFieldLabel.Tooltip = {''};
            app.RangeEditFieldLabel.Text = 'range (s)';
            app.RangeEditField = uieditfield(app.NavAnalysisTab, 'numeric');
            app.RangeEditField.Limits = [0 Inf];
            app.RangeEditField.ValueDisplayFormat = '%.2f';
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayEditFieldValuesChanged, true);
            app.RangeEditField.Enable = 'off';
            app.RangeEditField.Tooltip = {'Range of plotted navigator window'};
            app.RangeSlider = uislider(app.NavAnalysisTab);
            app.RangeSlider.Limits = [0 2000];
            app.RangeSlider.MajorTicks = [];
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @DisplaySlidersValueChanged, true);
            app.RangeSlider.MinorTicks = [0 100 200 300 400 500 600 700 800 900 1000 1100 1200 1300 1400 1500 1600 1700 1800 1900 2000];
            app.RangeSlider.Enable = 'off';
            app.RangeSlider.Tooltip = {'Range slider'};
            app.RangeSlider.Value = 1000;
            app.NavStartButton = uibutton(app.NavAnalysisTab, 'push');
            app.NavStartButton.ButtonPushedFcn = createCallbackFcn(app, @StartNavTimeButtonPushed, true);
            app.NavStartButton.Enable = 'off';
            app.NavStartButton.Tooltip = {'Start navigator-time movie'};
            app.NavStartButton.Text = ' ';
            app.NavStopButton = uibutton(app.NavAnalysisTab, 'push');
            app.NavStopButton.ButtonPushedFcn = createCallbackFcn(app, @StopNavTimeButtonPushed, true);
            app.NavStopButton.Enable = 'off';
            app.NavStopButton.Tooltip = {'Stop navigator-time movie'};
            app.NavStopButton.Text = ' ';
            app.NavFastSlowSwitch = uiswitch(app.NavAnalysisTab, 'rocker');
            app.NavFastSlowSwitch.Items = {'slow', 'fast'};
            app.NavFastSlowSwitch.ValueChangedFcn = createCallbackFcn(app, @NavFastSlowSwitchValueChanged, true);
            app.NavFastSlowSwitch.Enable = 'off';
            app.NavFastSlowSwitch.Tooltip = {'Navigator movie fast or slow'};
            app.NavFastSlowSwitch.Value = 'slow';
            app.IncludeCheckBox = uicheckbox(app.NavAnalysisTab);
            app.IncludeCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeCheckBoxValueChanged, true);
            app.IncludeCheckBox.Tooltip = {'Switch include window on/off'};
            app.IncludeCheckBox.Enable = 'off';
            app.IncludeCheckBox.Text = '';
            app.IncludeCheckBoxLabel = uilabel(app.NavAnalysisTab);
            app.IncludeCheckBoxLabel.Enable = 'off';
            app.IncludeCheckBoxLabel.Tooltip = {'Windows the data to reconstruct only part of the acquisition. '; 'Only the green selection area will be included. '; 'Window can be adjusted with the sliders.'};
            app.IncludeCheckBoxLabel.Text = 'Include';
            app.IncludeStartSlider = uislider(app.NavAnalysisTab);
            app.IncludeStartSlider.Limits = [1 6000];
            app.IncludeStartSlider.MajorTicks = [];
            app.IncludeStartSlider.ValueChangedFcn = createCallbackFcn(app, @IncludeSliderValueChanged, true);
            app.IncludeStartSlider.MinorTicks = [1 301 601 901 1201 1501 1801 2101 2401 2701 3001 3301 3601 3901 4201 4501 4801 5101 5401 5701 6000];
            app.IncludeStartSlider.Enable = 'off';
            app.IncludeStartSlider.Tooltip = {'Include data start'};
            app.IncludeStartSlider.Value = 1000;
            app.IncludeEndSlider = uislider(app.NavAnalysisTab);
            app.IncludeEndSlider.Limits = [1 6000];
            app.IncludeEndSlider.MajorTicks = [];
            app.IncludeEndSlider.ValueChangedFcn = createCallbackFcn(app, @IncludeSliderValueChanged, true);
            app.IncludeEndSlider.MinorTicks = [1 301 601 901 1201 1501 1801 2101 2401 2701 3001 3301 3601 3901 4201 4501 4801 5101 5401 5701 6000];
            app.IncludeEndSlider.Enable = 'off';
            app.IncludeEndSlider.Tooltip = {'Include data end'};
            app.IncludeEndSlider.Value = 1000;
            app.ExcludeCheckBox = uicheckbox(app.NavAnalysisTab);
            app.ExcludeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeCheckBoxValueChanged, true);
            app.ExcludeCheckBox.Tooltip = {'Switch exclude window on/off'};
            app.ExcludeCheckBox.Enable = 'off';
            app.ExcludeCheckBox.Text = '';
            app.ExcludeCheckBoxLabel = uilabel(app.NavAnalysisTab);
            app.ExcludeCheckBoxLabel.Enable = 'off';
            app.ExcludeCheckBoxLabel.Tooltip = {'Windows the data to exclude part of the acquisition. '; 'The red selection area will be excluded. '; 'Window can be adjusted with the sliders.'};
            app.ExcludeCheckBoxLabel.Text = 'Exclude';
            app.ExcludeStartSlider = uislider(app.NavAnalysisTab);
            app.ExcludeStartSlider.Limits = [1 6000];
            app.ExcludeStartSlider.MajorTicks = [];
            app.ExcludeStartSlider.ValueChangedFcn = createCallbackFcn(app, @ExcludeSliderValueChanged, true);
            app.ExcludeStartSlider.MinorTicks = [1 301 601 901 1201 1501 1801 2101 2401 2701 3001 3301 3601 3901 4201 4501 4801 5101 5401 5701 6000];
            app.ExcludeStartSlider.Enable = 'off';
            app.ExcludeStartSlider.Tooltip = {'Exclude data start'};
            app.ExcludeStartSlider.Value = 1000;
            app.ExcludeEndSlider = uislider(app.NavAnalysisTab);
            app.ExcludeEndSlider.Limits = [1 6000];
            app.ExcludeEndSlider.MajorTicks = [];
            app.ExcludeEndSlider.ValueChangedFcn = createCallbackFcn(app, @ExcludeSliderValueChanged, true);
            app.ExcludeEndSlider.MinorTicks = [1 301 601 901 1201 1501 1801 2101 2401 2701 3001 3301 3601 3901 4201 4501 4801 5101 5401 5701 6000];
            app.ExcludeEndSlider.Enable = 'off';
            app.ExcludeEndSlider.Tooltip = {'Exclude data end'};
            app.ExcludeEndSlider.Value = 1000;
            app.NavTabGroup = uitabgroup(app.UIFigure);
            app.NavTab = uitab(app.NavTabGroup);
            app.NavTab.Title = 'Navigator';
            app.Nav2Fig = uiaxes(app.NavTab);
            title(app.Nav2Fig, 'Respiration navigator')
            xlabel(app.Nav2Fig, 'Samples')
            ylabel(app.Nav2Fig, 'Signal')
            app.Nav2Fig.YTick = [];
            app.Nav1Fig = uiaxes(app.NavTab);
            title(app.Nav1Fig, 'Heart navigator')
            xlabel(app.Nav1Fig, 'Samples')
            ylabel(app.Nav1Fig, 'Signal')
            app.Nav1Fig.YTick = [];
            app.NavEnd1EditFieldLabel = uilabel(app.NavTab);
            app.NavEnd1EditFieldLabel.Enable = 'off';
            app.NavEnd1EditFieldLabel.Tooltip = {'Primary navigator point.'};
            app.NavEnd1EditFieldLabel.Text = 'primary';
            app.NavEnd1EditField = uieditfield(app.NavTab, 'numeric');
            app.NavEnd1EditField.Limits = [1 1024];
            app.NavEnd1EditField.ValueDisplayFormat = '%.0f';
            app.NavEnd1EditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavEnd1EditField.Enable = 'off';
            app.NavEnd1EditField.Tooltip = {'Primary navigator point analyzed'};
            app.NavEnd1EditField.Value = 10;
            app.NavNRPoints1EditFieldLabel = uilabel(app.NavTab);
            app.NavNRPoints1EditFieldLabel.Enable = 'off';
            app.NavNRPoints1EditFieldLabel.Tooltip = {'Number of points that are considered for navigator analysis.'};
            app.NavNRPoints1EditFieldLabel.Text = '#points';
            app.NavNRPoints1EditField = uieditfield(app.NavTab, 'numeric');
            app.NavNRPoints1EditField.Limits = [1 24];
            app.NavNRPoints1EditField.ValueDisplayFormat = '%.0f';
            app.NavNRPoints1EditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavNRPoints1EditField.Enable = 'off';
            app.NavNRPoints1EditField.Tooltip = {'Number of navigator points analyzed'};
            app.NavNRPoints1EditField.Value = 1;
            app.Nav1ButtonGroup = uibuttongroup(app.NavTab);
            app.Nav1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NavButtonGroupSelectionChanged, true);
            app.SliceFID1Button = uiradiobutton(app.Nav1ButtonGroup);
            app.SliceFID1Button.Enable = 'off';
            app.SliceFID1Button.Tooltip = {'Slice FID navigator'};
            app.SliceFID1Button.Text = 'slice FID';
            app.SliceFID1Button.Interpreter = 'html';
            app.SliceFID1Button.Value = true;
            app.ExtFID1Button = uiradiobutton(app.Nav1ButtonGroup);
            app.ExtFID1Button.Enable = 'off';
            app.ExtFID1Button.Tooltip = {'External FID navigator'};
            app.ExtFID1Button.Text = 'external FID';
            app.ExtFID1Button.Interpreter = 'html';
            app.ExtECHO1Button = uiradiobutton(app.Nav1ButtonGroup);
            app.ExtECHO1Button.Enable = 'off';
            app.ExtECHO1Button.Tooltip = {'External ECHO navigator (readout profile)'};
            app.ExtECHO1Button.Text = 'external ECHO';
            app.ExtECHO1Button.Interpreter = 'html';
            app.Nav2ButtonGroup = uibuttongroup(app.NavTab);
            app.Nav2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NavButtonGroupSelectionChanged, true);
            app.SliceFID2Button = uiradiobutton(app.Nav2ButtonGroup);
            app.SliceFID2Button.Enable = 'off';
            app.SliceFID2Button.Tooltip = {'Slice FID navigator'};
            app.SliceFID2Button.Text = 'slice FID';
            app.SliceFID2Button.Interpreter = 'html';
            app.SliceFID2Button.Value = true;
            app.ExtFID2Button = uiradiobutton(app.Nav2ButtonGroup);
            app.ExtFID2Button.Enable = 'off';
            app.ExtFID2Button.Tooltip = {'External FID navigator'};
            app.ExtFID2Button.Text = 'external FID';
            app.ExtFID2Button.Interpreter = 'html';
            app.ExtECHO2Button = uiradiobutton(app.Nav2ButtonGroup);
            app.ExtECHO2Button.Enable = 'off';
            app.ExtECHO2Button.Tooltip = {'External ECHO navigator (readout profile)'};
            app.ExtECHO2Button.Text = 'external ECHO';
            app.ExtECHO2Button.Interpreter = 'html';
            app.NavEnd2EditFieldLabel = uilabel(app.NavTab);
            app.NavEnd2EditFieldLabel.Enable = 'off';
            app.NavEnd2EditFieldLabel.Tooltip = {'Primary navigator point.'};
            app.NavEnd2EditFieldLabel.Text = 'primary';
            app.NavEnd2EditField = uieditfield(app.NavTab, 'numeric');
            app.NavEnd2EditField.Limits = [1 1024];
            app.NavEnd2EditField.ValueDisplayFormat = '%.0f';
            app.NavEnd2EditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavEnd2EditField.Enable = 'off';
            app.NavEnd2EditField.Tooltip = {'Primary navigator point analyzed'};
            app.NavEnd2EditField.Value = 10;
            app.NavNRPoints2EditFieldLabel = uilabel(app.NavTab);
            app.NavNRPoints2EditFieldLabel.Enable = 'off';
            app.NavNRPoints2EditFieldLabel.Tooltip = {'Number of points that are considered for navigator analysis.'};
            app.NavNRPoints2EditFieldLabel.Text = '#points';
            app.NavNRPoints2EditField = uieditfield(app.NavTab, 'numeric');
            app.NavNRPoints2EditField.Limits = [1 24];
            app.NavNRPoints2EditField.ValueDisplayFormat = '%.0f';
            app.NavNRPoints2EditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavNRPoints2EditField.Enable = 'off';
            app.NavNRPoints2EditField.Tooltip = {'Number of navigator points analyzed'};
            app.NavNRPoints2EditField.Value = 1;
            app.StartMotionButton = uibutton(app.NavTab, 'push');
            app.StartMotionButton.ButtonPushedFcn = createCallbackFcn(app, @StartRawNavButtonPushed, true);
            app.StartMotionButton.Enable = 'off';
            app.StartMotionButton.Tooltip = {'Start raw navigator movie'};
            app.StartMotionButton.Text = ' ';
            app.StopMotionButton = uibutton(app.NavTab, 'push');
            app.StopMotionButton.ButtonPushedFcn = createCallbackFcn(app, @StopRawNavButtonPushed, true);
            app.StopMotionButton.Enable = 'off';
            app.StopMotionButton.Tooltip = {'Stop raw navigator movie'};
            app.StopMotionButton.Text = ' ';
            app.RespCoilSpinner1 = uispinner(app.NavTab);
            app.RespCoilSpinner1.Limits = [1 16];
            app.RespCoilSpinner1.ValueDisplayFormat = '%.0f';
            app.RespCoilSpinner1.ValueChangedFcn = createCallbackFcn(app, @NavCoilSpinnerValueChanged, true);
            app.RespCoilSpinner1.Enable = 'off';
            app.RespCoilSpinner1.Tooltip = {'RF receiver coil number'};
            app.RespCoilSpinner1.Value = 1;
            app.RespCoilSpinner1Label = uilabel(app.NavTab);
            app.RespCoilSpinner1Label.Enable = 'off';
            app.RespCoilSpinner1Label.Tooltip = {'Toggles between RF-coils to inspect the raw navigator signals.'};
            app.RespCoilSpinner1Label.Text = 'coil';
            app.HeartCoilSpinner1 = uispinner(app.NavTab);
            app.HeartCoilSpinner1.Limits = [1 16];
            app.HeartCoilSpinner1.ValueDisplayFormat = '%.0f';
            app.HeartCoilSpinner1.ValueChangedFcn = createCallbackFcn(app, @NavCoilSpinnerValueChanged, true);
            app.HeartCoilSpinner1.Enable = 'off';
            app.HeartCoilSpinner1.Tooltip = {'RF receiver coil number'};
            app.HeartCoilSpinner1.Value = 1;
            app.HeartCoilSpinner1Label = uilabel(app.NavTab);
            app.HeartCoilSpinner1Label.Enable = 'off';
            app.HeartCoilSpinner1Label.Tooltip = {'Toggles between RF-coils to inspect the raw navigator signals.'};
            app.HeartCoilSpinner1Label.Text = 'coil';
            app.HeartTab = uitab(app.NavTabGroup);
            app.HeartTab.Tooltip = {'Raw heart navigator and its power spectrum'};
            app.HeartTab.Title = 'Heart';
            app.HeartRawNavFig = uiaxes(app.HeartTab);
            title(app.HeartRawNavFig, 'Raw navigator')
            xlabel(app.HeartRawNavFig, 'Time (s)')
            ylabel(app.HeartRawNavFig, 'Signal')
            app.HeartRawNavFig.YTick = [];
            app.HeartSpectrumFig = uiaxes(app.HeartTab);
            title(app.HeartSpectrumFig, 'Navigator Spectrum')
            xlabel(app.HeartSpectrumFig, 'Frequency (bpm)')
            ylabel(app.HeartSpectrumFig, 'Power')
            app.HeartCoilSpinner2 = uispinner(app.HeartTab);
            app.HeartCoilSpinner2.Limits = [1 16];
            app.HeartCoilSpinner2.ValueDisplayFormat = '%.0f';
            app.HeartCoilSpinner2.ValueChangedFcn = createCallbackFcn(app, @NavCoilSpinnerValueChanged, true);
            app.HeartCoilSpinner2.Enable = 'off';
            app.HeartCoilSpinner2.Tooltip = {'RF receiver coil number'};
            app.HeartCoilSpinner2.Value = 1;
            app.HeartCoilSpinner2Label = uilabel(app.HeartTab);
            app.HeartCoilSpinner2Label.Enable = 'off';
            app.HeartCoilSpinner2Label.Tooltip = {'Toggles between RF-coils to inspect the raw navigator signals.'};
            app.HeartCoilSpinner2Label.Text = 'coil';
            app.HeartLogLinButton = uibutton(app.HeartTab, 'push');
            app.HeartLogLinButton.ButtonPushedFcn = createCallbackFcn(app, @HeartLogLinButtonPushed, true);
            app.HeartLogLinButton.Enable = 'off';
            app.HeartLogLinButton.Text = 'lin';
            app.HeartNavFlipSwitchLabel = uilabel(app.HeartTab);
            app.HeartNavFlipSwitchLabel.Enable = 'off';
            app.HeartNavFlipSwitchLabel.Text = 'flip';
            app.HeartNavFlipSwitch = uiswitch(app.HeartTab, 'toggle');
            app.HeartNavFlipSwitch.Items = {'Down', 'Up'};
            app.HeartNavFlipSwitch.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.HeartNavFlipSwitch.Enable = 'off';
            app.HeartNavFlipSwitch.Tooltip = {'Flip navigator signal'};
            app.HeartNavFlipSwitch.Value = 'Down';
            app.HeartMinEditFieldLabel = uilabel(app.HeartTab);
            app.HeartMinEditFieldLabel.Enable = 'off';
            app.HeartMinEditFieldLabel.Text = 'min';
            app.HeartMinEditField = uieditfield(app.HeartTab, 'numeric');
            app.HeartMinEditField.Limits = [0 1000];
            app.HeartMinEditField.RoundFractionalValues = 'on';
            app.HeartMinEditField.ValueDisplayFormat = '%.0f';
            app.HeartMinEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrumEditFieldValueChanged, true);
            app.HeartMinEditField.Enable = 'off';
            app.HeartMinEditField.Tooltip = {'Minimum frequency'};
            app.HeartMinEditField.Value = 300;
            app.HeartMaxEditFieldLabel = uilabel(app.HeartTab);
            app.HeartMaxEditFieldLabel.Enable = 'off';
            app.HeartMaxEditFieldLabel.Text = 'max';
            app.HeartMaxEditField = uieditfield(app.HeartTab, 'numeric');
            app.HeartMaxEditField.Limits = [0 1000];
            app.HeartMaxEditField.RoundFractionalValues = 'on';
            app.HeartMaxEditField.ValueDisplayFormat = '%.0f';
            app.HeartMaxEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrumEditFieldValueChanged, true);
            app.HeartMaxEditField.Enable = 'off';
            app.HeartMaxEditField.Tooltip = {'Maximum frequency'};
            app.HeartMaxEditField.Value = 700;
            app.RespTab = uitab(app.NavTabGroup);
            app.RespTab.Title = 'Respiration';
            app.RespRawNavFig = uiaxes(app.RespTab);
            title(app.RespRawNavFig, 'Raw navigator')
            xlabel(app.RespRawNavFig, 'Time (s)')
            ylabel(app.RespRawNavFig, 'Signal')
            app.RespRawNavFig.YTick = [];
            app.RespSpectrumFig = uiaxes(app.RespTab);
            title(app.RespSpectrumFig, 'Navigator Spectrum')
            xlabel(app.RespSpectrumFig, 'Frequency (bpm)')
            ylabel(app.RespSpectrumFig, 'Power')
            app.RespCoilSpinner2 = uispinner(app.RespTab);
            app.RespCoilSpinner2.Limits = [1 16];
            app.RespCoilSpinner2.ValueDisplayFormat = '%.0f';
            app.RespCoilSpinner2.ValueChangedFcn = createCallbackFcn(app, @NavCoilSpinnerValueChanged, true);
            app.RespCoilSpinner2.Enable = 'off';
            app.RespCoilSpinner2.Tooltip = {'RF receiver coil number'};
            app.RespCoilSpinner2.Value = 1;
            app.RespCoilSpinner2Label = uilabel(app.RespTab);
            app.RespCoilSpinner2Label.Enable = 'off';
            app.RespCoilSpinner2Label.Tooltip = {'Toggles between RF-coils to inspect the raw navigator signals.'};
            app.RespCoilSpinner2Label.Text = 'coil';
            app.RespLogLinButton = uibutton(app.RespTab, 'push');
            app.RespLogLinButton.ButtonPushedFcn = createCallbackFcn(app, @RespLogLinButtonPushed, true);
            app.RespLogLinButton.Enable = 'off';
            app.RespLogLinButton.Text = 'lin';
            app.RespNavFlipSwitchLabel = uilabel(app.RespTab);
            app.RespNavFlipSwitchLabel.Enable = 'off';
            app.RespNavFlipSwitchLabel.Text = 'flip';
            app.RespNavFlipSwitch = uiswitch(app.RespTab, 'toggle');
            app.RespNavFlipSwitch.Items = {'Down', 'Up'};
            app.RespNavFlipSwitch.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.RespNavFlipSwitch.Enable = 'off';
            app.RespNavFlipSwitch.Tooltip = {'Flip navigator signal'};
            app.RespNavFlipSwitch.Value = 'Down';
            app.RespMinEditFieldLabel = uilabel(app.RespTab);
            app.RespMinEditFieldLabel.Enable = 'off';
            app.RespMinEditFieldLabel.Text = 'min';
            app.RespMinEditField = uieditfield(app.RespTab, 'numeric');
            app.RespMinEditField.Limits = [0 1000];
            app.RespMinEditField.RoundFractionalValues = 'on';
            app.RespMinEditField.ValueDisplayFormat = '%.0f';
            app.RespMinEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrumEditFieldValueChanged, true);
            app.RespMinEditField.Enable = 'off';
            app.RespMinEditField.Tooltip = {'Minimum frequency'};
            app.RespMinEditField.Value = 10;
            app.RespMaxEditFieldLabel = uilabel(app.RespTab);
            app.RespMaxEditFieldLabel.Enable = 'off';
            app.RespMaxEditFieldLabel.Text = 'max';
            app.RespMaxEditField = uieditfield(app.RespTab, 'numeric');
            app.RespMaxEditField.Limits = [0 1000];
            app.RespMaxEditField.RoundFractionalValues = 'on';
            app.RespMaxEditField.ValueDisplayFormat = '%.0f';
            app.RespMaxEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrumEditFieldValueChanged, true);
            app.RespMaxEditField.Enable = 'off';
            app.RespMaxEditField.Tooltip = {'Maximum frequency'};
            app.RespMaxEditField.Value = 200;
            app.NavSettingsTab = uitab(app.NavTabGroup);
            app.NavSettingsTab.Title = 'Settings';
            app.DiscardEditField = uieditfield(app.NavSettingsTab, 'numeric');
            app.DiscardEditField.Limits = [0 1024];
            app.DiscardEditField.ValueDisplayFormat = '%.0f';
            app.DiscardEditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.DiscardEditField.Enable = 'off';
            app.DiscardEditField.Tooltip = {'Number of frequency encoding points discarded after navigator. Increase if FID artifact is present in movie.'};
            app.DiscardEditField.Value = 35;
            app.RespToggleCheckBox = uicheckbox(app.NavSettingsTab);
            app.RespToggleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RespirationToggleCheckBoxValueChanged, true);
            app.RespToggleCheckBox.Tooltip = {'Reconstruct cardiac CINE frames in expiration (not checked) or inspiration (checked)'};
            app.RespToggleCheckBox.Enable = 'off';
            app.RespToggleCheckBox.Text = '';
            app.IgnoreRespToggleCheckBox = uicheckbox(app.NavSettingsTab);
            app.IgnoreRespToggleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RespirationToggleCheckBoxValueChanged, true);
            app.IgnoreRespToggleCheckBox.Tooltip = {'Check to ignore respiration and use all data'};
            app.IgnoreRespToggleCheckBox.Enable = 'off';
            app.IgnoreRespToggleCheckBox.Text = '';
            app.AutoSoundsCheckBox = uicheckbox(app.NavSettingsTab);
            app.AutoSoundsCheckBox.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBox.Text = '';
            app.AutoSoundsCheckBox.Value = true;
            app.MessageLevelSpinner = uispinner(app.NavSettingsTab);
            app.MessageLevelSpinner.Limits = [1 3];
            app.MessageLevelSpinner.Value = 1;
            app.ColorsButtonGroup = uibuttongroup(app.NavSettingsTab);
            app.ColorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorsButtonGroupSelectionChanged, true);
            app.ColorsButtonGroup.Tooltip = {'App color scheme selection'};
            app.ClassicButton = uiradiobutton(app.ColorsButtonGroup);
            app.ClassicButton.Text = 'classic';
            app.ClassicButton.Interpreter = 'html';
            app.BlackWhiteButton = uiradiobutton(app.ColorsButtonGroup);
            app.BlackWhiteButton.Text = 'black/white';
            app.BlackWhiteButton.Interpreter = 'html';
            app.BlackWhiteButton.Value = true;
            app.PresetsLabel = uilabel(app.NavSettingsTab);
            app.PresetsLabel.Text = 'Presets';
            app.SpeciesButton = uibuttongroup(app.NavSettingsTab);
            app.SpeciesButton.SelectionChangedFcn = createCallbackFcn(app, @SpeciesButtonSelectionChanged, true);
            app.SpeciesButton.Tooltip = {'Preset navigator filter values for mouse and rat'};
            app.SpeciesButton.ButtonDownFcn = createCallbackFcn(app, @SpeciesButtonButtonDown, true);
            app.MouseButton = uiradiobutton(app.SpeciesButton);
            app.MouseButton.Enable = 'off';
            app.MouseButton.Tooltip = {'Mouse preset navigator filter settings'};
            app.MouseButton.Text = 'mouse';
            app.MouseButton.Interpreter = 'html';
            app.MouseButton.Value = true;
            app.RatButton = uiradiobutton(app.SpeciesButton);
            app.RatButton.Enable = 'off';
            app.RatButton.Tooltip = {'Rat preset navigator filter settings'};
            app.RatButton.Text = 'rat';
            app.RatButton.Interpreter = 'html';
            app.ZebraFishButton = uiradiobutton(app.SpeciesButton);
            app.ZebraFishButton.Enable = 'off';
            app.ZebraFishButton.Tooltip = {'Zebrafish preset navigator filter settings'};
            app.ZebraFishButton.Text = 'zebrafish';
            app.ZebraFishButton.Interpreter = 'html';
            app.FilterOrderEditField = uieditfield(app.NavSettingsTab, 'numeric');
            app.FilterOrderEditField.Limits = [1 6];
            app.FilterOrderEditField.ValueDisplayFormat = '%.0f';
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.FilterOrderEditField.Enable = 'off';
            app.FilterOrderEditField.Tooltip = {'Butterworth filter order'};
            app.FilterOrderEditField.Value = 4;
            app.MinHeartRateEditField = uieditfield(app.NavSettingsTab, 'numeric');
            app.MinHeartRateEditField.Limits = [1 1000];
            app.MinHeartRateEditField.ValueDisplayFormat = '%.0f';
            app.MinHeartRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MinHeartRateEditField.Enable = 'off';
            app.MinHeartRateEditField.Tooltip = {'Minimum of search range heart rate'};
            app.MinHeartRateEditField.Value = 100;
            app.MaxHeartRateEditField = uieditfield(app.NavSettingsTab, 'numeric');
            app.MaxHeartRateEditField.Limits = [1 1000];
            app.MaxHeartRateEditField.ValueDisplayFormat = '%.0f';
            app.MaxHeartRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MaxHeartRateEditField.Enable = 'off';
            app.MaxHeartRateEditField.Tooltip = {'Maximum of search range heart rate'};
            app.MaxHeartRateEditField.Value = 1000;
            app.MinRespRateEditField = uieditfield(app.NavSettingsTab, 'numeric');
            app.MinRespRateEditField.Limits = [1 1000];
            app.MinRespRateEditField.ValueDisplayFormat = '%.0f';
            app.MinRespRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MinRespRateEditField.Enable = 'off';
            app.MinRespRateEditField.Tooltip = {'Minimum search range respiration rate'};
            app.MinRespRateEditField.Value = 5;
            app.MaxRespRateEditField = uieditfield(app.NavSettingsTab, 'numeric');
            app.MaxRespRateEditField.Limits = [1 1000];
            app.MaxRespRateEditField.ValueDisplayFormat = '%.0f';
            app.MaxRespRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MaxRespRateEditField.Enable = 'off';
            app.MaxRespRateEditField.Tooltip = {'Maximum search range respiration rate'};
            app.MaxRespRateEditField.Value = 400;
            app.RespShiftEditField = uieditfield(app.NavSettingsTab, 'numeric');
            app.RespShiftEditField.Limits = [-100 100];
            app.RespShiftEditField.ValueDisplayFormat = '%.0f';
            app.RespShiftEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.RespShiftEditField.Enable = 'off';
            app.RespShiftEditField.Tooltip = {'Shifts the respiration window left or right'};
            app.FilterPerSliceCheckBox = uicheckbox(app.NavSettingsTab);
            app.FilterPerSliceCheckBox.Tooltip = {'Filter cardiac and respiratory motion for each slice separately'};
            app.FilterPerSliceCheckBox.Enable = 'off';
            app.FilterPerSliceCheckBox.Text = '';
            app.PhysioFilteringLabel = uilabel(app.NavSettingsTab);
            app.PhysioFilteringLabel.Text = 'Physio filtering';
            app.ColorsLabel = uilabel(app.NavSettingsTab);
            app.ColorsLabel.Text = 'Colors';
            app.SoundMessagesLabel = uilabel(app.NavSettingsTab);
            app.SoundMessagesLabel.Text = 'Sounds/messages';
            app.RespLabel = uilabel(app.NavSettingsTab);
            app.RespLabel.Text = 'Respiration';
            app.NavLabel2 = uilabel(app.NavSettingsTab);
            app.NavLabel2.Text = 'Navigator';
            app.DiscardEditFieldLabel = uilabel(app.NavSettingsTab);
            app.DiscardEditFieldLabel.Enable = 'off';
            app.DiscardEditFieldLabel.Text = 'freq Nx discard';
            app.RespToggleCheckBoxLabel = uilabel(app.NavSettingsTab);
            app.RespToggleCheckBoxLabel.Enable = 'off';
            app.RespToggleCheckBoxLabel.Tooltip = {'Reconstruct cardiac CINE frames in expiration (not checked) or inspiration (checked)'};
            app.RespToggleCheckBoxLabel.Text = 'toggle in/expiration';
            app.IgnoreRespToggleCheckBoxLabel = uilabel(app.NavSettingsTab);
            app.IgnoreRespToggleCheckBoxLabel.Enable = 'off';
            app.IgnoreRespToggleCheckBoxLabel.Tooltip = {'Check to ignore respiration and use all data'};
            app.IgnoreRespToggleCheckBoxLabel.Text = 'ignore respiration';
            app.AutoSoundsCheckBoxLabel = uilabel(app.NavSettingsTab);
            app.AutoSoundsCheckBoxLabel.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBoxLabel.Text = 'sounds on/off';
            app.MessageLevelSpinnerLabel = uilabel(app.NavSettingsTab);
            app.MessageLevelSpinnerLabel.Text = 'message level';
            app.FilterOrderEditFieldLabel = uilabel(app.NavSettingsTab);
            app.FilterOrderEditFieldLabel.Enable = 'off';
            app.FilterOrderEditFieldLabel.Tooltip = {'Butterworth filter order'};
            app.FilterOrderEditFieldLabel.Text = 'filter order';
            app.MinHeartRateEditFieldLabel = uilabel(app.NavSettingsTab);
            app.MinHeartRateEditFieldLabel.Enable = 'off';
            app.MinHeartRateEditFieldLabel.Tooltip = {'Minimum of search range heart rate'};
            app.MinHeartRateEditFieldLabel.Text = 'min heart rate';
            app.MaxHeartRateEditFieldLabel = uilabel(app.NavSettingsTab);
            app.MaxHeartRateEditFieldLabel.Enable = 'off';
            app.MaxHeartRateEditFieldLabel.Tooltip = {'Maximum of search range heart rate'};
            app.MaxHeartRateEditFieldLabel.Text = 'max heart rate';
            app.MinRespRateEditFieldLabel = uilabel(app.NavSettingsTab);
            app.MinRespRateEditFieldLabel.Enable = 'off';
            app.MinRespRateEditFieldLabel.Tooltip = {'Minimum search range respiration rate'};
            app.MinRespRateEditFieldLabel.Text = 'min resp rate';

            app.MaxRespRateEditFieldLabel = uilabel(app.NavSettingsTab);
            app.MaxRespRateEditFieldLabel.Enable = 'off';
            app.MaxRespRateEditFieldLabel.Tooltip = {'Maximum search range respiration rate'};
            app.MaxRespRateEditFieldLabel.Text = 'max resp rate';
            app.RespShiftEditFieldLabel = uilabel(app.NavSettingsTab);
            app.RespShiftEditFieldLabel.Enable = 'off';
            app.RespShiftEditFieldLabel.Tooltip = {'Shifts the respiration window left or right'};
            app.RespShiftEditFieldLabel.Text = 'resp shift (samples)';
            app.FilterPerSliceLabel = uilabel(app.NavSettingsTab);
            app.FilterPerSliceLabel.Enable = 'off';
            app.FilterPerSliceLabel.Tooltip = {'Filter cardiac and respiratory motion for each slice separately'};
            app.FilterPerSliceLabel.Text = 'filter per slice';
            app.DiscardCheckBox = uicheckbox(app.NavSettingsTab);
            app.DiscardCheckBox.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.DiscardCheckBox.Enable = 'off';
            app.DiscardCheckBox.Text = '';
            app.DashboardTabGroup = uitabgroup(app.UIFigure);
            app.DashboardTab = uitab(app.DashboardTabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.Lamp2 = uilamp(app.DashboardTab);
            app.Lamp3 = uilamp(app.DashboardTab);
            app.Lamp4 = uilamp(app.DashboardTab);
            app.Lamp5 = uilamp(app.DashboardTab);
            app.Lamp6 = uilamp(app.DashboardTab);
            app.Lamp7 = uilamp(app.DashboardTab);
            app.DetectedRatesBpmPanel = uipanel(app.DashboardTab);
            app.DetectedRatesBpmPanel.Title = 'Heart / respiration filters';
            app.HeartEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.HeartEditField.Limits = [1 1000];
            app.HeartEditField.AllowEmpty = 'on';
            app.HeartEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.HeartEditField.Enable = 'off';
            app.HeartEditField.Tooltip = {'Heart rate estimate'};
            app.HeartEditField.Value = 500;
            app.HeartWidthEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.HeartWidthEditField.Limits = [20 300];
            app.HeartWidthEditField.ValueDisplayFormat = '%.0f';
            app.HeartWidthEditField.AllowEmpty = 'on';
            app.HeartWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.HeartWidthEditField.Enable = 'off';
            app.HeartWidthEditField.Tooltip = {'Heart rate variation estimate'};
            app.HeartWidthEditField.Value = 100;
            app.RespEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.RespEditField.Limits = [1 1000];
            app.RespEditField.AllowEmpty = 'on';
            app.RespEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespEditField.Enable = 'off';
            app.RespEditField.Tooltip = {'Respiration rate estimate'};
            app.RespEditField.Value = 50;
            app.RespWidthEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.RespWidthEditField.Limits = [5 100];
            app.RespWidthEditField.AllowEmpty = 'on';
            app.RespWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespWidthEditField.Enable = 'off';
            app.RespWidthEditField.Tooltip = {'Respiration rate variation estimate'};
            app.RespWidthEditField.Value = 40;
            app.RespWindowEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.RespWindowEditField.Limits = [5 95];
            app.RespWindowEditField.ValueDisplayFormat = '%.0f';
            app.RespWindowEditField.AllowEmpty = 'on';
            app.RespWindowEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespWindowEditField.Enable = 'off';
            app.RespWindowEditField.Tooltip = {'Respiration discard window width in percentage of respiration period'};
            app.RespWindowEditField.Value = 30;
            app.ResetFilterValuesButton = uibutton(app.DetectedRatesBpmPanel, 'push');
            app.ResetFilterValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFilterValuesButtonPushed, true);
            app.ResetFilterValuesButton.Enable = 'off';
            app.ResetFilterValuesButton.Tooltip = {'Reset filters'};
            app.ResetFilterValuesButton.Text = 'reset';
            app.CenterLabel = uilabel(app.DetectedRatesBpmPanel);
            app.CenterLabel.Enable = 'off';
            app.CenterLabel.Text = 'center';
            app.WidthLabel = uilabel(app.DetectedRatesBpmPanel);
            app.WidthLabel.Enable = 'off';
            app.WidthLabel.Text = 'width';
            app.HeartEditFieldLabel = uilabel(app.DetectedRatesBpmPanel);
            app.HeartEditFieldLabel.Enable = 'off';
            app.HeartEditFieldLabel.Text = 'heart (bpm)';
            app.RespEditFieldLabel = uilabel(app.DetectedRatesBpmPanel);
            app.RespEditFieldLabel.Enable = 'off';
            app.RespEditFieldLabel.Text = 'resp. (bpm)';
            app.RespWindowEditFieldLabel = uilabel(app.DetectedRatesBpmPanel);
            app.RespWindowEditFieldLabel.Enable = 'off';
            app.RespWindowEditFieldLabel.Text = 'window (%)';
            app.CinePanel = uipanel(app.DashboardTab);
            app.CinePanel.Title = 'Cine';
            app.RecoTypeDropDown = uidropdown(app.CinePanel);
            app.RecoTypeDropDown.Items = {'cardiac', 'cardiac abs', 'respiratory', 'realtime'};
            app.RecoTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.RecoTypeDropDown.Enable = 'off';
            app.RecoTypeDropDown.Tooltip = {'Data sorting:'; '- cardiac phase binning'; '- cardiac absolute binning'; '- respiratory phase binning'; '- realtime, 1 dynamic per heart beat'};
            app.RecoTypeDropDown.Value = 'cardiac';
            app.FramesEditField = uieditfield(app.CinePanel, 'numeric');
            app.FramesEditField.Limits = [1 512];
            app.FramesEditField.RoundFractionalValues = 'on';
            app.FramesEditField.ValueDisplayFormat = '%.0f';
            app.FramesEditField.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.FramesEditField.Enable = 'off';
            app.FramesEditField.Tooltip = {'Number of desired CINE frames'};
            app.FramesEditField.Value = 15;
            app.DynamicsEditField = uieditfield(app.CinePanel, 'numeric');
            app.DynamicsEditField.Limits = [1 Inf];
            app.DynamicsEditField.RoundFractionalValues = 'on';
            app.DynamicsEditField.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.DynamicsEditField.Enable = 'off';
            app.DynamicsEditField.Tooltip = {'Number of desired dynamics'};
            app.DynamicsEditField.Value = 1;
            app.RecoTypeDropDownLabel = uilabel(app.CinePanel);
            app.RecoTypeDropDownLabel.Enable = 'off';
            app.RecoTypeDropDownLabel.Text = 'movie';
            app.FramesEditFieldLabel = uilabel(app.CinePanel);
            app.FramesEditFieldLabel.Enable = 'off';
            app.FramesEditFieldLabel.Text = '#CINE frames';
            app.DynamicsEditFieldLabel = uilabel(app.CinePanel);
            app.DynamicsEditFieldLabel.Enable = 'off';
            app.DynamicsEditFieldLabel.Tooltip = {'Number of desired dynamics'};
            app.DynamicsEditFieldLabel.Text = '#dynamics';
            app.ReconstructionPanel = uipanel(app.DashboardTab);
            app.ReconstructionPanel.Title = 'Reconstruction';
            app.ExportPanel = uipanel(app.DashboardTab);
            app.ExportPanel.Title = 'Export';
            app.LoadMRDButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.LoadMRDButton.Enable = 'off';
            app.LoadMRDButton.Tooltip = {'Select MRD file - '; 'for multicoil data select one of the MRD files'};
            app.LoadMRDButton.Text = ' Load MRD';
            app.FilterButton = uibutton(app.DashboardTab, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Enable = 'off';
            app.FilterButton.Tooltip = {'Filters the navigator signal to extract cardiac and respiratory triggering points'};
            app.FilterButton.Text = ' Filter';
            app.SortKspaceButton = uibutton(app.DashboardTab, 'push');
            app.SortKspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SortKspaceButtonPushed, true);
            app.SortKspaceButton.Enable = 'off';
            app.SortKspaceButton.Tooltip = {'Sort the data in the desired number of cardiac or respiratory frames'};
            app.SortKspaceButton.Text = 'Sort k-space';
            app.ReconstructButton = uibutton(app.DashboardTab, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @RecoMoviesButtonPushed, true);
            app.ReconstructButton.Enable = 'off';
            app.ReconstructButton.Tooltip = {'Reconstruct the CINE movie'};
            app.ReconstructButton.Text = 'Reco';
            app.ExportDCMButton = uibutton(app.DashboardTab, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Enable = 'off';
            app.ExportDCMButton.Tooltip = {'Export movie(s) to DICOM'};
            app.ExportDCMButton.Text = 'DCM / NIFTI';
            app.ExportGIFButton = uibutton(app.DashboardTab, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Enable = 'off';
            app.ExportGIFButton.Tooltip = {'Export movie(s) to animated GIF'};
            app.ExportGIFButton.Text = 'GIF';
            app.ExportMRDButton = uibutton(app.DashboardTab, 'push');
            app.ExportMRDButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMRDButtonPushed, true);
            app.ExportMRDButton.Enable = 'off';
            app.ExportMRDButton.Tooltip = {'Export movie(s) to a new MRD file'};
            app.ExportMRDButton.Text = 'MRD';
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Tooltip = {'Exit the application'};
            app.ExitButton.Text = '     Exit';
            app.ProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.ProgressGauge.MajorTicks = [0 25 50 75 100];
            app.ReconstructionTypeButtonGroup = uibuttongroup(app.DashboardTab);
            app.ReconstructionTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ReconstructionTypeButtonGroupSelectionChanged, true);
            app.CompressedSensingButton = uiradiobutton(app.ReconstructionTypeButtonGroup);
            app.CompressedSensingButton.Enable = 'off';
            app.CompressedSensingButton.Tooltip = {'Compressed sensing reconstruction'};
            app.CompressedSensingButton.Text = 'Compressed Sensing';
            app.CompressedSensingButton.Interpreter = 'html';
            app.CompressedSensingButton.Value = true;
            app.DeepLearningButton = uiradiobutton(app.ReconstructionTypeButtonGroup);
            app.DeepLearningButton.Enable = 'off';
            app.DeepLearningButton.Tooltip = {'Dynamic recurrent inference machine reconstruction.'; 'Requires 2D Cartesian data and 4 or more frames'};
            app.DeepLearningButton.Text = 'Deep Learning';
            app.DeepLearningButton.Interpreter = 'html';
            app.RetrospectiveCreditLabel = uilabel(app.UIFigure);
            app.RetrospectiveCreditLabel.Interpreter = 'html';
            app.RetrospectiveCreditLabel.Text = 'Retrospective can be credited by citing this paper:';
            app.JoveHyperlink = uihyperlink(app.UIFigure);
            app.JoveHyperlink.URL = 'https://www.jove.com/t/62595/quantification-mouse-heart-left-ventricular-function-myocardial';
            app.JoveHyperlink.Text = 'JOVE';
            app.RetrospectiveTitleImage = uiimage(app.UIFigure);
            app.AutoRecoCheckBox = uicheckbox(app.UIFigure);
            app.AutoRecoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoRecoCheckBoxValueChanged, true);
            app.AutoRecoCheckBox.Tooltip = {'Tick box for automatic reconstruction'};
            app.AutoRecoCheckBox.Text = '';
            app.AutoRecoLabel = uilabel(app.UIFigure);
            app.AutoRecoLabel.Tooltip = {'Tick box for automatic reconstruction'};
            app.AutoRecoLabel.Text = 'auto reco';
            app.MessageBox = uitextarea(app.UIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Messages'};
            app.MessageBox.Value = {'Messages ...'};
            app.GitHubHyperlink = uihyperlink(app.UIFigure);
            app.GitHubHyperlink.URL = 'https://github.com/Moby1971/';
            app.GitHubHyperlink.Text = 'GitHub';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = retrospective10

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end